classdef EMGLAB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadConditionsDirectoryMenu     matlab.ui.container.Menu
        LoadxdfRawEVAfilesMenu          matlab.ui.container.Menu
        OpenDIGMenu                     matlab.ui.container.Menu
        LoadDataTableMenu               matlab.ui.container.Menu
        ExcelxlsxMenu                   matlab.ui.container.Menu
        MATLABmatMenu                   matlab.ui.container.Menu
        QuitMATLABMenu                  matlab.ui.container.Menu
        RefreshMenu                     matlab.ui.container.Menu
        SmoothedChannelMaxEditField     matlab.ui.control.NumericEditField
        SmoothedChannelMaxEditFieldLabel  matlab.ui.control.Label
        EventTrialNavigationPanel       matlab.ui.container.Panel
        ResetTableButton                matlab.ui.control.Button
        EventDropDownLabel              matlab.ui.control.Label
        EventDropDown                   matlab.ui.control.DropDown
        TrialEditField                  matlab.ui.control.EditField
        NextTrialButton                 matlab.ui.control.Button
        PreviousTrialButton             matlab.ui.control.Button
        FigureVisibilityPanel           matlab.ui.container.Panel
        CheckAllButton                  matlab.ui.control.Button
        UncheckAllButton                matlab.ui.control.Button
        SetWindowOnsetVisibleCheckBox   matlab.ui.control.CheckBox
        SetWindowVisibleCheckBox        matlab.ui.control.CheckBox
        ProcessedEMGVisibleCheckBox     matlab.ui.control.CheckBox
        BestBaselineOnsetVisibleCheckBox  matlab.ui.control.CheckBox
        BestBaselineVisibleCheckBox     matlab.ui.control.CheckBox
        RawEMGVisibleCheckBox           matlab.ui.control.CheckBox
        AnalysisOptionsPanel            matlab.ui.container.Panel
        NoTrialButton                   matlab.ui.control.Button
        SaveFiguresButton               matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        OverrideButton                  matlab.ui.control.Button
        SetWindowButton                 matlab.ui.control.Button
        LowestBaselineButton            matlab.ui.control.Button
        SetNewOnsetButton               matlab.ui.control.Button
        mVLabel                         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        RawEMGPlot                      matlab.ui.control.UIAxes
        SetWindowPlot                   matlab.ui.control.UIAxes
        BestBaselinePlot                matlab.ui.control.UIAxes
        ProcessedEMGPlot                matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        LoadAnalysisInformationPanel    matlab.ui.container.Panel
        FigureCheckButton               matlab.ui.control.Button
        UserInitialsEditField           matlab.ui.control.EditField
        UserInitialsEditFieldLabel      matlab.ui.control.Label
        ChangeDirectoryButton           matlab.ui.control.Button
        ResultsDirectoryEditField       matlab.ui.control.EditField
        ResultsDirectoryEditFieldLabel  matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        RecordingLimbButtonGroup        matlab.ui.container.ButtonGroup
        RecordingLimb_RightButton       matlab.ui.control.RadioButton
        RecordingLimb_LeftButton        matlab.ui.control.RadioButton
        PareticLimbButtonGroup          matlab.ui.container.ButtonGroup
        PareticLimb_RightButton         matlab.ui.control.RadioButton
        PareticLimb_LeftButton          matlab.ui.control.RadioButton
        FilenameDropDown                matlab.ui.control.DropDown
        FilenameDropDownLabel           matlab.ui.control.Label
        TimePointDropDown               matlab.ui.control.DropDown
        TimePointDropDownLabel          matlab.ui.control.Label
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
    end


    properties (Access = private)
        % BestBaselineOverrideOnsetLine
        % ProcessedEMGOverrideOnsetLine
        % SetWindowOverrideOnsetLine
        AffectedArm % L or R; arm affected by stroke
        AllDataCh1RF
        AllDataCh2RE
        AnalysisDir
        BaselineStats % struct containing best baseline window information
        BestBaselineAllDataCh1RF
        BestBaselineAllDataCh2RE
        BestBaselineCombinedData
        BestBaselineCue
        BestBaselineDelay
        BestBaselineMax
        BestBaselineOnsetLine
        BestBaselineShadedArea
        BestBaselineSummaryDataCh1RF % data from onset detection
        BestBaselineSummaryDataCh2RE % data from onset detection
        BestBaselineTime2Peak
        BestBaselinePlotXLim
        BestBaselinePlotYLim
        Ch1SmoothedCalibrationMax
        Ch1EMG
        Ch1EMGMax
        Ch1SmoothedRecordingMax
        Ch2SmoothedCalibrationMax
        Ch2EMG
        Ch2EMGMax
        Ch2SmoothedRecordingMax
        CombinedData
        ConditionData % cell array to extract event and trial data
        ConditionDirectory % path where condition files are stored
        ConditionInfo % information stored in condition file
        CurrentEvent % current event
        CurrentTrial % store current trial
        Dur
        Dur2
        EMGBurstOnsets
        EMGData
        EMG_Time
        Events % list of events in EVA
        EventsLabel
        EventsTime
        ExcelHeaders
        FormattedDate
        Levels
        LevelsTime
        MaxBaselineDiff
        MaxEMG
        newColumnRE
        newColumnRF
        NewOnset
        NoTrial
        OverrideX
        OverrideY
        ProcessedEMG % bandpass- and notch-filtered; rectified EMG
        ProcessedEMGMax
        ProcessedEMGOnsetLine
        ProcessedEMGxLim
        ProcessedEMGyLim
        RawEMG % formatted struct of Raw EMG timeseries data
        RawEMGMax
        RawEMGOnsetLine
        RawEMGOverrideOnsetLine
        RawEMGxLim
        RawEMGyLim
        RE_AntagonistIDX
        RE_IDX % Ch1RE
        RecordingArm % L or R; arm being used to play game in this condition file
        RecordingMax
        ResultsDir % where to save all the data
        RectifiedCh1EMGCalibrationMax
        RectifiedCh2EMGCalibrationMax
        RF_AntagonistIDX
        RF_IDX % Ch1RF
        SampleRate % sampling rate
        SetWindowAllDataCh1RF
        SetWindowAllDataCh2RE
        SetWindowBaselineStats
        SetWindowCombinedData
        SetWindowCue
        SetWindowDelay
        SetWindowEMGBurstOnsets
        SetWindowMax
        SetWindowMaxBaselineDiff
        SetWindowNoTrial
        SetWindowOnsetLine
        SetWindowShadedArea
        SetWindowSummaryDataCh1RF
        SetWindowSummaryDataCh2RE
        SetWindowTempCh1RF
        SetWindowTempCh2RE
        SetWindowTime2Peak
        SetWindowxLim
        SetWindowyLim
        SmoothedEMG % lowpass filtered EMG
        StartSearchTime
        Streams
        SubID % subject study ID
        SubjectParentFolder
        SummaryTable
        TargetEvents % indexes of events of interest (e.g., RF and RE)
        TempCh1RF
        TempCh2RE
        ThreshFields
        ThresholdSize
        ThreshRE
        ThreshRF
        Time % time of recording
        TimePoint % recording timepoint in MAPS study (e.g., T0, T1, T3, T6)
        TrialCount % total number of trials
        TrialThreshold
        User
    end

    methods (Access = private)

        % Function to populate the results directory field in app window
        function setDefaultResultsDirectory(app)
            currentFolder = pwd;
            app.ResultsDir = currentFolder;
            app.ResultsDirectoryEditField.Value = currentFolder;
        end

        function setAppFontsToTimesNewRoman(app)
            % Get all children of the main UIFigure
            components = app.UIFigure.Children;

            % Loop through each component and set the font to Times New Roman
            for i = 1:length(components)
                try
                    % Set the FontName property if it exists
                    if isprop(components(i), 'FontName')
                        components(i).FontName = 'Times New Roman';
                    end
                catch
                    % Ignore components that don't have a FontName property
                    continue;
                end
            end
        end

        function plotBestBaselineOnsets(app)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            if app.UITable.Data{app.CurrentTrial, 4} ~= 0

                if app.CurrentEvent == app.Events{app.RF_IDX}
                    % Plot onsets
                    if (app.TempCh1RF{app.CurrentTrial, 2} == 1) && (app.TempCh1RF{app.CurrentTrial, 4} == 0)
                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        delete(app.BestBaselineOnsetLine)
                    else
                        if app.TempCh1RF{app.CurrentTrial, 4} == 0
                            delete(app.RawEMGOnsetLine)
                            delete(app.ProcessedEMGOnsetLine)
                            delete(app.BestBaselineOnsetLine)
                        else
                            if app.RawEMGVisibleCheckBox.Value
                                app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.RawEMGPlot, app.RawEMGxLim);
                                % ylim(app.RawEMGPlot, app.RawEMGyLim);
                            end
                            if app.ProcessedEMGVisibleCheckBox.Value
                                app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                                % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
                                % ylim(app.ProcessedEMGPlot, [0 app.ProcessedEMGyLim(2)]);
                            end
                            if app.BestBaselineVisibleCheckBox.Value
                                app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            end
                        end
                    end
                    % app.resetPlotLimits();
                    app.formatPlots();

                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    % Plot onsets
                    if (app.TempCh2RE{app.CurrentTrial, 2} == 1) && (app.TempCh2RE{app.CurrentTrial, 4} == 0)
                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        delete(app.BestBaselineOnsetLine)
                    else
                        if app.TempCh2RE{app.CurrentTrial, 4} == 0
                            delete(app.RawEMGOnsetLine)
                            delete(app.ProcessedEMGOnsetLine)
                            delete(app.BestBaselineOnsetLine)
                        else
                            if app.RawEMGVisibleCheckBox.Value
                                app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.RawEMGPlot, app.RawEMGxLim);
                                % ylim(app.RawEMGPlot, app.RawEMGyLim);
                            end
                            if app.ProcessedEMGVisibleCheckBox.Value
                                app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
                                % ylim(app.ProcessedEMGPlot, [0 app.ProcessedEMGyLim(2)]);
                            end
                            if app.BestBaselineVisibleCheckBox.Value
                                app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            end
                        end
                    end

                    % app.resetPlotLimits();
                    app.formatPlots();

                end
            else
                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
            end

        end

        function plotSetWindowConstants(app)
            clear originalXLim originalYLim
            windowStart = -2350; % ms
            windowStop = -1350; % ms

            if app.CurrentEvent == app.Events{app.RF_IDX}
                % Plot baseline average
                % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                if app.SetWindowVisibleCheckBox.Value
                    % app.plotBestBaselineWindow();

                    %% Plot shaded baseline window
                    originalXLim = xlim(app.SetWindowPlot);
                    originalYLim = ylim(app.SetWindowPlot);
                    % Plot baseline rectangle for onset threshold optimization
                    baselineData = app.SetWindowBaselineStats;
                    baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);
                    tempMax_cropped = app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 16};
                    tempMax_cropped = tempMax_cropped + (tempMax_cropped/3);

                    tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                    tempMax = tempMax + (tempMax/4);

                    % Define the x and y coordinates for the filled region
                    window_ms = windowStop - windowStart; % leave this
                    x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) tempMax; baselineStart tempMax];
                    % y_fill_ax1 = [originalYLim(1), originalYLim(1), tempMax, tempMax];
                    y_fill_ax1 = [0, 0, tempMax, tempMax];

                    % Use fill() to create the shaded area
                    BaselineFill = fill(app.SetWindowPlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', app.SetWindowPlot);
                    % Use patch() to create the shaded area
                    % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.SetWindowPlot);

                    hold(app.SetWindowPlot,'on')

                    % Plot grey rectangle on axes
                    uistack(BaselineFill, 'bottom');
                    xlim(app.SetWindowPlot, originalXLim);
                    ylim(app.SetWindowPlot, [0 tempMax]);
                    hold(app.SetWindowPlot,'off')

                    %% Plot Baseline Threshold
                    hold(app.SetWindowPlot,'on')
                    plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                    % plotYLim = ylim(app.SetWindowPlot);
                    % ylim(app.SetWindowPlot, [-0.001, plotYLim(2)])
                    % yline(app.SetWindowPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    % yline(app.SetWindowPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', 'r', 'LineWidth', 1.25); % plot baseline
                    baseline = app.SetWindowBaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                    sd = app.SetWindowBaselineStats.(app.CurrentEvent)(2, app.CurrentTrial);
                    threshold = baseline + (app.ThresholdSize * sd);

                    yline(app.SetWindowPlot, threshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    hold(app.SetWindowPlot,'off')

                    %% Plot Max
                    maxTime = app.Time(app.MaxEMG.(app.CurrentEvent)(1, app.CurrentTrial));
                    maxValue = app.MaxEMG.(app.CurrentEvent)(2, app.CurrentTrial);
                    % app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    % app.MaxEMG.(events{e})(2, trial) = maxEMG;
                    % hold(app.RawEMGPlot,'on')
                    % app.RawEMGMax = plot(app.RawEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.RawEMGPlot,'off')
                    %
                    % hold(app.ProcessedEMGPlot,'on')
                    % app.ProcessedEMGMax = plot(app.ProcessedEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.ProcessedEMGPlot,'off')

                    hold(app.SetWindowPlot,'on')
                    app.BestBaselineMax = plot(app.SetWindowPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', [0.6350 0.0780 0.1840], 'MarkerEdgeColor', [0.6350 0.0780 0.1840]);
                    hold(app.SetWindowPlot,'off')

                    % hold(app.SetWindowPlot,'on')
                    % tempOnsetTime = app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} / 1000;
                    % tempEMG = app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial);
                    %
                    % plot(app.SetWindowPlot, [tempOnsetTime maxTime], [maxValue maxValue], 'LineWidth', 1.5, 'Color', [0.75 0 0])
                    % area(app.SetWindowPlot, app.Time(tempOnsetTime:end),tempEMG(tempOnsetTime:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1)
                    % hold(app.SetWindowPlot,'off')

                end

                % app.resetPlotLimits();
                % app.plotBestBaselineOnsets();
                app.formatPlots();

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                % Plot baseline average
                % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                if app.SetWindowVisibleCheckBox.Value
                    % app.plotBestBaselineWindow();

                    originalXLim = xlim(app.SetWindowPlot);
                    originalYLim = ylim(app.SetWindowPlot);
                    % Plot baseline rectangle for onset threshold optimization
                    baselineData = app.SetWindowBaselineStats;
                    baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);
                    tempMax_cropped = app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 16};
                    tempMax_cropped = tempMax_cropped + (tempMax_cropped/3);

                    tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                    tempMax = tempMax + (tempMax/4);

                    % Define the x and y coordinates for the filled region
                    window_ms = windowStop - windowStart; % leave this
                    x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) tempMax; baselineStart tempMax];
                    % y_fill_ax1 = [originalYLim(1), originalYLim(1), tempMax, tempMax];
                    y_fill_ax1 = [0, 0, tempMax, tempMax];

                    % Use fill() to create the shaded area
                    BaselineFill = fill(app.SetWindowPlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', app.SetWindowPlot);
                    % Use patch() to create the shaded area
                    % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.SetWindowPlot);

                    hold(app.SetWindowPlot,'on')

                    % Plot grey rectangle on axes
                    uistack(BaselineFill, 'bottom');

                    xlim(app.SetWindowPlot, originalXLim);
                    ylim(app.SetWindowPlot, [0 tempMax]);

                    hold(app.SetWindowPlot,'off')

                    hold(app.SetWindowPlot,'on')
                    plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                    % plotYLim = ylim(app.SetWindowPlot);
                    % ylim(app.SetWindowPlot, [-0.001, plotYLim(2)])
                    % yline(app.SetWindowPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    % yline(app.SetWindowPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', 'r', 'LineWidth', 1.25); % plot baseline
                    baseline = app.SetWindowBaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                    sd = app.SetWindowBaselineStats.(app.CurrentEvent)(2, app.CurrentTrial);
                    threshold = baseline + (app.ThresholdSize * sd);

                    yline(app.SetWindowPlot, threshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline

                    hold(app.SetWindowPlot,'off')

                    maxTime = app.Time(app.MaxEMG.(app.CurrentEvent)(1, app.CurrentTrial));
                    maxValue = app.MaxEMG.(app.CurrentEvent)(2, app.CurrentTrial);
                    % app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    % app.MaxEMG.(events{e})(2, trial) = maxEMG;
                    % hold(app.RawEMGPlot,'on')
                    % app.RawEMGMax = plot(app.RawEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.RawEMGPlot,'off')
                    %
                    % hold(app.ProcessedEMGPlot,'on')
                    % app.ProcessedEMGMax = plot(app.ProcessedEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.ProcessedEMGPlot,'off')

                    hold(app.SetWindowPlot,'on')
                    app.BestBaselineMax = plot(app.SetWindowPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', [0.6350 0.0780 0.1840], 'MarkerEdgeColor', [0.6350 0.0780 0.1840]);
                    hold(app.SetWindowPlot,'off')

                    % hold(app.SetWindowPlot,'on')
                    % tempOnsetTime = app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} / 1000;
                    % tempEMG = app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial);
                    %
                    % plot(app.SetWindowPlot, [tempOnsetTime maxTime], [maxValue maxValue], 'LineWidth', 1.5, 'Color', [0.75 0 0])
                    % area(app.SetWindowPlot, app.Time(tempOnsetTime:end),tempEMG(tempOnsetTime:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1)
                    % hold(app.SetWindowPlot,'off')
                end

                % app.resetPlotLimits();
                % app.plotBestBaselineOnsets();
                app.formatPlots();

            end
        end

        function plotBestBaselineConstants(app)

            % clear originalXLim originalYLim
            if app.CurrentEvent == app.Events{app.RF_IDX}
                % Plot baseline average
                % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                if app.BestBaselineVisibleCheckBox.Value
                    % app.plotBestBaselineWindow();

                    %% Plot shaded baseline window
                    originalXLim = xlim(app.BestBaselinePlot);
                    originalYLim = ylim(app.BestBaselinePlot);
                    % Plot baseline rectangle for onset threshold optimization
                    baselineData = app.BaselineStats;
                    baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);
                    tempMax_cropped = app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 16};
                    tempMax_cropped = tempMax_cropped + (tempMax_cropped/3);

                    tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                    tempMax = tempMax + (tempMax/4);

                    % Define the x and y coordinates for the filled region
                    window_ms = 500;
                    x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) tempMax; baselineStart tempMax];
                    % y_fill_ax1 = [originalYLim(1), originalYLim(1), tempMax, tempMax];
                    y_fill_ax1 = [0, 0, tempMax, tempMax];

                    % Use fill() to create the shaded area
                    BaselineFill = fill(app.BestBaselinePlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', app.BestBaselinePlot);
                    % Use patch() to create the shaded area
                    % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.BestBaselinePlot);

                    hold(app.BestBaselinePlot,'on')

                    % Plot grey rectangle on axes
                    uistack(BaselineFill, 'bottom');
                    xlim(app.BestBaselinePlot, originalXLim);
                    ylim(app.BestBaselinePlot, [0 tempMax]);
                    hold(app.BestBaselinePlot,'off')

                    %% Plot Baseline Threshold
                    hold(app.BestBaselinePlot,'on')
                    plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                    % plotYLim = ylim(app.BestBaselinePlot);
                    % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    % yline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    % yline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', 'r', 'LineWidth', 1.25); % plot baseline
                    baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                    sd = app.BaselineStats.(app.CurrentEvent)(2, app.CurrentTrial);
                    threshold = baseline + (app.ThresholdSize * sd);

                    yline(app.BestBaselinePlot, threshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    hold(app.BestBaselinePlot,'off')

                    %% Plot Max
                    maxTime = app.Time(app.MaxEMG.(app.CurrentEvent)(1, app.CurrentTrial));
                    maxValue = app.MaxEMG.(app.CurrentEvent)(2, app.CurrentTrial);
                    % app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    % app.MaxEMG.(events{e})(2, trial) = maxEMG;
                    % hold(app.RawEMGPlot,'on')
                    % app.RawEMGMax = plot(app.RawEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.RawEMGPlot,'off')
                    %
                    % hold(app.ProcessedEMGPlot,'on')
                    % app.ProcessedEMGMax = plot(app.ProcessedEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.ProcessedEMGPlot,'off')

                    hold(app.BestBaselinePlot,'on')
                    app.BestBaselineMax = plot(app.BestBaselinePlot, maxTime, maxValue, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', [0.6350 0.0780 0.1840], 'MarkerEdgeColor', [0.6350 0.0780 0.1840]);
                    hold(app.BestBaselinePlot,'off')

                    % hold(app.BestBaselinePlot,'on')
                    % tempOnsetTime = app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4} / 1000;
                    % tempEMG = app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial);
                    %
                    % plot(app.BestBaselinePlot, [tempOnsetTime maxTime], [maxValue maxValue], 'LineWidth', 1.5, 'Color', [0.75 0 0])
                    % area(app.BestBaselinePlot, app.Time(tempOnsetTime:end),tempEMG(tempOnsetTime:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1)
                    % hold(app.BestBaselinePlot,'off')


                end

                % app.resetPlotLimits();
                % app.plotBestBaselineOnsets();
                % app.formatPlots();

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                % Plot baseline average
                % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                if app.BestBaselineVisibleCheckBox.Value
                    % app.plotBestBaselineWindow();

                    originalXLim = xlim(app.BestBaselinePlot);
                    originalYLim = ylim(app.BestBaselinePlot);
                    % Plot baseline rectangle for onset threshold optimization
                    baselineData = app.BaselineStats;
                    baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);
                    tempMax_cropped = app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 16};
                    tempMax_cropped = tempMax_cropped + (tempMax_cropped/3);

                    tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                    tempMax = tempMax + (tempMax/4);

                    % Define the x and y coordinates for the filled region
                    window_ms = 500;
                    x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) tempMax; baselineStart tempMax];
                    % y_fill_ax1 = [originalYLim(1), originalYLim(1), tempMax, tempMax];
                    y_fill_ax1 = [0, 0, tempMax, tempMax];

                    % Use fill() to create the shaded area
                    BaselineFill = fill(app.BestBaselinePlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', app.BestBaselinePlot);
                    % Use patch() to create the shaded area
                    % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.BestBaselinePlot);

                    hold(app.BestBaselinePlot,'on')

                    % Plot grey rectangle on axes
                    uistack(BaselineFill, 'bottom');

                    xlim(app.BestBaselinePlot, originalXLim);
                    ylim(app.BestBaselinePlot, [0 tempMax]);

                    hold(app.BestBaselinePlot,'off')

                    hold(app.BestBaselinePlot,'on')
                    plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                    % plotYLim = ylim(app.BestBaselinePlot);
                    % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    % yline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    % yline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', 'r', 'LineWidth', 1.25); % plot baseline
                    baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                    sd = app.BaselineStats.(app.CurrentEvent)(2, app.CurrentTrial);
                    threshold = baseline + (2*sd);

                    yline(app.BestBaselinePlot, threshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline

                    hold(app.BestBaselinePlot,'off')

                    maxTime = app.Time(app.MaxEMG.(app.CurrentEvent)(1, app.CurrentTrial));
                    maxValue = app.MaxEMG.(app.CurrentEvent)(2, app.CurrentTrial);
                    % app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    % app.MaxEMG.(events{e})(2, trial) = maxEMG;
                    % hold(app.RawEMGPlot,'on')
                    % app.RawEMGMax = plot(app.RawEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.RawEMGPlot,'off')
                    %
                    % hold(app.ProcessedEMGPlot,'on')
                    % app.ProcessedEMGMax = plot(app.ProcessedEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.ProcessedEMGPlot,'off')

                    hold(app.BestBaselinePlot,'on')
                    app.BestBaselineMax = plot(app.BestBaselinePlot, maxTime, maxValue, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', [0.6350 0.0780 0.1840], 'MarkerEdgeColor', [0.6350 0.0780 0.1840]);
                    hold(app.BestBaselinePlot,'off')

                    % hold(app.BestBaselinePlot,'on')
                    % tempOnsetTime = app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4} / 1000;
                    % tempEMG = app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial);
                    %
                    % plot(app.BestBaselinePlot, [tempOnsetTime maxTime], [maxValue maxValue], 'LineWidth', 1.5, 'Color', [0.75 0 0])
                    % area(app.BestBaselinePlot, app.Time(tempOnsetTime:end),tempEMG(tempOnsetTime:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1)
                    % hold(app.BestBaselinePlot,'off')
                end

                % app.resetPlotLimits();
                % app.plotBestBaselineOnsets();
                % app.formatPlots();

            end
        end

        function plotBestBaselineWindow(app)
            % Plot baseline average
            % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
            % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
            if app.BestBaselineVisibleCheckBox.Value
                originalXLim = xlim(app.BestBaselinePlot);
                originalYLim = ylim(app.BestBaselinePlot);
                % Plot baseline rectangle for onset threshold optimization
                baselineData = app.BaselineStats;
                baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);
                tempMax_cropped = app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 16};
                tempMax_cropped = tempMax_cropped + (tempMax_cropped/3);

                tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                tempMax = tempMax + (tempMax/4);

                % Define the x and y coordinates for the filled region
                window_ms = 500;
                x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) tempMax; baselineStart tempMax];
                y_fill_ax1 = [originalYLim(1), originalYLim(1), tempMax, tempMax];

                % Use fill() to create the shaded area
                BaselineFill = fill(app.BestBaselinePlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', app.BestBaselinePlot);
                % Use patch() to create the shaded area
                % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.BestBaselinePlot);

                hold(app.BestBaselinePlot,'on')

                % Plot grey rectangle on axes
                uistack(BaselineFill, 'bottom');

                xlim(app.BestBaselinePlot, originalXLim);
                ylim(app.BestBaselinePlot, [0 tempMax]);

                hold(app.BestBaselinePlot,'off')

            end
        end

        function formatPlots(app)
            title(app.RawEMGPlot, 'Raw EMG')
            xlabel(app.RawEMGPlot, 'Time (s)')
            ylabel(app.RawEMGPlot, 'EMG (mV)')
            app.RawEMGPlot.FontName = 'Times New Roman';
            app.RawEMGPlot.TickLength = [0.005 0.025];
            app.RawEMGPlot.XMinorTick = 'on';
            app.RawEMGPlot.YMinorTick = 'on';
            app.RawEMGPlot.FontSize = 14;

            title(app.ProcessedEMGPlot, 'Processed EMG')
            xlabel(app.ProcessedEMGPlot, 'Time (s)')
            ylabel(app.ProcessedEMGPlot, 'EMG (mV)')
            app.ProcessedEMGPlot.FontName = 'Times New Roman';
            app.ProcessedEMGPlot.TickLength = [0.005 0.025];
            app.ProcessedEMGPlot.XMinorTick = 'on';
            app.ProcessedEMGPlot.YMinorTick = 'on';
            app.ProcessedEMGPlot.FontSize = 14;

            title(app.BestBaselinePlot, 'Smoothed EMG – Lowest Baseline Average')
            xlabel(app.BestBaselinePlot, 'Time (s)')
            ylabel(app.BestBaselinePlot, 'EMG (mV)')
            app.BestBaselinePlot.FontName = 'Times New Roman';
            app.BestBaselinePlot.TickLength = [0.005 0.025];
            app.BestBaselinePlot.XMinorTick = 'on';
            app.BestBaselinePlot.YMinorTick = 'on';
            app.BestBaselinePlot.FontSize = 14;

            title(app.SetWindowPlot, 'Smoothed EMG – Set Window')
            xlabel(app.SetWindowPlot, 'Time (s)')
            ylabel(app.SetWindowPlot, 'EMG (mV)')
            app.SetWindowPlot.FontName = 'Times New Roman';
            app.SetWindowPlot.TickLength = [0.005 0.025];
            app.SetWindowPlot.XMinorTick = 'on';
            app.SetWindowPlot.YMinorTick = 'on';
            app.SetWindowPlot.FontSize = 14;


        end

        function plotSetWindowOnsets(app)
            % delete(app.RawEMGOnsetLine)
            % delete(app.ProcessedEMGOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.UITable.Data{app.CurrentTrial, 4} ~= 0
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    % Plot onsets
                    if (app.SetWindowTempCh1RF{app.CurrentTrial, 2} == 1) && (app.SetWindowTempCh1RF{app.CurrentTrial, 4} == 0)
                        % delete(app.RawEMGOnsetLine)
                        % delete(app.ProcessedEMGOnsetLine)
                        delete(app.SetWindowOnsetLine)
                    else
                        if app.SetWindowTempCh1RF{app.CurrentTrial, 4} == 0
                            % delete(app.RawEMGOnsetLine)
                            % delete(app.ProcessedEMGOnsetLine)
                            % delete(app.BestBaselineOnsetLine)
                        else
                            % if app.RawEMGVisibleCheckBox.Value
                            %     app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.SetWindowTempCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            %     % xlim(app.RawEMGPlot, app.RawEMGxLim);
                            %     % ylim(app.RawEMGPlot, app.RawEMGyLim);
                            % end
                            % if app.ProcessedEMGVisibleCheckBox.Value
                            %     app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.SetWindowTempCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            %     % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
                            %     % ylim(app.ProcessedEMGPlot, [0 app.ProcessedEMGyLim(2)]);
                            % end
                            if app.SetWindowOnsetVisibleCheckBox.Value
                                app.SetWindowOnsetLine = xline(app.SetWindowPlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            end
                        end
                    end
                    % app.resetPlotLimits();
                    app.formatPlots();

                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    % Plot onsets
                    if (app.SetWindowTempCh2RE{app.CurrentTrial, 2} == 1) && (app.SetWindowTempCh2RE{app.CurrentTrial, 4} == 0)
                        % delete(app.RawEMGOnsetLine)
                        % delete(app.ProcessedEMGOnsetLine)
                        delete(app.SetWindowOnsetLine)
                    else
                        if app.SetWindowTempCh2RE{app.CurrentTrial, 4} == 0
                            % delete(app.RawEMGOnsetLine)
                            % delete(app.ProcessedEMGOnsetLine)
                            % delete(app.BestBaselineOnsetLine)
                        else
                            % if app.RawEMGVisibleCheckBox.Value
                            %     app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.SetWindowTempCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            %     % xlim(app.RawEMGPlot, app.RawEMGxLim);
                            %     % ylim(app.RawEMGPlot, app.RawEMGyLim);
                            % end
                            % if app.ProcessedEMGVisibleCheckBox.Value
                            %     app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.SetWindowTempCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            %     % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
                            %     % ylim(app.ProcessedEMGPlot, [0 app.ProcessedEMGyLim(2)]);
                            % end
                            if app.SetWindowOnsetVisibleCheckBox.Value
                                app.SetWindowOnsetLine = xline(app.SetWindowPlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            end
                        end
                    end

                    % app.resetPlotLimits();
                    app.formatPlots();

                end
            else
                delete(app.SetWindowOnsetLine);
            end
        end

        function plotBestBaselineTime2Peak(app)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.TempCh1RF = app.UITable.Data;

                if app.BestBaselineVisibleCheckBox.Value
                    tempOnsetTime = app.TempCh1RF{app.CurrentTrial, 4} / 1000;

                    if tempOnsetTime ~= 0
                        delete(app.BestBaselineTime2Peak)
                        delete(app.BestBaselineShadedArea)
                        delete(app.BestBaselineCue)
                        delete(app.BestBaselineDelay)
                        app.BestBaselineTime2Peak = [];
                        app.BestBaselineShadedArea = [];
                        app.BestBaselineCue = [];
                        app.BestBaselineDelay = [];

                        hold(app.BestBaselinePlot,'on')
                        app.BestBaselineCue = xline(app.BestBaselinePlot, 0, 'LineWidth', 2, 'Color', [0.01 0.01 0.01]);

                        [~, tempOnsetIDX] = min(abs(app.Time - tempOnsetTime));
                        tempMaxTime = app.TempCh1RF{app.CurrentTrial, 5} / 1000;
                        tempMax = app.TempCh1RF{app.CurrentTrial, 16};
                        tempEMG = app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial);
                        delay_cue = (tempMax + (tempMax / 8));

                        app.BestBaselineDelay = plot(app.BestBaselinePlot, [tempOnsetTime 0], [delay_cue delay_cue], 'LineWidth', 1.5, 'Color', [0.01 0.01 0.01]);
                        app.BestBaselineTime2Peak = plot(app.BestBaselinePlot, [tempOnsetTime tempMaxTime], [tempMax tempMax], 'LineWidth', 1.5, 'Color', [0.6350 0.0780 0.1840]);
                        app.BestBaselineShadedArea = area(app.BestBaselinePlot, app.Time(tempOnsetIDX:end),tempEMG(tempOnsetIDX:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1);

                        tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                        tempMax = tempMax + (tempMax/4);

                        ylim(app.BestBaselinePlot, [0 tempMax])
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.3) % Pre-Processed EMG
                        hold(app.BestBaselinePlot,'off')
                    else
                        delete(app.BestBaselineTime2Peak)
                        delete(app.BestBaselineShadedArea)
                        delete(app.BestBaselineCue)
                        delete(app.BestBaselineDelay)
                        app.BestBaselineTime2Peak = [];
                        app.BestBaselineShadedArea = [];
                        app.BestBaselineCue = [];
                        app.BestBaselineDelay = [];
                    end


                end

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.TempCh2RE = app.UITable.Data;

                if app.BestBaselineVisibleCheckBox.Value

                    tempOnsetTime = app.TempCh2RE{app.CurrentTrial, 4} / 1000;

                    if tempOnsetTime ~= 0
                        delete(app.BestBaselineTime2Peak)
                        delete(app.BestBaselineShadedArea)
                        delete(app.BestBaselineCue)
                        delete(app.BestBaselineDelay)
                        app.BestBaselineTime2Peak = [];
                        app.BestBaselineShadedArea = [];
                        app.BestBaselineCue = [];
                        app.BestBaselineDelay = [];

                        hold(app.BestBaselinePlot,'on')
                        app.BestBaselineCue = xline(app.BestBaselinePlot, 0, 'LineWidth', 2, 'Color', [0.01 0.01 0.01]);

                        [~, tempOnsetIDX] = min(abs(app.Time - tempOnsetTime));
                        tempMaxTime = app.TempCh2RE{app.CurrentTrial, 5} / 1000;
                        tempMax = app.TempCh2RE{app.CurrentTrial, 16};
                        tempEMG = app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial);
                        delay_cue = (tempMax + (tempMax / 8));

                        app.BestBaselineDelay = plot(app.BestBaselinePlot, [tempOnsetTime 0], [delay_cue delay_cue], 'LineWidth', 1.5, 'Color', [0.01 0.01 0.01]);
                        app.BestBaselineTime2Peak = plot(app.BestBaselinePlot, [tempOnsetTime tempMaxTime], [tempMax tempMax], 'LineWidth', 1.5, 'Color', [0.6350 0.0780 0.1840]);
                        app.BestBaselineShadedArea = area(app.BestBaselinePlot, app.Time(tempOnsetIDX:end),tempEMG(tempOnsetIDX:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1);

                        tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                        tempMax = tempMax + (tempMax/4);

                        ylim(app.BestBaselinePlot, [0 tempMax])
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.3) % Pre-Processed EMG
                        hold(app.BestBaselinePlot,'off')
                    else
                        delete(app.BestBaselineTime2Peak)
                        delete(app.BestBaselineShadedArea)
                        delete(app.BestBaselineCue)
                        delete(app.BestBaselineDelay)
                        app.BestBaselineTime2Peak = [];
                        app.BestBaselineShadedArea = [];
                        app.BestBaselineCue = [];
                        app.BestBaselineDelay = [];
                    end

                end

            end
        end

        function plotSetWindowTime2Peak(app)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if app.SetWindowVisibleCheckBox.Value

                    tempOnsetTime = app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} / 1000;
                    if tempOnsetTime ~= 0
                        delete(app.SetWindowTime2Peak)
                        delete(app.SetWindowShadedArea)
                        delete(app.SetWindowCue)
                        delete(app.SetWindowDelay)
                        app.SetWindowTime2Peak = [];
                        app.SetWindowShadedArea = [];
                        app.SetWindowCue = [];
                        app.SetWindowDelay = [];

                        hold(app.SetWindowPlot,'on')
                        app.SetWindowCue = xline(app.SetWindowPlot, 0, 'LineWidth', 2, 'Color', [0.01 0.01 0.01]);

                        [~, tempOnsetIDX] = min(abs(app.Time - tempOnsetTime));
                        tempMaxTime = app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 5} / 1000;
                        tempMax = app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 16};
                        tempEMG = app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial);
                        delay_cue = (tempMax + (tempMax / 8));

                        app.SetWindowDelay = plot(app.SetWindowPlot, [tempOnsetTime 0], [delay_cue delay_cue], 'LineWidth', 1.5, 'Color', [0.01 0.01 0.01]);
                        app.SetWindowTime2Peak = plot(app.SetWindowPlot, [tempOnsetTime tempMaxTime], [tempMax tempMax], 'LineWidth', 1.5, 'Color', [0.6350 0.0780 0.1840]);
                        app.SetWindowShadedArea = area(app.SetWindowPlot, app.Time(tempOnsetIDX:end),tempEMG(tempOnsetIDX:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1);

                        tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                        tempMax = tempMax + (tempMax/4);
                       
                        ylim(app.SetWindowPlot, [0 tempMax])
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.3) % Pre-Processed EMG
                        hold(app.SetWindowPlot,'off')
                    else
                        delete(app.SetWindowTime2Peak)
                        delete(app.SetWindowShadedArea)
                        delete(app.SetWindowCue)
                        delete(app.SetWindowDelay)
                        app.SetWindowTime2Peak = [];
                        app.SetWindowShadedArea = [];
                        app.SetWindowCue = [];
                        app.SetWindowDelay = [];
                    end
                end

            elseif app.CurrentEvent == app.Events{app.RE_IDX}

                if app.SetWindowVisibleCheckBox.Value

                    tempOnsetTime = app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} / 1000;
                    if tempOnsetTime ~= 0
                        delete(app.SetWindowTime2Peak)
                        delete(app.SetWindowShadedArea)
                        delete(app.SetWindowCue)
                        delete(app.SetWindowDelay)
                        app.SetWindowTime2Peak = [];
                        app.SetWindowShadedArea = [];
                        app.SetWindowCue = [];
                        app.SetWindowDelay = [];

                        hold(app.SetWindowPlot,'on')
                        app.SetWindowCue = xline(app.SetWindowPlot, 0, 'LineWidth', 2, 'Color', [0.01 0.01 0.01]);

                        [~, tempOnsetIDX] = min(abs(app.Time - tempOnsetTime));
                        tempMaxTime = app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 5} / 1000;
                        tempMax = app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 16};
                        tempEMG = app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial);
                        delay_cue = (tempMax + (tempMax / 8));

                        app.SetWindowDelay = plot(app.SetWindowPlot, [tempOnsetTime 0], [delay_cue delay_cue], 'LineWidth', 1.5, 'Color', [0.01 0.01 0.01]);
                        app.SetWindowTime2Peak = plot(app.SetWindowPlot, [tempOnsetTime tempMaxTime], [tempMax tempMax], 'LineWidth', 1.5, 'Color', [0.6350 0.0780 0.1840]);
                        app.SetWindowShadedArea = area(app.SetWindowPlot, app.Time(tempOnsetIDX:end),tempEMG(tempOnsetIDX:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1);
                        
                        tempMax = app.MaxEMG.(app.CurrentEvent)(4, app.CurrentTrial);
                        tempMax = tempMax + (tempMax/4);
                       
                        ylim(app.SetWindowPlot, [0 tempMax])
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.3) % Pre-Processed EMG
                        hold(app.SetWindowPlot,'off')
                    else
                        delete(app.SetWindowTime2Peak)
                        delete(app.SetWindowShadedArea)
                        delete(app.SetWindowCue)
                        delete(app.SetWindowDelay)
                        app.SetWindowTime2Peak = [];
                        app.SetWindowShadedArea = [];
                        app.SetWindowCue = [];
                        app.SetWindowDelay = [];
                    end
                end

            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            close all

            % app.setDefaultResultsDirectory();
            app.setAppFontsToTimesNewRoman();
            app.FigureCheckButton.Visible = 'off';
            app.AnalyzeButton.Visible = 'off';
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SetWindowButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';
            app.LoadDataButton.Visible = 'off';
            % app.AnalysisOptionsPanel.Visible = 'off';
            app.UITable.SelectionType = 'row';

            % app.setAppFontsToTimesNewRoman();;

            app.UITable.Data = [];
            app.BestBaselineSummaryDataCh1RF = [];
            app.BestBaselineSummaryDataCh2RE = [];
            app.AllDataCh1RF = [];
            app.AllDataCh2RE = [];
            app.TempCh1RF = [];
            app.TempCh2RE = [];

            % close all;
            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();

            app.Dur = 0.050;
            app.Dur2 = 0.050;
            % app.StartSearchTime = -1.250;
            app.ThresholdSize = 2;

            % Set up figure properties:
            % Enlarge figure to full screen.
            % set(gcf, 'Units', 'Normalized', 'OuterPosition', [0, 0.04, 1, 0.96]);
            % Get rid of tool bar and pulldown menus that are along top of figure.
            % set(gcf, 'Toolbar', 'none', 'Menu', 'none');
            % Give a name to the title bar.

            % screenSize = get(groot,'ScreenSize');
            % screenWidth = screenSize(3);
            % screenHeight = screenSize(4);
            %
            % left = screenWidth;
            % bottom = screenHeight;
            % width = screenWidth;
            % height = screenHeight;
            % drawnow;
            % app.UIFigure.Position = [left bottom width height];

            set(app.UIFigure, 'Name', 'Movement and Plasticity Lab – EMGLAB', 'NumberTitle', 'off')
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            close all

            % app.startupFcn();
            % restore app defaults
            app.ResultsDirectoryEditField.Value = app.ResultsDirectoryEditField.Placeholder;
            app.SubjectIDEditField.Value = app.SubjectIDEditField.Placeholder;
            app.UserInitialsEditField.Value = app.UserInitialsEditField.Placeholder;
            app.TimePointDropDown.Value = app.TimePointDropDown.Placeholder;
            app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
            app.PareticLimbButtonGroup.SelectedObject = app.PareticLimb_LeftButton;

            app.UITable.Data = [];
            app.BestBaselineSummaryDataCh1RF = [];
            app.BestBaselineSummaryDataCh2RE = [];
            app.AllDataCh1RF = [];
            app.AllDataCh2RE = [];
            app.TempCh1RF = [];
            app.TempCh2RE = [];

            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();

            app.EventDropDown.Items = {'–' 'Items 1' 'Items 2' 'Items 3' 'Items 4'}; % Clear the dropdown items
            app.EventDropDown.Value = '–'; % Clear the selected value
            app.FilenameDropDown.Items = {'condition-filename.mat' '–'}; % Clear the dropdown items
            app.FilenameDropDown.Value = '–'; % Clear the selected value
            % app.EventDropDown.Value = app.EventDropDown.Placeholder;
            app.TrialEditField.Value = app.TrialEditField.Placeholder;
            app.SmoothedChannelMaxEditField.Value = 0;

            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.FigureCheckButton.Visible = 'off';
            app.AnalyzeButton.Visible = 'off';
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SetWindowButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';
            % app.AnalysisOptionsPanel.Visible = 'off';


            app.LoadDataButton.Visible = 'off';

            app.CurrentTrial = 0;

        end

        % Menu selected function: LoadConditionsDirectoryMenu
        function LoadConditionsDirectoryMenuSelected(app, event)
            app.RefreshMenuSelected;

            app.FigureCheckButton.Visible = 'off';
            app.LoadDataButton.Visible = 'off';
            app.AnalyzeButton.Visible = 'off';

            % app.setDefaultResultsDirectory();

            ff = fixfocus;
            app.UIFigure.WindowButtonMotionFcn = [];
            app.ConditionDirectory = uigetdir(); % load condition file extracted from DIG
            delete(ff);
            % data = load(fullfile(path, filename)); % loads data stored in condition file
            % app.FilenameEditField.Value = filename;
            drawnow; % puts UI Figure at the forefront
            figure(app.UIFigure) % puts UI Figure at the forefront

            files = dir(fullfile(app.ConditionDirectory, "*.mat"));

            % Check if the directory contains any .mat files
            if isempty(files)
                error("Selected Condition File Directory does not contain any .mat files. Select another folder.")
            else
                app.FilenameDropDown.Items = ['–' {files.name}];
                app.FilenameDropDown.Value = app.FilenameDropDown.Items(2);
            end


            % Get the previous folder directory
            [app.SubjectParentFolder, ~] = fileparts(app.ConditionDirectory);
            % [app.SubjectGrandparentFolder, ~] = fileparts(app.SubjectParentFolder);
            app.ResultsDir = app.SubjectParentFolder;
            app.ResultsDirectoryEditField.Value = app.SubjectParentFolder;
            app.AnalysisDir = sprintf('%s/%s_%s_EMG-Burst-Onset_%s', app.ResultsDir, app.TimePoint, app.SubID, app.FormattedDate);

            % Get today's date
            today = datetime('today');
            app.FormattedDate = datestr(today, 'yyyymmdd'); %#ok<DATST> % format date
            app.FormattedDate = char(app.FormattedDate); % convert to string

            app.LoadDataButton.Visible = 'on';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Loading Data from Condition File',...
                'Indeterminate','on');

            app.UITable.Data = [];
            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();

            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SetWindowButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';

            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.SubjectIDEditField.Value = app.SubjectIDEditField.Placeholder;
            % app.UserInitialsEditField.Value = app.UserInitialsEditField.Placeholder;
            app.TimePointDropDown.Value = app.TimePointDropDown.Placeholder;
            app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
            % app.PareticLimbButtonGroup.SelectedObject = app.PareticLimb_LeftButton;
            app.TrialEditField.Value = app.TrialEditField.Placeholder;
            app.SmoothedChannelMaxEditField.Value = 0;


            filename = app.FilenameDropDown.Value;
            path = app.ConditionDirectory;
            data = load(fullfile(path, filename)); % loads data stored in condition file

            if ~isempty(filename) % extract information from condition file loaded
                pattern = "^[a-zA-Z0-9]+_(T0|T1|T3|T6)_[LR].*\.mat$"; % Define the pattern
                isMatch = ~isempty(regexp(filename, pattern, 'once'));

                if isMatch
                    parts = split(filename, ["_", " "]);
                    app.SubjectIDEditField.Value = parts{1}; % MAPS###
                    app.User = app.UserInitialsEditField.Value;
                    app.SubID = parts{1}; % MAPS###
                    app.TimePointDropDown.Value = parts{2}; % T0, T1, T3, T6
                    app.TimePoint = parts{2}; % T0, T1, T3, T6

                    recordingArmTemp = parts{3}; % L or R
                    recordingArmTempSplitList = split(recordingArmTemp, ".");
                    app.RecordingArm = recordingArmTempSplitList{1};

                    % auto-populate user input fields with information from
                    % condition file
                    if strcmp(app.RecordingArm, 'L')
                        app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
                    elseif strcmp(app.RecordingArm, 'R')
                        app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_RightButton;
                    else
                        app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton; % leave left as default
                    end
                    % else
                    %     app.startupFcn();
                    %
                    %     % restore app defaults
                    %     % app.ResultsDirectoryEditField.Value = app.ResultsDirectoryEditField.Placeholder;
                    %     % app.FilenameEditField.Value = app.FilenameEditField.Placeholder;
                    %     app.SubjectIDEditField.Value = app.SubjectIDEditField.Placeholder;
                    %     app.TimePointDropDown.Value = app.TimePointDropDown.Placeholder;
                    %     app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
                    %     app.PareticLimbButtonGroup.SelectedObject = app.PareticLimb_LeftButton;
                    %     app.EventDropDown.Value = app.EventDropDown.Placeholder;
                    %     app.TrialEditField.Value = app.TrialEditField.Placeholder;
                    %     app.UITable.Data = [];
                    %     cla(app.RawEMGPlot, 'reset');
                    %     cla(app.ProcessedEMGPlot, 'reset');
                    %     cla(app.SetWindowPlot, 'reset');
                    %     cla(app.BestBaselinePlot, 'reset');
                else
                    app.SubID = app.SubjectIDEditField.Value; % MAPS###
                    app.User = app.UserInitialsEditField.Value;
                    app.TimePoint = app.TimePointDropDown.Value; % T0, T1, T3, T6
                    if strcmp(app.RecordingLimbButtonGroup.SelectedObject.Text, 'Left')
                        app.RecordingArm = 'L';
                    else
                        app.RecordingArm = 'R';
                    end

                end


            end

            app.UserInitialsEditField.Value = 'ANH';
            app.User = app.UserInitialsEditField.Value;

            % ConditionInfo is stored in the condition files (.mat) acquired from DIG
            app.Streams = data.streams;
            app.ConditionInfo = data.ConditionInfo;
            app.ConditionData = data.ConditionData;
            app.Events = app.ConditionInfo(:, 2);
            app.EventsLabel = data.Events;
            app.EventsTime = data.EventsTime;
            app.Time = data.time;
            app.RawEMG = mat2struct(app.Events, app.ConditionData);
            app.EMGData = data.EMG_Data;
            app.Ch1EMG = app.EMGData(1, :) * 1000; % to get in mv
            app.Ch2EMG = app.EMGData(2, :) * 1000; % to get in mv
            app.EMG_Time = data.EMG_Time;
            app.TrialThreshold = data.EventThresholds;
            app.Levels = data.Thresholds;
            app.LevelsTime = data.ThresholdsTime;
            % app.LoadDataButton.Visible = 'on';
            app.SampleRate = data.sampleRate;
            % app.SampleRate = 1 / round(mean(diff(app.EMG_Time)), 4); % actual sampling rate in Hz

            if strcmp(app.RecordingArm, 'L')
                eventRF = find(strcmp(app.Events, 'Ch1LeRF'));
                eventRE = find(strcmp(app.Events, 'Ch2LeRE'));
                eventRFCh2 = find(strcmp(app.Events, 'Ch2LeRF'));
                eventRECh1 = find(strcmp(app.Events, 'Ch1LeRE'));
            else
                eventRF = find(strcmp(app.Events, 'Ch1RiRF'));
                eventRE = find(strcmp(app.Events, 'Ch2RiRE'));
                eventRFCh2 = find(strcmp(app.Events, 'Ch2RiRF'));
                eventRECh1 = find(strcmp(app.Events, 'Ch1RiRE'));
            end

            app.RF_IDX = eventRF; % default = 8
            app.RE_IDX = eventRE; % default = 16
            app.RF_AntagonistIDX = eventRFCh2;
            app.RE_AntagonistIDX = eventRECh1;
            app.TargetEvents = [app.RF_IDX app.RE_IDX app.RF_AntagonistIDX app.RE_AntagonistIDX];

            if app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_LeftButton
                app.AffectedArm = 'L';
            elseif app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_RightButton
                app.AffectedArm = 'R';
            end


            % % set variables once button is pushed
            % % initials = app.InitialsEditField.Value;
            % app.SubID = app.SubjectIDEditField.Value;
            % app.TimePoint = app.TimePointDropDown.Value;
            % recordingArm = app.RecordingLimbButtonGroup.SelectedObject.Text;
            % affectedArm = app.PareticLimbButtonGroup.SelectedObject.Text;

            app.AnalyzeButton.Visible = 'on';
            app.FigureCheckButton.Visible = 'on';

            % % app.CurrentTrial = 1;
            % % trialText = sprintf("Trial %02d", app.CurrentTrial);
            % % app.TrialEditField.Value = trialText;
            %
            % if strcmp(recordingArm, 'Left')
            %     app.RecordingArm = 'L';
            % elseif strcmp(recordingArm, 'Right')
            %     app.RecordingArm = 'R';
            % else
            %     error("Unable to identify recording arm. Select option under User Inputs")
            % end
            %
            % if strcmp(affectedArm, 'Left')
            %     app.AffectedArm = 'L';
            % elseif strcmp(recordingArm, 'Right')
            %     app.AffectedArm = 'R';
            % else
            %     error("Unable to identify affected arm. Select option under User Inputs")
            % end

            % update events list menu items
            if ~isempty(app.Events) % Check if app.Events is not empty
                % app.EventDropDown.Items = app.Events; % Populate the dropdown with the values from app.Events
                app.EventDropDown.Items = app.Events(app.TargetEvents(1:2)); % Populate the dropdown with the values from app.Events
                app.EventDropDown.Items = ['–' app.EventDropDown.Items];
                app.EventDropDown.Value = app.EventDropDown.Items(1);
            else
                % If app.Events is empty, clear the dropdown
                app.EventDropDown.Items = {}; % Clear the dropdown items
                app.EventDropDown.Value = ''; % Clear the selected value
            end

            pause(1)
            close(d)
        end

        % Value changed function: FilenameDropDown
        function FilenameDropDownValueChanged(app, event)
            app.FigureCheckButton.Visible = 'off';
            app.AnalyzeButton.Visible = 'off';
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SetWindowButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';


            app.LoadDataButton.Visible = 'on';

            % app.AnalysisOptionsPanel.Visible = 'off';

            % restore app defaults
            app.SubjectIDEditField.Value = app.SubjectIDEditField.Placeholder;
            app.UserInitialsEditField.Value = app.UserInitialsEditField.Placeholder;
            app.TimePointDropDown.Value = app.TimePointDropDown.Placeholder;
            app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
            % app.PareticLimbButtonGroup.SelectedObject = app.PareticLimb_LeftButton;
            app.EventDropDown.Items = {'–' 'Items 1' 'Items 2' 'Items 3' 'Items 4'}; % Clear the dropdown items
            app.EventDropDown.Value = '–'; % Clear the selected value

            app.CurrentTrial = 0;
            app.TrialEditField.Value = app.TrialEditField.Placeholder;
            app.SmoothedChannelMaxEditField.Value = 0;
            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.UITable.Data = [];
            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();
        end

        % Button pushed function: FigureCheckButton
        function FigureCheckButtonPushed(app, event)
            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Plotting Figure Checks',...
                'Indeterminate','on');

            streams = app.Streams;

            if ~isempty(app.RecordingArm)
                arm = app.RecordingArm;
                if strcmp(arm, 'L')
                    arm = "Left";
                elseif strcmp(arm, 'R')
                    arm = "Right";
                else
                    arm = "Arm Unknown";
                end
            else
                arm = "Arm Unknown";
            end

            %% Find Streams
            for s = 1:length(streams)

                type = streams{1, s}.info.type;

                if isequal(type, 'Events')
                    eventsIDX = s;
                elseif isequal(type, 'Level')
                    levelsIDX = s;
                elseif isequal(type, 'Runner') % target or actual position? not where block is
                    runnerIDX = s;
                    % elseif isequal(type, 'Settings')
                    %     settingsIDX = s;
                    % elseif isequal(type, 'Data')
                    %     dataIDX = s;
                elseif isequal(type, 'EMG')
                    emgIDX = s;
                    % elseif isequal(type, 'Smooth EMG')
                    %     smoothIDX = s;
                end

            end

            %% Store streams data
            events = streams{1, eventsIDX}.time_series; % event labels
            events_time = streams{1, eventsIDX}.time_stamps; % event times
            % events_sr = str2double(streams{1, eventsIDX}.info.nominal_srate);

            startIDX = find(strcmp(events, 'START'));
            stopIDX = find(strcmp(events, 'STOP'));

            start_time = events_time(startIDX);
            stop_time = events_time(stopIDX);
            game_start_idx = find(strcmp(events, 'game start'));

            calibrationStop = startIDX - 1;
            tutorialStop = game_start_idx - 1;

            calibration = events_time(1:calibrationStop);
            tutorial = events_time(startIDX + 1:tutorialStop);

            blockStartIDX = find(strcmp(events,'expected zone start'));
            % blockStartIDX = blockStartIDX(4:end); % ignore tutorial events
            blockStartTimes = events_time(blockStartIDX);
            blockStopIDX = find(strcmp(events,'expected zone stop'));
            % blockStopIDX = blockStopIDX(4:end); % ignore tutorial events
            blockStopTimes = events_time(blockStopIDX);

            levels = streams{1, levelsIDX}.time_series(1,:); % level labels
            levels_time = streams{1, levelsIDX}.time_stamps; % levels times

            runner_position = streams{1, runnerIDX}.time_series(2,:); % block position
            runner_time = streams{1, runnerIDX}.time_stamps;
            % runner_sr = str2double(streams{1, runnerIDX}.info.nominal_srate);
            runner_dt = diff(runner_time);
            runner_dt = [0 runner_dt];

            emg = streams{1, emgIDX}.time_series;
            emg_ch1 = emg(2,:) * 1000; % channel 1, mv
            emg_ch2 = emg(4,:) * 1000; % channel 2, mv
            emg_time = streams{1, emgIDX}.time_stamps;
            % emg_sr = str2double(streams{1, emgIDX}.info.nominal_srate);
            emg_dt = diff(emg_time);
            emg_dt = [0 emg_dt];

            %% Manually adjust event time stamps
            % events_dt = diff(events_time);
            % events_dt = [0 events_dt];
            %
            % events_time_adjusted = [];
            %
            % for i = 1:length(events_dt)
            %     temp = round(events_dt(i), 2);
            %
            %     if temp == 3
            %         events_time_adjusted(i) = temp;
            %     elseif temp == 0.5
            %         events_time_adjusted(i) = temp;
            %     else
            %         events_time_adjusted(i) = events_dt(i);
            %     end
            %
            % end
            %
            % events_fixed = [events_time(1)];
            % i = 2;
            % while i <= length(events_time)
            %
            %     adj = events_fixed(i - 1) + events_time_adjusted(i);
            %     events_fixed(i) = adj;
            %     i = i + 1;
            % end
            %
            % events_fixed(end) = emg_time(end);
            %
            % % events_fixed(level_diffs) = events_time(level_diffs);


            %% Extract tutorial and calibration
            start_time = events_time(startIDX);
            stop_time = events_time(stopIDX);
            game_start_idx = find(strcmp(events, 'game start'));

            calibrationStop = startIDX - 1;
            tutorialStop = game_start_idx - 1;

            calibration = events_time(1:calibrationStop);
            calibration_blockStartIDX = find(strcmp(calibration,'expected zone start'));
            calibration_blockStartTimes = events_time(calibration_blockStartIDX);
            calibration_blockStopIDX = find(strcmp(calibration,'expected zone stop'));
            calibration_blockStopTimes = events_time(calibration_blockStopIDX);

            tutorial = events_time(startIDX + 1:tutorialStop);
            tutorial_blockStartIDX = find(strcmp(tutorial,'expected zone start'));
            tutorial_blockStartTimes = events_time(tutorial_blockStartIDX);
            tutorial_blockStopIDX = find(strcmp(tutorial,'expected zone stop'));
            tutorial_blockStopTimes = events_time(tutorial_blockStopIDX);

            % Start with index 2, keep two, skip one: pattern = [2 3 skip 5 6 skip ...]
            clear idx
            idx = [];

            i = 2;
            while i <= length(tutorial)
                idx = [idx, i];
                if i+1 <= length(tutorial)
                    idx = [idx, i+1];
                end
                i = i + 3;
            end

            tutorial_clean = tutorial(idx);

            clear tutorialExpStop tutorialExpStart
            tutorialExpStart = [];
            tutorialExpStop = [];

            for i = 1:length(tutorial_clean)
                if mod(i, 2) == 0
                    tutorialExpStop = [tutorialExpStop, i];
                else
                    tutorialExpStart = [tutorialExpStart, i];
                end
            end

            tutorialStartTimes = events_time(tutorialExpStart);

            %% Adjust runner position values for visualization
            if max(emg_ch1) >= max(emg_ch2)
                max_emg = max(emg_ch1);
            else
                max_emg = max(emg_ch2);
            end

            if min(emg_ch1) >= min(emg_ch2)
                min_emg = min(emg_ch1);
            else
                min_emg = min(emg_ch2);
            end

            if mean(emg_ch1) >= mean(emg_ch2)
                med = median(emg_ch1);
            else
                med = median(emg_ch2);
            end


            runner_position_adjusted = zeros(size(runner_position));
            for i = 1:length(runner_position)
                if max_emg ~= 0
                    switch round(runner_position(i))
                        case 2
                            runner_position_adjusted(i) = med + (max_emg * 0.4);
                        case 1
                            runner_position_adjusted(i) = med;
                        case 0
                            runner_position_adjusted(i) = med - (max_emg * 0.4);
                    end
                else
                    switch round(runner_position(i))
                        case 2
                            runner_position_adjusted(i) = med + (min_emg * .8);
                        case 1
                            runner_position_adjusted(i) = med;
                        case 0
                            runner_position_adjusted(i) = med - (min_emg * .8);
                    end
                end
            end

            % %% Manually adjust runner time stamps
            % blockStartTimes_fixed = events_fixed(blockStartIDX);
            % blockStopTimes_fixed = events_fixed(blockStopIDX);
            %
            % % Interpolate runner_position onto events_time:
            % start_time = events_fixed(startIDX);
            % offset = start_time - runner_time(1);
            % runner_time_offset = runner_time + offset;
            %
            % % target_times = [blockStartTimes_fixed blockStopTimes_fixed]; % add the tutorial times
            % target_times = blockStartTimes_fixed;
            % % target_times(repeated) = [];
            % [target_times_sorted, ~] = sort(target_times);
            %
            % % % Create interpolant
            % % F = griddedInterpolant(runner_time, runner_position, 'nearest');
            % % % Get runner position at event times
            % % runner_at_events = F(events_fixed);
            % % F_event = griddedInterpolant(target_times_sorted, runner_at_events, 'nearest');
            % % runner_position_stretched = F_event(runner_time_offset);
            %
            % states = interp1(runner_time, runner_position, target_times_sorted, 'previous');
            % states(isnan(states)) = 1;
            % % states = states(2:end);
            % % states = [states 1];
            % % states(1) = 1;
            %
            % % change first state following the start of the level to be 'rest' (1)
            % for i = 1:length(levels_time)
            %     % Find first index in blockStopTimes where time > levels_time(i)
            %     idx = find(target_times_sorted > levels_time(i), 1, 'first');
            %
            %     if ~isempty(idx) && idx > 1
            %         % Set previous index to 1
            %         states(idx-1) = 1;
            %     elseif idx == 1
            %         % If first target_times is already > levels_time(i), do nothing
            %         % (cannot go to previous index)
            %         continue
            %     else
            %         % If levels_time is after all blockStopTimes, set the last one
            %         states(end) = 1;
            %     end
            % end
            %
            % stretched_states = interp1(target_times_sorted, states, runner_time, 'previous', 'extrap');
            % % stretched_states = interp1(target_times_sorted, states, runner_time, 'previous');
            % % % adding NaNs for first state for some reason when stretched
            % stretched_states(isnan(stretched_states)) = 1;
            %
            % %% ChatGPT
            % % aligned_runner_time = runner_time;                      % Copy original
            % % aligned_runner_position = nan(size(runner_position));  % Preallocate the same length
            % %
            % % % Get the stop times
            % % expected_stop_times = events_fixed(strcmp(events, 'expected zone stop'));
            % %
            % % % Initial segment (before first stop) — just copy the original positions
            % % initial_mask = runner_time < expected_stop_times(1);
            % % aligned_runner_position(initial_mask) = runner_position(initial_mask);
            % %
            % % % Segments between stops
            % % for seg = 1:length(expected_stop_times)-1
            % %     t_start = expected_stop_times(seg);
            % %     t_end = expected_stop_times(seg + 1);
            % %     runner_mask = runner_time > t_start & runner_time < t_end;
            % %
            % %     runner_segment = runner_position(runner_mask);
            % %     target_value = mode(runner_segment);
            % %
            % %     aligned_runner_position(runner_mask) = target_value;
            % % end
            % %
            % % % Final segment after the last stop
            % % last_mask = runner_time >= expected_stop_times(end);
            % % aligned_runner_position(last_mask) = runner_position(last_mask);
            %
            % expected_stop_indices = find(strcmp(events, 'expected zone start'));
            % expected_stop_times = events_fixed(expected_stop_indices(:));
            % expected_stop_times = unique(expected_stop_times(:));
            % expected_stop_times = expected_stop_times(expected_stop_times <= runner_time(end));
            %
            % %% Preallocate Aligned Output
            % aligned_runner_time = runner_time;                 % Same length
            % aligned_runner_position = nan(size(runner_position));
            %
            % %% 1️⃣ Handle the START Segment (before first stop)
            % if ~isempty(expected_stop_times)
            %     first_boundary = expected_stop_times(1);
            %     start_mask = runner_time < first_boundary;
            %
            %     if any(start_mask)
            %         target_value = mode(runner_position(start_mask));
            %         aligned_runner_position(start_mask) = target_value;
            %     end
            % end
            %
            % %% 2️⃣ Handle the MAIN Segments
            % for seg = 1:length(expected_stop_times) - 1
            %     t_start = expected_stop_times(seg);
            %     t_end = expected_stop_times(seg + 1);
            %
            %     runner_mask = runner_time >= t_start & runner_time < t_end;
            %
            %     if any(runner_mask)
            %         target_value = mode(runner_position(runner_mask));
            %         aligned_runner_position(runner_mask) = target_value;
            %     end
            % end
            %
            % %% 3️⃣ Handle the END Segment (after the final stop)
            % last_boundary = expected_stop_times(end);
            % tail_mask = runner_time >= last_boundary;
            %
            % if any(tail_mask)
            %     target_value = mode(runner_position(tail_mask));
            %     aligned_runner_position(tail_mask) = target_value;
            % end
            %
            % % adjusted_mask = runner_time <= levels_time(1);
            % % aligned_runner_position(adjusted_mask) = stretched_states(adjusted_mask);

            aligned_runner_time = runner_time;
            aligned_runner_position = runner_position;

            levels_time_with_start = [events_time(game_start_idx) levels_time];
            level_first_block_idx = [];
            level_first_block_diff = [];

            i = 1;
            while i <= numel(levels_time_with_start) && levels_time_with_start(i) < events_time(end)
                if i == 1
                    % Find the *7th* event after the first level time
                    idx_all = find(events_time > levels_time_with_start(i));
                    if numel(idx_all) >= 7
                        idx = idx_all(7);
                    else
                        idx = idx_all(end);  % fallback to last available
                        warning('Less than 7 events after levels_time_with_start(1); using last available.');
                    end
                else
                    % Regular case: find first event after level time
                    idx = find(events_time > levels_time_with_start(i), 1, 'first');
                end

                level_first_block_idx(i) = idx;
                level_first_block_diff(i) = events_time(idx) - levels_time_with_start(i);

                i = i + 1;
            end


            i = 1;
            while i <= numel(levels_time_with_start) && levels_time_with_start(i) < events_time(end)
                if i == 1
                    % Special case:
                    % start_level = 6th event *after* levels_time_with_start(1)
                    idx_all = find(events_time > levels_time_with_start(i));
                    if numel(idx_all) >= 6
                        start_level = events_time(idx_all(6));
                    else
                        start_level = events_time(idx_all(end)); % fallback
                        warning('Less than 6 events after levels_time_with_start(1); using last available.');
                    end
                    end_time = levels_time_with_start(i);
                else
                    % Regular case
                    start_level = levels_time_with_start(i);
                    end_time = events_time(level_first_block_idx(i));
                end

                % Apply range to aligned_runner_time
                idx_range = aligned_runner_time >= start_level & aligned_runner_time <= end_time;

                % Set aligned_runner_position to 1 within this range
                aligned_runner_position(idx_range) = 1;

                i = i + 1;
            end

            % clear large_gap_level_idx
            % level_gap_nonoutliers = find(level_first_block_diff < 35);
            % level_first_block_diff_cleaned = level_first_block_diff(level_gap_nonoutliers);
            % large_gap_level_idx = [];
            % average_start = mean(level_first_block_diff_cleaned);
            %
            % for i = 2:length(level_first_block_diff) - 1
            %     if level_first_block_diff(i) > average_start
            %         large_gap_level_idx = [large_gap_level_idx i];
            %         mask_start = find(aligned_runner_time >= levels_time_with_start(i), 1, 'first');
            %         mask_end = find(aligned_runner_time <= levels_time_with_start(i + 1), 1, 'last');
            %         % adjusted_mask = any(aligned_runner_time >= levels_time_with_start(i) & aligned_runner_time <= levels_time_with_start(i + 1));
            %         aligned_runner_position(mask_start:mask_end) = stretched_states(mask_start:mask_end);
            %     end
            % end

            %% Adjust states for easier visualization
            dtw = zeros(size(aligned_runner_position));
            for i = 1:length(aligned_runner_position)
                if max_emg ~= 0
                    switch round(aligned_runner_position(i))
                        case 2
                            dtw(i) = med + (max_emg * 0.4);
                        case 1
                            dtw(i) = med;
                        case 0
                            dtw(i) = med - (max_emg * 0.4);
                    end
                else
                    switch round(aligned_runner_position(i))
                        case 2
                            dtw(i) = med + (min_emg * .8);
                        case 1
                            dtw(i) = med;
                        case 0
                            dtw(i) = med - (min_emg * .8);
                    end
                end
            end

            %% Plot the data
            close all

            % figure(1)
            % subplot(2, 1, 1)
            % plot(aligned_runner_time, runner_position_adjusted, 'LineWidth', 1.25, 'DisplayName', 'Target Position RAW')
            % title("Target Position RAW", 'FontSize', 15)
            % xlabel("Time (s)")
            % ylabel("Position")
            % hold on
            % xline(levels_time, 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            % hold off
            % subplot(2, 1, 2)
            % plot(aligned_runner_time, dtw, 'LineWidth', 1.25, 'Color', 'r', 'DisplayName', 'Target Position DYNAMIC TIME WARPED')
            % title("Target Position DYNAMIC TIME WARPED", 'FontSize', 15)
            % xlabel("Time (s)")
            % ylabel("Position")
            % hold on
            % xline(levels_time, 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            % hold off

            % Plot raw flexor EMG
            figure(1);
            subplot(2, 1, 1)
            plot(emg_time, emg_ch1, 'DisplayName', 'Channel 1 – Flexor')
            plot_title_ch1 = sprintf("%s — Ch1 (FLEXOR) | %s Arm", app.SubID, arm);
            title(plot_title_ch1)
            hold on
            plot(runner_time, runner_position_adjusted, 'LineWidth', 1, 'Color', 'r', 'DisplayName', 'Target Position RAW')
            xline(events_time(blockStartIDX), 'Color', [0.1 0.1 0.1], 'HandleVisibility','off')
            xline(events_time(blockStopIDX), 'LineStyle', '-.', 'Color', [0.1 0.1 0.1], 'HandleVisibility','off')
            xline(levels_time, 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            xline(events_time(game_start_idx), 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            xline(calibration, 'Color', [0.7 0.4 0.5], 'HandleVisibility', 'off')
            xline(tutorial_clean(tutorialExpStart), 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(tutorial_clean(tutorialExpStop), 'LineStyle', '-.', 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(start_time, 'Color', [0.2 0.7 0.5], 'DisplayName', 'Start (Tutorial + Game)', 'LineWidth', 3)
            xlabel("Time (s)")
            ylabel("EMG (mV)")
            legend()
            hold off

            % Plot raw extensor EMG
            subplot(2, 1, 2)
            plot(emg_time, emg_ch2, 'DisplayName', 'Channel 2 – Extensor', 'Color', [0 0.65 0.3])
            plot_title_ch2 = sprintf("%s — Ch2 (EXTENSOR) | %s Arm", app.SubID, arm);
            title(plot_title_ch2)
            hold on
            plot(runner_time, runner_position_adjusted, 'LineWidth', 1, 'Color', 'r', 'DisplayName', 'Target Position RAW')
            xline(events_time(blockStartIDX), 'Color', [0.1 0.1 0.1], 'HandleVisibility','off')
            xline(events_time(blockStopIDX), 'LineStyle', '-.', 'Color', [0.1 0.1 0.1], 'HandleVisibility','off')
            xline(levels_time, 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            xline(events_time(game_start_idx), 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            xline(calibration, 'Color', [0.7 0.4 0.5], 'HandleVisibility', 'off')
            xline(tutorial_clean(tutorialExpStart), 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(tutorial_clean(tutorialExpStop), 'LineStyle', '-.', 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(start_time, 'Color', [0.2 0.7 0.5], 'DisplayName', 'Start (Tutorial + Game)', 'LineWidth', 3)
            xlabel("Time (s)")
            ylabel("EMG (mV)")
            legend()
            hold off

            % Plot adjusted flexor EMG
            figure(2);
            subplot(2, 1, 1)
            plot(emg_time, emg_ch1, 'DisplayName', 'Channel 1 – Flexor')
            plot_title_ch1 = sprintf("%s — Ch1 (FLEXOR) | %s Arm", app.SubID, arm);
            title(plot_title_ch1)
            hold on
            % plot(emg_time, emg_ch2 / 10, 'DisplayName', 'Channel 2 – Extensor')
            % plot(runner_time_offset_adjusted, runner_position_adjusted, 'LineWidth', 1, 'Color', 'r', 'DisplayName', 'Target Position OFFSET')
            % adjusted events
            xline(events_time(blockStartIDX), 'Color', [0.1 0.1 0.1], 'HandleVisibility','off')
            xline(events_time(blockStopIDX), 'LineStyle', '-.', 'Color', [0.1 0.1 0.1], 'HandleVisibility','off')
            % % raw events
            % xline(events_time(blockStartIDX), 'Color', 'b', 'HandleVisibility','off')
            % xline(events_time(blockStopIDX), 'LineStyle', '-.', 'Color', 'b', 'HandleVisibility','off')
            xline(levels_time, 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            xline(events_time(game_start_idx), 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            % xline(all_changes_runner_time, 'Color', 'magenta', 'LineWidth', 1.5, 'HandleVisibility', 'off')
            xline(calibration, 'Color', [0.7 0.4 0.5], 'HandleVisibility', 'off')
            % xline(tutorial, 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(tutorial_clean(tutorialExpStart), 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(tutorial_clean(tutorialExpStop), 'LineStyle', '-.', 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(start_time, 'Color', [0.2 0.7 0.5], 'DisplayName', 'Start (Tutorial + Game)', 'LineWidth', 3)
            plot(aligned_runner_time, dtw, 'LineWidth', 1.25, 'Color', 'r', 'DisplayName', 'Target Position First Event Rest')
            % plot(runner_time, runner_position_adjusted, 'LineStyle', '--', 'LineWidth', 1.25, 'Color', 'black', 'DisplayName', 'Target Position RAW')
            xlabel("Time (s)")
            ylabel("EMG (mV)")
            legend()
            hold off

            % Plot adjusted extensor EMG
            % figure(2);
            subplot(2, 1, 2)
            plot(emg_time, emg_ch2, 'DisplayName', 'Channel 2 – Extensor', 'Color', [0 0.65 0.3])
            plot_title_ch2 = sprintf("%s — Ch2 (EXTENSOR) | %s Arm", app.SubID, arm);
            title(plot_title_ch2)
            hold on
            % plot(emg_time, emg_ch2 / 10, 'DisplayName', 'Channel 2 – Extensor')
            % plot(runner_time_offset_adjusted, runner_position_adjusted, 'LineWidth', 1, 'Color', 'r', 'DisplayName', 'Target Position OFFSET')
            % adjusted events
            xline(events_time(blockStartIDX), 'Color', [0.1 0.1 0.1], 'HandleVisibility','off')
            xline(events_time(blockStopIDX), 'LineStyle', '-.', 'Color', [0.1 0.1 0.1], 'HandleVisibility','off')
            % % raw events
            % xline(events_time(blockStartIDX), 'Color', 'b', 'HandleVisibility','off')
            % xline(events_time(blockStopIDX), 'LineStyle', '-.', 'Color', 'b', 'HandleVisibility','off')
            xline(levels_time, 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            xline(events_time(game_start_idx), 'Color', [0.1 0.1 0.1], 'LineWidth', 3, 'HandleVisibility', 'off')
            % xline(all_changes_runner_time, 'Color', 'magenta', 'LineWidth', 1.5, 'HandleVisibility', 'off')
            xline(calibration, 'Color', [0.7 0.4 0.5], 'HandleVisibility', 'off')
            % xline(tutorial, 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(tutorial_clean(tutorialExpStart), 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(tutorial_clean(tutorialExpStop), 'LineStyle', '-.', 'Color', [0.7 0.5 0.2], 'HandleVisibility', 'off')
            xline(start_time, 'Color', [0.2 0.7 0.5], 'DisplayName', 'Start (Tutorial + Game)', 'LineWidth', 3)
            plot(aligned_runner_time, dtw, 'LineWidth', 1.25, 'Color', 'r', 'DisplayName', 'Target Position First Event Rest')
            % plot(runner_time, runner_position_adjusted, 'LineStyle', '--', 'LineWidth', 1.25, 'Color', 'black', 'DisplayName', 'Target Position RAW')
            xlabel("Time (s)")
            ylabel("EMG (mV)")
            legend()
            hold off

            delete(d)
            % drawnow; % puts UI Figure at the forefront
            % figure(app.UIFigure) % puts UI Figure at the forefront
        end

        % Button pushed function: ChangeDirectoryButton
        function ChangeDirectoryButtonPushed(app, event)
            ff = fixfocus;
            app.UIFigure.WindowButtonMotionFcn = [];
            app.ResultsDir = uigetdir();
            delete(ff);

            app.ResultsDirectoryEditField.Value = app.ResultsDir;

            drawnow; % puts UI Figure at the forefront
            figure(app.UIFigure) % puts UI Figure at the forefront
        end

        % Selection changed function: PareticLimbButtonGroup
        function PareticLimbButtonGroupSelectionChanged(app, event)
            if app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_LeftButton
                app.AffectedArm = 'L';
            elseif app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_RightButton
                app.AffectedArm = 'R';
            end
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)

            fig = app.UIFigure;
            d1 = uiprogressdlg(fig,'Title','Analyzing Condition File',...
                'Indeterminate','on');

            % reset window fields
            app.UITable.Data = [];
            app.BestBaselineSummaryDataCh1RF = [];
            app.BestBaselineSummaryDataCh2RE = [];
            app.AllDataCh1RF = [];
            app.AllDataCh2RE = [];
            app.TempCh1RF = [];
            app.TempCh2RE = [];

            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();

            app.SetWindowButton.Visible = 'off';
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';

            app.TrialEditField.Value = app.TrialEditField.Placeholder;
            app.SmoothedChannelMaxEditField.Value = 0;
            app.EventDropDown.Value = '–'; % Clear the selected value
            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.SubID = app.SubjectIDEditField.Value; % MAPS###
            app.User = app.UserInitialsEditField.Value;
            app.TimePoint = app.TimePointDropDown.Value; % T0, T1, T3, T6
            if strcmp(app.RecordingLimbButtonGroup.SelectedObject.Text, 'Left')
                app.RecordingArm = 'L';
            else
                app.RecordingArm = 'R';
            end

            % subject = app.SubID;
            % affected_arm = app.AffectedArm;
            % timepoint = app.TimePoint;
            % recording_arm = app.RecordingArm;
            % ConditionData = app.ConditionData;
            events = app.Events;
            time = app.Time;
            sampleRate = app.SampleRate;
            target_eventIDX = app.TargetEvents;

            if strcmp(app.FilenameDropDown.Value, app.FilenameDropDown.Placeholder)
                error("Filename not selected. Please select filename from drop down and Load Data again.")
            elseif strcmp(app.SubjectIDEditField.Value, app.SubjectIDEditField.Placeholder)
                error("Subject ID not entered. Please enter subject ID and Load Data again.")
            elseif strcmp(app.TimePointDropDown.Value, app.TimePointDropDown.Placeholder)
                error("Time point not selected. Please select time point from drop down and Load Data again.")
            end

            %% CHANGE THESE
            % Define baseline window size and step size
            % sr * window (s) = # of samples
            window_ms = 500; % ms
            step_ms = 25; % ms
            startSearchTime = -1.500;

            windowStart = -2350; % ms
            windowStop = -1350; % ms
            windowSize = windowStop - windowStart; % leave this

            aFq = 10; % lower bound of filter (Hz)
            bFq = 500; % upper bound of filter (Hz)
            envelope_lowpassHz = 20; % low-pass filter cut-off for EMG envelope

            thresholdSize = app.ThresholdSize; % how many SDs above the baseline mean to determine onset threshold
            dur = app.Dur; % s; duration signal needs to be held 2SD above baseline average to determine onset
            dur2 = app.Dur2; %s; subsequent duration signal needs to be held 1SD above baseline average to confirm onset

            % Get today's date
            today = datetime('today');
            app.FormattedDate = datestr(today, 'yyyymmdd'); %#ok<DATST> % format date
            app.FormattedDate = char(app.FormattedDate); % convert to string


            %% ---------------------------------------------------------------------%%
            %% Pre-Processing
            %% ---------------------------------------------------------------------%%
            %% Bandpass Filter & Rectify
            fprintf("\nApplying Bandpass Filter and Rectifying EMG signal...\n")
            d1.Message = sprintf("\nApplying Bandpass Filter and Rectifying EMG signal...");
            % [ProcessedEMG, events_processed] = preprocess_emg(ConditionData, events, [aFq bFq], sampleRate, 'butter');
            [ProcessedEMG2] = preprocess_emg_app(app.ConditionData, events, [aFq bFq], sampleRate, 'butter');

            nyq_freq = sampleRate / 2;
            [b2, a2] = butter(4, [(aFq/nyq_freq) (bFq/nyq_freq)], 'bandpass'); % bandpass butterworth filter

            Ch1Filtered = filtfilt(b2, a2, app.Ch1EMG');
            Ch1Filtered = abs(Ch1Filtered);

            Ch2Filtered = filtfilt(b2, a2, app.Ch2EMG');
            Ch2Filtered = abs(Ch2Filtered);

            fprintf("EMG Bandpass filtered and Rectified!\n")
            d1.Message = sprintf("EMG Bandpass filtered and Rectified!");

            %% ---------------------------------------------------------------------%%
            %% Notch Filter
            % Account for power line interference at 60 Hz
            % https://www.mathworks.com/help/signal/ug/remove-the-60-hz-hum-from-a-signal.html
            %% ---------------------------------------------------------------------%%
            fprintf("\nApplying 60Hz notch Filter...\n")
            d1.Message = sprintf("\nApplying 60Hz notch Filter...");

            d = designfilt('bandstopiir','FilterOrder',2, ...
                'HalfPowerFrequency1', 59, 'HalfPowerFrequency2', 61, ...
                'DesignMethod', 'butter', 'SampleRate', sampleRate);

            app.ProcessedEMG = struct();
            for e = 1:length(events)
                [~, cols] = size(ProcessedEMG2.(events{e}));
                for trial = 1:cols
                    processed = ProcessedEMG2.(events{e})(:, trial);
                    notch = filtfilt(d, processed);
                    app.ProcessedEMG.(events{e})(:, trial) = notch;
                end
            end

            Ch1Notched = filtfilt(d, Ch1Filtered);
            Ch2Notched = filtfilt(d, Ch2Filtered);

            fprintf("EMG notched filtered!\n")
            d1.Message = sprintf("EMG notched filtered!");

            %% ---------------------------------------------------------------------%%
            %% Smooth Signal
            %% ---------------------------------------------------------------------%%
            fprintf("\nSmoothing Processed EMG signal...\n")
            d1.Message = sprintf("\nSmoothing Processed EMG signal...");

            app.SmoothedEMG = struct();
            % EnvelopeEMG = struct();

            % events_smoothed = events + "_smoothed";
            % events_envelope = events + "_envelope";
            % Ch1EventsSmoothed = events_smoothed(1:length(events) / 2);
            % Ch2EventsSmoothed = events_smoothed((length(events) / 2) + 1:end);

            % for i = 1:length(ConditionData)
            clear b a
            for e = 1:length(events)
                [~, cols] = size(app.ProcessedEMG.(events{e}));

                % [b, a] = butter(6, 5 / nyq_freq, 'low'); % butterworth filter
                [b, a] = butter(6, envelope_lowpassHz / (sampleRate / 2), 'low'); % butterworth filter
                for trial = 1:cols
                    processed_data = app.ProcessedEMG.(events{e})(:, trial); % EMG recordings for each condition in a given trial
                    LowPassEMG = filtfilt(b, a, processed_data);
                    app.SmoothedEMG.(events{e})(:, trial) = LowPassEMG;
                end
            end

            % for e = 1:length(events)
            %     [~, cols] = size(app.ProcessedEMG.(events{e}));
            %
            %     [b, a] = butter(6, envelope_lowpassHz / (sampleRate / 2), 'low'); % butterworth filter
            %     for trial = 1:cols
            %         processed_data = app.ProcessedEMG.(events{e})(:, trial); % EMG recordings for each condition in a given trial
            %         [~, tkeo] = energyop(processed_data);
            %         LowPassEMG = filtfilt(b, a, tkeo);
            %         app.SmoothedEMG.(events{e})(:, trial) = LowPassEMG;
            %     end
            % end

            clear b2 a2
            [b2, a2] = butter(6, envelope_lowpassHz / (sampleRate / 2), 'low'); % butterworth filter
            Ch1Smooth = filtfilt(b2, a2, Ch1Notched);
            Ch2Smooth = filtfilt(b2, a2, Ch2Notched);
            fprintf("EMG Smoothed!\n\n")
            d1.Message = sprintf("EMG Smoothed!");

            %% ---------------------------------------------------------------------%%
            %% Get Max EMG
            %% ---------------------------------------------------------------------%%
            fprintf("Get max EMG (mV) for each trial...\n")
            d1.Message = sprintf("Get max EMG (mV) for each trial...");

            clear tempEMG_IDX tempCh1EMG tempCh2EMG
            calibrationStop = find(strcmp(app.EventsLabel, 'game start'), 1, 'first');
            calibrationStopTime = app.EventsTime(calibrationStop);
            tempEMG_IDX = find(app.EMG_Time <= calibrationStopTime);
            tempCh1EMG = Ch1Smooth(tempEMG_IDX);
            tempCh2EMG = Ch2Smooth(tempEMG_IDX);

            app.MaxEMG = struct();

            startMaxSearch = -1.500;
            % startMaxSearchFull = -2.500;
            startMaxSearchIDX = find(time == startMaxSearch);
            % startMaxSearchIDXFull = find(time == startMaxSearchFull);

            finalTime = length(time);

            for e = 1:length(events)
                [~, cols] = size(app.SmoothedEMG.(events{e}));

                for trial = 1:cols
                    tempEMG_cropped = app.SmoothedEMG.(events{e})(startMaxSearchIDX:finalTime, trial);
                    maxEMG_cropped = max(tempEMG_cropped, [], 'all');
                    maxIDX_cropped = find(app.SmoothedEMG.(events{e})(:, trial) == maxEMG_cropped, 1);
                    app.MaxEMG.(events{e})(1, trial) = maxIDX_cropped;
                    app.MaxEMG.(events{e})(2, trial) = maxEMG_cropped;

                    tempEMG = app.SmoothedEMG.(events{e})(:, trial);
                    maxEMG = max(tempEMG, [], 'all');
                    maxIDX = find(app.SmoothedEMG.(events{e})(:, trial) == maxEMG, 1);
                    app.MaxEMG.(events{e})(3, trial) = maxIDX;
                    app.MaxEMG.(events{e})(4, trial) = maxEMG;
                end
            end

            clear tempEMG_IDX tempCh1EMG tempCh2EMG
            tempEMG_IDX = find(app.EMG_Time > calibrationStopTime);
            tempCh1EMG = Ch1Smooth(tempEMG_IDX);
            tempCh2EMG = Ch2Smooth(tempEMG_IDX);

            app.Ch1SmoothedRecordingMax = max(tempCh1EMG, [], 'all');
            app.Ch2SmoothedRecordingMax = max(tempCh2EMG, [], 'all');

            if app.Ch1SmoothedRecordingMax >= app.Ch2SmoothedRecordingMax
                app.RecordingMax = app.Ch1SmoothedRecordingMax;
            elseif app.Ch1SmoothedRecordingMax < app.Ch2SmoothedRecordingMax
                app.RecordingMax = app.Ch2SmoothedRecordingMax;
            else
                error("No maximum calculated. Check raw EMG files to make sure there are no errors in the recording file.")
            end

            %% ---------------------------------------------------------------------%%
            %% Get calibration Max
            %% ---------------------------------------------------------------------%%

            % tempCh1EMGabs = abs(tempCh1EMG);
            % tempCh2EMGabs = abs(tempCh2EMG);
            % tempCh1EMGmax = max(tempCh1EMGabs, [], 'all');
            % tempCh2EMGmax = max(tempCh2EMGabs, [], 'all');
            clear tempEMG_IDX tempCh1EMG tempCh2EMG
            tempEMG_IDX = find(app.EMG_Time <= calibrationStopTime);
            tempCh1EMG = Ch1Smooth(tempEMG_IDX);
            tempCh2EMG = Ch2Smooth(tempEMG_IDX);

            tempCh1EMGmax = max(tempCh1EMG, [], 'all');
            tempCh2EMGmax = max(tempCh2EMG, [], 'all');

            % app.RectifiedCh1EMGCalibrationMax = tempCh1EMGmax;
            % app.RectifiedCh2EMGCalibrationMax = tempCh2EMGmax;

            % Ch1CalibrationMaxIDX = find(tempCh1EMGabs == tempCh1EMGmax, 1, 'first');
            % Ch2CalibrationMaxIDX = find(tempCh2EMGabs == tempCh2EMGmax, 1, 'first');

            % app.Ch1SmoothedCalibrationMax = tempCh1EMG(Ch1CalibrationMaxIDX);
            % app.Ch2SmoothedCalibrationMax = tempCh2EMG(Ch2CalibrationMaxIDX);

            app.Ch1SmoothedCalibrationMax = tempCh1EMGmax;
            app.Ch2SmoothedCalibrationMax = tempCh2EMGmax;

            if app.Ch1SmoothedCalibrationMax >= app.Ch1SmoothedRecordingMax
                app.Ch1EMGMax = app.Ch1SmoothedCalibrationMax;
            elseif app.Ch1SmoothedCalibrationMax < app.Ch1SmoothedRecordingMax
                app.Ch1EMGMax = app.Ch1SmoothedRecordingMax;
            else
                error("No maximum calculated. Check raw EMG files to make sure there are no errors in the recording file.")
            end

            if app.Ch2SmoothedCalibrationMax >= app.Ch2SmoothedRecordingMax
                app.Ch2EMGMax = app.Ch2SmoothedCalibrationMax;
            elseif app.Ch2SmoothedCalibrationMax < app.Ch2SmoothedRecordingMax
                app.Ch2EMGMax = app.Ch2SmoothedRecordingMax;
            else
                error("No maximum calculated. Check raw EMG files to make sure there are no errors in the recording file.")
            end

            fprintf("Max EMG (mV) stored in app.MaxEMG\n\n")
            d1.Message = sprintf("Max EMG (mV) stored in app.MaxEMG");


            %% ---------------------------------------------------------------------%%
            %% Sliding Average to detect Baseline
            % Sanity check:
            % Number of Samples = 2000Hz (sr) × 3s (pre-stim dur) = 6000 samples
            %% ---------------------------------------------------------------------%%
            fprintf("Starting EMG Burst Onset detection...\n")
            d1.Message = sprintf("Starting EMG Burst Onset detection...");

            BaselineEMG = struct();

            % events_baseline = events + "_baseline";

            for e = 1:length(events)

                % Baseline [-3, 0)
                baselineIDX = time <= 0;
                BaselineEMG.(events{e}) = app.SmoothedEMG.(events{e})(baselineIDX, :);
                % BaselineEMG.(events{e}) = app.ProcessedEMG.(events{e})(baselineIDX, :);
                % BaselineEMG.(events{e}) = RawEMG.(events{e})(baselineIDX, :);

            end

            app.BaselineStats = struct();

            % Define window size and step size
            % sr * window (s) = # of samples
            window = sampleRate * (window_ms/1000);
            step = sampleRate * (step_ms/1000);

            % total_trials = 0;
            for e = 1:length(events)
                [~, cols] = size(BaselineEMG.(events{e}));

                % total_trials = total_trials + size(BaselineEMG.(events{e}), 2);

                for trial = 1:cols
                    data = BaselineEMG.(events{e})(:, trial);

                    % Initialize an array to store the averages
                    num_windows = round((length(data) - window) / step);
                    windowAverages = zeros(1, num_windows);
                    % windowMedians = zeros(1, num_windows);
                    windowSTD = zeros(1, num_windows);
                    windowKeys = [];
                    windowKeysStart = zeros(num_windows, 1);
                    windowKeysStop = zeros(num_windows, 1);
                    windowMin = zeros(1, num_windows);
                    windowMax = zeros(1, num_windows);

                    % windowStartValues = zeros(total_trials, 1);

                    % Loop through the data using the sliding window
                    for i = 1:num_windows
                        start = ((i - 1) * step) + 1;
                        stop = start + window;

                        % Compute the mean for the current window
                        windowAverages(i) = mean(data(start:stop));
                        % windowMedians(i) = median(data(start:stop), 1);
                        windowSTD(i) = std(data(start:stop));
                        windowKeysStart(i) = time(start);
                        windowKeysStop(i) = time(stop);
                        windowMin(i) = min(data(start:stop), [], 'all');
                        windowMax(i) = max(data(start:stop), [], 'all');

                        key_format = sprintf("(%.4f, %.4f)", time(start), time(stop));
                        windowKeys = [windowKeys; key_format];

                    end

                    % Find the minimum average
                    min_average = min(windowAverages, [], 'all');
                    baselineIDX = find(windowAverages == min_average);
                    % min_median = min(windowMedians, [], 'all');
                    % baselineIDX = find(windowMedians == min_median, 1); % need to specify take the first index where min_median is found

                    app.BaselineStats.(events{e})(1, trial) = min_average;
                    % app.BaselineStats.(events{e})(1, trial) = min_median;
                    app.BaselineStats.(events{e})(2, trial) = windowSTD(baselineIDX);
                    app.BaselineStats.(events{e})(3, trial) = windowKeysStart(baselineIDX);
                    app.BaselineStats.(events{e})(4, trial) = windowKeysStop(baselineIDX);
                    app.BaselineStats.(events{e})(5, trial) = windowMin(baselineIDX);
                    app.BaselineStats.(events{e})(6, trial) = windowMax(baselineIDX);

                end
            end

            %% ---------------------------------------------------------------------%%
            %% Get Set Window Baseline
            % Sanity check:
            % Number of Samples = 2000Hz (sr) × 3s (pre-stim dur) = 6000 samples
            %% ---------------------------------------------------------------------%%
            SetWindowBaselineEMG = struct();

            for e = 1:length(events)

                % Baseline [-3, 0)
                baselineIDX = time <= 0;
                SetWindowBaselineEMG.(events{e}) = app.SmoothedEMG.(events{e})(baselineIDX, :);

            end

            app.SetWindowBaselineStats = struct();

            windowStartIDX = find(time == (windowStart/1000));
            windowStopIDX = find(time == (windowStop/1000));

            for e = 1:length(events)
                [~, cols] = size(SetWindowBaselineEMG.(events{e}));

                % total_trials = total_trials + size(BaselineEMG.(events_baseline{e}), 2);

                for trial = 1:cols
                    data = SetWindowBaselineEMG.(events{e})(:, trial);

                    % Find the Baseline EMG average
                    baselineAVG =  mean(data(windowStartIDX:windowStopIDX));
                    baselineSTD =  std(data(windowStartIDX:windowStopIDX));

                    app.SetWindowBaselineStats.(events{e})(1, trial) = baselineAVG;
                    app.SetWindowBaselineStats.(events{e})(2, trial) = baselineSTD;
                    app.SetWindowBaselineStats.(events{e})(3, trial) = time(windowStartIDX);
                    app.SetWindowBaselineStats.(events{e})(4, trial) = time(windowStopIDX);

                end
            end

            %% ---------------------------------------------------------------------%%
            %% Get Onset
            % Theshold = z-score; 2 SD
            % dur = 30ms
            %% ---------------------------------------------------------------------%%
            app.EMGBurstOnsets = struct();

            % close all;

            % for e = 1:length(events)
            for e = target_eventIDX
                [~, cols] = size(app.SmoothedEMG.(events{e}));

                for trial = 1:cols
                    clear tempEMG tempAvg tempSD
                    tempEMG = app.SmoothedEMG.(events{e})(:, trial);
                    tempAvg = app.BaselineStats.(events{e})(1, trial);
                    tempSD = app.BaselineStats.(events{e})(2, trial);

                    clear onsetThreshold aboveThreshold
                    onsetThreshold = tempAvg + (tempSD * thresholdSize);
                    % tempMax = app.BaselineStats.(events{e})(6, trial);
                    % tempMin = app.BaselineStats.(events{e})(5, trial);
                    % onsetThreshold = percentBaselineRange * (tempMax - tempMin);
                    % onsetThreshold = onsetThreshold * zSize;
                    samplesToSearch = ceil(dur * sampleRate) + 1;
                    samplesToSearch2 = ceil(dur2 * sampleRate) + samplesToSearch;

                    aboveThreshold = tempEMG > onsetThreshold;
                    aboveThreshold2 = tempEMG > (tempAvg + tempSD);
                    startSearchIDX = find(time == startSearchTime); % start search at -500ms

                    app.EMGBurstOnsets.(events{e})(1, trial) = 0;
                    app.EMGBurstOnsets.(events{e})(2, trial) = 0;

                    firstThresholdIndex = [];
                    for i = startSearchIDX:(length(tempEMG) - samplesToSearch2)
                        if all(aboveThreshold(i:i + samplesToSearch))
                            if all(aboveThreshold2(i + samplesToSearch + 1: i + samplesToSearch2))
                                firstThresholdIndex = i;
                                app.EMGBurstOnsets.(events{e})(1, trial) = firstThresholdIndex;
                                app.EMGBurstOnsets.(events{e})(2, trial) = time(firstThresholdIndex);
                                break;
                            end

                        end
                    end
                end
            end

            %% ---------------------------------------------------------------------%%
            %% Get Onset Set Window
            % Theshold = z-score; 2 SD
            % dur = 30ms
            %% ---------------------------------------------------------------------%%
            app.SetWindowEMGBurstOnsets = struct();

            % close all;

            for e = target_eventIDX
                [~, cols] = size(app.SmoothedEMG.(events{e}));

                for trial = 1:cols
                    clear tempEMG tempAvg tempSD
                    tempEMG = app.SmoothedEMG.(events{e})(:, trial);
                    tempAvg = app.SetWindowBaselineStats.(events{e})(1, trial);
                    tempSD = app.SetWindowBaselineStats.(events{e})(2, trial);

                    clear onsetThreshold aboveThreshold
                    onsetThreshold = tempAvg + (tempSD * app.ThresholdSize);
                    samplesToSearch = ceil(dur * sampleRate) + 1;
                    samplesToSearch2 = ceil(dur2 * sampleRate) + samplesToSearch;
                    aboveThreshold = tempEMG > onsetThreshold;
                    aboveThreshold2 = tempEMG > (tempAvg + tempSD);
                    startSearchIDX = find(time == startSearchTime); % start search at -1250ms

                    app.SetWindowEMGBurstOnsets.(events{e})(1, trial) = 0;
                    app.SetWindowEMGBurstOnsets.(events{e})(2, trial) = 0;

                    firstThresholdIndex = [];
                    for i = startSearchIDX:(length(tempEMG) - samplesToSearch2)
                        if all(aboveThreshold(i:i + samplesToSearch))
                            if all(aboveThreshold2(i + samplesToSearch + 1: i + samplesToSearch2))
                                firstThresholdIndex = i;
                                app.SetWindowEMGBurstOnsets.(events{e})(1, trial) = firstThresholdIndex;
                                app.SetWindowEMGBurstOnsets.(events{e})(2, trial) = time(firstThresholdIndex);
                                break;
                            end
                        end
                    end
                end
            end
            fprintf("Onsets detected and stored!\n")
            d1.Message = sprintf("Onsets detected and stored!");


            %% ---------------------------------------------------------------------%%
            %% Calculate AUC
            %% ---------------------------------------------------------------------%%
            fprintf("\nCalculating AUC...\n")
            d1.Message = sprintf("Calculating AUC...");

            AUC = struct(); % row 1 = 100ms after onset, row 2 = onset to end (3000ms)
            SetWindowAUC = struct();
            window100ms = ceil(0.100 * sampleRate) + 1; % in samples
            % window3000ms = ceil(3.000 * sampleRate); % in samples

            for e = target_eventIDX
                [~, cols] = size(app.EMGBurstOnsets.(events{e}));

                for trial = 1:cols
                    onsetIDX = app.EMGBurstOnsets.(events{e})(1, trial);
                    onsetTime = app.EMGBurstOnsets.(events{e})(2, trial);

                    onsetIDXSetWindow = app.SetWindowEMGBurstOnsets.(events{e})(1, trial);
                    onsetTimeSetWindow = app.SetWindowEMGBurstOnsets.(events{e})(2, trial);

                    tempEMG = app.SmoothedEMG.(events{e})(:, trial);

                    time_ms = time * 1000;
                    tempEMGMatrix = [time' tempEMG];

                    stop100 = onsetIDX + window100ms;
                    stop100SetWindow = onsetIDXSetWindow + window100ms;
                    % stop3000 = length(time);
                    % stop3000 = onsetIDX + window3000ms;

                    AUC.(events{e})(1, trial) = 0;
                    AUC.(events{e})(2, trial) = 0;
                    % AUC.(events{e})(3, trial) = 0;
                    AUC.(events{e})(4, trial) = 0;

                    SetWindowAUC.(events{e})(1, trial) = 0;
                    SetWindowAUC.(events{e})(2, trial) = 0;
                    % SetWindowAUC.(events{e})(3, trial) = 0;
                    SetWindowAUC.(events{e})(4, trial) = 0;

                    if onsetIDX > 0 && stop100 <= length(tempEMGMatrix)
                        auc100 = trapz(tempEMGMatrix(onsetIDX:stop100, 1) * 1000, tempEMGMatrix(onsetIDX:stop100, 2));
                        AUC.(events{e})(1, trial) = auc100; % multiple by 1000 to get in ms
                    end

                    if onsetIDXSetWindow > 0 && stop100SetWindow <= length(tempEMGMatrix)
                        auc100SetWindow = trapz(tempEMGMatrix(onsetIDXSetWindow:stop100SetWindow, 1) * 1000, tempEMGMatrix(onsetIDXSetWindow:stop100SetWindow, 2));
                        SetWindowAUC.(events{e})(1, trial) = auc100SetWindow; % multiple by 1000 to get in ms
                    end

                    if onsetIDX > 0
                        duration = (time(end) * 1000) - (onsetTime * 1000);

                        auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                        aucDurNorm = auc3000 / duration;

                        AUC.(events{e})(2, trial) = aucDurNorm;
                        % AUC.(events{e})(3, trial) = aucDurNorm / app.RecordingMax; % multiple by 1000 to get in ms; normalize by duration
                        AUC.(events{e})(4, trial) = auc3000;

                    end

                    if onsetIDXSetWindow > 0
                        durationSetWindow = (time(end) * 1000) - (onsetTimeSetWindow * 1000);

                        auc3000SetWindow = trapz(tempEMGMatrix(onsetIDXSetWindow:end, 1) * 1000, tempEMGMatrix(onsetIDXSetWindow:end, 2));
                        aucDurNormSetWindow = auc3000SetWindow / durationSetWindow;

                        SetWindowAUC.(events{e})(2, trial) = aucDurNormSetWindow;
                        % SetWindowAUC.(events{e})(3, trial) = aucDurNormSetWindow / app.RecordingMax; % multiple by 1000 to get in ms; normalize by duration
                        SetWindowAUC.(events{e})(4, trial) = auc3000SetWindow;

                    end

                end
            end

            fprintf("AUC calculated!\n")
            d1.Message = sprintf("AUC calculated!");

            %% ---------------------------------------------------------------------%%
            %% Mod Ratio
            %% ---------------------------------------------------------------------%%
            fprintf("\nCalculating Mod Ratio...\n")
            d1.Message = sprintf("Calculating Mod Ratio...");

            ModRatio = struct(); % row 1 = 100ms after onset, row 2 = onset to end (3000ms)
            SetWindowModRatio = struct(); % row 1 = 100ms after onset, row 2 = onset to end (3000ms)

            for e = target_eventIDX
                [~, cols] = size(AUC.(events{e}));

                for trial = 1:cols
                    auc100 = AUC.(events{e})(1, trial);
                    % auc3000 = AUC.(events{e})(2, trial);
                    baselineData = app.BaselineStats;
                    baseline = baselineData.(events{e})(1, trial);

                    auc100SetWindow = SetWindowAUC.(events{e})(1, trial);
                    % auc3000SetWindow = AUC.(events{e})(2, trial);
                    baselineDataSetWindow = app.SetWindowBaselineStats;
                    baselineSetWindow = baselineDataSetWindow.(events{e})(1, trial);

                    ModRatio.(events{e})(1, trial) = 0;
                    ModRatio.(events{e})(2, trial) = 0;
                    SetWindowModRatio.(events{e})(1, trial) = 0;
                    SetWindowModRatio.(events{e})(2, trial) = 0;

                    if auc100 > 0
                        modRatio100 = (auc100) / (baseline * 100); % per Lang and Bastian, 1999; Wagner et al., 2007
                        ModRatio.(events{e})(1, trial) = modRatio100;
                    end

                    % if auc3000 > 0
                    %     modRatio3000 = auc3000 / (baseline * 3000); % per Lang and Bastian, 1999; Wagner et al., 2007
                    %     ModRatio.(events{e})(2, trial) = modRatio3000; % per Lang and Bastian, 1999; Wagner et al., 2007
                    % end

                    if auc100SetWindow > 0
                        modRatio100SetWindow = (auc100SetWindow) / (baselineSetWindow * 100); % per Lang and Bastian, 1999; Wagner et al., 2007
                        SetWindowModRatio.(events{e})(1, trial) = modRatio100SetWindow;
                    end

                    % if auc3000SetWindow > 0
                    %     modRatio3000SetWindow = auc3000SetWindow / (baselineSetWindow * 3000); % per Lang and Bastian, 1999; Wagner et al., 2007
                    %     SetWindowModRatio.(events{e})(2, trial) = modRatio3000SetWindow; % per Lang and Bastian, 1999; Wagner et al., 2007
                    % end
                end
            end

            fprintf("Mod Ratio calculated\n\n")
            d1.Message = sprintf("Mod Ratio calculated");

            %% ---------------------------------------------------------------------%%
            %% Antagonist AUC and mod ratio (onset - end of block)
            %% ---------------------------------------------------------------------%%
            AntagonistAUC = struct();
            SetWindowAntagonistAUC = struct();
            antagonist_events = app.TargetEvents(end-1:end);

            for e = 1:length(antagonist_events)
                agonist_event = target_eventIDX(e);
                antagonist_event = antagonist_events(e);
                [~, cols] = size(app.SmoothedEMG.(events{antagonist_event}));

                for trial = 1:cols
                    trialOnset = app.EMGBurstOnsets.(events{agonist_event})(2, trial);
                    trialOnsetIDX = find(time == trialOnset);

                    tempSmooth = app.SmoothedEMG.(events{antagonist_event})(:, trial);
                    antagonist_smooth = [time' tempSmooth];

                    AntagonistAUC.(events{antagonist_event})(1, trial) = 0;
                    AntagonistAUC.(events{antagonist_event})(2, trial) = 0;
                    if trialOnset ~= 0
                        duration = (time(end) * 1000) - (trialOnset * 1000);

                        antagonist_auc = trapz(antagonist_smooth(trialOnsetIDX:end, 1) * 1000, antagonist_smooth(trialOnsetIDX:end, 2));
                        antagonist_aucDurNorm = antagonist_auc / duration;

                        AntagonistAUC.(events{antagonist_event})(1, trial) = antagonist_auc;
                        AntagonistAUC.(events{antagonist_event})(2, trial) = antagonist_aucDurNorm;
                    else
                        AntagonistAUC.(events{antagonist_event})(1, trial) = 0;
                        AntagonistAUC.(events{antagonist_event})(2, trial) = 0;
                    end

                    trialOnsetSet = app.SetWindowEMGBurstOnsets.(events{agonist_event})(2, trial);
                    trialOnsetIDXSet = find(time == trialOnsetSet);


                    SetWindowAntagonistAUC.(events{antagonist_event})(1, trial) = 0;
                    SetWindowAntagonistAUC.(events{antagonist_event})(2, trial) = 0;
                    if trialOnsetSet ~= 0
                        duration = (time(end) * 1000) - (trialOnsetSet * 1000);

                        antagonist_aucSet = trapz(antagonist_smooth(trialOnsetIDXSet:end, 1) * 1000, antagonist_smooth(trialOnsetIDXSet:end, 2));
                        aucDurNormSet = antagonist_aucSet / duration;

                        SetWindowAntagonistAUC.(events{antagonist_event})(1, trial) = antagonist_aucSet;
                        SetWindowAntagonistAUC.(events{antagonist_event})(2, trial) = aucDurNormSet;
                    else
                        SetWindowAntagonistAUC.(events{antagonist_event})(1, trial) = 0;
                        SetWindowAntagonistAUC.(events{antagonist_event})(2, trial) = 0;
                    end
                end
            end


            %% ---------------------------------------------------------------------%%
            %% Time from onset to peak
            %% ---------------------------------------------------------------------%%
            Time2Peak = struct();
            SetWindowTime2Peak = struct();

            for e = target_eventIDX
                [~, cols] = size(app.EMGBurstOnsets.(events{e}));

                for trial = 1:cols
                    onset = app.EMGBurstOnsets.(events{e})(2, trial) * 1000;
                    peak = time(app.MaxEMG.(events{e})(1, trial)) * 1000;
                    Time2Peak.(events{e})(1, trial) = peak;
                    Time2Peak.(events{e})(2, trial) = onset;
                    diff = peak - onset;

                    if onset == 0
                        Time2Peak.(events{e})(3, trial) = 0;
                    else
                        Time2Peak.(events{e})(3, trial) = diff;
                    end

                    onsetSetWindow = app.SetWindowEMGBurstOnsets.(events{e})(2, trial) * 1000;
                    peakSetWindow = time(app.MaxEMG.(events{e})(1, trial)) * 1000;
                    SetWindowTime2Peak.(events{e})(1, trial) = peakSetWindow;
                    SetWindowTime2Peak.(events{e})(2, trial) = onsetSetWindow;
                    diff2 = peakSetWindow - onsetSetWindow;

                    if onsetSetWindow == 0
                        SetWindowTime2Peak.(events{e})(3, trial) = 0;
                    else
                        SetWindowTime2Peak.(events{e})(3, trial) = diff2;
                    end
                end

            end

            %% ---------------------------------------------------------------------%%
            %%  Track NT
            %% ---------------------------------------------------------------------%%
            app.NoTrial = struct();
            app.SetWindowNoTrial = struct();

            for e = target_eventIDX
                [~, cols] = size(app.EMGBurstOnsets.(events{e}));

                for trial = 1:cols
                    onset = app.EMGBurstOnsets.(events{e})(2, trial) * 1000;
                    app.NoTrial.(events{e})(trial, 1) = "";
                    if onset == 0
                        app.NoTrial.(events{e})(trial, 1) = "no onset detected";
                    end

                    onsetSetWindow = app.SetWindowEMGBurstOnsets.(events{e})(2, trial) * 1000;
                    app.SetWindowNoTrial.(events{e})(trial, 1) = "";
                    if onsetSetWindow == 0
                        app.SetWindowNoTrial.(events{e})(trial, 1) = "no onset detected";
                    end
                end

            end

            %% ---------------------------------------------------------------------%%
            %%  Track Max - Baseline
            %% ---------------------------------------------------------------------%%
            app.MaxBaselineDiff = struct();
            app.SetWindowMaxBaselineDiff = struct();

            for e = target_eventIDX
                [~, cols] = size(app.MaxEMG.(events{e}));

                for trial = 1:cols
                    tempMax = app.MaxEMG.(events{e})(2, trial);
                    tempBaseline = app.BaselineStats.(events{e})(1, trial);
                    tempDiff = tempMax - tempBaseline;

                    if tempDiff == 0
                        app.MaxBaselineDiff.(events{e})(trial, 1) = 0;
                    else
                        app.MaxBaselineDiff.(events{e})(trial, 1) = tempDiff;
                    end

                    tempBaseline2 = app.SetWindowBaselineStats.(events{e})(1, trial);
                    tempDiff2 = tempMax - tempBaseline2;

                    if tempDiff2 == 0
                        app.SetWindowMaxBaselineDiff.(events{e})(trial, 1) = 0;
                    else
                        app.SetWindowMaxBaselineDiff.(events{e})(trial, 1) = tempDiff2;
                    end

                end

            end


            %% ---------------------------------------------------------------------%%
            %% Save Everything
            %% ---------------------------------------------------------------------%%
            if strcmp(app.RecordingLimbButtonGroup.SelectedObject.Text, 'Left')
                app.RecordingArm = 'L';
            else
                app.RecordingArm = 'R';
            end

            app.ThreshFields = fieldnames(app.TrialThreshold);
            if strcmp(app.RecordingArm, 'L')
                app.ThreshRF = find(strcmp(app.ThreshFields, 'Ch1LeRF'));
                app.ThreshRE = find(strcmp(app.ThreshFields, 'Ch1LeRE'));
            else
                app.ThreshRF = find(strcmp(app.ThreshFields, 'Ch1RiRF'));
                app.ThreshRE = find(strcmp(app.ThreshFields, 'Ch1RiRE'));
            end

            numRowsRF = length(app.EMGBurstOnsets.(events{app.RF_IDX})(2, :));
            TrialListRF = 1:numRowsRF;
            app.BestBaselineSummaryDataCh1RF = cell(numRowsRF, 18);  % Preallocate as a cell array
            app.BestBaselineSummaryDataCh1RF(:, 1) = num2cell(TrialListRF'); % Convert numeric to cell
            app.BestBaselineSummaryDataCh1RF(:, 2) = num2cell(zeros(length(app.EMGBurstOnsets.(events{app.RF_IDX})(2, :)), 1));
            app.BestBaselineSummaryDataCh1RF(:, 3) = num2cell(round(app.TrialThreshold.(app.ThreshFields{app.ThreshRF})(2,:) * 100))'; % threshold information
            app.BestBaselineSummaryDataCh1RF(:, 4) = num2cell(round(double(app.EMGBurstOnsets.(events{app.RF_IDX})(2, :)' * 1000), 1)); % Onset Times
            app.BestBaselineSummaryDataCh1RF(:, 5) = num2cell(round(double(Time2Peak.(events{app.RF_IDX})(1, :)), 1))'; % Peak Time
            app.BestBaselineSummaryDataCh1RF(:, 6) = num2cell(round(double(Time2Peak.(events{app.RF_IDX})(3, :)), 1))'; % Time to Peak (peak - onset)
            app.BestBaselineSummaryDataCh1RF(:, 7) = num2cell(AUC.(events{app.RF_IDX})(1, :))'; % AUC 100ms after onset
            app.BestBaselineSummaryDataCh1RF(:, 8) = num2cell(ModRatio.(events{app.RF_IDX})(1, :))'; % Mod Ratio 100ms after onset
            app.BestBaselineSummaryDataCh1RF(:, 9) = num2cell(AUC.(events{app.RF_IDX})(4, :))'; % AUC onset to end of block
            app.BestBaselineSummaryDataCh1RF(:, 10) = num2cell(AntagonistAUC.(events{app.RF_AntagonistIDX})(1, :))'; % Antagonist AUC onset to end of block
            app.BestBaselineSummaryDataCh1RF(:, 11) = num2cell(AUC.(events{app.RF_IDX})(2, :))'; % Duration Normalized AUC
            app.BestBaselineSummaryDataCh1RF(:, 12) = num2cell(AntagonistAUC.(events{app.RF_AntagonistIDX})(2, :))'; % Antagonist Duration Normalized AUC
            % app.BestBaselineSummaryDataCh1RF(:, 9) = num2cell(ModRatio.(events{app.RF_IDX})(2, :))'; % Mod Ratio 3000ms after onset
            app.BestBaselineSummaryDataCh1RF(:, 13) = num2cell(AUC.(events{app.RF_IDX})(2, :) / app.Ch1EMGMax)'; % Maximum Normalized
            app.BestBaselineSummaryDataCh1RF(:, 14) = num2cell(AntagonistAUC.(events{app.RF_AntagonistIDX})(2, :) / app.Ch1EMGMax)'; % Maximum Normalized; should be antagonist channel max
            % app.BestBaselineSummaryDataCh1RF(:, 11) = num2cell(AUC.(events{app.RF_IDX})(4, :))'; % AUC 3000ms after onset; Choice Probability
            app.BestBaselineSummaryDataCh1RF(:, 15) = num2cell(app.BaselineStats.(events{app.RF_IDX})(1, :)); % baseline average
            app.BestBaselineSummaryDataCh1RF(:, 16) = num2cell(app.MaxEMG.(events{app.RF_IDX})(2, :))'; % max sEMG signal
            app.BestBaselineSummaryDataCh1RF(:, 17) = num2cell(app.MaxBaselineDiff.(events{app.RF_IDX})(:, 1)); % Numeric subtraction
            % app.BestBaselineSummaryDataCh1RF(:, 15) = {''}; % comments for no trial detected
            app.BestBaselineSummaryDataCh1RF(:, 18) = cellstr(app.NoTrial.(events{app.RF_IDX})(:, 1)); % comments for no trial detected
            app.AllDataCh1RF = app.BestBaselineSummaryDataCh1RF;

            numRowsRE = length(app.EMGBurstOnsets.(events{app.RE_IDX})(2, :));
            TrialListRE = 1:numRowsRE;
            app.BestBaselineSummaryDataCh2RE = cell(numRowsRE, 18);  % Preallocate as a cell array
            app.BestBaselineSummaryDataCh2RE(:, 1) = num2cell(TrialListRE'); % Convert numeric to cell
            app.BestBaselineSummaryDataCh2RE(:, 2) = num2cell(zeros(length(app.EMGBurstOnsets.(events{app.RE_IDX})(2, :)), 1));
            app.BestBaselineSummaryDataCh2RE(:, 3) = num2cell(round(app.TrialThreshold.(app.ThreshFields{app.ThreshRE})(3,:) * 100))'; % threshold information
            app.BestBaselineSummaryDataCh2RE(:, 4) = num2cell(round(double(app.EMGBurstOnsets.(events{app.RE_IDX})(2, :)' * 1000), 1)); % Onset Times
            app.BestBaselineSummaryDataCh2RE(:, 5) = num2cell(round(double(Time2Peak.(events{app.RE_IDX})(1, :)), 1))'; % Peak Time
            app.BestBaselineSummaryDataCh2RE(:, 6) = num2cell(round(double(Time2Peak.(events{app.RE_IDX})(3, :)), 1))'; % Time to Peak (peak - onset)
            app.BestBaselineSummaryDataCh2RE(:, 7) = num2cell(AUC.(events{app.RE_IDX})(1, :))'; % AUC 100ms after onset
            app.BestBaselineSummaryDataCh2RE(:, 8) = num2cell(ModRatio.(events{app.RE_IDX})(1, :))'; % Mod Ratio 100ms after onset
            app.BestBaselineSummaryDataCh2RE(:, 9) = num2cell(AUC.(events{app.RE_IDX})(4, :))'; % AUC onset to end of block
            app.BestBaselineSummaryDataCh2RE(:, 10) = num2cell(AntagonistAUC.(events{app.RE_AntagonistIDX})(1, :))'; % Antagonist AUC onset to end of block
            app.BestBaselineSummaryDataCh2RE(:, 11) = num2cell(AUC.(events{app.RE_IDX})(2, :))'; % Duration Normalized AUC
            app.BestBaselineSummaryDataCh2RE(:, 12) = num2cell(AntagonistAUC.(events{app.RE_AntagonistIDX})(2, :))'; % Antagonist Duration Normalized AUC
            % app.BestBaselineSummaryDataCh2RE(:, 9) = num2cell(ModRatio.(events{app.RE_IDX})(2, :))'; % Mod Ratio 3000ms after onset
            app.BestBaselineSummaryDataCh2RE(:, 13) = num2cell(AUC.(events{app.RE_IDX})(2, :) / app.Ch2EMGMax)'; % Maximum Normalized
            app.BestBaselineSummaryDataCh2RE(:, 14) = num2cell(AntagonistAUC.(events{app.RE_AntagonistIDX})(2, :) / app.Ch2EMGMax)'; % Maximum Normalized; should be antagonist channel max
            % app.BestBaselineSummaryDataCh2RE(:, 11) = num2cell(AUC.(events{app.RE_IDX})(4, :))'; % AUC 3000ms after onset; Choice Probability
            app.BestBaselineSummaryDataCh2RE(:, 15) = num2cell(app.BaselineStats.(events{app.RE_IDX})(1, :)); % baseline average
            app.BestBaselineSummaryDataCh2RE(:, 16) = num2cell(app.MaxEMG.(events{app.RE_IDX})(2, :))'; % max sEMG signal
            app.BestBaselineSummaryDataCh2RE(:, 17) = num2cell(app.MaxBaselineDiff.(events{app.RE_IDX})(:, 1)); % Numeric subtraction
            % app.BestBaselineSummaryDataCh2RE(:, 15) = {''}; % comments for no trial detected
            app.BestBaselineSummaryDataCh2RE(:, 18) = cellstr(app.NoTrial.(events{app.RE_IDX})(:, 1)); % comments for no trial detected
            app.AllDataCh2RE = app.BestBaselineSummaryDataCh2RE;

            %% Set Window Data
            numRowsRFSetWindow = length(app.SetWindowEMGBurstOnsets.(events{app.RF_IDX})(2, :));
            SetWindowTrialListRF = 1:numRowsRFSetWindow;
            app.SetWindowSummaryDataCh1RF = cell(numRowsRFSetWindow, 18);  % Preallocate as a cell array
            app.SetWindowSummaryDataCh1RF(:, 1) = num2cell(SetWindowTrialListRF'); % Convert numeric to cell
            app.SetWindowSummaryDataCh1RF(:, 2) = num2cell(zeros(length(app.SetWindowEMGBurstOnsets.(events{app.RF_IDX})(2, :)), 1));
            app.SetWindowSummaryDataCh1RF(:, 3) = num2cell(round(app.TrialThreshold.(app.ThreshFields{app.ThreshRF})(2,:) * 100))'; % threshold information
            app.SetWindowSummaryDataCh1RF(:, 4) = num2cell(round(double(app.SetWindowEMGBurstOnsets.(events{app.RF_IDX})(2, :)' * 1000), 1)); % Onset Times
            app.SetWindowSummaryDataCh1RF(:, 5) = num2cell(round(double(SetWindowTime2Peak.(events{app.RF_IDX})(1, :)), 1))'; % Peak Time
            app.SetWindowSummaryDataCh1RF(:, 6) = num2cell(round(double(SetWindowTime2Peak.(events{app.RF_IDX})(3, :)), 1))'; % Time to Peak (peak - onset)
            app.SetWindowSummaryDataCh1RF(:, 7) = num2cell(SetWindowAUC.(events{app.RF_IDX})(1, :))'; % AUC 100ms after onset
            app.SetWindowSummaryDataCh1RF(:, 8) = num2cell(SetWindowModRatio.(events{app.RF_IDX})(1, :))'; % Mod Ratio 100ms after onset
            app.SetWindowSummaryDataCh1RF(:, 9) = num2cell(SetWindowAUC.(events{app.RF_IDX})(4, :))'; % AUC onset to end of block
            app.SetWindowSummaryDataCh1RF(:, 10) = num2cell(SetWindowAntagonistAUC.(events{app.RF_AntagonistIDX})(1, :))'; % Antagonist AUC onset to end of block
            app.SetWindowSummaryDataCh1RF(:, 11) = num2cell(SetWindowAUC.(events{app.RF_IDX})(2, :))'; % Duration Normalized AUC
            app.SetWindowSummaryDataCh1RF(:, 12) = num2cell(SetWindowAntagonistAUC.(events{app.RF_AntagonistIDX})(2, :))'; % Antagonist Duration Normalized AUC
            % app.SetWindowSummaryDataCh1RF(:, 9) = num2cell(SetWindowModRatio.(events{app.RF_IDX})(2, :))'; % Mod Ratio 3000ms after onset
            app.SetWindowSummaryDataCh1RF(:, 13) = num2cell(SetWindowAUC.(events{app.RF_IDX})(2, :) / app.Ch1EMGMax)'; % Maximum Normalized
            app.SetWindowSummaryDataCh1RF(:, 14) = num2cell(SetWindowAntagonistAUC.(events{app.RF_AntagonistIDX})(2, :) / app.Ch1EMGMax)'; % Maximum Normalized; should be antagonist channel max
            % app.SetWindowSummaryDataCh1RF(:, 11) = num2cell(SetWindowAUC.(events{app.RF_IDX})(4, :))'; % AUC 3000ms after onset; Choice Probability
            app.SetWindowSummaryDataCh1RF(:, 15) = num2cell(app.SetWindowBaselineStats.(events{app.RF_IDX})(1, :)); % baseline average
            app.SetWindowSummaryDataCh1RF(:, 16) = num2cell(app.MaxEMG.(events{app.RF_IDX})(2, :))'; % max sEMG signal
            app.SetWindowSummaryDataCh1RF(:, 17) = num2cell(app.SetWindowMaxBaselineDiff.(events{app.RF_IDX})(:, 1)); % Numeric subtraction
            % app.SetWindowSummaryDataCh1RF(:, 15) = {''}; % comments for no trial detected
            app.SetWindowSummaryDataCh1RF(:, 18) = cellstr(app.SetWindowNoTrial.(events{app.RF_IDX})(:, 1)); % comments for no trial detected
            app.SetWindowAllDataCh1RF = app.SetWindowSummaryDataCh1RF;

            numRowsRESetWindow = length(app.SetWindowEMGBurstOnsets.(events{app.RE_IDX})(2, :));
            SetWindowTrialListRE = 1:numRowsRESetWindow;
            app.SetWindowSummaryDataCh2RE = cell(numRowsRESetWindow, 18);  % Preallocate as a cell array
            app.SetWindowSummaryDataCh2RE(:, 1) = num2cell(SetWindowTrialListRE'); % Convert numeric to cell
            app.SetWindowSummaryDataCh2RE(:, 2) = num2cell(zeros(length(app.SetWindowEMGBurstOnsets.(events{app.RE_IDX})(2, :)), 1));
            app.SetWindowSummaryDataCh2RE(:, 3) = num2cell(round(app.TrialThreshold.(app.ThreshFields{app.ThreshRE})(3,:) * 100))'; % threshold information
            app.SetWindowSummaryDataCh2RE(:, 4) = num2cell(round(double(app.SetWindowEMGBurstOnsets.(events{app.RE_IDX})(2, :)' * 1000), 1)); % Onset Times
            app.SetWindowSummaryDataCh2RE(:, 5) = num2cell(round(double(SetWindowTime2Peak.(events{app.RE_IDX})(1, :)), 1))'; % Peak Time
            app.SetWindowSummaryDataCh2RE(:, 6) = num2cell(round(double(SetWindowTime2Peak.(events{app.RE_IDX})(3, :)), 1))'; % Time to Peak (peak - onset)
            app.SetWindowSummaryDataCh2RE(:, 7) = num2cell(SetWindowAUC.(events{app.RE_IDX})(1, :))'; % AUC 100ms after onset
            app.SetWindowSummaryDataCh2RE(:, 8) = num2cell(SetWindowModRatio.(events{app.RE_IDX})(1, :))'; % Mod Ratio 100ms after onset
            app.SetWindowSummaryDataCh2RE(:, 9) = num2cell(SetWindowAUC.(events{app.RE_IDX})(4, :))'; % AUC onset to end of block
            app.SetWindowSummaryDataCh2RE(:, 10) = num2cell(SetWindowAntagonistAUC.(events{app.RE_AntagonistIDX})(1, :))'; % Antagonist AUC onset to end of block
            app.SetWindowSummaryDataCh2RE(:, 11) = num2cell(SetWindowAUC.(events{app.RE_IDX})(2, :))'; % Duration Normalized AUC
            app.SetWindowSummaryDataCh2RE(:, 12) = num2cell(SetWindowAntagonistAUC.(events{app.RE_AntagonistIDX})(2, :))'; % Antagonist Duration Normalized AUC
            % app.SetWindowSummaryDataCh2RE(:, 9) = num2cell(SetWindowModRatio.(events{app.RE_IDX})(2, :))'; % Mod Ratio 3000ms after onset
            app.SetWindowSummaryDataCh2RE(:, 13) = num2cell(SetWindowAUC.(events{app.RE_IDX})(2, :) / app.Ch2EMGMax)'; % Maximum Normalized
            app.SetWindowSummaryDataCh2RE(:, 14) = num2cell(SetWindowAntagonistAUC.(events{app.RE_AntagonistIDX})(2, :) / app.Ch2EMGMax)'; % Maximum Normalized; should be antagonist channel max
            % app.SetWindowSummaryDataCh2RE(:, 11) = num2cell(SetWindowAUC.(events{app.RE_IDX})(4, :))'; % AUC 3000ms after onset; Choice Probability
            app.SetWindowSummaryDataCh2RE(:, 15) = num2cell(app.SetWindowBaselineStats.(events{app.RE_IDX})(1, :)); % baseline average
            app.SetWindowSummaryDataCh2RE(:, 16) = num2cell(app.MaxEMG.(events{app.RE_IDX})(2, :))'; % max sEMG signal
            app.SetWindowSummaryDataCh2RE(:, 17) = num2cell(app.SetWindowMaxBaselineDiff.(events{app.RE_IDX})(:, 1)); % Numeric subtraction
            % app.SetWindowSummaryDataCh2RE(:, 15) = {''}; % comments for no trial detected
            app.SetWindowSummaryDataCh2RE(:, 18) = cellstr(app.SetWindowNoTrial.(events{app.RE_IDX})(:, 1)); % comments for no trial detected
            app.SetWindowAllDataCh2RE = app.SetWindowSummaryDataCh2RE;

            app.EventDropDown.Value = app.EventDropDown.Items(2);
            app.CurrentEvent = app.EventDropDown.Value;
            app.CurrentTrial = 1;
            trialText = sprintf("Trial %02d", app.CurrentTrial);
            app.TrialEditField.Value = trialText;

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.TrialCount = numRowsRF;
                app.SmoothedChannelMaxEditField.Value = app.Ch1EMGMax;
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.TrialCount = numRowsRE;
                app.SmoothedChannelMaxEditField.Value = app.Ch2EMGMax;
            end

            % fprintf("\nSaving everything...")
            % data_filename = sprintf('%s_%s_%s-Arm_EMG-Burst-Onset', timepoint, subject, recording_arm);

            % if strcmp(recording_arm_lower, affected_arm_lower)
            %     output_file = sprintf('%s/%s.mat', affected_dir, data_filename);
            % elseif strcmp(recording_arm_lower, unaffected_arm_lower)
            %     output_file = sprintf('%s/%s.mat', unaffected_dir, data_filename);
            % end

            % output_file = sprintf('%s/%s.mat', app.AnalysisDir, data_filename);


            %% ---------------------------------------------------------------------%%
            %% Clean up things you don't need
            %% ---------------------------------------------------------------------%%
            % vars = {
            %     'a', 'affected_arm_lower', 'affected_dir', 'app.AnalysisDir', 'ax1', 'ax2',...
            %     'b', 'baselineIDX', 'baselineStart', 'cols', 'condition_file',...
            %     'condition_name_baseline', 'condition_name_processed', 'condition_name_smoothed',...
            %     'conditions_dir', 'counts', 'data', 'data_filename', 'dir', 'dir_list',...
            %     'e', 'eventEMGBurstOnset', 'events_baseline', 'events_processed', 'events_smoothed',...
            %     'EventsToConditions', 'FigDir', 'figures_dir', 'filename_png', 'filename_png2',...
            %     'filename_png3', 'firstOnset', 'i', 'idx', 'l', 'lgd', 'LowPassEMG', 'min_average',...
            %     'OnsetFigureTitle', 'OnsetPlotTitle', 'processed_data', 'recording_app.AnalysisDir',...
            %     'recording_arm_lower', 'root', 'samplesToSearch', 'start', 'startSearchIDX', 'step',...
            %     'stop', 'target_eventIDX', 'tempAvg', 'tempData', 'tempEMG', 'tempSD', 'today',...
            %     'trial', 'unaffected_arm_lower', 'window', 'windowMax', 'windowMin', 'windowStarts',...
            %     'windowStartValues', 'windowSTD', 'x_fill_ax1', 'x_fill_ax2', 'y_fill_ax1',...
            %     'y_fill_ax2', 'y_lim_ax1', 'y_lim_ax2', 'bins', 'samples'
            %     };
            % clear(vars{:});
            % clear vars
            %
            % save(output_file);

            % pause(.75);
            % app.AnalyzeButton.Visible = "on";
            app.FigureCheckButton.Visible = 'on';

            app.OverrideButton.Visible = 'on';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'on';
            app.SetWindowButton.Visible = 'on';
            % app.AnalysisOptionsPanel.Visible = "on";
            app.SaveResultsButton.Visible = 'on';
            app.SaveFiguresButton.Visible = 'on';
            app.NoTrialButton.Visible = 'on';

            app.RawEMGVisibleCheckBox.Value = true;
            app.ProcessedEMGVisibleCheckBox.Value = true;
            app.BestBaselineVisibleCheckBox.Value = true;
            app.SetWindowVisibleCheckBox.Value = true;
            app.BestBaselineOnsetVisibleCheckBox.Value = true;
            app.SetWindowOnsetVisibleCheckBox.Value = true;

            if isempty(app.TempCh1RF)
                app.TempCh1RF = app.BestBaselineSummaryDataCh1RF;
            end

            if isempty(app.TempCh2RE)
                app.TempCh2RE = app.BestBaselineSummaryDataCh2RE;
            end

            if isempty(app.SetWindowTempCh1RF)
                app.SetWindowTempCh1RF = app.SetWindowSummaryDataCh1RF;
            end

            if isempty(app.SetWindowTempCh2RE)
                app.SetWindowTempCh2RE = app.SetWindowSummaryDataCh2RE;
            end

            %% Update UITable with data
            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.SmoothedChannelMaxEditFieldLabel.Text = "Smoothed Flexor Max (Channel 1)";

                app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                if isempty(app.TempCh1RF)
                    app.TempCh1RF = app.UITable.Data;
                end
                % app.UITable.Data(:, 13) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                % app.AllDataCh1RF = app.UITable.Data;

                % % Automatic update of comments
                % % Get the existing data table from the UITable
                % dataTable = app.UITable.Data;
                % % Convert the numeric column to a cell array to handle mixed types
                % tempColumn = num2cell(dataTable{:, 15});
                % % Replace any cells containing 1 with the string 'no onset detected'
                % tempColumn(cell2mat(tempColumn) == 1) = {"no onset detected"};
                % % Update the table column with the modified cell array
                % dataTable{:, columnIndex} = tempColumn;
                % % Update the UITable data
                % app.UITable.Data = dataTable;

                %% Update plots
                plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                % app.RawEMGxLim = xlim(app.RawEMGPlot);
                % app.RawEMGyLim = ylim(app.RawEMGPlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                % hold off

                plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                % app.ProcessedEMGxLim = xlim(app.ProcessedEMGPlot);
                % app.ProcessedEMGyLim = ylim(app.ProcessedEMGPlot);
                ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                % hold off

                plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                % plotYLim = ylim(app.BestBaselinePlot);
                % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                % hold off

                setWindowOnset = cell2mat(app.SetWindowSummaryDataCh1RF(app.CurrentTrial, 4)) / 1000;
                plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                % plotYLim2 = ylim(app.SetWindowPlot);
                % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                app.plotSetWindowConstants();

                if setWindowOnset ~= 0
                    app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                end
                app.plotSetWindowTime2Peak();
                % app.SetWindowxLim = xlim(app.SetWindowPlot);
                % app.SetWindowyLim = ylim(app.SetWindowPlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                % hold off
                % if app.BestBaselineOnsetVisibleCheckBox.Value
                %     app.plotBestBaselineOnsets();
                % end

                % app.plotBestBaselineConstants();
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.SmoothedChannelMaxEditFieldLabel.Text = "Smoothed Extensor Max (Channel 2)";

                app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                if isempty(app.TempCh2RE)
                    app.TempCh2RE = app.UITable.Data;
                end
                % app.UITable.Data(:, 13) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                % app.AllDataCh2RE = app.UITable.Data;

                % % Automatic update of comments
                % % Get the existing data table from the UITable
                % dataTable = app.UITable.Data;
                % % Convert the numeric column to a cell array to handle mixed types
                % tempColumn = num2cell(dataTable{:, 15});
                % % Replace any cells containing 1 with the string 'no onset detected'
                % tempColumn(cell2mat(tempColumn) == 1) = {"no onset detected"};
                % % Update the table column with the modified cell array
                % dataTable{:, columnIndex} = tempColumn;
                % % Update the UITable data
                % app.UITable.Data = dataTable;

                %% Update plots
                plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                % app.RawEMGxLim = xlim(app.RawEMGPlot);
                % app.RawEMGyLim = ylim(app.RawEMGPlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                % hold off

                plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                % app.ProcessedEMGxLim = xlim(app.ProcessedEMGPlot);
                % app.ProcessedEMGyLim = ylim(app.ProcessedEMGPlot);
                ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                % hold off

                plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                % plotYLim = ylim(app.BestBaselinePlot);
                % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                % hold off

                setWindowOnset = cell2mat(app.SetWindowSummaryDataCh2RE(app.CurrentTrial, 4)) / 1000;
                plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                % plotYLim2 = ylim(app.SetWindowPlot);
                % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                app.plotSetWindowConstants();

                if setWindowOnset ~= 0
                    app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                end
                app.plotSetWindowTime2Peak();
                % app.SetWindowxLim = xlim(app.SetWindowPlot);
                % app.SetWindowyLim = ylim(app.SetWindowPlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                % hold off

                % if app.BestBaselineOnsetVisibleCheckBox.Value
                %     app.plotBestBaselineOnsets();
                % end
                %
                % app.plotBestBaselineConstants();
                % else
                %     app.UITable.Data = [];
                %     cla(app.RawEMGPlot, 'reset');
                %     cla(app.ProcessedEMGPlot, 'reset');
                %     cla(app.SetWindowPlot, 'reset');
                %     cla(app.BestBaselinePlot, 'reset');
                %     app.formatPlots();
            end

            % app.resetPlotLimits();
            app.plotBestBaselineConstants();
            app.plotBestBaselineOnsets();
            % app.plotSetWindowConstants();
            % app.plotSetWindowOnsets();
            app.plotBestBaselineTime2Peak();
            % app.plotSetWindowTime2Peak();
            app.formatPlots();

            % % Get the number of rows in app.AllDataCh1RF
            % numRowsRF = size(app.AllDataCh1RF, 1);
            % numRowsRE = size(app.AllDataCh2RE, 1);
            %
            % % Create a new column with repmat of data details
            % app.newColumnRF = repmat({[app.SubID '_' app.TimePoint '_' app.Events{app.RF_IDX}]}, numRowsRF, 1);
            % app.newColumnRE = repmat({[app.SubID '_' app.TimePoint '_' app.Events{app.RE_IDX}]}, numRowsRE, 1);
            % app.ExcelHeaders = ['EventNames'; app.UITable.ColumnName(:)];
            % % Add the new column as the first column
            % app.AllDataCh1RF = [app.newColumnRF, app.AllDataCh1RF];
            % app.AllDataCh2RE = [app.newColumnRE, app.AllDataCh2RE];
            %
            % combinedData = [app.AllDataCh1RF; app.AllDataCh2RE];
            % app.CombinedData = cell2table(combinedData);

            close(d1)

        end

        % Value changed function: EventDropDown
        function EventDropDownValueChanged(app, event)
            app.CurrentEvent = app.EventDropDown.Value;
            cla(app.BestBaselinePlot, 'reset')
            cla(app.SetWindowPlot, 'reset')

            if strcmp(app.CurrentEvent, '–')
                app.CurrentTrial = 0;
                app.TrialEditField.Value = app.TrialEditField.Placeholder;
                app.SmoothedChannelMaxEditField.Value = 0;
                app.RawEMGVisibleCheckBox.Value = false;
                app.ProcessedEMGVisibleCheckBox.Value = false;
                app.BestBaselineVisibleCheckBox.Value = false;
                app.SetWindowVisibleCheckBox.Value = false;
                app.BestBaselineOnsetVisibleCheckBox.Value = false;
                app.SetWindowOnsetVisibleCheckBox.Value = false;

                app.AnalyzeButton.Visible = 'off';
                app.OverrideButton.Visible = 'off';
                app.SetNewOnsetButton.Visible = 'off';
                app.LowestBaselineButton.Visible = 'off';
                app.SetWindowButton.Visible = 'off';
                app.SaveResultsButton.Visible = 'off';
                app.SaveFiguresButton.Visible = 'off';
                app.NoTrialButton.Visible = 'off';
            else
                app.CurrentTrial = 1;
                trialText = sprintf("Trial %02d", app.CurrentTrial);
                app.TrialEditField.Value = trialText;
                app.RawEMGVisibleCheckBox.Value = true;
                app.ProcessedEMGVisibleCheckBox.Value = true;
                app.BestBaselineVisibleCheckBox.Value = true;
                app.SetWindowVisibleCheckBox.Value = true;
                app.BestBaselineOnsetVisibleCheckBox.Value = true;
                app.SetWindowOnsetVisibleCheckBox.Value = true;

                app.AnalyzeButton.Visible = 'on';
                app.OverrideButton.Visible = 'on';
                app.SetNewOnsetButton.Visible = 'off';
                app.LowestBaselineButton.Visible = 'on';
                app.SetWindowButton.Visible = 'on';
                app.SaveResultsButton.Visible = 'on';
                app.SaveFiguresButton.Visible = 'on';
                app.NoTrialButton.Visible = 'on';
            end

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}

                app.SmoothedChannelMaxEditFieldLabel.Text = "Smoothed Flexor Max (Channel 1)";

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch1EMGMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh1RF(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh1RF{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end

                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.plotBestBaselineTime2Peak();
                    app.plotSetWindowTime2Peak();

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                    end
                    app.plotBestBaselineTime2Peak();
                    app.plotSetWindowTime2Peak();
                end

            elseif app.CurrentEvent == app.Events{app.RE_IDX}

                app.SmoothedChannelMaxEditFieldLabel.Text = "Smoothed Extensor Max (Channel 2)";

                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch2EMGMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh2RE(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh2RE{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.plotBestBaselineTime2Peak();
                    app.plotSetWindowTime2Peak();
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end

                    app.plotBestBaselineTime2Peak();
                    app.plotSetWindowTime2Peak();
                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.plotBestBaselineTime2Peak();
                app.plotSetWindowTime2Peak();
                app.formatPlots();
            end
            app.BestBaselinePlotXLim = get(app.BestBaselinePlot, 'XLim');
            app.BestBaselinePlotYLim = get(app.BestBaselinePlot, 'YLim');

        end

        % Button pushed function: PreviousTrialButton
        function PreviousTrialButtonPushed(app, event)
            if app.CurrentTrial > 1
                app.CurrentTrial = app.CurrentTrial - 1;
                app.UITable.Selection = app.CurrentTrial;

                trialText = sprintf("Trial %02d", app.CurrentTrial);
                app.TrialEditField.Value = trialText;
            end

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch1EMGMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh1RF(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh1RF{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                        app.plotBestBaselineTime2Peak();
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                        app.plotSetWindowTime2Peak();
                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.plotBestBaselineTime2Peak();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        app.plotSetWindowTime2Peak();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch2EMGMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh2RE(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh2RE{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                        app.plotBestBaselineTime2Peak();
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                        app.plotSetWindowTime2Peak();
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.plotBestBaselineTime2Peak();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        app.plotSetWindowTime2Peak();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end

            app.BestBaselinePlotXLim = get(app.BestBaselinePlot, 'XLim');
            app.BestBaselinePlotYLim = get(app.BestBaselinePlot, 'YLim');
        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.TrialCount = size(app.BestBaselineSummaryDataCh1RF, 1);
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.TrialCount = size(app.BestBaselineSummaryDataCh2RE, 1);
            end

            if app.CurrentTrial > 0 && app.CurrentTrial < app.TrialCount
                app.CurrentTrial = app.CurrentTrial + 1;
                app.UITable.Selection = app.CurrentTrial;

                trialText = sprintf("Trial %02d", app.CurrentTrial);
                app.TrialEditField.Value = trialText;
            end

            app.SetNewOnsetButton.Visible = 'off';
            app.OverrideButton.Visible = 'on';

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch1EMGMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh1RF(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh1RF{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                        app.plotBestBaselineTime2Peak();
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                        app.plotSetWindowTime2Peak();
                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch2EMGMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh2RE(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh2RE{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                        app.plotBestBaselineTime2Peak();
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                        app.plotSetWindowTime2Peak();
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end
            app.BestBaselinePlotXLim = get(app.BestBaselinePlot, 'XLim');
            app.BestBaselinePlotYLim = get(app.BestBaselinePlot, 'YLim');

            %     if app.CurrentEvent == app.Events{app.RF_IDX}
            %         %% Update plots
            %         if app.RawEMGVisibleCheckBox.Value
            %             cla(app.RawEMGPlot, 'reset');
            %             plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
            %         end
            %         if app.ProcessedEMGVisibleCheckBox.Value
            %             cla(app.ProcessedEMGPlot, 'reset');
            %             plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
            %             ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
            %         end
            %         if app.BestBaselineVisibleCheckBox.Value
            %             cla(app.BestBaselinePlot, 'reset');
            %             plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25)
            %             plotYLim = ylim(app.BestBaselinePlot);
            %             ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
            %             app.plotBestBaselineConstants();
            %         end
            %         if app.SetWindowVisibleCheckBox.Value
            %             cla(app.SetWindowPlot, 'reset');
            %             plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25)
            %             plotYLim2 = ylim(app.SetWindowPlot);
            %             ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
            %         end
            %
            %         % app.resetPlotLimits();
            %         app.formatPlots();
            %         if app.BestBaselineOnsetVisibleCheckBox.Value
            %             app.plotBestBaselineOnsets();
            %         end
            %
            %     elseif app.CurrentEvent == app.Events{app.RE_IDX}
            %
            %         %% Update plots
            %         if app.RawEMGVisibleCheckBox.Value
            %             cla(app.RawEMGPlot, 'reset');
            %             plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
            %         end
            %         if app.ProcessedEMGVisibleCheckBox.Value
            %             cla(app.ProcessedEMGPlot, 'reset');
            %             plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
            %             ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
            %         end
            %         if app.BestBaselineVisibleCheckBox.Value
            %             cla(app.BestBaselinePlot, 'reset');
            %             plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25)
            %             plotYLim = ylim(app.BestBaselinePlot);
            %             ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
            %             app.plotBestBaselineConstants();
            %         end
            %         if app.SetWindowVisibleCheckBox.Value
            %             cla(app.SetWindowPlot, 'reset');
            %             plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25)
            %             plotYLim2 = ylim(app.SetWindowPlot);
            %             ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
            %         end
            %
            %         % app.resetPlotLimits();
            %         app.formatPlots();
            %         if app.BestBaselineOnsetVisibleCheckBox.Value
            %             app.plotBestBaselineOnsets();
            %         end
            %
            %     % else
            %         % cla(app.RawEMGPlot, 'reset');
            %         % cla(app.ProcessedEMGPlot, 'reset');
            %         % cla(app.SetWindowPlot, 'reset');
            %         % cla(app.BestBaselinePlot, 'reset');
            %         % app.formatPlots();
            %     end
            % end
            %
            % if app.BestBaselineOnsetVisibleCheckBox.Value
            %     app.plotBestBaselineOnsets();
            %     % app.plotBestBaselineWindow();
            %     app.plotBestBaselineConstants();
            % end
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            trial = event.Indices;
            app.CurrentTrial = trial(1);
            trialText = sprintf("Trial %02d", app.CurrentTrial);
            app.TrialEditField.Value = trialText;

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch1EMGMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh1RF(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh1RF{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                        app.plotBestBaselineTime2Peak();
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                        app.plotSetWindowTime2Peak();
                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch2SmoothedRecordingMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh2RE(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh2RE{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                        app.plotBestBaselineTime2Peak();
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                        app.plotSetWindowTime2Peak();
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end

            app.BestBaselinePlotXLim = get(app.BestBaselinePlot, 'XLim');
            app.BestBaselinePlotYLim = get(app.BestBaselinePlot, 'YLim');
        end

        % Value changed function: RawEMGVisibleCheckBox
        function RawEMGVisibleCheckBoxValueChanged(app, event)
            if ~app.RawEMGVisibleCheckBox.Value
                cla(app.RawEMGPlot, 'reset');
                app.formatPlots();
            else
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                    app.plotBestBaselineConstants
                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                end
                if app.BestBaselineOnsetVisibleCheckBox.Value
                    app.plotBestBaselineOnsets();
                    % app.plotBestBaselineWindow();
                end
                app.plotBestBaselineTime2Peak();
                app.formatPlots();
            end

        end

        % Value changed function: ProcessedEMGVisibleCheckBox
        function ProcessedEMGVisibleCheckBoxValueChanged(app, event)
            if ~app.ProcessedEMGVisibleCheckBox.Value
                cla(app.ProcessedEMGPlot, 'reset');
                app.formatPlots();
            else
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                    ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                    ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                end
                if app.BestBaselineOnsetVisibleCheckBox.Value
                    app.plotBestBaselineOnsets();
                    % app.plotBestBaselineWindow();
                end
                app.formatPlots();
            end

        end

        % Value changed function: BestBaselineVisibleCheckBox
        function BestBaselineVisibleCheckBoxValueChanged(app, event)
            if ~app.BestBaselineVisibleCheckBox.Value
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            else
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    cla(app.BestBaselinePlot, 'reset');
                    plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25)
                    % plotYLim = ylim(app.BestBaselinePlot);
                    % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    app.plotBestBaselineConstants()

                    if app.BestBaselineOnsetVisibleCheckBox.Value
                        app.plotBestBaselineOnsets();
                    end
                    app.plotBestBaselineTime2Peak();
                    app.formatPlots();

                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    cla(app.BestBaselinePlot, 'reset');
                    plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25)
                    % plotYLim = ylim(app.BestBaselinePlot);
                    % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    app.plotBestBaselineConstants()

                    if app.BestBaselineOnsetVisibleCheckBox.Value
                        app.plotBestBaselineOnsets();
                    end
                    app.plotBestBaselineTime2Peak();
                    app.formatPlots();

                end

            end

        end

        % Value changed function: SetWindowVisibleCheckBox
        function SetWindowVisibleCheckBoxValueChanged(app, event)
            if ~app.SetWindowVisibleCheckBox.Value
                cla(app.SetWindowPlot, 'reset');
                app.formatPlots();
            else
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    cla(app.SetWindowPlot, 'reset');
                    plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25)
                    % plotYLim2 = ylim(app.SetWindowPlot);
                    % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])

                    app.plotSetWindowConstants();

                    if app.SetWindowOnsetVisibleCheckBox.Value
                        app.plotSetWindowOnsets();
                    end
                    app.plotSetWindowTime2Peak();
                    app.formatPlots();
                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    cla(app.SetWindowPlot, 'reset');
                    plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25)
                    % plotYLim2 = ylim(app.SetWindowPlot);
                    % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])

                    app.plotSetWindowConstants();

                    if app.SetWindowOnsetVisibleCheckBox.Value
                        app.plotSetWindowOnsets();
                    end
                    app.plotSetWindowTime2Peak();
                    app.formatPlots();
                end

            end

        end

        % Value changed function: BestBaselineOnsetVisibleCheckBox
        function BestBaselineOnsetVisibleCheckBoxValueChanged(app, event)
            if ~app.BestBaselineOnsetVisibleCheckBox.Value
                % Delete only xline objects from each plot
                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.BestBaselineTime2Peak)
                delete(app.BestBaselineShadedArea)
                delete(app.BestBaselineCue)
                delete(app.BestBaselineDelay)
                app.BestBaselineTime2Peak = [];
                app.BestBaselineShadedArea = [];
                app.BestBaselineCue = [];
                app.BestBaselineDelay = [];

            else
                %% Update UITable with data
                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.BestBaselineTime2Peak)
                delete(app.BestBaselineShadedArea)
                delete(app.BestBaselineCue)
                delete(app.BestBaselineDelay)
                app.BestBaselineTime2Peak = [];
                app.BestBaselineShadedArea = [];
                app.BestBaselineCue = [];
                app.BestBaselineDelay = [];

                if app.CurrentEvent == app.Events{app.RF_IDX}

                    if isempty(app.TempCh1RF)
                        app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                    else
                        app.UITable.Data = app.TempCh1RF;
                    end
                    app.TempCh1RF = app.UITable.Data;

                    if app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                        % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                        % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                        app.SmoothedChannelMaxEditField.Value = app.Ch1EMGMax;

                        onset_ms = app.TempCh1RF{app.CurrentTrial, 4} / 1000;

                        %% Update plots
                        if app.RawEMGVisibleCheckBox.Value
                            cla(app.RawEMGPlot, 'reset')
                            plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                        end

                        if app.ProcessedEMGVisibleCheckBox.Value
                            cla(app.ProcessedEMGPlot, 'reset')
                            plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        end

                        if app.BestBaselineVisibleCheckBox.Value
                            cla(app.BestBaselinePlot, 'reset')
                            plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                            % plotYLim = ylim(app.BestBaselinePlot);
                            % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                            app.plotBestBaselineConstants();
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            app.plotBestBaselineTime2Peak();
                        end

                        % if app.SetWindowVisibleCheckBox.Value
                        %     cla(app.SetWindowPlot, 'reset')
                        %     plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        %     plotYLim2 = ylim(app.SetWindowPlot);
                        %     ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        %     app.plotSetWindowConstants();
                        %     if setWindowOnset ~= 0
                        %         app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        %     else
                        %         delete(app.SetWindowOnsetLine)
                        %     end
                        %
                        % end


                        % if app.BestBaselineOnsetVisibleCheckBox.Value
                        %     app.plotBestBaselineOnsets();
                        % end

                        app.formatPlots();
                    else
                        %% Update plots
                        if app.RawEMGVisibleCheckBox.Value
                            cla(app.RawEMGPlot, 'reset')
                            plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            cla(app.ProcessedEMGPlot, 'reset')
                            plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        end

                        if app.BestBaselineVisibleCheckBox.Value
                            cla(app.BestBaselinePlot, 'reset')
                            plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                            % plotYLim = ylim(app.BestBaselinePlot);
                            % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                            app.plotBestBaselineConstants();
                            app.plotBestBaselineTime2Peak();
                        end
                        % if app.SetWindowVisibleCheckBox.Value
                        %     cla(app.SetWindowPlot, 'reset')
                        %     plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        %     plotYLim2 = ylim(app.SetWindowPlot);
                        %     ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        %     app.plotSetWindowConstants();
                        % end
                    end


                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    if isempty(app.TempCh2RE)
                        app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                    else
                        app.UITable.Data = app.TempCh2RE;
                    end
                    app.TempCh2RE = app.UITable.Data;

                    if app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                        % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                        % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                        app.SmoothedChannelMaxEditField.Value = app.Ch2EMGMax;

                        onset_ms = app.TempCh2RE{app.CurrentTrial, 4} / 1000;
                        setWindowOnset = cell2mat(app.SetWindowSummaryDataCh2RE(app.CurrentTrial, 4)) / 1000;

                        %% Update plots
                        if app.RawEMGVisibleCheckBox.Value
                            cla(app.RawEMGPlot, 'reset')
                            plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            cla(app.ProcessedEMGPlot, 'reset')
                            plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                        end
                        if app.BestBaselineVisibleCheckBox.Value
                            cla(app.BestBaselinePlot, 'reset')
                            plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                            % plotYLim = ylim(app.BestBaselinePlot);
                            % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                            app.plotBestBaselineConstants();
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            app.plotBestBaselineTime2Peak();
                        end
                        app.formatPlots();
                    else
                        %% Update plots
                        if app.RawEMGVisibleCheckBox.Value
                            cla(app.RawEMGPlot, 'reset')
                            plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            cla(app.ProcessedEMGPlot, 'reset')
                            plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        end

                        if app.BestBaselineVisibleCheckBox.Value
                            cla(app.BestBaselinePlot, 'reset')
                            plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                            % plotYLim = ylim(app.BestBaselinePlot);
                            % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                            app.plotBestBaselineConstants();
                            app.plotBestBaselineTime2Peak();
                        end

                    end

                else
                    app.UITable.Data = [];
                    cla(app.RawEMGPlot, 'reset');
                    cla(app.ProcessedEMGPlot, 'reset');
                    cla(app.SetWindowPlot, 'reset');
                    cla(app.BestBaselinePlot, 'reset');
                    app.formatPlots();
                end
            end

        end

        % Value changed function: SetWindowOnsetVisibleCheckBox
        function SetWindowOnsetVisibleCheckBoxValueChanged(app, event)
            if ~app.SetWindowOnsetVisibleCheckBox.Value
                % Delete only xline objects from each plot
                delete(app.SetWindowOnsetLine)
                delete(app.SetWindowTime2Peak)
                delete(app.SetWindowShadedArea)
                delete(app.SetWindowCue)
                delete(app.SetWindowDelay)
                app.SetWindowTime2Peak = [];
                app.SetWindowShadedArea = [];
                app.SetWindowCue = [];
                app.SetWindowDelay = [];

            else
                %% Update UITable with data
                delete(app.SetWindowOnsetLine)
                delete(app.SetWindowTime2Peak)
                delete(app.SetWindowShadedArea)
                delete(app.SetWindowCue)
                delete(app.SetWindowDelay)
                app.SetWindowTime2Peak = [];
                app.SetWindowShadedArea = [];
                app.SetWindowCue = [];
                app.SetWindowDelay = [];

                if app.CurrentEvent == app.Events{app.RF_IDX}

                    if isempty(app.TempCh1RF)
                        app.UITable.Data = app.SetWindowSummaryDataCh1RF;
                    else
                        app.UITable.Data = app.TempCh1RF;
                    end
                    app.TempCh1RF = app.UITable.Data;

                    if app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                        % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                        % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                        app.SmoothedChannelMaxEditField.Value = app.Ch1EMGMax;

                        setWindowOnset = cell2mat(app.SetWindowSummaryDataCh1RF(app.CurrentTrial, 4)) / 1000;

                        %% Update plots
                        if app.SetWindowVisibleCheckBox.Value
                            cla(app.SetWindowPlot, 'reset')
                            plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                            % plotYLim = ylim(app.SetWindowPlot);
                            % ylim(app.SetWindowPlot, [-0.001, plotYLim(2)])
                            app.plotSetWindowConstants();
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            app.plotSetWindowTime2Peak();
                        end

                        app.formatPlots();
                    else
                        %% Update plots
                        if app.SetWindowVisibleCheckBox.Value
                            cla(app.SetWindowPlot, 'reset')
                            plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                            % plotYLim = ylim(app.SetWindowPlot);
                            % ylim(app.SetWindowPlot, [-0.001, plotYLim(2)])
                            app.plotSetWindowConstants();
                            app.plotSetWindowTime2Peak();
                        end

                    end


                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    if isempty(app.TempCh2RE)
                        app.UITable.Data = app.SetWindowSummaryDataCh2RE;
                    else
                        app.UITable.Data = app.TempCh2RE;
                    end
                    app.TempCh2RE = app.UITable.Data;

                    if app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                        % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                        % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                        app.SmoothedChannelMaxEditField.Value = app.Ch2EMGMax;

                        setWindowOnset = cell2mat(app.SetWindowSummaryDataCh2RE(app.CurrentTrial, 4)) / 1000;

                        %% Update plots
                        if app.SetWindowVisibleCheckBox.Value
                            cla(app.SetWindowPlot, 'reset')
                            plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                            % plotYLim = ylim(app.SetWindowPlot);
                            % ylim(app.SetWindowPlot, [-0.001, plotYLim(2)])
                            app.plotSetWindowConstants();
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            app.plotSetWindowTime2Peak();
                        end

                        app.formatPlots();
                    else
                        %% Update plots
                        if app.SetWindowVisibleCheckBox.Value
                            cla(app.SetWindowPlot, 'reset')
                            plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                            % plotYLim = ylim(app.SetWindowPlot);
                            % ylim(app.SetWindowPlot, [-0.001, plotYLim(2)])
                            app.plotSetWindowConstants();
                            app.plotSetWindowTime2Peak();
                        end

                    end

                else
                    app.UITable.Data = [];
                    % cla(app.RawEMGPlot, 'reset');
                    % cla(app.ProcessedEMGPlot, 'reset');
                    cla(app.SetWindowPlot, 'reset');
                    % cla(app.BestBaselinePlot, 'reset');
                    app.formatPlots();
                end
            end
            % if ~app.SetWindowOnsetVisibleCheckBox.Value
            %     % Delete only xline objects from each plot
            %     % delete(app.RawEMGOnsetLine)
            %     % delete(app.ProcessedEMGOnsetLine)
            %     delete(app.SetWindowOnsetLine)
            %     delete(app.SetWindowTime2Peak)
            %     delete(app.SetWindowShadedArea)
            %     delete(app.SetWindowCue)
            %     delete(app.SetWindowDelay)
            %     app.SetWindowTime2Peak = [];
            %     app.SetWindowShadedArea = [];
            %     app.SetWindowCue = [];
            %     app.SetWindowDelay = [];
            % else
            %     % app.plotBestBaselineWindow();
            %     delete(app.SetWindowOnsetLine)
            %     delete(app.SetWindowTime2Peak)
            %     delete(app.SetWindowShadedArea)
            %     delete(app.SetWindowCue)
            %     delete(app.SetWindowDelay)
            %     app.SetWindowTime2Peak = [];
            %     app.SetWindowShadedArea = [];
            %     app.SetWindowCue = [];
            %     app.SetWindowDelay = [];
            %     app.plotSetWindowOnsets();
            % end
            %
            % app.plotSetWindowTime2Peak();
        end

        % Button pushed function: UncheckAllButton
        function UncheckAllButtonPushed(app, event)
            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.RawEMGVisibleCheckBoxValueChanged();
            app.ProcessedEMGVisibleCheckBoxValueChanged();
            app.BestBaselineVisibleCheckBoxValueChanged();
            app.SetWindowVisibleCheckBoxValueChanged();
            app.BestBaselineVisibleCheckBoxValueChanged();
            app.SetWindowOnsetVisibleCheckBoxValueChanged();

            app.formatPlots()
        end

        % Button pushed function: CheckAllButton
        function CheckAllButtonPushed(app, event)
            app.RawEMGVisibleCheckBox.Value = true;
            app.ProcessedEMGVisibleCheckBox.Value = true;
            app.BestBaselineVisibleCheckBox.Value = true;
            app.SetWindowVisibleCheckBox.Value = true;
            app.BestBaselineOnsetVisibleCheckBox.Value = true;
            app.SetWindowOnsetVisibleCheckBox.Value = true;

            app.RawEMGVisibleCheckBoxValueChanged();
            app.ProcessedEMGVisibleCheckBoxValueChanged();
            app.BestBaselineVisibleCheckBoxValueChanged();
            app.SetWindowVisibleCheckBoxValueChanged();
            app.BestBaselineVisibleCheckBoxValueChanged();
            app.SetWindowOnsetVisibleCheckBoxValueChanged();

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch1EMGMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh1RF(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh1RF{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh1RF{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh1RF{app.CurrentTrial, 2} == 1 && app.TempCh1RF{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                        app.plotBestBaselineTime2Peak();
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end

                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.plotSetWindowTime2Peak();
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.plotBestBaselineTime2Peak();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        app.plotSetWindowTime2Peak();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.SmoothedChannelMaxEditField.Value = app.Ch2EMGMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 4} / 1000;
                    setWindowOnset = cell2mat(app.SetWindowSummaryDataCh2RE(app.CurrentTrial, 4)) / 1000;
                    overrideOnset = app.TempCh2RE{app.CurrentTrial, 4} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.RawEMGOnsetLine)
                        end

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        else
                            delete(app.ProcessedEMGOnsetLine)
                        end
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                        if app.TempCh2RE{app.CurrentTrial, 2} == 0 && app.BestBaselineOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == -1 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        elseif app.TempCh2RE{app.CurrentTrial, 2} == 1 && app.TempCh2RE{app.CurrentTrial, 4} ~= 0
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, overrideOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.BestBaselineOnsetLine)
                        end
                        app.plotBestBaselineTime2Peak();
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0 && app.SetWindowOnsetVisibleCheckBox.Value
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                        app.plotSetWindowTime2Peak();
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim = ylim(app.BestBaselinePlot);
                        % ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.plotBestBaselineTime2Peak();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', [0 0 0], 'LineWidth', 1.25) % Pre-Processed EMG
                        % plotYLim2 = ylim(app.SetWindowPlot);
                        % ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        app.plotSetWindowTime2Peak();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end

        end

        % Button pushed function: ResetTableButton
        function ResetTableButtonPushed(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            app.SetNewOnsetButton.Visible = 'off';
            app.OverrideButton.Visible = 'on';

            % app.CurrentTrial = 1;
            % trialText = sprintf("Trial %02d", app.CurrentTrial);
            % app.TrialEditField.Value = trialText;
            % app.UITable.Selection = app.CurrentTrial;

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                app.TempCh1RF = app.UITable.Data;
                app.SetWindowTempCh1RF = app.SetWindowSummaryDataCh1RF;

                app.plotBestBaselineConstants();
                app.plotBestBaselineOnsets();
                app.plotSetWindowConstants();
                app.plotSetWindowOnsets();
                app.plotBestBaselineTime2Peak();
                app.plotSetWindowTime2Peak();
                app.formatPlots();
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                app.TempCh2RE = app.UITable.Data;
                app.SetWindowTempCh2RE = app.SetWindowSummaryDataCh2RE;

                app.plotBestBaselineConstants();
                app.plotBestBaselineOnsets();
                app.plotSetWindowConstants();
                app.plotSetWindowOnsets();
                app.plotBestBaselineTime2Peak();
                app.plotSetWindowTime2Peak();
                app.formatPlots();
            end
        end

        % Button pushed function: LowestBaselineButton
        function LowestBaselineButtonPushed(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}
                % app.TempCh1RF = app.UITable.Data;
                % restore calculated columns
                app.TempCh1RF(app.CurrentTrial, :) = app.BestBaselineSummaryDataCh1RF(app.CurrentTrial, :);
                app.UITable.Data = app.TempCh1RF;
                app.TempCh1RF = app.UITable.Data;

                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.BestBaselineTime2Peak)
                delete(app.BestBaselineShadedArea)
                delete(app.SetWindowTime2Peak)
                delete(app.SetWindowShadedArea)
                delete(app.BestBaselineCue)
                delete(app.BestBaselineDelay)
                delete(app.SetWindowCue)
                delete(app.SetWindowDelay)
                app.BestBaselineTime2Peak = [];
                app.BestBaselineShadedArea = [];
                app.SetWindowTime2Peak = [];
                app.SetWindowShadedArea = [];
                app.BestBaselineCue = [];
                app.BestBaselineDelay = [];
                app.SetWindowCue = [];
                app.SetWindowDelay = [];

                if app.UITable.Data{app.CurrentTrial, 4} == 0
                    delete(app.RawEMGOnsetLine)
                    delete(app.ProcessedEMGOnsetLine)
                    delete(app.BestBaselineOnsetLine)
                    delete(app.BestBaselineTime2Peak)
                    delete(app.BestBaselineShadedArea)
                    delete(app.SetWindowTime2Peak)
                    delete(app.SetWindowShadedArea)
                    delete(app.BestBaselineCue)
                    delete(app.BestBaselineDelay)
                    delete(app.SetWindowCue)
                    delete(app.SetWindowDelay)
                    app.BestBaselineTime2Peak = [];
                    app.BestBaselineShadedArea = [];
                    app.SetWindowTime2Peak = [];
                    app.SetWindowShadedArea = [];
                    app.BestBaselineCue = [];
                    app.BestBaselineDelay = [];
                    app.SetWindowCue = [];
                    app.SetWindowDelay = [];

                else
                    if app.RawEMGVisibleCheckBox.Value
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        app.plotBestBaselineTime2Peak();
                    end
                end

                if app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} == 0
                    delete(app.SetWindowOnsetLine)
                else
                    if app.SetWindowOnsetVisibleCheckBox.Value
                        delete(app.SetWindowOnsetLine)
                        app.SetWindowOnsetLine = xline(app.SetWindowPlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        app.plotSetWindowTime2Peak();
                    end

                end
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                % app.TempCh2RE = app.UITable.Data;
                % restore calculated columns
                app.TempCh2RE(app.CurrentTrial, :) = app.BestBaselineSummaryDataCh2RE(app.CurrentTrial, :);
                app.UITable.Data = app.TempCh2RE;
                app.TempCh2RE = app.UITable.Data;

                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.BestBaselineTime2Peak)
                delete(app.BestBaselineShadedArea)
                delete(app.SetWindowTime2Peak)
                delete(app.SetWindowShadedArea)
                delete(app.BestBaselineCue)
                delete(app.BestBaselineDelay)
                delete(app.SetWindowCue)
                delete(app.SetWindowDelay)
                app.BestBaselineTime2Peak = [];
                app.BestBaselineShadedArea = [];
                app.SetWindowTime2Peak = [];
                app.SetWindowShadedArea = [];
                app.BestBaselineCue = [];
                app.BestBaselineDelay = [];
                app.SetWindowCue = [];
                app.SetWindowDelay = [];

                if app.UITable.Data{app.CurrentTrial, 4} == 0
                    delete(app.RawEMGOnsetLine)
                    delete(app.ProcessedEMGOnsetLine)
                    delete(app.BestBaselineOnsetLine)
                    delete(app.BestBaselineTime2Peak)
                    delete(app.BestBaselineShadedArea)
                    delete(app.SetWindowTime2Peak)
                    delete(app.SetWindowShadedArea)
                    delete(app.BestBaselineCue)
                    delete(app.BestBaselineDelay)
                    delete(app.SetWindowCue)
                    delete(app.SetWindowDelay)
                    app.BestBaselineTime2Peak = [];
                    app.BestBaselineShadedArea = [];
                    app.SetWindowTime2Peak = [];
                    app.SetWindowShadedArea = [];
                    app.BestBaselineCue = [];
                    app.BestBaselineDelay = [];
                    app.SetWindowCue = [];
                    app.SetWindowDelay = [];

                else
                    if app.RawEMGVisibleCheckBox.Value
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        app.plotBestBaselineTime2Peak();
                    end
                end

                if app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} == 0
                    delete(app.SetWindowOnsetLine)
                else
                    if app.SetWindowOnsetVisibleCheckBox.Value
                        delete(app.SetWindowOnsetLine)
                        app.SetWindowOnsetLine = xline(app.SetWindowPlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        app.plotSetWindowTime2Peak();
                    end

                end
            end


        end

        % Button pushed function: SetWindowButton
        function SetWindowButtonPushed(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.SetWindowTempCh1RF = app.UITable.Data;
                % restore calculated columns
                app.SetWindowTempCh1RF(app.CurrentTrial, :) = app.SetWindowSummaryDataCh1RF(app.CurrentTrial, :);
                app.SetWindowTempCh1RF(app.CurrentTrial, 2) = {-1};
                if app.SetWindowTempCh1RF{app.CurrentTrial, 4} == 0
                    app.SetWindowTempCh1RF{app.CurrentTrial, 6} = 0;
                    app.SetWindowTempCh1RF(app.CurrentTrial, end) = {'no onset detected – "Set Window" button pressed'};

                end
                app.UITable.Data = app.SetWindowTempCh1RF;
                app.SetWindowTempCh1RF = app.UITable.Data;

                if app.UITable.Data{app.CurrentTrial, 4} == 0
                    delete(app.RawEMGOnsetLine)
                    delete(app.ProcessedEMGOnsetLine)
                    delete(app.BestBaselineOnsetLine)
                    delete(app.SetWindowOnsetLine)
                    delete(app.BestBaselineTime2Peak)
                    delete(app.BestBaselineShadedArea)
                    delete(app.SetWindowTime2Peak)
                    delete(app.SetWindowShadedArea)
                    delete(app.BestBaselineCue)
                    delete(app.BestBaselineDelay)
                    delete(app.SetWindowCue)
                    delete(app.SetWindowDelay)
                    app.BestBaselineTime2Peak = [];
                    app.BestBaselineShadedArea = [];
                    app.SetWindowTime2Peak = [];
                    app.SetWindowShadedArea = [];
                    app.BestBaselineCue = [];
                    app.BestBaselineDelay = [];
                    app.SetWindowCue = [];
                    app.SetWindowDelay = [];

                    app.SetWindowTempCh1RF(app.CurrentTrial, end) = {'no onset detected – "Set Window" button pressed'};
                else
                    delete(app.SetWindowOnsetLine)
                    app.plotSetWindowConstants();
                    % app.SetWindowTempCh1RF(app.CurrentTrial, end) = {'"Set Window" button pressed'};

                    if app.SetWindowOnsetVisibleCheckBox.Value
                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        % delete(app.BestBaselineOnsetLine)
                        % if app.BestBaselineOnsetVisibleCheckBox.Value
                        %     app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        % end
                        if app.RawEMGVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        app.plotSetWindowOnsets();
                        app.plotSetWindowTime2Peak();
                        app.plotBestBaselineTime2Peak();
                    end
                end

                if app.UITable.Data{app.CurrentTrial, 4} ~= 0
                    % app.SetWindowTempCh1RF(app.CurrentTrial, end) = {'"Set Window" button pressed'};

                    if app.BestBaselineOnsetVisibleCheckBox.Value
                        delete(app.BestBaselineOnsetLine)
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                end
                app.UITable.Data{app.CurrentTrial, end} = '"Set Window" button pressed';
                app.TempCh1RF = app.UITable.Data;

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.SetWindowTempCh2RE = app.UITable.Data;
                % restore calculated columns
                app.SetWindowTempCh2RE(app.CurrentTrial, :) = app.SetWindowSummaryDataCh2RE(app.CurrentTrial, :);
                app.SetWindowTempCh2RE(app.CurrentTrial, 2) = {-1};
                if app.SetWindowTempCh2RE{app.CurrentTrial, 4} == 0
                    app.SetWindowTempCh2RE{app.CurrentTrial, 6} = 0;
                    app.SetWindowTempCh2RE(app.CurrentTrial, end) = {'no onset detected – "Set Window" button pressed'};
                end
                app.UITable.Data = app.SetWindowTempCh2RE;
                app.SetWindowTempCh2RE = app.UITable.Data;

                if app.UITable.Data{app.CurrentTrial, 4} == 0
                    delete(app.RawEMGOnsetLine)
                    delete(app.ProcessedEMGOnsetLine)
                    delete(app.BestBaselineOnsetLine)
                    delete(app.SetWindowOnsetLine)
                    delete(app.BestBaselineTime2Peak)
                    delete(app.BestBaselineShadedArea)
                    delete(app.SetWindowTime2Peak)
                    delete(app.SetWindowShadedArea)
                    delete(app.BestBaselineCue)
                    delete(app.BestBaselineDelay)
                    delete(app.SetWindowCue)
                    delete(app.SetWindowDelay)
                    app.BestBaselineTime2Peak = [];
                    app.BestBaselineShadedArea = [];
                    app.SetWindowTime2Peak = [];
                    app.SetWindowShadedArea = [];
                    app.BestBaselineCue = [];
                    app.BestBaselineDelay = [];
                    app.SetWindowCue = [];
                    app.SetWindowDelay = [];

                    app.SetWindowTempCh2RE(app.CurrentTrial, end) = {'no onset detected – "Set Window" button pressed'};

                else
                    delete(app.SetWindowOnsetLine)
                    app.plotSetWindowConstants();
                    % app.SetWindowTempCh2RE(app.CurrentTrial, end) = {'"Set Window" button pressed'};

                    if app.SetWindowOnsetVisibleCheckBox.Value
                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        % delete(app.BestBaselineOnsetLine)
                        % if app.BestBaselineOnsetVisibleCheckBox.Value
                        %     app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        % end
                        if app.RawEMGVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        app.plotSetWindowOnsets();
                        app.plotSetWindowTime2Peak();
                        app.plotBestBaselineTime2Peak();
                    end
                end
                if app.UITable.Data{app.CurrentTrial, 4} ~= 0
                    % app.SetWindowTempCh2RE(app.CurrentTrial, end) = {'"Set Window" button pressed'};

                    if app.BestBaselineOnsetVisibleCheckBox.Value
                        delete(app.BestBaselineOnsetLine)
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                end
                app.UITable.Data{app.CurrentTrial, end} = '"Set Window" button pressed';
                app.TempCh2RE = app.UITable.Data;
            end

            % % delete(app.RawEMGOnsetLine)
            % % delete(app.ProcessedEMGOnsetLine)
            % if app.CurrentEvent == app.Events{app.RF_IDX}
            %     app.SetWindowTempCh1RF = app.UITable.Data;
            %     app.plotSetWindowConstants();
            %
            %     if app.SetWindowOnsetVisibleCheckBox.Value
            %         % change override to -1 for Set Window replacement
            %         app.SetWindowTempCh1RF(app.CurrentTrial, :) = app.SetWindowSummaryDataCh1RF(app.CurrentTrial, :);
            % app.SetWindowTempCh1RF(app.CurrentTrial, 2) = {-1};
            % if app.SetWindowTempCh1RF{app.CurrentTrial, 4} == 0
            %     app.SetWindowTempCh1RF{app.CurrentTrial, 5} = 0;
            % end
            %         app.UITable.Data = app.SetWindowTempCh1RF;
            %         % app.plotSetWindowOnsets();
            %
            %         % if app.RawEMGVisibleCheckBox.Value
            %         %     app.RawEMGOnsetLine = xline(app.RawEMGOnsetLine, app.SetWindowTempCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            %         % end
            %         % if app.ProcessedEMGVisibleCheckBox.Value
            %         %     app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGOnsetLine, app.SetWindowTempCh1RF{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            %         % end
            %     end
            %     app.formatPlots();
            %
            % elseif app.CurrentEvent == app.Events{app.RE_IDX}
            %     app.SetWindowTempCh2RE = app.UITable.Data;
            %     if app.SetWindowOnsetVisibleCheckBox.Value
            %         app.plotSetWindowConstants();
            %
            %         % change override to -1 for Set Window replacement
            %         app.SetWindowTempCh2RE(app.CurrentTrial, :) = app.SetWindowSummaryDataCh2RE(app.CurrentTrial, :);
            %         app.SetWindowTempCh2RE(app.CurrentTrial, 2) = {-1};
            %         if app.SetWindowTempCh2RE{app.CurrentTrial, 4} == 0
            %             app.SetWindowTempCh2RE{app.CurrentTrial, 5} = 0;
            %         end
            %         app.UITable.Data = app.SetWindowTempCh2RE;
            %         app.plotSetWindowOnsets();
            %
            %         % if app.RawEMGVisibleCheckBox.Value
            %         %     app.RawEMGOnsetLine = xline(app.RawEMGOnsetLine, app.SetWindowTempCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            %         % end
            %         % if app.ProcessedEMGVisibleCheckBox.Value
            %         %     app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGOnsetLine, app.SetWindowTempCh2RE{app.CurrentTrial, 4} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            %         % end
            %     end
            %     app.formatPlots();
            % end

        end

        % Button pushed function: NoTrialButton
        function NoTrialButtonPushed(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            delete(app.SetWindowTime2Peak)
            delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            app.SetWindowTime2Peak = [];
            app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            app.SetWindowCue = [];
            app.SetWindowDelay = [];

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.TempCh1RF = app.UITable.Data;
                % sprintf("\nTrial %d updating...\n", app.CurrentTrial)

                % change override to 1
                app.TempCh1RF(app.CurrentTrial, 2) = {1};

                % change onset to 0
                app.TempCh1RF(app.CurrentTrial, 4) = {0};

                % change comment
                app.TempCh1RF(app.CurrentTrial, end) = {'no onset detected – "No Trial" button pressed'};

                % change Time-to-Peak
                app.TempCh1RF(app.CurrentTrial, 6) = {0};

                % change AUC and mod ratio
                app.TempCh1RF(app.CurrentTrial, 7) = {0};
                app.TempCh1RF(app.CurrentTrial, 8) = {0};
                app.TempCh1RF(app.CurrentTrial, 9) = {0};
                app.TempCh1RF(app.CurrentTrial, 10) = {0};
                app.TempCh1RF(app.CurrentTrial, 11) = {0};
                app.TempCh1RF(app.CurrentTrial, 12) = {0};
                app.TempCh1RF(app.CurrentTrial, 13) = {0};
                app.TempCh1RF(app.CurrentTrial, 14) = {0};
                % sprintf("\nTrial %d updated!", app.CurrentTrial)

                baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                peak = app.TempCh1RF{app.CurrentTrial, 16};
                diff = peak - baseline;
                app.TempCh1RF(app.CurrentTrial, 15) = {baseline}; % set baseline to best 500ms window baseline
                app.TempCh1RF(app.CurrentTrial, 17) = {diff}; % set baseline to best 500ms window baseline

                app.UITable.Data = app.TempCh1RF;
                app.TempCh1RF = app.UITable.Data;

                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.SetWindowOnsetLine)
                delete(app.BestBaselineTime2Peak)
                delete(app.BestBaselineShadedArea)
                delete(app.SetWindowTime2Peak)
                delete(app.SetWindowShadedArea)
                delete(app.BestBaselineCue)
                delete(app.BestBaselineDelay)
                delete(app.SetWindowCue)
                delete(app.SetWindowDelay)
                app.BestBaselineTime2Peak = [];
                app.BestBaselineShadedArea = [];
                app.SetWindowTime2Peak = [];
                app.SetWindowShadedArea = [];
                app.BestBaselineCue = [];
                app.BestBaselineDelay = [];
                app.SetWindowCue = [];
                app.SetWindowDelay = [];

                % app.plotBestBaselineTime2Peak();
                % app.plotSetWindowTime2Peak();
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.TempCh2RE = app.UITable.Data;
                % sprintf("\nTrial %d updating...\n", app.CurrentTrial)

                % change override to 1
                app.TempCh2RE(app.CurrentTrial, 2) = {1};

                % change onset to 0
                app.TempCh2RE(app.CurrentTrial, 4) = {0};

                % change comment
                app.TempCh2RE(app.CurrentTrial, end) = {'no onset detected – "No Trial" button pressed'};

                % change Time-to-Peak
                app.TempCh2RE(app.CurrentTrial, 6) = {0};

                % change AUC and mod ratio
                app.TempCh2RE(app.CurrentTrial, 7) = {0};
                app.TempCh2RE(app.CurrentTrial, 8) = {0};
                app.TempCh2RE(app.CurrentTrial, 9) = {0};
                app.TempCh2RE(app.CurrentTrial, 10) = {0};
                app.TempCh2RE(app.CurrentTrial, 11) = {0};
                app.TempCh2RE(app.CurrentTrial, 12) = {0};
                app.TempCh2RE(app.CurrentTrial, 13) = {0};
                app.TempCh2RE(app.CurrentTrial, 14) = {0};
                % sprintf("\nTrial %d updated!", app.CurrentTrial)

                baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                peak = app.TempCh2RE{app.CurrentTrial, 16};
                diff = peak - baseline;
                app.TempCh2RE(app.CurrentTrial, 15) = {baseline}; % set baseline to best 500ms window baseline
                app.TempCh2RE(app.CurrentTrial, 17) = {diff}; % set baseline to best 500ms window baseline

                app.UITable.Data = app.TempCh2RE;
                app.TempCh2RE = app.UITable.Data;

                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.SetWindowOnsetLine)
                delete(app.BestBaselineTime2Peak)
                delete(app.BestBaselineShadedArea)
                delete(app.SetWindowTime2Peak)
                delete(app.SetWindowShadedArea)
                delete(app.BestBaselineCue)
                delete(app.BestBaselineDelay)
                delete(app.SetWindowCue)
                delete(app.SetWindowDelay)
                app.BestBaselineTime2Peak = [];
                app.BestBaselineShadedArea = [];
                app.SetWindowTime2Peak = [];
                app.SetWindowShadedArea = [];
                app.BestBaselineCue = [];
                app.BestBaselineDelay = [];
                app.SetWindowCue = [];
                app.SetWindowDelay = [];

                % app.plotBestBaselineTime2Peak();
                % app.plotSetWindowTime2Peak();
            end

        end

        % Button pushed function: OverrideButton
        function OverrideButtonPushed(app, event)
            % delete(app.RawEMGOnsetLine)
            % delete(app.ProcessedEMGOnsetLine)
            % delete(app.BestBaselineOnsetLine)
            delete(app.NewOnset);

            app.formatPlots();

            app.NewOnset = drawcrosshair('Parent', app.BestBaselinePlot, 'LineWidth', 0.75, 'Color', [0 0.4470 0.7410]);
            % app.OverrideX = app.NewOnset.Position(1);
            % app.OverrideY = app.NewOnset.Position(2);
            % newPointsPrint = sprintf("Override at (%.3f, %.3f)\n\n", app.OverrideX, app.OverrideY);
            % fprintf(newPointsPrint)
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'on';
            % app.SetWindowPlot.Visible = 'off';

        end

        % Button pushed function: SetNewOnsetButton
        function SetNewOnsetButtonPushed(app, event)
            app.SetNewOnsetButton.Visible = 'off';
            app.OverrideButton.Visible = 'on';
            % app.SetWindowPlot.Visible = 'on';

            app.OverrideX = app.NewOnset.Position(1);
            app.OverrideY = app.NewOnset.Position(2);
            newPointsPrint = sprintf("Override at (%.3f, %.3f)\n\n", app.OverrideX, app.OverrideY);
            fprintf(newPointsPrint)

            delete(app.NewOnset)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineShadedArea)
            % delete(app.SetWindowTime2Peak)
            % delete(app.SetWindowShadedArea)
            delete(app.BestBaselineCue)
            delete(app.BestBaselineDelay)
            delete(app.SetWindowCue)
            delete(app.SetWindowDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineShadedArea = [];
            % app.SetWindowTime2Peak = [];
            % app.SetWindowShadedArea = [];
            app.BestBaselineCue = [];
            app.BestBaselineDelay = [];
            % app.SetWindowCue = [];
            % app.SetWindowDelay = [];

            if app.RawEMGVisibleCheckBox.Value
                app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.OverrideX, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            end
            if app.ProcessedEMGVisibleCheckBox.Value
                app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.OverrideX, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            end
            if app.BestBaselineVisibleCheckBox.Value
                app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.OverrideX, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            end

            app.formatPlots();

            onset_ms = app.OverrideX * 1000; % onset in ms
            roundedOnset_s = round((onset_ms/ 1000) / 0.0005) * 0.0005;
            roundedOnset_s = round(roundedOnset_s, 4);

            % roundedOnset_ms = roundedOnset * 1000;

            time = app.Time;

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                app.TempCh1RF = app.UITable.Data;

                % update Override status
                app.TempCh1RF(app.CurrentTrial, 2) = {1};
                % update Onset
                app.TempCh1RF(app.CurrentTrial, 4) = {onset_ms};

                % update time-to-peak
                peak = app.TempCh1RF{app.CurrentTrial, 5};
                time2peak = peak - onset_ms;
                app.TempCh1RF(app.CurrentTrial, 6) = {time2peak};

                % update auc 100ms post onset
                window100ms = ceil(0.100 * app.SampleRate) + 1; % in samples
                onsetIDX = find(time == roundedOnset_s);
                stop100 = onsetIDX + window100ms;

                tempEMG = app.SmoothedEMG.(app.CurrentEvent)(:, app.CurrentTrial);
                antagonist_smooth = app.SmoothedEMG.(app.Events{app.RF_AntagonistIDX})(:, app.CurrentTrial);

                tempEMGMatrix = [time' tempEMG];
                antagonist_temp = [time' antagonist_smooth];

                auc100 = trapz(tempEMGMatrix(onsetIDX:stop100, 1) * 1000, tempEMGMatrix(onsetIDX:stop100, 2));
                app.TempCh1RF(app.CurrentTrial, 7) = {auc100};

                baselineData = app.BaselineStats;
                baseline = baselineData.(app.CurrentEvent)(1, app.CurrentTrial);

                % update mod ratio for auc 100ms post onset
                modRatio100 = 0;
                if auc100 > 0
                    modRatio100 = (auc100) / (baseline * 100); % per Lang and Bastian, 1999; Wagner et al., 2007
                end
                app.TempCh1RF(app.CurrentTrial, 8) = {modRatio100};

                % update auc from onset to end of block
                auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                app.TempCh1RF(app.CurrentTrial, 9) = {auc3000};
                antagonist_auc3000 = trapz(antagonist_temp(onsetIDX:end, 1) * 1000, antagonist_temp(onsetIDX:end, 2));
                app.TempCh1RF(app.CurrentTrial, 10) = {antagonist_auc3000};

                duration = (time(end) * 1000) - onset_ms;
                aucDurNorm = auc3000 / duration;
                antagonist_aucDurNorm = antagonist_auc3000 / duration;
                app.TempCh1RF(app.CurrentTrial, 11) = {aucDurNorm};
                app.TempCh1RF(app.CurrentTrial, 12) = {antagonist_aucDurNorm};

                aucMaxNorm = aucDurNorm / app.Ch1EMGMax;
                antagonist_aucMaxNorm = antagonist_aucDurNorm / app.Ch2EMGMax;
                app.TempCh1RF(app.CurrentTrial, 13) = {aucMaxNorm};
                app.TempCh1RF(app.CurrentTrial, 14) = {antagonist_aucMaxNorm}; % should be channel of antagonist

                % update comment / reason for override
                prompt = {'Enter comment/reason for override:'};
                dlgtitle = 'Override Comment';
                fieldsize = [1 85];
                definput = {''};
                overrideComment = inputdlg(prompt, dlgtitle, fieldsize, definput);
                % data = load(fullfile(path, filename)); % loads data stored in condition file
                % app.FilenameEditField.Value = filename;
                drawnow; % puts UI Figure at the forefront
                figure(app.UIFigure) % puts UI Figure at the forefront
                app.TempCh1RF(app.CurrentTrial, end) = overrideComment;

                app.UITable.Data = app.TempCh1RF;
                app.TempCh1RF = app.UITable.Data;
                app.plotBestBaselineTime2Peak();

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                app.TempCh2RE = app.UITable.Data;

                % update Override status
                app.TempCh2RE(app.CurrentTrial, 2) = {1};
                % update Onset
                app.TempCh2RE(app.CurrentTrial, 4) = {onset_ms};

                % update time-to-peak
                peak = app.TempCh2RE{app.CurrentTrial, 5};
                time2peak = peak - onset_ms;
                app.TempCh2RE(app.CurrentTrial, 6) = {time2peak};

                % update auc 100ms post onset
                window100ms = ceil(0.100 * app.SampleRate) + 1; % in samples
                onsetIDX = find(time == roundedOnset_s);
                stop100 = onsetIDX + window100ms;

                tempEMG = app.SmoothedEMG.(app.CurrentEvent)(:, app.CurrentTrial);
                antagonist_smooth = app.SmoothedEMG.(app.Events{app.RE_AntagonistIDX})(:, app.CurrentTrial);

                tempEMGMatrix = [time' tempEMG];
                antagonist_temp = [time' antagonist_smooth];

                auc100 = trapz(tempEMGMatrix(onsetIDX:stop100, 1) * 1000, tempEMGMatrix(onsetIDX:stop100, 2));
                app.TempCh2RE(app.CurrentTrial, 7) = {auc100};

                baselineData = app.BaselineStats;
                baseline = baselineData.(app.CurrentEvent)(1, app.CurrentTrial);

                % update mod ratio for auc 100ms post onset
                modRatio100 = 0;
                if auc100 > 0
                    modRatio100 = (auc100) / (baseline * 100); % per Lang and Bastian, 1999; Wagner et al., 2007
                end
                app.TempCh2RE(app.CurrentTrial, 8) = {modRatio100};

                % update auc from onset to end of block
                auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                app.TempCh2RE(app.CurrentTrial, 9) = {auc3000};
                antagonist_auc3000 = trapz(antagonist_temp(onsetIDX:end, 1) * 1000, antagonist_temp(onsetIDX:end, 2));
                app.TempCh2RE(app.CurrentTrial, 10) = {antagonist_auc3000};

                duration = (time(end) * 1000) - onset_ms;
                aucDurNorm = auc3000 / duration;
                antagonist_aucDurNorm = antagonist_auc3000 / duration;
                app.TempCh2RE(app.CurrentTrial, 11) = {aucDurNorm};
                app.TempCh2RE(app.CurrentTrial, 12) = {antagonist_aucDurNorm};

                aucMaxNorm = aucDurNorm / app.Ch2EMGMax;
                antagonist_aucMaxNorm = antagonist_aucDurNorm / app.Ch1EMGMax; % should be channel of antagonist
                app.TempCh2RE(app.CurrentTrial, 13) = {aucMaxNorm};
                app.TempCh2RE(app.CurrentTrial, 14) = {antagonist_aucMaxNorm};

                % update comment / reason for override
                % ff = fixfocus;
                prompt = {'Enter comment/reason for override:'};
                dlgtitle = 'Override Comment';
                fieldsize = [1.5 85];
                definput = {''};
                overrideComment = inputdlg(prompt, dlgtitle, fieldsize, definput);
                % data = load(fullfile(path, filename)); % loads data stored in condition file
                % app.FilenameEditField.Value = filename;
                drawnow; % puts UI Figure at the forefront
                figure(app.UIFigure) % puts UI Figure at the forefront
                app.TempCh2RE(app.CurrentTrial, end) = overrideComment;

                app.UITable.Data = app.TempCh2RE;
                app.TempCh2RE = app.UITable.Data;
                app.plotBestBaselineTime2Peak();

            end
            ylim(app.BestBaselinePlot, [app.BestBaselinePlotYLim(1) app.BestBaselinePlotYLim(2)]);
            xlim(app.BestBaselinePlot, [app.BestBaselinePlotXLim(1) app.BestBaselinePlotXLim(2)]);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            delete(app.NewOnset)
            % delete(app.RawEMGOnsetLine)
            % delete(app.ProcessedEMGOnsetLine)
            % delete(app.BestBaselineOnsetLine)
            % delete(app.BestBaselineTime2Peak)
            % delete(app.BestBaselineShadedArea)
            % delete(app.SetWindowTime2Peak)
            % delete(app.SetWindowShadedArea)
            % delete(app.BestBaselineCue)
            % delete(app.BestBaselineDelay)
            % delete(app.SetWindowCue)
            % delete(app.SetWindowDelay)
            % app.BestBaselineTime2Peak = [];
            % app.BestBaselineShadedArea = [];
            % app.SetWindowTime2Peak = [];
            % app.SetWindowShadedArea = [];
            % app.BestBaselineCue = [];
            % app.BestBaselineDelay = [];
            % app.SetWindowCue = [];
            % app.SetWindowDelay = [];

            trialCell = event.Indices;

            if trialCell(2) == 4
                app.CurrentTrial = trialCell(1);
                trialText = sprintf("Trial %02d", app.CurrentTrial);
                app.TrialEditField.Value = trialText;

                onset_ms = event.NewData;

                % app.UITable.Data(app.CurrentTrial, 4) = {tempOnset};
                % onset_ms = (app.UITable.Data(app.CurrentTrial, 4));

                delete(app.NewOnset)
                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.BestBaselineTime2Peak)
                delete(app.BestBaselineShadedArea)
                delete(app.SetWindowTime2Peak)
                delete(app.SetWindowShadedArea)
                delete(app.BestBaselineCue)
                delete(app.BestBaselineDelay)
                delete(app.SetWindowCue)
                delete(app.SetWindowDelay)
                app.BestBaselineTime2Peak = [];
                app.BestBaselineShadedArea = [];
                app.SetWindowTime2Peak = [];
                app.SetWindowShadedArea = [];
                app.BestBaselineCue = [];
                app.BestBaselineDelay = [];
                app.SetWindowCue = [];
                app.SetWindowDelay = [];

                if app.RawEMGVisibleCheckBox.Value
                    app.RawEMGOnsetLine = xline(app.RawEMGPlot, (onset_ms / 1000), 'k--', 'LineWidth', 1.25, 'Color', 'k');
                end
                if app.ProcessedEMGVisibleCheckBox.Value
                    app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, (onset_ms / 1000), 'k--', 'LineWidth', 1.25, 'Color', 'k');
                end
                if app.BestBaselineVisibleCheckBox.Value
                    app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, (onset_ms / 1000), 'k--', 'LineWidth', 1.25, 'Color', 'k');
                end

                app.formatPlots();

                roundedOnset_s = round((onset_ms / 1000) / 0.0005) * 0.0005;
                roundedOnset_s = round(roundedOnset_s, 4);
                % roundedOnset_ms = roundedOnset * 1000;

                time = app.Time;

                if app.CurrentEvent == app.Events{app.RF_IDX}
                    app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    app.TempCh1RF = app.UITable.Data;

                    if onset_ms == 0
                        app.TempCh1RF = app.UITable.Data;
                        % sprintf("\nTrial %d updating...\n", app.CurrentTrial)

                        % change override to 1
                        app.TempCh1RF(app.CurrentTrial, 2) = {1};

                        % change onset to 0
                        app.TempCh1RF(app.CurrentTrial, 4) = {0};

                        % change comment
                        app.TempCh1RF(app.CurrentTrial, end) = {'no onset detected'};

                        % change Time-to-Peak
                        app.TempCh1RF(app.CurrentTrial, 6) = {0};

                        % change AUC and mod ratio
                        app.TempCh1RF(app.CurrentTrial, 7) = {0};
                        app.TempCh1RF(app.CurrentTrial, 8) = {0};
                        app.TempCh1RF(app.CurrentTrial, 9) = {0};
                        app.TempCh1RF(app.CurrentTrial, 10) = {0};
                        app.TempCh1RF(app.CurrentTrial, 11) = {0};
                        app.TempCh1RF(app.CurrentTrial, 12) = {0};
                        app.TempCh1RF(app.CurrentTrial, 13) = {0};
                        app.TempCh1RF(app.CurrentTrial, 14) = {0};
                        % sprintf("\nTrial %d updated!", app.CurrentTrial)

                        baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                        peak = app.TempCh1RF{app.CurrentTrial, 16};
                        diff = peak - baseline;
                        app.TempCh1RF(app.CurrentTrial, 15) = {baseline}; % set baseline to best 500ms window baseline
                        app.TempCh1RF(app.CurrentTrial, 17) = {diff}; % set baseline to best 500ms window baseline

                        app.UITable.Data = app.TempCh1RF;
                        app.TempCh1RF = app.UITable.Data;

                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        delete(app.BestBaselineOnsetLine)
                        delete(app.SetWindowOnsetLine)
                        delete(app.BestBaselineTime2Peak)
                        delete(app.BestBaselineShadedArea)
                        delete(app.SetWindowTime2Peak)
                        delete(app.SetWindowShadedArea)
                        delete(app.BestBaselineCue)
                        delete(app.BestBaselineDelay)
                        delete(app.SetWindowCue)
                        delete(app.SetWindowDelay)
                        app.BestBaselineTime2Peak = [];
                        app.BestBaselineShadedArea = [];
                        app.SetWindowTime2Peak = [];
                        app.SetWindowShadedArea = [];
                        app.BestBaselineCue = [];
                        app.BestBaselineDelay = [];
                        app.SetWindowCue = [];
                        app.SetWindowDelay = [];

                    else

                        % update Override status
                        app.TempCh1RF(app.CurrentTrial, 2) = {1};
                        app.TempCh1RF(app.CurrentTrial, 2) = {1};
                        
                        % update Onset
                        app.TempCh1RF(app.CurrentTrial, 4) = {onset_ms};

                        % update time-to-peak
                        peak = app.TempCh1RF{app.CurrentTrial, 5};
                        time2peak = peak - onset_ms;
                        app.TempCh1RF(app.CurrentTrial, 6) = {time2peak};

                        % update auc 100ms post onset
                        window100ms = ceil(0.100 * app.SampleRate) + 1; % in samples
                        onsetIDX = find(time == roundedOnset_s);
                        stop100 = onsetIDX + window100ms;

                        tempEMG = app.SmoothedEMG.(app.CurrentEvent)(:, app.CurrentTrial);
                        antagonist_smooth = app.SmoothedEMG.(app.Events{app.RF_AntagonistIDX})(:, app.CurrentTrial);

                        tempEMGMatrix = [time' tempEMG];
                        antagonist_temp = [time' antagonist_smooth];

                        auc100 = trapz(tempEMGMatrix(onsetIDX:stop100, 1) * 1000, tempEMGMatrix(onsetIDX:stop100, 2));
                        app.TempCh1RF(app.CurrentTrial, 7) = {auc100};

                        baselineData = app.BaselineStats;
                        baseline = baselineData.(app.CurrentEvent)(1, app.CurrentTrial);

                        % update mod ratio for auc 100ms post onset
                        modRatio100 = 0;
                        if auc100 > 0
                            modRatio100 = (auc100) / (baseline * 100); % per Lang and Bastian, 1999; Wagner et al., 2007
                        end

                        app.TempCh1RF(app.CurrentTrial, 8) = {modRatio100};

                        % update auc from onset to end of block
                        auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                        app.TempCh1RF(app.CurrentTrial, 9) = {auc3000};
                        antagonist_auc3000 = trapz(antagonist_temp(onsetIDX:end, 1) * 1000, antagonist_temp(onsetIDX:end, 2));
                        app.TempCh1RF(app.CurrentTrial, 10) = {antagonist_auc3000};

                        duration = (time(end) * 1000) - onset_ms;
                        aucDurNorm = auc3000 / duration;
                        antagonist_aucDurNorm = antagonist_auc3000 / duration;
                        app.TempCh1RF(app.CurrentTrial, 11) = {aucDurNorm};
                        app.TempCh1RF(app.CurrentTrial, 12) = {antagonist_aucDurNorm};

                        aucMaxNorm = aucDurNorm / app.Ch1EMGMax;
                        antagonist_aucMaxNorm = antagonist_aucDurNorm / app.Ch2EMGMax;
                        app.TempCh1RF(app.CurrentTrial, 13) = {aucMaxNorm};
                        app.TempCh1RF(app.CurrentTrial, 14) = {antagonist_aucMaxNorm}; % should be channel of antagonist                        

                        % update comment / reason for override
                        prompt = {'Enter comment/reason for override:'};
                        dlgtitle = 'Override Comment';
                        fieldsize = [1 85];
                        definput = {''};
                        overrideComment = inputdlg(prompt, dlgtitle, fieldsize, definput);
                        % data = load(fullfile(path, filename)); % loads data stored in condition file
                        % app.FilenameEditField.Value = filename;
                        drawnow; % puts UI Figure at the forefront
                        figure(app.UIFigure) % puts UI Figure at the forefront
                        app.TempCh1RF(app.CurrentTrial, end) = overrideComment;

                        app.UITable.Data = app.TempCh1RF;
                        app.TempCh1RF = app.UITable.Data;
                        app.plotBestBaselineTime2Peak();
                    end

                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    app.TempCh2RE = app.UITable.Data;

                    if onset_ms == 0
                        app.TempCh2RE = app.UITable.Data;
                        % sprintf("\nTrial %d updating...\n", app.CurrentTrial)

                        % change override to 1
                        app.TempCh2RE(app.CurrentTrial, 2) = {1};

                        % change onset to 0
                        app.TempCh2RE(app.CurrentTrial, 4) = {0};

                        % change comment
                        app.TempCh2RE(app.CurrentTrial, end) = {'no onset detected'};

                        % change Time-to-Peak
                        app.TempCh2RE(app.CurrentTrial, 6) = {0};

                        % change AUC and mod ratio
                        app.TempCh2RE(app.CurrentTrial, 7) = {0};
                        app.TempCh2RE(app.CurrentTrial, 8) = {0};
                        app.TempCh2RE(app.CurrentTrial, 9) = {0};
                        app.TempCh2RE(app.CurrentTrial, 10) = {0};
                        app.TempCh2RE(app.CurrentTrial, 11) = {0};
                        app.TempCh2RE(app.CurrentTrial, 12) = {0};
                        app.TempCh2RE(app.CurrentTrial, 13) = {0};
                        app.TempCh2RE(app.CurrentTrial, 14) = {0};
                        % sprintf("\nTrial %d updated!", app.CurrentTrial)

                        baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                        peak = app.TempCh2RE{app.CurrentTrial, 16};
                        diff = peak - baseline;
                        app.TempCh2RE(app.CurrentTrial, 15) = {baseline}; % set baseline to best 500ms window baseline
                        app.TempCh2RE(app.CurrentTrial, 17) = {diff}; % set baseline to best 500ms window baseline

                        app.UITable.Data = app.TempCh2RE;
                        app.TempCh2RE = app.UITable.Data;

                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        delete(app.BestBaselineOnsetLine)
                        delete(app.SetWindowOnsetLine)
                        delete(app.BestBaselineTime2Peak)
                        delete(app.BestBaselineShadedArea)
                        delete(app.SetWindowTime2Peak)
                        delete(app.SetWindowShadedArea)
                        delete(app.BestBaselineCue)
                        delete(app.BestBaselineDelay)
                        delete(app.SetWindowCue)
                        delete(app.SetWindowDelay)
                        app.BestBaselineTime2Peak = [];
                        app.BestBaselineShadedArea = [];
                        app.SetWindowTime2Peak = [];
                        app.SetWindowShadedArea = [];
                        app.BestBaselineCue = [];
                        app.BestBaselineDelay = [];
                        app.SetWindowCue = [];
                        app.SetWindowDelay = [];

                    else
                        % update Override status
                        app.TempCh2RE(app.CurrentTrial, 2) = {1};
                        % update Onset
                        app.TempCh2RE(app.CurrentTrial, 4) = {onset_ms};

                        % update time-to-peak
                        peak = app.TempCh2RE{app.CurrentTrial, 5};
                        time2peak = peak - onset_ms;
                        app.TempCh2RE(app.CurrentTrial, 6) = {time2peak};

                        % update auc 100ms post onset
                        window100ms = ceil(0.100 * app.SampleRate) + 1; % in samples
                        onsetIDX = find(time == roundedOnset_s);
                        stop100 = onsetIDX + window100ms;

                        tempEMG = app.SmoothedEMG.(app.CurrentEvent)(:, app.CurrentTrial);
                        antagonist_smooth = app.SmoothedEMG.(app.Events{app.RE_AntagonistIDX})(:, app.CurrentTrial);

                        tempEMGMatrix = [time' tempEMG];
                        antagonist_temp = [time' antagonist_smooth];

                        auc100 = trapz(tempEMGMatrix(onsetIDX:stop100, 1) * 1000, tempEMGMatrix(onsetIDX:stop100, 2));
                        app.TempCh2RE(app.CurrentTrial, 7) = {auc100};

                        baselineData = app.BaselineStats;
                        baseline = baselineData.(app.CurrentEvent)(1, app.CurrentTrial);

                        % update mod ratio for auc 100ms post onset
                        modRatio100 = 0;
                        if auc100 > 0
                            modRatio100 = (auc100) / (baseline * 100); % per Lang and Bastian, 1999; Wagner et al., 2007
                        end
                        app.TempCh2RE(app.CurrentTrial, 8) = {modRatio100};

                        % update auc from onset to end of block
                        auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                        app.TempCh2RE(app.CurrentTrial, 9) = {auc3000};
                        antagonist_auc3000 = trapz(antagonist_temp(onsetIDX:end, 1) * 1000, antagonist_temp(onsetIDX:end, 2));
                        app.TempCh2RE(app.CurrentTrial, 10) = {antagonist_auc3000};

                        duration = (time(end) * 1000) - onset_ms;
                        aucDurNorm = auc3000 / duration;
                        antagonist_aucDurNorm = antagonist_auc3000 / duration;
                        app.TempCh2RE(app.CurrentTrial, 11) = {aucDurNorm};
                        app.TempCh2RE(app.CurrentTrial, 12) = {antagonist_aucDurNorm};

                        aucMaxNorm = aucDurNorm / app.Ch2EMGMax;
                        antagonist_aucMaxNorm = antagonist_aucDurNorm / app.Ch1EMGMax; % should be channel of antagonist
                        app.TempCh2RE(app.CurrentTrial, 13) = {aucMaxNorm};
                        app.TempCh2RE(app.CurrentTrial, 14) = {antagonist_aucMaxNorm};

                        % update comment / reason for override
                        % ff = fixfocus;
                        prompt = {'Enter comment/reason for override:'};
                        dlgtitle = 'Override Comment';
                        fieldsize = [1.5 85];
                        definput = {''};
                        overrideComment = inputdlg(prompt, dlgtitle, fieldsize, definput);
                        % data = load(fullfile(path, filename)); % loads data stored in condition file
                        % app.FilenameEditField.Value = filename;
                        drawnow; % puts UI Figure at the forefront
                        figure(app.UIFigure) % puts UI Figure at the forefront
                        app.TempCh2RE(app.CurrentTrial, end) = overrideComment;

                        app.UITable.Data = app.TempCh2RE;
                        app.TempCh2RE = app.UITable.Data;
                        app.plotBestBaselineTime2Peak();
                    end
                end
            elseif trialCell(2) == 18
                app.CurrentTrial = trialCell(1);
                trialText = sprintf("Trial %02d", app.CurrentTrial);
                app.TrialEditField.Value = trialText;

                userComment = event.NewData;

                if app.CurrentEvent == app.Events{app.RF_IDX}
                    app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    app.TempCh1RF = app.UITable.Data;
                    app.TempCh1RF(app.CurrentTrial, end) = {userComment};

                    app.UITable.Data = app.TempCh1RF;
                    app.TempCh1RF = app.UITable.Data;
                    app.plotBestBaselineTime2Peak();
                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    app.TempCh2RE = app.UITable.Data;
                    app.TempCh2RE(app.CurrentTrial, end) = {userComment};

                    app.UITable.Data = app.TempCh2RE;
                    app.TempCh2RE = app.UITable.Data;
                    app.plotBestBaselineTime2Peak();
                end
            end

            app.BestBaselinePlotXLim = get(app.BestBaselinePlot, 'XLim');
            app.BestBaselinePlotYLim = get(app.BestBaselinePlot, 'YLim');

        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            app.SubID = app.SubjectIDEditField.Value; % MAPS###
            app.User = app.UserInitialsEditField.Value;
            app.TimePoint = app.TimePointDropDown.Value; % T0, T1, T3, T6
            if strcmp(app.RecordingLimbButtonGroup.SelectedObject.Text, 'Left')
                app.RecordingArm = 'L';
            else
                app.RecordingArm = 'R';
            end

            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Saving Results to Patient Folder',...
                'Indeterminate','on');

            % app.SaveResultsButton.Visible = 'off';
            fprintf("\nSaving everything...\n\n")
            d.Message = sprintf("Saving everything...");

            if strcmp(app.User, app.UserInitialsEditField.Placeholder)
                app.User = "[Your Initials]";
            else
                app.User = app.UserInitialsEditField.Value;
            end

            data_filename = sprintf('%s_%s_%s-Arm_EMGLAB', app.SubID, app.TimePoint, app.RecordingArm);
            app.AnalysisDir = sprintf('%s/%s_EMGLAB_%s_%s_%s', app.ResultsDir, app.SubID, app.TimePoint, app.FormattedDate, app.User);

            % Loop through and create directories if they do not exist
            fprintf("\nCreating directories...\n")

            if ~exist(app.AnalysisDir, 'dir')
                mkdir(app.AnalysisDir);
            end

            % add to path to access for analysis
            addpath(app.AnalysisDir)
            fprintf("Directories created!\n")
            savepath

            %% ----------------------------------------------------------------------------------------------------------------%%
            % Format data to save as .mat and .xlsx

            % Get the number of rows in app.AllDataCh1RF
            if isempty(app.TempCh1RF)
                numRowsRF = size(app.AllDataCh1RF, 1);
            else
                numRowsRF = size(app.TempCh1RF, 1);
            end

            if isempty(app.TempCh2RE)
                numRowsRE = size(app.AllDataCh2RE, 1);
            else
                numRowsRE = size(app.TempCh2RE, 1);
            end

            % Create a new column with repmat of data details
            app.newColumnRF = repmat({[app.SubID '_' app.TimePoint '_' app.Events{app.RF_IDX}]}, numRowsRF, 1);
            app.newColumnRE = repmat({[app.SubID '_' app.TimePoint '_' app.Events{app.RE_IDX}]}, numRowsRE, 1);
            app.ExcelHeaders = ['EventNames'; app.UITable.ColumnName(:)];

            % Add the new column as the first column
            if isempty(app.TempCh1RF)
                app.AllDataCh1RF = [app.newColumnRF, app.AllDataCh1RF];
                app.BestBaselineAllDataCh1RF = [app.newColumnRF, app.BestBaselineSummaryDataCh1RF];
                app.SetWindowAllDataCh1RF = [app.newColumnRF, app.SetWindowSummaryDataCh1RF];
            else
                app.AllDataCh1RF = [app.newColumnRF, app.TempCh1RF];
                app.BestBaselineAllDataCh1RF = [app.newColumnRF, app.BestBaselineSummaryDataCh1RF];
                app.SetWindowAllDataCh1RF = [app.newColumnRF, app.SetWindowSummaryDataCh1RF];
            end
            if isempty(app.TempCh2RE)
                app.AllDataCh2RE = [app.newColumnRE, app.AllDataCh2RE];
                app.BestBaselineAllDataCh2RE = [app.newColumnRE, app.BestBaselineSummaryDataCh2RE];
                app.SetWindowAllDataCh2RE = [app.newColumnRE, app.SetWindowSummaryDataCh2RE];
            else
                app.AllDataCh2RE = [app.newColumnRE, app.TempCh2RE];
                app.BestBaselineAllDataCh2RE = [app.newColumnRE, app.BestBaselineSummaryDataCh2RE];
                app.SetWindowAllDataCh2RE = [app.newColumnRE, app.SetWindowSummaryDataCh2RE];
            end

            combinedData = [app.AllDataCh1RF; app.AllDataCh2RE];
            app.CombinedData = cell2table(combinedData);

            % Create headers as a cell array
            app.CombinedData.Properties.VariableNames = app.ExcelHeaders;

            bestCombinedData = [app.BestBaselineAllDataCh1RF; app.BestBaselineAllDataCh2RE];
            app.BestBaselineCombinedData = cell2table(bestCombinedData);
            % Create headers as a cell array
            app.BestBaselineCombinedData.Properties.VariableNames = app.ExcelHeaders;

            setCombinedData = [app.SetWindowAllDataCh1RF; app.SetWindowAllDataCh2RE];
            app.SetWindowCombinedData = cell2table(setCombinedData);
            % Create headers as a cell array
            app.SetWindowCombinedData.Properties.VariableNames = app.ExcelHeaders;

            if app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_LeftButton
                app.AffectedArm = 'L';
                unaffected = 'R';
            elseif app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_RightButton
                app.AffectedArm = 'R';
                unaffected = 'L';
            end

            %% ----------------------------------------------------------------------------------------------------------------%%
            % Create summary table
            Ch1Max = round(app.Ch1SmoothedRecordingMax, 5);
            Ch2Max = round(app.Ch2SmoothedRecordingMax, 5);
            Ch1CalMax = round(app.Ch1SmoothedCalibrationMax, 5);
            Ch2CalMax = round(app.Ch2SmoothedCalibrationMax, 5);
            affected = app.AffectedArm;
            highestLevel = round(max(app.Levels, [], 'all'), 2) * 100;
            highestLevelString = sprintf("%d%%", highestLevel);
            today = datetime('today');
            date_str = datestr(today, 'mm-dd-yyyy'); %#ok<DATST> % format date
            date_char = char(date_str); % convert to string


            info = {"SubjectID"; "Time point"; "Affected Arm"; "Unaffected Arm"; "Recording Arm"; "Max Threshold";...
                "Flexor Max (Overall)"; "Flexor Max (Calibration)"; "Extensor Max (Overall)"; "Extensor Max (Calibration)"; ""; "Study Team Member"; "Analysis Date"};
            summary_data = {app.SubID; app.TimePoint; affected; unaffected; app.RecordingArm; highestLevelString;...
                Ch1Max; Ch1CalMax; Ch2Max; Ch2CalMax; []; app.User; date_char};

            summary = cell(length(info), 2);
            summary(:, 1) = (info);
            summary(:, 2) = summary_data;

            app.SummaryTable = cell2table(summary);
            summary_sheetname = sprintf("%s-Arm Summary Table", app.RecordingArm);

            %% ----------------------------------------------------------------------------------------------------------------%%
            % Save data into .mat file

            variables2save = {
                'app.ConditionDirectory', 'app.ConditionInfo', 'app.ConditionData', 'app.Events',...
                'app.SampleRate', 'app.Time', 'app.TrialThreshold', 'app.RF_IDX', 'app.RE_IDX',...
                'app.RF_AntagonistIDX', 'app.RE_AntagonistIDX', 'app.BestBaselineSummaryDataCh1RF',...
                'app.BestBaselineSummaryDataCh2RE', 'app.SetWindowSummaryDataCh1RF', 'app.SetWindowSummaryDataCh2RE',...
                'app.CombinedData', 'app.BestBaselineCombinedData', 'app.SetWindowCombinedData', 'app.RawEMG',...
                'app.ProcessedEMG', 'app.SmoothedEMG', 'app.MaxEMG', 'app.BaselineStats', 'app.SetWindowBaselineStats',...
                'app.EMGBurstOnsets', 'app.SetWindowEMGBurstOnsets', 'app.NoTrial', 'app.SetWindowNoTrial',...
                'app.MaxBaselineDiff', 'app.SetWindowMaxBaselineDiff', 'app.TargetEvents', 'app.FormattedDate',...
                'app.SubID', 'app.TimePoint', 'app.RecordingArm', 'app.AffectedArm', 'app.newColumnRF', 'app.newColumnRE',...
                'app.ExcelHeaders', 'app.Dur', 'app.Dur2', 'app.StartSearchTime', 'app.ThresholdSize', 'app.TempCh1RF',...
                'app.TempCh2RE', 'app.SetWindowTempCh1RF', 'app.SetWindowTempCh2RE', 'app.EMGData', 'app.Ch1EMG',...
                'app.Ch2EMG', 'app.Ch1EMGMax', 'app.Ch2EMGMax', 'app.RecordingMax',...
                'app.ThreshFields', 'app.ThreshRF', 'app.ThreshRE', 'app.Streams'
                };
            OutputMat = sprintf('%s/%s.mat', app.AnalysisDir, data_filename);

            % Initialize a struct to store the variables
            dataToSave = struct();

            % Extract variables from `app` and assign them to the struct
            for i = 1:length(variables2save)
                variableName = variables2save{i};

                % Remove 'app.' prefix to get the variable name
                varNameClean = erase(variableName, 'app.');

                % Use dynamic field names to store variables in the struct
                dataToSave.(varNameClean) = eval(variableName);
            end

            % Save the struct into a .mat file
            save(OutputMat, '-struct', 'dataToSave');

            %% ----------------------------------------------------------------------------------------------------------------%%

            % save data into xlsx file
            OutputExcel = sprintf('%s/%s.xlsx', app.AnalysisDir, data_filename);

            % Write the data to the CSV file
            writetable(app.CombinedData, OutputExcel, 'WriteVariableNames', true, 'Sheet', 'UserAnalysis');
            writetable(app.BestBaselineCombinedData, OutputExcel, 'WriteVariableNames', true, 'Sheet', 'BestBaseline (500ms)');
            writetable(app.SetWindowCombinedData, OutputExcel, 'WriteVariableNames', true, 'Sheet', 'SetWindow (-2350 – -1350ms)');
            writetable(app.SummaryTable, OutputExcel, 'WriteVariableNames', false, 'Sheet', summary_sheetname);

            fprintf("\nAnalysis saved!\n")
            d.Message = sprintf("Analysis saved!");

            pause(1)
            close(d)
        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed(app, event)
            app.SubID = app.SubjectIDEditField.Value; % MAPS###
            app.User = app.UserInitialsEditField.Value;
            app.TimePoint = app.TimePointDropDown.Value; % T0, T1, T3, T6
            if strcmp(app.RecordingLimbButtonGroup.SelectedObject.Text, 'Left')
                app.RecordingArm = 'L';
            else
                app.RecordingArm = 'R';
            end

            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Saving Figures to Patient Folder',...
                'Indeterminate','on');

            close all;
            fprintf("\nSaving figures...\n")
            d.Message = sprintf("Saving figures...");

            % app.SaveResultsButton.Visible = 'off';
            app.AnalysisDir = sprintf('%s/%s_EMGLAB_%s_%s_%s', app.ResultsDir, app.SubID, app.TimePoint, app.FormattedDate, app.User);

            for e = app.TargetEvents(1:2)
                if e == app.RF_IDX
                    tempData = app.TempCh1RF;
                elseif e == app.RE_IDX
                    tempData = app.TempCh2RE;
                end

                [~, cols] = size(app.SmoothedEMG.(app.Events{e}));
                onsets = tempData(:, 4);
                mvc = tempData(:, 3);

                for trial = 1:cols
                    clear tempEMG tempAvg tempSD
                    % tempEMG = app.SmoothedEMG.(app.Events{e})(:, trial);
                    tempAvg = app.BaselineStats.(app.Events{e})(1, trial);
                    tempSD = app.BaselineStats.(app.Events{e})(2, trial);

                    clear onsetThreshold aboveThreshold
                    onsetThreshold = tempAvg + (tempSD * app.ThresholdSize);
                    % samplesToSearch = ceil(app.Dur * app.SampleRate) + 1;
                    % samplesToSearch2 = ceil(app.Dur2 * app.SampleRate) + samplesToSearch;
                    %
                    % aboveThreshold = tempEMG > onsetThreshold;
                    % aboveThreshold2 = tempEMG > (tempAvg + tempSD);
                    % startSearchIDX = find(app.Time == app.StartSearchTime); % start search at -500ms

                    % app.EMGBurstOnsets.(app.Events{e})(1, trial) = 0;
                    % app.EMGBurstOnsets.(app.Events{e})(2, trial) = 0;
                    %
                    % firstThresholdIndex = [];
                    % for i = startSearchIDX:(length(tempEMG) - samplesToSearch2)
                    %     if all(aboveThreshold(i:i + samplesToSearch))
                    %         if all(aboveThreshold2(i + samplesToSearch + 1: i + samplesToSearch2))
                    %             firstThresholdIndex = i;
                    %             app.EMGBurstOnsets.(app.Events{e})(1, trial) = firstThresholdIndex;
                    %             app.EMGBurstOnsets.(app.Events{e})(2, trial) = app.Time(firstThresholdIndex);
                    %             break;
                    %         end
                    %
                    %     end
                    % end
                    mvc_temp = num2str(round(mvc{trial}));

                    if e == app.RF_IDX
                        if strcmp(app.RecordingArm, app.AffectedArm)
                            OnsetPlotTitle = sprintf("%s %s %s-Arm (Affected) – Trial %d (%s%% MVC) Rest-to-Flex", app.SubID, app.TimePoint, app.RecordingArm, trial, mvc_temp);
                        else
                            OnsetPlotTitle = sprintf("%s %s %s-Arm (Unaffected) – Trial %d (%s%% MVC) Rest-to-Flex", app.SubID, app.TimePoint, app.RecordingArm, trial, mvc_temp);
                        end
                        OnsetFigureTitle = sprintf("%s %s %s-Arm – Trial %d (%s%% MVC) Rest-to-Flex", app.SubID, app.TimePoint, app.RecordingArm, trial, mvc_temp);

                    elseif e == app.RE_IDX
                        if strcmp(app.RecordingArm, app.AffectedArm)
                            OnsetPlotTitle = sprintf("%s %s %s-Arm (Affected) – Trial %d (%s%% MVC) Rest-to-Extend", app.SubID, app.TimePoint, app.RecordingArm, trial, mvc_temp);
                        else
                            OnsetPlotTitle = sprintf("%s %s %s-Arm (Unaffected) – Trial %d (%s%% MVC) Rest-to-Extend", app.SubID, app.TimePoint, app.RecordingArm, trial, mvc_temp);
                        end
                        OnsetFigureTitle = sprintf("%s %s %s-Arm – Trial %d (%s%% MVC) Rest-to-Extend", app.SubID, app.TimePoint, app.RecordingArm, trial, mvc_temp);
                    end

                    % OnsetPlotTitle = sprintf("%s %s Trial %d (%s%% MVC) — %s %s-Arm", app.SubID, app.Events{e}, trial, mvc_temp, app.TimePoint, app.RecordingArm);
                    % OnsetFigureTitle = sprintf("%s %s Trial %d – EMG Burst onset", app.SubID, app.Events{e}, trial);
                    eventEMGBurstOnset = figure('Name', OnsetFigureTitle);

                    clear ax1 ax2 y_lim_ax1 y_lim_ax2
                    % First subplot for Smoothed EMG
                    ax1 = subplot(5, 1, 1:3, 'Parent', eventEMGBurstOnset);
                    hold on;
                    plot(app.Time, app.SmoothedEMG.(app.Events{e})(:, trial), 'LineWidth', 1, 'Color', [0, 0, 0], 'DisplayName', 'Smoothed EMG', 'HandleVisibility', 'off'); % Plot test data
                    % Plot baseline rectangle for onset threshold optimization
                    baselineStart = app.BaselineStats.(app.Events{e})(3, trial);
                    maxIDX = app.MaxEMG.(app.Events{e})(1, trial);
                    maxEMG = app.MaxEMG.(app.Events{e})(2, trial);
                    tempMax_cropped = maxEMG + (maxEMG/3);

                    tempMax = app.MaxEMG.(app.Events{e})(4, trial);
                    tempMax = tempMax + (tempMax/4);

                    y_lim_ax1 = get(ax1, 'YLim');
                    ylim(ax1, [0, tempMax])
                    % Define the x and y coordinates for the filled region
                    x_fill_ax1 = [baselineStart, baselineStart + (500/1000), baselineStart + (500/1000), baselineStart];
                    y_fill_ax1 = [0, 0, tempMax, tempMax];

                    % % Use fill to create the shaded area
                    fill(x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', ax1, 'DisplayName', 'Baseline Window');
                    plot(app.Time, app.SmoothedEMG.(app.Events{e})(:, trial), 'LineWidth', 1, 'Color', [0, 0, 0], 'HandleVisibility', 'off'); % Plot test data

                    % Plot horizontal line for onsetThreshold
                    yline(onsetThreshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25, 'DisplayName', 'Threshold');
                    % yline(tempAvg, 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');
                    % yline(onsetThreshold, 'o--', 'LineWidth', 1.25, 'DisplayName', 'Low Threshold');
                    % yline(tempAvg, '--', 'Color', 1/255*[1 1 1], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');

                    maxText = sprintf('Max = %.2f mV', maxEMG);
                    plot(app.Time(maxIDX), maxEMG, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', [0.6350 0.0780 0.1840], 'MarkerEdgeColor', [0.6350 0.0780 0.1840], 'DisplayName', maxText);
                    % xline(app.Time(maxIDX), 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', maxText)

                    % Plot vertical line at app.Time(firstIndex), if it exists
                    firstThresholdIndex = app.EMGBurstOnsets.(app.Events{e})(1, trial);
                    time = app.Time;

                    roundedOnset = round((onsets{trial} / 1000) / 0.0005) * 0.0005;

                    if roundedOnset ~= 0
                        xline(roundedOnset, 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');

                        % fill(x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', ax1, 'DisplayName', 'Baseline Window');
                        % plot(app.Time, app.SmoothedEMG.(app.Events{e})(:, trial), 'LineWidth', 1, 'Color', [0, 0, 0], 'HandleVisibility', 'off'); % Plot test data

                        tempOnsetTime = (onsets{trial}) / 1000;
                        [~, tempOnsetIDX] = min(abs(app.Time - tempOnsetTime));
                        tempMaxTime = tempData{trial, 5} / 1000;
                        tempMax_cropped = tempData{trial, 16};
                        tempEMG = app.SmoothedEMG.(app.Events{e})(:, trial);
                        tempMax = app.MaxEMG.(app.Events{e})(4, trial);
                        tempMax = tempMax + (tempMax/4);

                        plot(ax1, [tempOnsetTime tempMaxTime], [tempMax_cropped tempMax_cropped], 'LineWidth', 1.5, 'Color', [0.6350 0.0780 0.1840], 'HandleVisibility', 'off');
                        area(ax1, app.Time(tempOnsetIDX:end),tempEMG(tempOnsetIDX:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1, 'HandleVisibility', 'off');

                        xline(ax1, 0, 'LineWidth', 2, 'Color', [0.01 0.01 0.01], 'HandleVisibility', 'off');
                        delay_cue = (tempMax_cropped + (tempMax_cropped / 8));
                        plot(ax1, [tempOnsetTime 0], [delay_cue delay_cue], 'LineWidth', 1.5, 'Color', [0.01 0.01 0.01], 'HandleVisibility', 'off');
                        fprintf('\t%s | Trial %02d:\t%.1f ms\n', app.Events{e}, trial, (roundedOnset * 1000));
                        d.Message = sprintf('\t%s | Trial %02d:\t%.1f ms\n', app.Events{e}, trial, (roundedOnset * 1000));
                    else
                        fprintf('\t%s | Trial %02d:\t0.0 ms – NO ONSET DETECTED\n', app.Events{e}, trial);
                        d.Message = sprintf('\t%s | Trial %02d:\t0.0 ms – NO ONSET DETECTED\n', app.Events{e}, trial);
                    end

                    % tempOnsetTime = (onsets{trial}) / 1000;
                    % [~, tempOnsetIDX] = min(abs(app.Time - tempOnsetTime));
                    % tempMaxTime = tempData{trial, 5} / 1000;
                    % tempMax = tempData{trial, 16};
                    % tempEMG = app.SmoothedEMG.(app.Events{e})(:, trial);
                    %
                    % plot(ax1, [tempOnsetTime tempMaxTime], [tempMax tempMax], 'LineWidth', 1.5, 'Color', [0.6350 0.0780 0.1840], 'HandleVisibility', 'off');
                    % area(ax1, app.Time(tempOnsetIDX:end),tempEMG(tempOnsetIDX:end),0,'EdgeColor','none','FaceColor', [0 0.5 0], 'FaceAlpha', 0.1, 'HandleVisibility', 'off');
                    %
                    % xline(ax1, 0, 'LineWidth', 2, 'Color', [0.01 0.01 0.01], 'HandleVisibility', 'off');
                    % delay_cue = (tempMax + (tempMax / 8));
                    % plot(ax1, [tempOnsetTime 0], [delay_cue delay_cue], 'LineWidth', 1.5, 'Color', [0.01 0.01 0.01], 'HandleVisibility', 'off');

                    hold off
                    % Add labels and legend
                    % xlabel('Time (s)');
                    % ylabel('EMG Signal (mV)');
                    lgd = legend('show', 'Location', 'northoutside', 'Orientation', 'horizontal');
                    lgd.FontSize = 9;
                    title(ax1, "Smoothed EMG", 'FontSize', 11, 'FontWeight', 'bold');

                    % Second subplot for Processed EMG
                    ax2 = subplot(5, 1, 4, 'Parent', eventEMGBurstOnset);
                    hold on;
                    plot(app.Time, app.ProcessedEMG.(app.Events{e})(:, trial), 'DisplayName', 'Processed EMG', 'HandleVisibility', 'off')
                    % Plot baseline rectangle for onset threshold optimization
                    % Use fill to create the shaded area
                    y_lim_ax2 = get(ax2, 'YLim');
                    ylim(ax2, [0, y_lim_ax2(2)])
                    % Define the x and y coordinates for the filled region
                    x_fill_ax2 = [baselineStart, baselineStart + (500/1000), baselineStart + (500/1000), baselineStart];
                    y_fill_ax2 = [0, 0, y_lim_ax2(2), y_lim_ax2(2)];
                    fill(x_fill_ax2, y_fill_ax2, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', ax2, 'HandleVisibility', 'off');
                    plot(app.Time, app.ProcessedEMG.(app.Events{e})(:, trial), 'Color', [0, 0.4470, 0.7410], 'LineWidth', 1.25, 'HandleVisibility', 'off')

                    % Add labels and legend
                    % xlabel('Time (s)');
                    % ylabel('EMG Signal (mV)');
                    % title('Processed EMG');
                    % lgd = legend('show');
                    % lgd.FontSize = 8; % Smaller font size

                    hold on;
                    % yline(onsetThreshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25, 'DisplayName', 'Threshold');
                    % yline(tempAvg, 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');
                    % yline(onsetThreshold, 'r--', 'LineWidth', 1.25, 'DisplayName', 'Threshold');
                    % yline(tempAvg, '--', 'Color', 1/255*[1 1 1], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');

                    % xline(time(maxIDX), 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', maxText)

                    % Plot vertical line at app.Time(firstIndex), if it exists
                    if roundedOnset ~= 0
                        xline(roundedOnset, 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                    end
                    hold off;
                    title(ax2, "Processed EMG", 'FontSize', 9, 'FontWeight', 'bold');

                    % Third subplot for Raw EMG
                    ax3 = subplot(5, 1, 5, 'Parent', eventEMGBurstOnset);
                    hold on;
                    plot(app.Time, app.RawEMG.(app.Events{e})(:, trial), 'DisplayName', 'Raw EMG', 'HandleVisibility', 'off')
                    % Plot baseline rectangle for onset threshold optimization
                    % Use fill to create the shaded area
                    y_lim_ax3 = get(ax3, 'YLim');
                    ylim(ax3, [y_lim_ax3(1), y_lim_ax3(2)])
                    % Define the x and y coordinates for the filled region
                    x_fill_ax3 = [baselineStart, baselineStart + (500/1000), baselineStart + (500/1000), baselineStart];
                    y_fill_ax3 = [y_lim_ax3(1), y_lim_ax3(1), y_lim_ax3(2), y_lim_ax3(2)];
                    fill(x_fill_ax3, y_fill_ax3, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.75, 'Parent', ax3, 'HandleVisibility', 'off');
                    plot(app.Time, app.RawEMG.(app.Events{e})(:, trial), 'Color', [0, 0.4470, 0.7410], 'LineWidth', 1.25, 'HandleVisibility', 'off')

                    % Add labels and legend
                    % xlabel('Time (s)');
                    % ylabel('EMG Signal (mV)');
                    % title('Processed EMG');
                    % lgd = legend('show');
                    % lgd.FontSize = 8; % Smaller font size

                    hold on;
                    % yline(onsetThreshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25, 'DisplayName', 'Threshold');
                    % yline(tempAvg, 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');
                    % yline(onsetThreshold, 'r--', 'LineWidth', 1.25, 'DisplayName', 'Threshold');
                    % yline(tempAvg, '--', 'Color', 1/255*[1 1 1], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');

                    % xline(time(maxIDX), 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', maxText)

                    % Plot vertical line at app.Time(firstIndex), if it exists
                    if roundedOnset ~= 0
                        xline(roundedOnset, 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset');
                    end
                    hold off;
                    title(ax3, "Raw EMG", 'FontSize', 9, 'FontWeight', 'bold');

                    % Title of whole figure
                    sgtitle(OnsetPlotTitle, 'FontSize', 12, 'FontWeight', 'bold')

                    % Shared labels
                    ax2.XTick = [];
                    ax3.XTick = [];
                    l = axes(eventEMGBurstOnset, 'visible', 'off');
                    l.XLabel.Visible = 'on';
                    xlabel(l, 'Time (s)', 'FontSize', 12, 'FontWeight', 'bold');
                    l.YLabel.Visible = 'on';
                    ylabel(l, 'EMG (mV)', 'FontSize', 12, 'FontWeight', 'bold',...
                        'Position', [-0.085, 0.5, 0]);

                    % Save the figure
                    % filename_png = sprintf("%s_%s_Trial-%02d_%s_%s-Arm_EMGBurstOnset.png", app.Events{e}, app.SubID, trial, app.TimePoint, app.RecordingArm);
                    filename_png = sprintf("%s_%s_%s-Arm_%s_Trial-%02d.png",  app.SubID, app.TimePoint, app.RecordingArm, app.Events{e}(end-1:end), trial);
                    % event_short = app.Events{e}(end - 1:end);

                    if app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_LeftButton
                        app.AffectedArm = 'L';
                    elseif app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_RightButton
                        app.AffectedArm = 'R';
                    end

                    if strcmp(app.RecordingArm, app.AffectedArm)
                        if e == app.RF_IDX
                            FigDir = sprintf('%s/Figures/%s-Arm (Affected) Rest-to-Flex', app.AnalysisDir, app.RecordingArm);
                        elseif e == app.RE_IDX
                            FigDir = sprintf('%s/Figures/%s-Arm (Affected) Rest-to-Extend', app.AnalysisDir, app.RecordingArm);
                        end

                    else
                        if e == app.RF_IDX
                            FigDir = sprintf('%s/Figures/%s-Arm (Unaffected) Rest-to-Flex', app.AnalysisDir, app.RecordingArm);
                        elseif e == app.RE_IDX
                            FigDir = sprintf('%s/Figures/%s-Arm (Unaffected) Rest-to-Extend', app.AnalysisDir, app.RecordingArm);
                        end
                    end

                    if ~exist(FigDir, 'dir')
                        mkdir(FigDir);
                    end
                    saveas(eventEMGBurstOnset, fullfile(FigDir, filename_png));
                    % filename_fig = sprintf("%s_%s_Trial-%d_%s_%s-Arm_RawEMG.fig", eventName, app.SubID, trial, timepoint, affected_arm);
                    % saveas(eventRawEMG, fullfile(EE_fig_dir, filename_fig));
                    close(eventEMGBurstOnset); % comment out for sanity check
                end
                fprintf("\n")
            end

            for e = app.TargetEvents(1:2)
                if e == app.RF_IDX
                    tempData = app.TempCh1RF;
                elseif e == app.RE_IDX
                    tempData = app.TempCh2RE;
                end

                allEMG = app.SmoothedEMG.(app.Events{e});
                time = app.Time;
                [nSamples, nTrials] = size(allEMG);
                onsets = tempData(:, 4);  % Onsets in ms

                % Convert onsets from cell to numeric
                onsets_numeric = cell2mat(tempData(:, 4));  % Should be a numeric column vector

                % Find trials with valid onsets (non-zero)
                validTrials = find(onsets_numeric ~= 0);
                validEMG = allEMG(:, validTrials);
                nValid = length(validTrials);

                % Compute mean trace only across valid trials
                meanEMG = mean(validEMG, 2, 'omitnan');

                % Compute mean threshold (across valid trials)
                % avgBaseline = mean(app.BaselineStats.(app.Events{e})(1, validTrials), 'omitnan');
                % avgSD = mean(app.BaselineStats.(app.Events{e})(2, validTrials), 'omitnan');
                % avgThreshold = avgBaseline + (avgSD * app.ThresholdSize);
                % avgOnset = mean(onsets_numeric, 'omitnan') / 1000;

                % --- Create Figure ---
                figure('Name', sprintf('%s - Overlayed Trials & Mean', app.Events{e}));
                hold on;
                set(gcf, 'Color', 'w');

                % Generate distinct colors for valid trials
                colors = parula(nValid);

                % Plot valid trials
                for idx = 1:nValid
                    trial = validTrials(idx);
                    plot(time, allEMG(:, trial), 'Color', [colors(idx, :) 0.5], 'LineWidth', 0.5, 'HandleVisibility', 'off');
                end

                % Plot mean trace (bold black)
                meanLineDisplayName = sprintf('Mean Smoothed EMG (N = %i)', nValid);
                plot(time, meanEMG, 'k', 'LineWidth', 1.25, 'DisplayName', meanLineDisplayName);

                % % Plot threshold line (orange dashed)
                % yline(avgThreshold, 'Color', [0.8500 0.3250 0.0980], 'LineStyle', '--', ...
                %     'LineWidth', 1.5, 'DisplayName', 'Mean Threshold');

                % Labels, title, legend
                xlabel('Time (s)', 'FontSize', 11, 'FontWeight', 'bold');
                ylabel('EMG (mV)', 'FontSize', 11, 'FontWeight', 'bold');
                if e == app.RF_IDX
                    if strcmp(app.RecordingArm, app.AffectedArm)
                        average_plot_title = sprintf("%s %s %s-Arm (Affected) — Rest-to-Flex", app.SubID, app.TimePoint, app.RecordingArm);
                    else
                        average_plot_title = sprintf("%s %s %s-Arm (Unaffected) — Rest-to-Flex", app.SubID, app.TimePoint, app.RecordingArm);
                    end
                elseif e == app.RE_IDX
                    if strcmp(app.RecordingArm, app.AffectedArm)
                        average_plot_title = sprintf("%s %s %s-Arm (Affected) — Rest-to-Extend", app.SubID, app.TimePoint, app.RecordingArm);
                    else
                        average_plot_title = sprintf("%s %s %s-Arm (Unaffected) — Rest-to-Extend", app.SubID, app.TimePoint, app.RecordingArm);
                    end
                end
                title(average_plot_title, 'FontSize', 11, 'FontWeight', 'bold');

                legend('show', 'Location', 'northoutside', 'Orientation', 'horizontal', 'FontSize', 9);
                box on;

                % Force lower y-limit to zero
                ylim auto
                yLims = ylim;
                ylim([0, yLims(2)]);

                % xline(0, 'k-', 'HandleVisibility', 'off');
                % xline(avgOnset, 'Color', 'k', 'LineWidth', 1.25, 'DisplayName', 'Mean Onset')
                % Save figure
                if strcmp(app.RecordingArm, app.AffectedArm)
                    filename_overlay = sprintf("%s_%s_%s-Arm-Affected_All-Trials_%s.png", app.SubID, app.TimePoint, app.RecordingArm, app.Events{e}(end-1:end));
                else
                    filename_overlay = sprintf("%s_%s_%s-Arm-Unaffected_All-Trials_%s.png", app.SubID, app.TimePoint, app.RecordingArm, app.Events{e}(end-1:end));
                end

                saveas(gcf, fullfile(app.AnalysisDir, 'Figures/', filename_overlay));
                close(gcf);
            end


            fprintf("All figures saved!\n\n")
            d.Message = sprintf("All figures saved!");

            drawnow; % puts UI Figure at the forefront
            figure(app.UIFigure) % puts UI Figure at the forefront

            pause(1)
            close(d)
        end

        % Menu selected function: OpenDIGMenu
        function OpenDIGMenuSelected(app, event)
            dig
        end

        % Menu selected function: LoadxdfRawEVAfilesMenu
        function LoadxdfRawEVAfilesMenuSelected(app, event)
            process_eva
        end

        % Menu selected function: QuitMATLABMenu
        function QuitMATLABMenuSelected(app, event)
            quit
        end

        % Menu selected function: ExcelxlsxMenu
        function ExcelxlsxMenuSelected(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.BestBaselineTime2Peak)
            delete(app.BestBaselineDelay)
            app.BestBaselineTime2Peak = [];
            app.BestBaselineDelay = [];

            if isempty(app.Events)
                error("Condition file must be loaded first. Please load condition file for this patient")
            else

                if app.CurrentEvent == app.Events{app.RF_IDX}
                    if isempty(app.TempCh1RF)
                        error("You must load and analyze a condition file first in order to load an existing Excel file.")
                    else
                        ff = fixfocus;
                        % app.UIFigure.WindowButtonMotionFcn = [];
                        app.UIFigure.WindowButtonMotionFcn = [];  % Optional: clear after
                        [filename, path] = uigetfile('*.xlsx; *.xls','Select User Analyzed Excel file');
                        delete(ff)

                        % check if user canceled file selection and act accordingly
                        if filename == 0
                            return;
                        end

                        file_parts = strsplit(filename, '_');

                        if ~strcmp(file_parts{3}(1), app.RecordingArm)
                            error("Excel file does not match recording arm currently loaded and analyzed. Please load excel file for arm that matches condition file you are analyzing.")
                        else
                            excel = fullfile(path,filename);

                            inputTable = readtable(excel, ...
                                'Sheet', 'UserAnalysis', ...
                                'TextType', 'string', ...
                                'VariableNamingRule', 'preserve');

                            if strcmp(app.RecordingArm, "R")
                                target_events = contains(inputTable.(1), "Ch1RiRF");
                            elseif strcmp(app.RecordingArm, "L")
                                target_events = contains(inputTable.(1), "Ch1LeRF");
                            end

                            cleaned_table = inputTable(target_events, 2:end);

                            if size(app.TempCh1RF, 1) ~= size(cleaned_table, 1)
                                error("Size of excel file does not match data currently being analyzed")
                            else

                                % Assuming cleaned_table has exactly 18 columns
                                for i = 1:17
                                    cleaned_table.(i) = double(cleaned_table.(i));  % Convert to numeric
                                end

                                % Convert the 18th column to char (cell array of character vectors)
                                if isstring(cleaned_table.(18)) || iscategorical(cleaned_table.(18))
                                    cleaned_table.(18) = cellstr(cleaned_table.(18));
                                elseif ischar(cleaned_table.(18))
                                    cleaned_table.(18) = cellstr(string(cleaned_table.(18)));
                                end

                                tempTable = cleaned_table;
                                for i = 5:17
                                    colData = app.TempCh1RF(:, i);   % this is an N×1 cell array
                                    % If numeric, convert safely
                                    if isnumeric(colData{1})
                                        colData = cell2mat(colData);  % should give N×1 numeric
                                    end
                                    tempTable.(i) = colData;          % assign column
                                end

                                app.TempCh1RF = table2cell(tempTable);
                                app.UITable.Data = table2cell(tempTable);
                                % app.UITableCellEdit(app, [])
                            end
                        end
                    end
                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    if isempty(app.TempCh2RE)
                        error("You must load and analyze a condition file first in order to load an existing Excel file.")
                    else
                        ff = fixfocus;
                        % app.UIFigure.WindowButtonMotionFcn = [];
                        app.UIFigure.WindowButtonMotionFcn = [];  % Optional: clear after
                        [filename, path] = uigetfile('*.xlsx; *.xls','Select User Analyzed Excel file');
                        delete(ff)
                        % check if user canceled file selection and act accordingly
                        if filename == 0
                            return;
                        end

                        file_parts = strsplit(filename, '_');

                        if ~strcmp(file_parts{3}(1), app.RecordingArm)
                            error("Excel file does not match recording arm currently loaded and analyzed. Please load excel file for arm that matches condition file you are analyzing.")
                        else
                            excel = fullfile(path,filename);

                            inputTable = readtable(excel, ...
                                'Sheet', 'UserAnalysis', ...
                                'TextType', 'string', ...
                                'VariableNamingRule', 'preserve');

                            if strcmp(app.RecordingArm, "R")
                                target_events = contains(inputTable.(1), "Ch2RiRE");
                            elseif strcmp(app.RecordingArm, "L")
                                target_events = contains(inputTable.(1), "Ch2LeRE");
                            end

                            cleaned_table = inputTable(target_events, 2:end);

                            if size(app.TempCh2RE, 1) ~= size(cleaned_table, 1)
                                error("Size of excel file does not match data currently being analyzed")
                            else

                                % Assuming cleaned_table has exactly 18 columns
                                for i = 1:17
                                    cleaned_table.(i) = double(cleaned_table.(i));  % Convert to numeric
                                end

                                % Convert the 18th column to char (cell array of character vectors)
                                if isstring(cleaned_table.(18)) || iscategorical(cleaned_table.(18))
                                    cleaned_table.(18) = cellstr(cleaned_table.(18));
                                elseif ischar(cleaned_table.(18))
                                    cleaned_table.(18) = cellstr(string(cleaned_table.(18)));
                                end


                                tempTable = cleaned_table;
                                for i = 5:17
                                    colData = app.TempCh2RE(:, i);   % this is an N×1 cell array
                                    % If numeric, convert safely
                                    if isnumeric(colData{1})
                                        colData = cell2mat(colData);  % should give N×1 numeric
                                    end
                                    tempTable.(i) = colData;          % assign column
                                end

                                app.TempCh2RE = table2cell(tempTable);
                                app.UITable.Data = table2cell(tempTable);
                                % app.UITableCellEdit(app, [])
                            end
                        end
                    end
                end
            end


            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Loading Excel File',...
                'Indeterminate','on');

            close all;
            % fprintf("\nLoading file...\n")
            d.Message = sprintf("Loading file...");

            time = app.Time;

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                app.TempCh1RF = app.UITable.Data;

                % % update Override status
                % app.TempCh1RF(app.CurrentTrial, 2) = {1};
                % % update Onset
                % app.TempCh1RF(app.CurrentTrial, 4) = {onset_ms};

                for trial = 1:size(app.TempCh1RF, 1)
                    % update time-to-peak
                    peak = app.TempCh1RF{trial, 5};
                    onset_ms = app.TempCh1RF{trial, 4};

                    if onset_ms ~= 0
                    roundedOnset_s = round((onset_ms/ 1000) / 0.0005) * 0.0005;
                    roundedOnset_s = round(roundedOnset_s, 4);
                    time2peak = peak - onset_ms;
                    app.TempCh1RF(trial, 6) = {time2peak};

                    % update auc 100ms post onset
                    window100ms = ceil(0.100 * app.SampleRate) + 1; % in samples
                    onsetIDX = find(time == roundedOnset_s);
                    stop100 = onsetIDX + window100ms;

                    tempEMG = app.SmoothedEMG.(app.CurrentEvent)(:, trial);
                    antagonist_smooth = app.SmoothedEMG.(app.Events{app.RF_AntagonistIDX})(:, trial);

                    tempEMGMatrix = [time' tempEMG];
                    antagonist_temp = [time' antagonist_smooth];

                    auc100 = trapz(tempEMGMatrix(onsetIDX:stop100, 1) * 1000, tempEMGMatrix(onsetIDX:stop100, 2));
                    app.TempCh1RF(trial, 7) = {auc100};

                    baselineData = app.BaselineStats;
                    baseline = baselineData.(app.CurrentEvent)(1, trial);

                    % update mod ratio for auc 100ms post onset
                    modRatio100 = 0;
                    if auc100 > 0
                        modRatio100 = (auc100) / (baseline * 100); % per Lang and Bastian, 1999; Wagner et al., 2007
                    end
                    app.TempCh1RF(trial, 8) = {modRatio100};

                    % update auc from onset to end of block
                    auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                    app.TempCh1RF(trial, 9) = {auc3000};
                    antagonist_auc3000 = trapz(antagonist_temp(onsetIDX:end, 1) * 1000, antagonist_temp(onsetIDX:end, 2));
                    app.TempCh1RF(trial, 10) = {antagonist_auc3000};

                    duration = (time(end) * 1000) - onset_ms;
                    aucDurNorm = auc3000 / duration;
                    antagonist_aucDurNorm = antagonist_auc3000 / duration;
                    app.TempCh1RF(trial, 11) = {aucDurNorm};
                    app.TempCh1RF(trial, 12) = {antagonist_aucDurNorm};

                    aucMaxNorm = aucDurNorm / app.Ch1EMGMax;
                    antagonist_aucMaxNorm = antagonist_aucDurNorm / app.Ch2EMGMax;
                    app.TempCh1RF(trial, 13) = {aucMaxNorm};
                    app.TempCh1RF(trial, 14) = {antagonist_aucMaxNorm}; % should be channel of antagonist

                    baseline = app.BaselineStats.(app.CurrentEvent)(1, trial);
                    peak = app.TempCh1RF{trial, 16};
                    diff = peak - baseline;
                    app.TempCh1RF(trial, 15) = {baseline}; % set baseline to best 500ms window baseline
                    app.TempCh1RF(trial, 17) = {diff}; % set baseline to best 500ms window baseline

                    else
                        % change Time-to-Peak
                        app.TempCh1RF(trial, 6) = {0};

                        % change AUC and mod ratio
                        app.TempCh1RF(trial, 7) = {0};
                        app.TempCh1RF(trial, 8) = {0};
                        app.TempCh1RF(trial, 9) = {0};
                        app.TempCh1RF(trial, 10) = {0};
                        app.TempCh1RF(trial, 11) = {0};
                        app.TempCh1RF(trial, 12) = {0};
                        app.TempCh1RF(trial, 13) = {0};
                        app.TempCh1RF(trial, 14) = {0};
                        % sprintf("\nTrial %d updated!", trial)

                        baseline = app.BaselineStats.(app.CurrentEvent)(1, trial);
                        peak = app.TempCh1RF{trial, 16};
                        diff = peak - baseline;
                        app.TempCh1RF(trial, 15) = {baseline}; % set baseline to best 500ms window baseline
                        app.TempCh1RF(trial, 17) = {diff}; % set baseline to best 500ms window baseline

                    end

                    % % update comment / reason for override
                    % prompt = {'Enter comment/reason for override:'};
                    % dlgtitle = 'Override Comment';
                    % fieldsize = [1 85];
                    % definput = {''};
                    % overrideComment = inputdlg(prompt, dlgtitle, fieldsize, definput);
                    % data = load(fullfile(path, filename)); % loads data stored in condition file
                    % app.FilenameEditField.Value = filename;
                    drawnow; % puts UI Figure at the forefront
                    figure(app.UIFigure) % puts UI Figure at the forefront
                    % app.TempCh1RF(trial, end) = overrideComment;
                end

                app.UITable.Data = app.TempCh1RF;
                app.TempCh1RF = app.UITable.Data;

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                app.TempCh2RE = app.UITable.Data;

                % % update Override status
                % app.TempCh2RE(app.CurrentTrial, 2) = {1};
                % % update Onset
                % app.TempCh2RE(app.CurrentTrial, 4) = {onset_ms};

                for trial = 1:size(app.TempCh2RE, 1)
                    % update time-to-peak
                    peak = app.TempCh2RE{trial, 5};
                    onset_ms = app.TempCh2RE{trial, 4};

                    if onset_ms ~= 0
                    roundedOnset_s = round((onset_ms/ 1000) / 0.0005) * 0.0005;
                    roundedOnset_s = round(roundedOnset_s, 4);
                    time2peak = peak - onset_ms;
                    app.TempCh2RE(trial, 6) = {time2peak};

                    % update auc 100ms post onset
                    window100ms = ceil(0.100 * app.SampleRate) + 1; % in samples
                    onsetIDX = find(time == roundedOnset_s);
                    stop100 = onsetIDX + window100ms;

                    tempEMG = app.SmoothedEMG.(app.CurrentEvent)(:, trial);
                    antagonist_smooth = app.SmoothedEMG.(app.Events{app.RE_AntagonistIDX})(:, trial);

                    tempEMGMatrix = [time' tempEMG];
                    antagonist_temp = [time' antagonist_smooth];

                    auc100 = trapz(tempEMGMatrix(onsetIDX:stop100, 1) * 1000, tempEMGMatrix(onsetIDX:stop100, 2));
                    app.TempCh2RE(trial, 7) = {auc100};

                    baselineData = app.BaselineStats;
                    baseline = baselineData.(app.CurrentEvent)(1, trial);

                    % update mod ratio for auc 100ms post onset
                    modRatio100 = 0;
                    if auc100 > 0
                        modRatio100 = (auc100) / (baseline * 100); % per Lang and Bastian, 1999; Wagner et al., 2007
                    end
                    app.TempCh2RE(trial, 8) = {modRatio100};

                    % update auc from onset to end of block
                    auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                    app.TempCh2RE(trial, 9) = {auc3000};
                    antagonist_auc3000 = trapz(antagonist_temp(onsetIDX:end, 1) * 1000, antagonist_temp(onsetIDX:end, 2));
                    app.TempCh2RE(trial, 10) = {antagonist_auc3000};

                    duration = (time(end) * 1000) - onset_ms;
                    aucDurNorm = auc3000 / duration;
                    antagonist_aucDurNorm = antagonist_auc3000 / duration;
                    app.TempCh2RE(trial, 11) = {aucDurNorm};
                    app.TempCh2RE(trial, 12) = {antagonist_aucDurNorm};

                    aucMaxNorm = aucDurNorm / app.Ch2EMGMax;
                    antagonist_aucMaxNorm = antagonist_aucDurNorm / app.Ch1EMGMax; % should be channel of antagonist
                    app.TempCh2RE(trial, 13) = {aucMaxNorm};
                    app.TempCh2RE(trial, 14) = {antagonist_aucMaxNorm};

                    baseline = app.BaselineStats.(app.CurrentEvent)(1, trial);
                    peak = app.TempCh2RE{trial, 16};
                    diff = peak - baseline;
                    app.TempCh2RE(trial, 15) = {baseline}; % set baseline to best 500ms window baseline
                    app.TempCh2RE(trial, 17) = {diff}; % set baseline to best 500ms window baseline

                    else
                        % change Time-to-Peak
                        app.TempCh2RE(trial, 6) = {0};

                        % change AUC and mod ratio
                        app.TempCh2RE(trial, 7) = {0};
                        app.TempCh2RE(trial, 8) = {0};
                        app.TempCh2RE(trial, 9) = {0};
                        app.TempCh2RE(trial, 10) = {0};
                        app.TempCh2RE(trial, 11) = {0};
                        app.TempCh2RE(trial, 12) = {0};
                        app.TempCh2RE(trial, 13) = {0};
                        app.TempCh2RE(trial, 14) = {0};
                        % sprintf("\nTrial %d updated!", trial)

                        baseline = app.BaselineStats.(app.CurrentEvent)(1, trial);
                        peak = app.TempCh2RE{trial, 16};
                        diff = peak - baseline;
                        app.TempCh2RE(trial, 15) = {baseline}; % set baseline to best 500ms window baseline
                        app.TempCh2RE(trial, 17) = {diff}; % set baseline to best 500ms window baseline

                    end                    

                    % update comment / reason for override
                    % ff = fixfocus;
                    % prompt = {'Enter comment/reason for override:'};
                    % dlgtitle = 'Override Comment';
                    % fieldsize = [1.5 85];
                    % definput = {''};
                    % overrideComment = inputdlg(prompt, dlgtitle, fieldsize, definput);
                    % data = load(fullfile(path, filename)); % loads data stored in condition file
                    % app.FilenameEditField.Value = filename;
                    drawnow; % puts UI Figure at the forefront
                    figure(app.UIFigure) % puts UI Figure at the forefront
                    % app.TempCh2RE(trial, end) = overrideComment;
                end

                app.UITable.Data = app.TempCh2RE;
                app.TempCh2RE = app.UITable.Data;                

            end

            temp_onset = cell2mat(app.UITable.Data(app.CurrentTrial, 4)) / 1000;
            
            if temp_onset ~= 0
                if app.RawEMGVisibleCheckBox.Value
                    app.RawEMGOnsetLine = xline(app.RawEMGPlot, temp_onset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                end
                if app.ProcessedEMGVisibleCheckBox.Value
                    app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, temp_onset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                end
                if app.BestBaselineVisibleCheckBox.Value
                    app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, temp_onset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                end
            end

            % app.plotBestBaselineConstants();
            app.plotBestBaselineTime2Peak();
            app.formatPlots();

            pause(6)
            close(d)

            % ylim(app.BestBaselinePlot, [app.BestBaselinePlotYLim(1) app.BestBaselinePlotYLim(2)]);
            % xlim(app.BestBaselinePlot, [app.BestBaselinePlotXLim(1) app.BestBaselinePlotXLim(2)]);
        end

        % Menu selected function: MATLABmatMenu
        function MATLABmatMenuSelected(app, event)

            if isempty(app.Events)
                error("Condition file must be loaded first. Please load condition file for this patient")
            else

                if app.CurrentEvent == app.Events{app.RF_IDX}
                    if isempty(app.TempCh1RF)
                        error("You must load and analyze a condition file first in order to load an existing MAT file.")
                    else

                        ff = fixfocus;
                        % app.UIFigure.WindowButtonMotionFcn = [];
                        app.UIFigure.WindowButtonMotionFcn = [];  % Optional: clear after
                        [filename, path] = uigetfile('*.mat','Select User Analyzed MAT file');
                        delete(ff)
                        % check if user canceled file selection and act accordingly
                        if filename == 0
                            return;
                        end

                        file_parts = strsplit(filename, '_');

                        if ~strcmp(file_parts{3}(1), app.RecordingArm)
                            error("MAT file does not match recording arm currently loaded and analyzed. Please load MAT file for arm that matches condition file you are analyzing.")
                        else
                            matfile = fullfile(path,filename);

                            cleaned_table = load(matfile, 'TempCh1RF');
                            cleaned_table = cleaned_table.TempCh1RF;

                            if size(app.TempCh1RF, 1) ~= size(cleaned_table, 1)
                                error("Size of MATLAB file does not match data currently being analyzed")
                            else
                                app.TempCh1RF = cleaned_table;
                                app.UITable.Data = cleaned_table;
                                % app.UITableCellEdit(app, [])
                            end
                        end
                    end

                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    if isempty(app.TempCh2RE)
                        error("You must load and analyze a condition file first in order to load an existing MAT file.")
                    else
                        ff = fixfocus;
                        % app.UIFigure.WindowButtonMotionFcn = [];
                        app.UIFigure.WindowButtonMotionFcn = [];  % Optional: clear after
                        [filename, path] = uigetfile('*.mat','Select User Analyzed MAT file');
                        delete(ff)
                        % check if user canceled file selection and act accordingly
                        if filename == 0
                            return;
                        end

                        file_parts = strsplit(filename, '_');

                        if ~strcmp(file_parts{3}(1), app.RecordingArm)
                            error("MAT file does not match recording arm currently loaded and analyzed. Please load MAT file for arm that matches condition file you are analyzing.")
                        else
                            matfile = fullfile(path,filename);

                            cleaned_table = load(matfile, 'TempCh2RE');
                            cleaned_table = cleaned_table.TempCh2RE;

                            if size(app.TempCh2RE, 1) ~= size(cleaned_table, 1)
                                error("Size of MATLAB file does not match data currently being analyzed")
                            else
                                app.TempCh2RE = cleaned_table;
                                app.UITable.Data = cleaned_table;
                                % app.UITableCellEdit(app, [])
                            end
                            app.TempCh2RE = cleaned_table;
                            app.UITable.Data = cleaned_table;
                            % app.UITableCellEdit(app, [])
                        end
                    end
                end
            end
        end

        % Menu selected function: LoadDataTableMenu
        function LoadDataTableMenuSelected(app, event)
            
        end

        % Menu selected function: FileMenu
        function LoadDataTableMenuSelected2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataTableMenuSelected2, true);
            app.FileMenu.Text = 'File';
            app.LoadConditionsDirectoryMenu = uimenu(app.FileMenu);
            app.LoadConditionsDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConditionsDirectoryMenuSelected, true);
            app.LoadConditionsDirectoryMenu.Text = 'Load ''Conditions'' Directory';
            app.LoadxdfRawEVAfilesMenu = uimenu(app.FileMenu);
            app.LoadxdfRawEVAfilesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadxdfRawEVAfilesMenuSelected, true);
            app.LoadxdfRawEVAfilesMenu.Text = 'Load .xdf Raw EVA files';
            app.OpenDIGMenu = uimenu(app.FileMenu);
            app.OpenDIGMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenDIGMenuSelected, true);
            app.OpenDIGMenu.Visible = 'off';
            app.OpenDIGMenu.Text = 'Open DIG';
            app.LoadDataTableMenu = uimenu(app.FileMenu);
            app.LoadDataTableMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDataTableMenuSelected, true);
            app.LoadDataTableMenu.Text = 'Load Data Table';
            app.ExcelxlsxMenu = uimenu(app.LoadDataTableMenu);
            app.ExcelxlsxMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcelxlsxMenuSelected, true);
            app.ExcelxlsxMenu.Text = 'Excel (.xlsx)';
            app.MATLABmatMenu = uimenu(app.LoadDataTableMenu);
            app.MATLABmatMenu.MenuSelectedFcn = createCallbackFcn(app, @MATLABmatMenuSelected, true);
            app.MATLABmatMenu.Text = 'MATLAB (.mat)';
            app.QuitMATLABMenu = uimenu(app.FileMenu);
            app.QuitMATLABMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMATLABMenuSelected, true);
            app.QuitMATLABMenu.Text = 'Quit MATLAB';
            app.RefreshMenu = uimenu(app.UIFigure);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.LoadAnalysisInformationPanel = uipanel(app.UIFigure);
            app.LoadAnalysisInformationPanel.Title = 'Load Analysis Information';
            app.LoadDataButton = uibutton(app.LoadAnalysisInformationPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SubjectIDEditFieldLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.SubjectIDEditFieldLabel.Text = 'SubjectID';
            app.SubjectIDEditField = uieditfield(app.LoadAnalysisInformationPanel, 'text');
            app.SubjectIDEditField.CharacterLimits = [0 7];
            app.SubjectIDEditField.Placeholder = 'MAPS000';
            app.TimePointDropDownLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.TimePointDropDownLabel.Text = 'Time Point';
            app.TimePointDropDown = uidropdown(app.LoadAnalysisInformationPanel);
            app.TimePointDropDown.Items = {'–', 'T0', 'T1', 'T3', 'T6'};
            app.TimePointDropDown.Placeholder = '–';
            app.TimePointDropDown.Value = '–';
            app.FilenameDropDownLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.FilenameDropDownLabel.Text = 'Filename';
            app.FilenameDropDown = uidropdown(app.LoadAnalysisInformationPanel);
            app.FilenameDropDown.Items = {'condition-filename.mat', '–'};
            app.FilenameDropDown.ValueChangedFcn = createCallbackFcn(app, @FilenameDropDownValueChanged, true);
            app.FilenameDropDown.Placeholder = 'condition-filename.mat';
            app.FilenameDropDown.Value = 'condition-filename.mat';
            app.PareticLimbButtonGroup = uibuttongroup(app.LoadAnalysisInformationPanel);
            app.PareticLimbButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PareticLimbButtonGroupSelectionChanged, true);
            app.PareticLimbButtonGroup.Title = 'Paretic Limb';
            app.PareticLimb_LeftButton = uiradiobutton(app.PareticLimbButtonGroup);
            app.PareticLimb_LeftButton.Text = 'Left';
            app.PareticLimb_LeftButton.Value = true;
            app.PareticLimb_RightButton = uiradiobutton(app.PareticLimbButtonGroup);
            app.PareticLimb_RightButton.Text = 'Right';
            app.RecordingLimbButtonGroup = uibuttongroup(app.LoadAnalysisInformationPanel);
            app.RecordingLimbButtonGroup.Title = 'Recording Limb';
            app.RecordingLimb_LeftButton = uiradiobutton(app.RecordingLimbButtonGroup);
            app.RecordingLimb_LeftButton.Text = 'Left';
            app.RecordingLimb_LeftButton.Value = true;
            app.RecordingLimb_RightButton = uiradiobutton(app.RecordingLimbButtonGroup);
            app.RecordingLimb_RightButton.Text = 'Right';
            app.AnalyzeButton = uibutton(app.LoadAnalysisInformationPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ResultsDirectoryEditFieldLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.ResultsDirectoryEditFieldLabel.Text = 'Results Directory';
            app.ResultsDirectoryEditField = uieditfield(app.LoadAnalysisInformationPanel, 'text');
            app.ResultsDirectoryEditField.Placeholder = '/Users/username/Documents/MAPL/results-directory/';
            app.ChangeDirectoryButton = uibutton(app.LoadAnalysisInformationPanel, 'push');
            app.ChangeDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDirectoryButtonPushed, true);
            app.ChangeDirectoryButton.Text = 'Change Directory';
            app.UserInitialsEditFieldLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.UserInitialsEditFieldLabel.Text = 'User Initials';
            app.UserInitialsEditField = uieditfield(app.LoadAnalysisInformationPanel, 'text');
            app.UserInitialsEditField.CharacterLimits = [0 7];
            app.UserInitialsEditField.Placeholder = 'ABC';
            app.FigureCheckButton = uibutton(app.LoadAnalysisInformationPanel, 'push');
            app.FigureCheckButton.ButtonPushedFcn = createCallbackFcn(app, @FigureCheckButtonPushed, true);
            app.FigureCheckButton.Text = 'Figure Check';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Trial'; 'Override'; '% MVC'; 'EMG Onset (ms)'; 'Peak Time (ms)'; 'Time-to-Peak (ms)'; 'AUC (100ms)'; 'Mod Ratio (100ms)'; 'AUC (3000ms)'; 'Antagonist AUC (3000ms)'; 'AUC (Dur Norm)'; 'Antagonist AUC (Dur Norm)'; 'AUC (Max Norm)'; 'Antagonist AUC (Max Norm)'; 'Trial Baseline Avg (mV)'; 'Trial Max (mV)'; 'Max-Baseline (mV)'; 'Comments'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = [false true true true false false false false false false false false false false false false false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.ProcessedEMGPlot = uiaxes(app.Panel);
            title(app.ProcessedEMGPlot, 'Processed EMG')
            xlabel(app.ProcessedEMGPlot, 'Time (s)')
            ylabel(app.ProcessedEMGPlot, 'EMG (mV)')
            app.BestBaselinePlot = uiaxes(app.Panel);
            title(app.BestBaselinePlot, 'Smoothed EMG – Lowest Baseline Average')
            xlabel(app.BestBaselinePlot, 'Time (s)')
            ylabel(app.BestBaselinePlot, 'EMG (mV)')
            app.SetWindowPlot = uiaxes(app.Panel);
            title(app.SetWindowPlot, 'Smoothed EMG – Set Window')
            xlabel(app.SetWindowPlot, 'Time (s)')
            ylabel(app.SetWindowPlot, 'EMG (mV)')
            app.RawEMGPlot = uiaxes(app.Panel);
            title(app.RawEMGPlot, 'Raw EMG')
            xlabel(app.RawEMGPlot, 'Time (s)')
            ylabel(app.RawEMGPlot, 'EMG (mV)')
            app.mVLabel = uilabel(app.UIFigure);
            app.mVLabel.Text = 'mV';
            app.AnalysisOptionsPanel = uipanel(app.UIFigure);
            app.AnalysisOptionsPanel.Title = 'Analysis Options';
            app.SetNewOnsetButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.SetNewOnsetButton.ButtonPushedFcn = createCallbackFcn(app, @SetNewOnsetButtonPushed, true);
            app.SetNewOnsetButton.Text = 'Set New Onset';
            app.LowestBaselineButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.LowestBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @LowestBaselineButtonPushed, true);
            app.LowestBaselineButton.Text = 'Lowest Baseline';
            app.SetWindowButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.SetWindowButton.ButtonPushedFcn = createCallbackFcn(app, @SetWindowButtonPushed, true);
            app.SetWindowButton.Text = 'Set Window';
            app.OverrideButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.OverrideButton.ButtonPushedFcn = createCallbackFcn(app, @OverrideButtonPushed, true);
            app.OverrideButton.Text = 'Override';
            app.SaveResultsButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.SaveFiguresButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFiguresButton.Text = 'Save Figures';
            app.NoTrialButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.NoTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NoTrialButtonPushed, true);
            app.NoTrialButton.Text = 'No Trial';
            app.FigureVisibilityPanel = uipanel(app.UIFigure);
            app.FigureVisibilityPanel.Title = 'Figure Visibility';
            app.RawEMGVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.RawEMGVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawEMGVisibleCheckBoxValueChanged, true);
            app.RawEMGVisibleCheckBox.Text = 'Raw Plot';
            app.BestBaselineVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.BestBaselineVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @BestBaselineVisibleCheckBoxValueChanged, true);
            app.BestBaselineVisibleCheckBox.Text = 'Lowest Baseline';
            app.BestBaselineOnsetVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.BestBaselineOnsetVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @BestBaselineOnsetVisibleCheckBoxValueChanged, true);
            app.BestBaselineOnsetVisibleCheckBox.Text = 'Lowest Onset';
            app.ProcessedEMGVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.ProcessedEMGVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessedEMGVisibleCheckBoxValueChanged, true);
            app.ProcessedEMGVisibleCheckBox.Text = 'Processed Plot';
            app.SetWindowVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.SetWindowVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetWindowVisibleCheckBoxValueChanged, true);
            app.SetWindowVisibleCheckBox.Text = 'Set Window';
            app.SetWindowOnsetVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.SetWindowOnsetVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetWindowOnsetVisibleCheckBoxValueChanged, true);
            app.SetWindowOnsetVisibleCheckBox.Text = 'Set Onset';
            app.UncheckAllButton = uibutton(app.FigureVisibilityPanel, 'push');
            app.UncheckAllButton.ButtonPushedFcn = createCallbackFcn(app, @UncheckAllButtonPushed, true);
            app.UncheckAllButton.Text = 'Uncheck All';
            app.CheckAllButton = uibutton(app.FigureVisibilityPanel, 'push');
            app.CheckAllButton.ButtonPushedFcn = createCallbackFcn(app, @CheckAllButtonPushed, true);
            app.CheckAllButton.Text = 'Check All';
            app.EventTrialNavigationPanel = uipanel(app.UIFigure);
            app.EventTrialNavigationPanel.Title = 'Event & Trial Navigation';
            app.PreviousTrialButton = uibutton(app.EventTrialNavigationPanel, 'push');
            app.PreviousTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrialButtonPushed, true);
            app.PreviousTrialButton.Text = '<';
            app.NextTrialButton = uibutton(app.EventTrialNavigationPanel, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Text = '>';
            app.TrialEditField = uieditfield(app.EventTrialNavigationPanel, 'text');
            app.TrialEditField.Placeholder = '–';
            app.EventDropDown = uidropdown(app.EventTrialNavigationPanel);
            app.EventDropDown.Items = {'–', 'Item 1', 'Item 2', 'Item 3', 'Item 4'};
            app.EventDropDown.ValueChangedFcn = createCallbackFcn(app, @EventDropDownValueChanged, true);
            app.EventDropDown.Value = '–';
            app.EventDropDownLabel = uilabel(app.EventTrialNavigationPanel);
            app.EventDropDownLabel.Text = 'Event';
            app.ResetTableButton = uibutton(app.EventTrialNavigationPanel, 'push');
            app.ResetTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButtonPushed, true);
            app.ResetTableButton.Text = 'Reset Table';
            app.SmoothedChannelMaxEditFieldLabel = uilabel(app.UIFigure);
            app.SmoothedChannelMaxEditFieldLabel.Text = 'Smoothed Channel Max';
            app.SmoothedChannelMaxEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMGLAB

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end