classdef MAPS_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        LoadxdfFilesMenu                matlab.ui.container.Menu
        LoadConditionFileDirectoryMenu  matlab.ui.container.Menu
        RefreshMenu                     matlab.ui.container.Menu
        MaxEditField                    matlab.ui.control.NumericEditField
        MaxEditFieldLabel               matlab.ui.control.Label
        mVLabel                         matlab.ui.control.Label
        EventTrialNavigationPanel       matlab.ui.container.Panel
        ResetTableButton                matlab.ui.control.Button
        EventDropDownLabel              matlab.ui.control.Label
        EventDropDown                   matlab.ui.control.DropDown
        TrialEditField                  matlab.ui.control.EditField
        NextTrialButton                 matlab.ui.control.Button
        PreviousTrialButton             matlab.ui.control.Button
        FigureVisibilityPanel           matlab.ui.container.Panel
        CheckAllButton                  matlab.ui.control.Button
        UncheckAllButton                matlab.ui.control.Button
        SetWindowOnsetVisibleCheckBox   matlab.ui.control.CheckBox
        SetWindowVisibleCheckBox        matlab.ui.control.CheckBox
        ProcessedEMGVisibleCheckBox     matlab.ui.control.CheckBox
        BestBaselineOnsetVisibleCheckBox  matlab.ui.control.CheckBox
        BestBaselineVisibleCheckBox     matlab.ui.control.CheckBox
        RawEMGVisibleCheckBox           matlab.ui.control.CheckBox
        AnalysisOptionsPanel            matlab.ui.container.Panel
        NoTrialButton                   matlab.ui.control.Button
        SaveFiguresButton               matlab.ui.control.Button
        SaveResultsButton               matlab.ui.control.Button
        OverrideButton                  matlab.ui.control.Button
        SetWindowButton                 matlab.ui.control.Button
        LowestBaselineButton            matlab.ui.control.Button
        FiguresSavedText                matlab.ui.control.Label
        ResultsSavedText                matlab.ui.control.Label
        SetNewOnsetButton               matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        RawEMGPlot                      matlab.ui.control.UIAxes
        SetWindowPlot                   matlab.ui.control.UIAxes
        BestBaselinePlot                matlab.ui.control.UIAxes
        ProcessedEMGPlot                matlab.ui.control.UIAxes
        UITable                         matlab.ui.control.Table
        LoadAnalysisInformationPanel    matlab.ui.container.Panel
        ChangeDirectoryButton           matlab.ui.control.Button
        ResultsDirectoryEditField       matlab.ui.control.EditField
        ResultsDirectoryEditFieldLabel  matlab.ui.control.Label
        FilenameDropDown                matlab.ui.control.DropDown
        FilenameDropDownLabel           matlab.ui.control.Label
        AnalyzeButton                   matlab.ui.control.Button
        RecordingLimbButtonGroup        matlab.ui.container.ButtonGroup
        RecordingLimb_RightButton       matlab.ui.control.RadioButton
        RecordingLimb_LeftButton        matlab.ui.control.RadioButton
        PareticLimbButtonGroup          matlab.ui.container.ButtonGroup
        PareticLimb_RightButton         matlab.ui.control.RadioButton
        PareticLimb_LeftButton          matlab.ui.control.RadioButton
        TimePointDropDown               matlab.ui.control.DropDown
        TimePointDropDownLabel          matlab.ui.control.Label
        SubjectIDEditField              matlab.ui.control.EditField
        SubjectIDEditFieldLabel         matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        LoadingText                     matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    properties (Access = private)
        ConditionDirectory % path where condition files are stored
        SubjectParentFolder
        AnalysisDir
        ConditionInfo % information stored in condition file
        ConditionData % cell array to extract event and trial data
        Events % list of events in EVA
        CurrentEvent % current event
        SampleRate % sampling rate
        Time % time of recording
        RF_IDX % Ch1RF
        RE_IDX % Ch1RE
        RF_AntagonistIDX
        RE_AntagonistIDX
        BestBaselineSummaryDataCh1RF % data from onset detection
        BestBaselineSummaryDataCh2RE % data from onset detection
        SetWindowSummaryDataCh1RF
        SetWindowSummaryDataCh2RE
        AllDataCh1RF
        AllDataCh2RE
        BestBaselineAllDataCh1RF
        BestBaselineAllDataCh2RE
        SetWindowAllDataCh1RF
        SetWindowAllDataCh2RE
        CombinedData
        BestBaselineCombinedData
        SetWindowCombinedData
        RawEMG % formatted struct of Raw EMG timeseries data
        ProcessedEMG % bandpass- and notch-filtered; rectified EMG
        SmoothedEMG % lowpass filtered EMG
        MaxEMG
        BaselineStats % struct containing best baseline window information
        SetWindowBaselineStats
        EMGBurstOnsets
        SetWindowEMGBurstOnsets
        NoTrial
        SetWindowNoTrial
        MaxBaselineDiff
        SetWindowMaxBaselineDiff
        TargetEvents % indexes of events of interest (e.g., RF and RE)
        FormattedDate
        SubID % subject study ID
        TimePoint % recording timepoint in MAPS study (e.g., T0, T1, T3, T6)
        RecordingArm % L or R; arm being used to play game in this condition file
        AffectedArm % L or R; arm affected by stroke
        ResultsDir % where to save all the data
        TrialCount % total number of trials
        CurrentTrial % store current trial
        RawEMGxLim
        RawEMGyLim
        ProcessedEMGxLim
        ProcessedEMGyLim
        BestBaselinexLim
        BestBaselineyLim
        SetWindowxLim
        SetWindowyLim
        RawEMGOnsetLine
        ProcessedEMGOnsetLine
        BestBaselineOnsetLine
        SetWindowOnsetLine
        RawEMGOverrideOnsetLine
        % ProcessedEMGOverrideOnsetLine
        % BestBaselineOverrideOnsetLine
        % SetWindowOverrideOnsetLine
        newColumnRF
        newColumnRE
        ExcelHeaders
        Dur
        Dur2
        StartSearchTime
        ThresholdSize
        TempCh1RF
        TempCh2RE
        SetWindowTempCh1RF
        SetWindowTempCh2RE
        RawEMGMax
        ProcessedEMGMax
        BestBaselineMax
        SetWindowMax
        EMGData
        Ch1EMG
        Ch2EMG
        Ch1RecordingMax
        Ch2RecordingMax
        RecordingMax
        NewOnset
        OverrideX
        OverrideY

    end

    methods (Access = private)

        % Function to populate the results directory field in app window
        function setDefaultResultsDirectory(app)
            currentFolder = pwd;
            app.ResultsDir = currentFolder;
            app.ResultsDirectoryEditField.Value = currentFolder;
        end

        function setAppFontsToTimesNewRoman(app)
            % Get all children of the main UIFigure
            components = app.UIFigure.Children;

            % Loop through each component and set the font to Times New Roman
            for i = 1:length(components)
                try
                    % Set the FontName property if it exists
                    if isprop(components(i), 'FontName')
                        components(i).FontName = 'Times New Roman';
                    end
                catch
                    % Ignore components that don't have a FontName property
                    continue;
                end
            end
        end

        function plotBestBaselineOnsets(app)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            if app.UITable.Data{app.CurrentTrial, 3} ~= 0

                if app.CurrentEvent == app.Events{app.RF_IDX}
                    % Plot onsets
                    if (app.TempCh1RF{app.CurrentTrial, 2} == 1) && (app.TempCh1RF{app.CurrentTrial, 3} == 0)
                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        delete(app.BestBaselineOnsetLine)
                    else
                        if app.TempCh1RF{app.CurrentTrial, 3} == 0
                            delete(app.RawEMGOnsetLine)
                            delete(app.ProcessedEMGOnsetLine)
                            delete(app.BestBaselineOnsetLine)
                        else
                            if app.RawEMGVisibleCheckBox.Value
                                app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.RawEMGPlot, app.RawEMGxLim);
                                % ylim(app.RawEMGPlot, app.RawEMGyLim);
                            end
                            if app.ProcessedEMGVisibleCheckBox.Value
                                app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
                                % ylim(app.ProcessedEMGPlot, [0 app.ProcessedEMGyLim(2)]);
                            end
                            if app.BestBaselineVisibleCheckBox.Value
                                app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.BestBaselinePlot, app.BestBaselinexLim);
                                % ylim(app.BestBaselinePlot, app.BestBaselineyLim);
                            end
                        end
                    end
                    % app.resetPlotLimits();
                    app.formatPlots();

                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    % Plot onsets
                    if (app.TempCh2RE{app.CurrentTrial, 2} == 1) && (app.TempCh2RE{app.CurrentTrial, 3} == 0)
                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        delete(app.BestBaselineOnsetLine)
                    else
                        if app.TempCh2RE{app.CurrentTrial, 3} == 0
                            delete(app.RawEMGOnsetLine)
                            delete(app.ProcessedEMGOnsetLine)
                            delete(app.BestBaselineOnsetLine)
                        else
                            if app.RawEMGVisibleCheckBox.Value
                                app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.RawEMGPlot, app.RawEMGxLim);
                                % ylim(app.RawEMGPlot, app.RawEMGyLim);
                            end
                            if app.ProcessedEMGVisibleCheckBox.Value
                                app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
                                % ylim(app.ProcessedEMGPlot, [0 app.ProcessedEMGyLim(2)]);
                            end
                            if app.BestBaselineVisibleCheckBox.Value
                                app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.BestBaselinePlot, app.BestBaselinexLim);
                                % ylim(app.BestBaselinePlot, app.BestBaselineyLim);
                            end
                        end
                    end

                    % app.resetPlotLimits();
                    app.formatPlots();

                end
            else
                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
            end

        end

        function plotSetWindowConstants(app)
            clear originalXLim originalYLim
            windowStart = -2350; % ms
            windowStop = -1350; % ms

            if app.CurrentEvent == app.Events{app.RF_IDX}
                % Plot baseline average
                % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                if app.SetWindowVisibleCheckBox.Value
                    % app.plotBestBaselineWindow();

                    %% Plot shaded baseline window
                    originalXLim = xlim(app.SetWindowPlot);
                    originalYLim = ylim(app.SetWindowPlot);
                    % Plot baseline rectangle for onset threshold optimization
                    baselineData = app.SetWindowBaselineStats;
                    baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);

                    % Define the x and y coordinates for the filled region
                    window_ms = windowStop - windowStart; % leave this
                    x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) originalYLim(2); baselineStart originalYLim(2)];
                    y_fill_ax1 = [originalYLim(1), originalYLim(1), originalYLim(2), originalYLim(2)];

                    % Use fill() to create the shaded area
                    BaselineFill = fill(app.SetWindowPlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'Parent', app.SetWindowPlot);
                    % Use patch() to create the shaded area
                    % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.SetWindowPlot);

                    hold(app.SetWindowPlot,'on')

                    % Plot grey rectangle on axes
                    uistack(BaselineFill, 'bottom');
                    xlim(app.SetWindowPlot, originalXLim);
                    ylim(app.SetWindowPlot, [0 originalYLim(2)]);
                    hold(app.SetWindowPlot,'off')

                    %% Plot Baseline Threshold
                    hold(app.SetWindowPlot,'on')
                    plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                    plotYLim = ylim(app.SetWindowPlot);
                    ylim(app.SetWindowPlot, [-0.001, plotYLim(2)])
                    % yline(app.SetWindowPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    % yline(app.SetWindowPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', 'r', 'LineWidth', 1.25); % plot baseline
                    baseline = app.SetWindowBaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                    sd = app.SetWindowBaselineStats.(app.CurrentEvent)(2, app.CurrentTrial);
                    threshold = baseline + (2*sd);

                    yline(app.SetWindowPlot, threshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    hold(app.SetWindowPlot,'off')

                    %% Plot Max
                    maxTime = app.Time(app.MaxEMG.(app.CurrentEvent)(1, app.CurrentTrial));
                    maxValue = app.MaxEMG.(app.CurrentEvent)(2, app.CurrentTrial);
                    % app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    % app.MaxEMG.(events{e})(2, trial) = maxEMG;
                    % hold(app.RawEMGPlot,'on')
                    % app.RawEMGMax = plot(app.RawEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.RawEMGPlot,'off')
                    %
                    % hold(app.ProcessedEMGPlot,'on')
                    % app.ProcessedEMGMax = plot(app.ProcessedEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.ProcessedEMGPlot,'off')

                    hold(app.SetWindowPlot,'on')
                    app.BestBaselineMax = plot(app.SetWindowPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', 'r');
                    hold(app.SetWindowPlot,'off')

                end

                % app.resetPlotLimits();
                % app.plotBestBaselineOnsets();
                app.formatPlots();

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                % Plot baseline average
                % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                if app.SetWindowVisibleCheckBox.Value
                    % app.plotBestBaselineWindow();

                    originalXLim = xlim(app.SetWindowPlot);
                    originalYLim = ylim(app.SetWindowPlot);
                    % Plot baseline rectangle for onset threshold optimization
                    baselineData = app.SetWindowBaselineStats;
                    baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);

                    % Define the x and y coordinates for the filled region
                    window_ms = windowStop - windowStart; % leave this
                    x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) originalYLim(2); baselineStart originalYLim(2)];
                    y_fill_ax1 = [originalYLim(1), originalYLim(1), originalYLim(2), originalYLim(2)];

                    % Use fill() to create the shaded area
                    BaselineFill = fill(app.SetWindowPlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'Parent', app.SetWindowPlot);
                    % Use patch() to create the shaded area
                    % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.SetWindowPlot);

                    hold(app.SetWindowPlot,'on')

                    % Plot grey rectangle on axes
                    uistack(BaselineFill, 'bottom');

                    xlim(app.SetWindowPlot, originalXLim);
                    ylim(app.SetWindowPlot, [0 originalYLim(2)]);

                    hold(app.SetWindowPlot,'off')

                    hold(app.SetWindowPlot,'on')
                    plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                    plotYLim = ylim(app.SetWindowPlot);
                    ylim(app.SetWindowPlot, [-0.001, plotYLim(2)])
                    % yline(app.SetWindowPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    % yline(app.SetWindowPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', 'r', 'LineWidth', 1.25); % plot baseline
                    baseline = app.SetWindowBaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                    sd = app.SetWindowBaselineStats.(app.CurrentEvent)(2, app.CurrentTrial);
                    threshold = baseline + (2*sd);

                    yline(app.SetWindowPlot, threshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline

                    hold(app.SetWindowPlot,'off')

                    maxTime = app.Time(app.MaxEMG.(app.CurrentEvent)(1, app.CurrentTrial));
                    maxValue = app.MaxEMG.(app.CurrentEvent)(2, app.CurrentTrial);
                    % app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    % app.MaxEMG.(events{e})(2, trial) = maxEMG;
                    % hold(app.RawEMGPlot,'on')
                    % app.RawEMGMax = plot(app.RawEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.RawEMGPlot,'off')
                    %
                    % hold(app.ProcessedEMGPlot,'on')
                    % app.ProcessedEMGMax = plot(app.ProcessedEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.ProcessedEMGPlot,'off')

                    hold(app.SetWindowPlot,'on')
                    app.BestBaselineMax = plot(app.SetWindowPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', 'r');
                    hold(app.SetWindowPlot,'off')
                end

                % app.resetPlotLimits();
                % app.plotBestBaselineOnsets();
                app.formatPlots();

            end
        end

        function plotBestBaselineConstants(app)

            % clear originalXLim originalYLim
            if app.CurrentEvent == app.Events{app.RF_IDX}
                % Plot baseline average
                % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                if app.BestBaselineVisibleCheckBox.Value
                    % app.plotBestBaselineWindow();

                    %% Plot shaded baseline window
                    originalXLim = xlim(app.BestBaselinePlot);
                    originalYLim = ylim(app.BestBaselinePlot);
                    % Plot baseline rectangle for onset threshold optimization
                    baselineData = app.BaselineStats;
                    baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);

                    % Define the x and y coordinates for the filled region
                    window_ms = 500;
                    x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) originalYLim(2); baselineStart originalYLim(2)];
                    y_fill_ax1 = [originalYLim(1), originalYLim(1), originalYLim(2), originalYLim(2)];

                    % Use fill() to create the shaded area
                    BaselineFill = fill(app.BestBaselinePlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'Parent', app.BestBaselinePlot);
                    % Use patch() to create the shaded area
                    % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.BestBaselinePlot);

                    hold(app.BestBaselinePlot,'on')

                    % Plot grey rectangle on axes
                    uistack(BaselineFill, 'bottom');
                    xlim(app.BestBaselinePlot, originalXLim);
                    ylim(app.BestBaselinePlot, [0 originalYLim(2)]);
                    hold(app.BestBaselinePlot,'off')

                    %% Plot Baseline Threshold
                    hold(app.BestBaselinePlot,'on')
                    plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                    plotYLim = ylim(app.BestBaselinePlot);
                    ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    % yline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    % yline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', 'r', 'LineWidth', 1.25); % plot baseline
                    baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                    sd = app.BaselineStats.(app.CurrentEvent)(2, app.CurrentTrial);
                    threshold = baseline + (2*sd);

                    yline(app.BestBaselinePlot, threshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    hold(app.BestBaselinePlot,'off')

                    %% Plot Max
                    maxTime = app.Time(app.MaxEMG.(app.CurrentEvent)(1, app.CurrentTrial));
                    maxValue = app.MaxEMG.(app.CurrentEvent)(2, app.CurrentTrial);
                    % app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    % app.MaxEMG.(events{e})(2, trial) = maxEMG;
                    % hold(app.RawEMGPlot,'on')
                    % app.RawEMGMax = plot(app.RawEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.RawEMGPlot,'off')
                    %
                    % hold(app.ProcessedEMGPlot,'on')
                    % app.ProcessedEMGMax = plot(app.ProcessedEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.ProcessedEMGPlot,'off')

                    hold(app.BestBaselinePlot,'on')
                    app.BestBaselineMax = plot(app.BestBaselinePlot, maxTime, maxValue, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', 'r');
                    hold(app.BestBaselinePlot,'off')

                end

                % app.resetPlotLimits();
                % app.plotBestBaselineOnsets();
                % app.formatPlots();

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                % Plot baseline average
                % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
                if app.BestBaselineVisibleCheckBox.Value
                    % app.plotBestBaselineWindow();

                    originalXLim = xlim(app.BestBaselinePlot);
                    originalYLim = ylim(app.BestBaselinePlot);
                    % Plot baseline rectangle for onset threshold optimization
                    baselineData = app.BaselineStats;
                    baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);

                    % Define the x and y coordinates for the filled region
                    window_ms = 500;
                    x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) originalYLim(2); baselineStart originalYLim(2)];
                    y_fill_ax1 = [originalYLim(1), originalYLim(1), originalYLim(2), originalYLim(2)];

                    % Use fill() to create the shaded area
                    BaselineFill = fill(app.BestBaselinePlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'Parent', app.BestBaselinePlot);
                    % Use patch() to create the shaded area
                    % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.BestBaselinePlot);

                    hold(app.BestBaselinePlot,'on')

                    % Plot grey rectangle on axes
                    uistack(BaselineFill, 'bottom');

                    xlim(app.BestBaselinePlot, originalXLim);
                    ylim(app.BestBaselinePlot, [0 originalYLim(2)]);

                    hold(app.BestBaselinePlot,'off')

                    hold(app.BestBaselinePlot,'on')
                    plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                    plotYLim = ylim(app.BestBaselinePlot);
                    ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    % yline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline
                    % yline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 11}, 'LineStyle', '-.', 'Color', 'r', 'LineWidth', 1.25); % plot baseline
                    baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                    sd = app.BaselineStats.(app.CurrentEvent)(2, app.CurrentTrial);
                    threshold = baseline + (2*sd);

                    yline(app.BestBaselinePlot, threshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25); % plot baseline

                    hold(app.BestBaselinePlot,'off')

                    maxTime = app.Time(app.MaxEMG.(app.CurrentEvent)(1, app.CurrentTrial));
                    maxValue = app.MaxEMG.(app.CurrentEvent)(2, app.CurrentTrial);
                    % app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    % app.MaxEMG.(events{e})(2, trial) = maxEMG;
                    % hold(app.RawEMGPlot,'on')
                    % app.RawEMGMax = plot(app.RawEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.RawEMGPlot,'off')
                    %
                    % hold(app.ProcessedEMGPlot,'on')
                    % app.ProcessedEMGMax = plot(app.ProcessedEMGPlot, maxTime, maxValue, 'ro', 'MarkerSize', 4);
                    % hold(app.ProcessedEMGPlot,'off')

                    hold(app.BestBaselinePlot,'on')
                    app.BestBaselineMax = plot(app.BestBaselinePlot, maxTime, maxValue, 'ro', 'MarkerSize', 4.5, 'MarkerFaceColor', 'r');
                    hold(app.BestBaselinePlot,'off')
                end

                % app.resetPlotLimits();
                % app.plotBestBaselineOnsets();
                % app.formatPlots();

            end
        end

        function plotBestBaselineWindow(app)
            % Plot baseline average
            % yline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
            % yline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 12}, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25);
            if app.BestBaselineVisibleCheckBox.Value
                originalXLim = xlim(app.BestBaselinePlot);
                originalYLim = ylim(app.BestBaselinePlot);
                % Plot baseline rectangle for onset threshold optimization
                baselineData = app.BaselineStats;
                baselineStart = baselineData.(app.CurrentEvent)(3, app.CurrentTrial);

                % Define the x and y coordinates for the filled region
                window_ms = 500;
                x_fill_ax1 = [baselineStart originalYLim(1); baselineStart + (window_ms/1000) originalYLim(1); baselineStart + (window_ms/1000) originalYLim(2); baselineStart originalYLim(2)];
                y_fill_ax1 = [originalYLim(1), originalYLim(1), originalYLim(2), originalYLim(2)];

                % Use fill() to create the shaded area
                BaselineFill = fill(app.BestBaselinePlot, x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'Parent', app.BestBaselinePlot);
                % Use patch() to create the shaded area
                % BaselineFill = patch('Faces', [1 2 3 4], 'Vertices', v, 'FaceColor', [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.15, 'Parent', app.BestBaselinePlot);

                hold(app.BestBaselinePlot,'on')

                % Plot grey rectangle on axes
                uistack(BaselineFill, 'bottom');

                xlim(app.BestBaselinePlot, originalXLim);
                ylim(app.BestBaselinePlot, [0 originalYLim(2)]);

                hold(app.BestBaselinePlot,'off')

            end
        end

        function resetPlotLimits(app)
            % xlim(app.RawEMGPlot, app.RawEMGxLim);
            % ylim(app.RawEMGPlot, app.RawEMGyLim);
            % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
            % ylim(app.ProcessedEMGPlot, app.ProcessedEMGyLim);
            % xlim(app.BestBaselinePlot, app.BestBaselinexLim);
            % ylim(app.BestBaselinePlot, app.BestBaselineyLim);
        end

        function formatPlots(app)
            title(app.RawEMGPlot, 'Raw EMG')
            xlabel(app.RawEMGPlot, 'Time (s)')
            ylabel(app.RawEMGPlot, 'EMG (mV)')
            app.RawEMGPlot.FontName = 'Times New Roman';
            app.RawEMGPlot.TickLength = [0.005 0.025];
            app.RawEMGPlot.XMinorTick = 'on';
            app.RawEMGPlot.YMinorTick = 'on';
            app.RawEMGPlot.FontSize = 14;

            title(app.ProcessedEMGPlot, 'Processed EMG')
            xlabel(app.ProcessedEMGPlot, 'Time (s)')
            ylabel(app.ProcessedEMGPlot, 'EMG (mV)')
            app.ProcessedEMGPlot.FontName = 'Times New Roman';
            app.ProcessedEMGPlot.TickLength = [0.005 0.025];
            app.ProcessedEMGPlot.XMinorTick = 'on';
            app.ProcessedEMGPlot.YMinorTick = 'on';
            app.ProcessedEMGPlot.FontSize = 14;

            title(app.BestBaselinePlot, 'Smoothed EMG – Lowest Baseline Average')
            xlabel(app.BestBaselinePlot, 'Time (s)')
            ylabel(app.BestBaselinePlot, 'EMG (mV)')
            app.BestBaselinePlot.FontName = 'Times New Roman';
            app.BestBaselinePlot.TickLength = [0.005 0.025];
            app.BestBaselinePlot.XMinorTick = 'on';
            app.BestBaselinePlot.YMinorTick = 'on';
            app.BestBaselinePlot.FontSize = 14;

            title(app.SetWindowPlot, 'Smoothed EMG – Set Window')
            xlabel(app.SetWindowPlot, 'Time (s)')
            ylabel(app.SetWindowPlot, 'EMG (mV)')
            app.SetWindowPlot.FontName = 'Times New Roman';
            app.SetWindowPlot.TickLength = [0.005 0.025];
            app.SetWindowPlot.XMinorTick = 'on';
            app.SetWindowPlot.YMinorTick = 'on';
            app.SetWindowPlot.FontSize = 14;
        end

        function plotSetWindowOnsets(app)
            % delete(app.RawEMGOnsetLine)
            % delete(app.ProcessedEMGOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.UITable.Data{app.CurrentTrial, 3} ~= 0
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    % Plot onsets
                    if (app.SetWindowTempCh1RF{app.CurrentTrial, 2} == 1) && (app.SetWindowTempCh1RF{app.CurrentTrial, 3} == 0)
                        % delete(app.RawEMGOnsetLine)
                        % delete(app.ProcessedEMGOnsetLine)
                        delete(app.SetWindowOnsetLine)
                    else
                        if app.SetWindowTempCh1RF{app.CurrentTrial, 3} == 0
                            % delete(app.RawEMGOnsetLine)
                            % delete(app.ProcessedEMGOnsetLine)
                            % delete(app.BestBaselineOnsetLine)
                        else
                            % if app.RawEMGVisibleCheckBox.Value
                            %     app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.SetWindowTempCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            %     % xlim(app.RawEMGPlot, app.RawEMGxLim);
                            %     % ylim(app.RawEMGPlot, app.RawEMGyLim);
                            % end
                            % if app.ProcessedEMGVisibleCheckBox.Value
                            %     app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.SetWindowTempCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            %     % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
                            %     % ylim(app.ProcessedEMGPlot, [0 app.ProcessedEMGyLim(2)]);
                            % end
                            if app.SetWindowOnsetVisibleCheckBox.Value
                                app.SetWindowOnsetLine = xline(app.SetWindowPlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.BestBaselinePlot, app.BestBaselinexLim);
                                % ylim(app.BestBaselinePlot, app.BestBaselineyLim);
                            end
                        end
                    end
                    % app.resetPlotLimits();
                    app.formatPlots();

                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    % Plot onsets
                    if (app.SetWindowTempCh2RE{app.CurrentTrial, 2} == 1) && (app.SetWindowTempCh2RE{app.CurrentTrial, 3} == 0)
                        % delete(app.RawEMGOnsetLine)
                        % delete(app.ProcessedEMGOnsetLine)
                        delete(app.SetWindowOnsetLine)
                    else
                        if app.SetWindowTempCh2RE{app.CurrentTrial, 3} == 0
                            % delete(app.RawEMGOnsetLine)
                            % delete(app.ProcessedEMGOnsetLine)
                            % delete(app.BestBaselineOnsetLine)
                        else
                            % if app.RawEMGVisibleCheckBox.Value
                            %     app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.SetWindowTempCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            %     % xlim(app.RawEMGPlot, app.RawEMGxLim);
                            %     % ylim(app.RawEMGPlot, app.RawEMGyLim);
                            % end
                            % if app.ProcessedEMGVisibleCheckBox.Value
                            %     app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.SetWindowTempCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            %     % xlim(app.ProcessedEMGPlot, app.ProcessedEMGxLim);
                            %     % ylim(app.ProcessedEMGPlot, [0 app.ProcessedEMGyLim(2)]);
                            % end
                            if app.SetWindowOnsetVisibleCheckBox.Value
                                app.SetWindowOnsetLine = xline(app.SetWindowPlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                                % xlim(app.BestBaselinePlot, app.BestBaselinexLim);
                                % ylim(app.BestBaselinePlot, app.BestBaselineyLim);
                            end
                        end
                    end

                    % app.resetPlotLimits();
                    app.formatPlots();

                end
            else
                delete(app.SetWindowOnsetLine);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.setDefaultResultsDirectory();
            app.setAppFontsToTimesNewRoman();
            app.AnalyzeButton.Visible = 'off';
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SetWindowButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';
            app.LoadDataButton.Visible = 'off';
            % app.AnalysisOptionsPanel.Visible = 'off';
            app.UITable.SelectionType = 'row';
            app.LoadingText.Visible = 'off';
            app.ResultsSavedText.Visible = 'off';
            app.FiguresSavedText.Visible = 'off';

            % app.setAppFontsToTimesNewRoman();;

            app.UITable.Data = [];
            app.BestBaselineSummaryDataCh1RF = [];
            app.BestBaselineSummaryDataCh2RE = [];
            app.AllDataCh1RF = [];
            app.AllDataCh2RE = [];
            app.TempCh1RF = [];
            app.TempCh2RE = [];

            close all;
            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();

            app.Dur = 0.030;
            app.Dur2 = 0.070;
            app.StartSearchTime = -1.250;
            app.ThresholdSize = 2;

            % Set up figure properties:
            % Enlarge figure to full screen.
            % set(gcf, 'Units', 'Normalized', 'OuterPosition', [0, 0.04, 1, 0.96]);
            % Get rid of tool bar and pulldown menus that are along top of figure.
            % set(gcf, 'Toolbar', 'none', 'Menu', 'none');
            % Give a name to the title bar.
            set(app.UIFigure, 'Name', 'Movement and Plasticity Lab – EMG Lab', 'NumberTitle', 'off')

        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            app.startupFcn();
            % restore app defaults
            app.ResultsDirectoryEditField.Value = app.ResultsDirectoryEditField.Placeholder;
            app.SubjectIDEditField.Value = app.SubjectIDEditField.Placeholder;
            app.TimePointDropDown.Value = app.TimePointDropDown.Placeholder;
            app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
            app.PareticLimbButtonGroup.SelectedObject = app.PareticLimb_LeftButton;

            app.UITable.Data = [];
            app.BestBaselineSummaryDataCh1RF = [];
            app.BestBaselineSummaryDataCh2RE = [];
            app.AllDataCh1RF = [];
            app.AllDataCh2RE = [];
            app.TempCh1RF = [];
            app.TempCh2RE = [];

            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();

            app.EventDropDown.Items = {'–' 'Items 1' 'Items 2' 'Items 3' 'Items 4'}; % Clear the dropdown items
            app.EventDropDown.Value = '–'; % Clear the selected value
            app.FilenameDropDown.Items = {'condition-filename.mat' '–'}; % Clear the dropdown items
            app.FilenameDropDown.Value = '–'; % Clear the selected value
            % app.EventDropDown.Value = app.EventDropDown.Placeholder;
            app.TrialEditField.Value = app.TrialEditField.Placeholder;
            app.MaxEditField.Value = 0;

            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.AnalyzeButton.Visible = 'off';
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SetWindowButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';
            % app.AnalysisOptionsPanel.Visible = 'off';
            app.LoadingText.Visible = 'off';
            app.ResultsSavedText.Visible = 'off';
            app.FiguresSavedText.Visible = 'off';
            app.LoadDataButton.Visible = 'off';

            app.CurrentTrial = 0;
        end

        % Menu selected function: LoadxdfFilesMenu
        function LoadxdfFilesMenuSelected(app, event)

        end

        % Menu selected function: LoadConditionFileDirectoryMenu
        function LoadConditionFileDirectoryMenuSelected(app, event)
            app.RefreshMenuSelected;

            app.LoadDataButton.Visible = 'off';
            app.AnalyzeButton.Visible = 'off';

            % app.setDefaultResultsDirectory();

            ff = fixfocus;
            app.ConditionDirectory = uigetdir(); % load condition file extracted from DIG
            delete(ff);
            % data = load(fullfile(path, filename)); % loads data stored in condition file
            % app.FilenameEditField.Value = filename;
            drawnow; % puts UI Figure at the forefront
            figure(app.UIFigure) % puts UI Figure at the forefront

            files = dir(fullfile(app.ConditionDirectory, "*.mat"));

            % Check if the directory contains any .mat files
            if isempty(files)
                error("Selected Condition File Directory does not contain any .mat files. Select another folder.")
            else
                app.FilenameDropDown.Items = ['–' {files.name}];
                app.FilenameDropDown.Value = app.FilenameDropDown.Items(2);
            end


            % Get the previous folder directory
            [app.SubjectParentFolder, ~] = fileparts(app.ConditionDirectory);
            % [app.SubjectGrandparentFolder, ~] = fileparts(app.SubjectParentFolder);
            app.ResultsDir = app.SubjectParentFolder;
            app.ResultsDirectoryEditField.Value = app.SubjectParentFolder;
            app.AnalysisDir = sprintf('%s/%s_%s_EMG-Burst-Onset_%s', app.ResultsDir, app.TimePoint, app.SubID, app.FormattedDate);

            % Get today's date
            today = datetime('today');
            app.FormattedDate = datestr(today, 'yyyymmdd'); %#ok<DATST> % format date
            app.FormattedDate = char(app.FormattedDate); % convert to string

            app.LoadDataButton.Visible = 'on';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)

            app.UITable.Data = [];
            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();

            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SetWindowButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';

            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.SubjectIDEditField.Value = app.SubjectIDEditField.Placeholder;
            app.TimePointDropDown.Value = app.TimePointDropDown.Placeholder;
            app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
            app.PareticLimbButtonGroup.SelectedObject = app.PareticLimb_LeftButton;
            app.TrialEditField.Value = app.TrialEditField.Placeholder;
            app.MaxEditField.Value = 0;
            app.LoadingText.Visible = 'off';

            filename = app.FilenameDropDown.Value;
            path = app.ConditionDirectory;
            data = load(fullfile(path, filename)); % loads data stored in condition file

            if ~isempty(filename) % extract information from condition file loaded
                pattern = "^[a-zA-Z0-9]+_[T0|T1|T3|T6]+_[LR]\.mat$"; % Define the pattern
                isMatch = ~isempty(regexp(filename, pattern, 'once'));

                if isMatch
                    parts = split(filename, "_");
                    app.SubjectIDEditField.Value = parts{1}; % MAPS###
                    app.SubID = parts{1}; % MAPS###
                    app.TimePointDropDown.Value = parts{2}; % T0, T1, T3, T6
                    app.TimePoint = parts{2}; % T0, T1, T3, T6

                    recordingArmTemp = parts{3}; % L or R
                    recordingArmTempSplitList = split(recordingArmTemp, ".");
                    app.RecordingArm = recordingArmTempSplitList{1};

                    % auto-populate user input fields with information from
                    % condition file
                    if strcmp(app.RecordingArm, 'L')
                        app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
                    elseif strcmp(app.RecordingArm, 'R')
                        app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_RightButton;
                    else
                        app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton; % leave left as default
                    end
                    % else
                    %     app.startupFcn();
                    %
                    %     % restore app defaults
                    %     % app.ResultsDirectoryEditField.Value = app.ResultsDirectoryEditField.Placeholder;
                    %     % app.FilenameEditField.Value = app.FilenameEditField.Placeholder;
                    %     app.SubjectIDEditField.Value = app.SubjectIDEditField.Placeholder;
                    %     app.TimePointDropDown.Value = app.TimePointDropDown.Placeholder;
                    %     app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
                    %     app.PareticLimbButtonGroup.SelectedObject = app.PareticLimb_LeftButton;
                    %     app.EventDropDown.Value = app.EventDropDown.Placeholder;
                    %     app.TrialEditField.Value = app.TrialEditField.Placeholder;
                    %     app.UITable.Data = [];
                    %     cla(app.RawEMGPlot, 'reset');
                    %     cla(app.ProcessedEMGPlot, 'reset');
                    %     cla(app.SetWindowPlot, 'reset');
                    %     cla(app.BestBaselinePlot, 'reset');
                end

            end


            % ConditionInfo is stored in the condition files (.mat) acquired from DIG
            app.ConditionInfo = data.ConditionInfo;
            app.ConditionData = data.ConditionData;
            app.Events = app.ConditionInfo(:, 2);
            app.SampleRate = data.sampleRate;
            app.Time = data.time;
            app.RawEMG = mat2struct(app.Events, app.ConditionData);
            app.EMGData = data.EMG_Data;
            app.Ch1EMG = app.EMGData(1, :) * 1000;
            app.Ch2EMG = app.EMGData(2, :) * 1000;

            % app.LoadDataButton.Visible = 'on';

            if strcmp(app.RecordingArm, 'L')
                eventRF = find(strcmp(app.Events, 'Ch1LeRF'));
                eventRE = find(strcmp(app.Events, 'Ch2LeRE'));
                eventRFCh2 = find(strcmp(app.Events, 'Ch2LeRF'));
                eventRECh1 = find(strcmp(app.Events, 'Ch1LeRE'));
            else
                eventRF = find(strcmp(app.Events, 'Ch1RiRF'));
                eventRE = find(strcmp(app.Events, 'Ch2RiRE'));
                eventRFCh2 = find(strcmp(app.Events, 'Ch2RiRF'));
                eventRECh1 = find(strcmp(app.Events, 'Ch1RiRE'));
            end

            app.RF_IDX = eventRF; % default = 8
            app.RE_IDX = eventRE; % default = 16
            app.RF_AntagonistIDX = eventRFCh2;
            app.RE_AntagonistIDX = eventRECh1;
            app.TargetEvents = [app.RF_IDX app.RE_IDX app.RF_AntagonistIDX app.RE_AntagonistIDX];

            if app.PareticLimbButtonGroup.SelectedObject == app.RecordingLimb_LeftButton
                app.AffectedArm = 'L';
            elseif app.PareticLimbButtonGroup.SelectedObject == app.RecordingLimb_RightButton
                app.AffectedArm = 'R';
            end

            % % set variables once button is pushed
            % % initials = app.InitialsEditField.Value;
            % app.SubID = app.SubjectIDEditField.Value;
            % app.TimePoint = app.TimePointDropDown.Value;
            % recordingArm = app.RecordingLimbButtonGroup.SelectedObject.Text;
            % affectedArm = app.PareticLimbButtonGroup.SelectedObject.Text;

            app.AnalyzeButton.Visible = 'on';

            % % app.CurrentTrial = 1;
            % % trialText = sprintf("Trial %02d", app.CurrentTrial);
            % % app.TrialEditField.Value = trialText;
            %
            % if strcmp(recordingArm, 'Left')
            %     app.RecordingArm = 'L';
            % elseif strcmp(recordingArm, 'Right')
            %     app.RecordingArm = 'R';
            % else
            %     error("Unable to identify recording arm. Select option under User Inputs")
            % end
            %
            % if strcmp(affectedArm, 'Left')
            %     app.AffectedArm = 'L';
            % elseif strcmp(recordingArm, 'Right')
            %     app.AffectedArm = 'R';
            % else
            %     error("Unable to identify affected arm. Select option under User Inputs")
            % end

            % update events list menu items
            if ~isempty(app.Events) % Check if app.Events is not empty
                % app.EventDropDown.Items = app.Events; % Populate the dropdown with the values from app.Events
                app.EventDropDown.Items = app.Events(app.TargetEvents(1:2)); % Populate the dropdown with the values from app.Events
                app.EventDropDown.Items = ['–' app.EventDropDown.Items];
                app.EventDropDown.Value = app.EventDropDown.Items(1);
            else
                % If app.Events is empty, clear the dropdown
                app.EventDropDown.Items = {}; % Clear the dropdown items
                app.EventDropDown.Value = ''; % Clear the selected value
            end

        end

        % Value changed function: FilenameDropDown
        function FilenameDropDownValueChanged(app, event)

            app.AnalyzeButton.Visible = 'off';
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SetWindowButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';
            app.LoadingText.Visible = 'off';
            app.ResultsSavedText.Visible = 'off';
            app.LoadDataButton.Visible = 'on';

            % app.AnalysisOptionsPanel.Visible = 'off';

            % restore app defaults
            app.SubjectIDEditField.Value = app.SubjectIDEditField.Placeholder;
            app.TimePointDropDown.Value = app.TimePointDropDown.Placeholder;
            app.RecordingLimbButtonGroup.SelectedObject = app.RecordingLimb_LeftButton;
            app.PareticLimbButtonGroup.SelectedObject = app.PareticLimb_LeftButton;
            app.EventDropDown.Items = {'–' 'Items 1' 'Items 2' 'Items 3' 'Items 4'}; % Clear the dropdown items
            app.EventDropDown.Value = '–'; % Clear the selected value

            app.CurrentTrial = 0;
            app.TrialEditField.Value = app.TrialEditField.Placeholder;
            app.MaxEditField.Value = 0;
            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.UITable.Data = [];
            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();
        end

        % Button pushed function: ChangeDirectoryButton
        function ChangeDirectoryButtonPushed(app, event)
            ff = fixfocus;
            app.ResultsDir = uigetdir();
            delete(ff);

            app.ResultsDirectoryEditField.Value = app.ResultsDir;

            drawnow; % puts UI Figure at the forefront
            figure(app.UIFigure) % puts UI Figure at the forefront
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % reset window fields
            app.UITable.Data = [];
            app.BestBaselineSummaryDataCh1RF = [];
            app.BestBaselineSummaryDataCh2RE = [];
            app.AllDataCh1RF = [];
            app.AllDataCh2RE = [];
            app.TempCh1RF = [];
            app.TempCh2RE = [];

            cla(app.RawEMGPlot, 'reset');
            cla(app.ProcessedEMGPlot, 'reset');
            cla(app.SetWindowPlot, 'reset');
            cla(app.BestBaselinePlot, 'reset');
            app.formatPlots();

            app.SetWindowButton.Visible = 'off';
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'off';
            app.SaveResultsButton.Visible = 'off';
            app.SaveFiguresButton.Visible = 'off';
            app.NoTrialButton.Visible = 'off';

            app.TrialEditField.Value = app.TrialEditField.Placeholder;
            app.MaxEditField.Value = 0;
            app.EventDropDown.Value = '–'; % Clear the selected value
            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            pause(0.5)
            app.AnalyzeButton.Visible = "off";
            pause(0.5)
            app.LoadingText.Visible = 'on';
            pause(.5);

            % subject = app.SubID;
            % affected_arm = app.AffectedArm;
            % timepoint = app.TimePoint;
            % recording_arm = app.RecordingArm;
            % ConditionData = app.ConditionData;
            events = app.Events;
            time = app.Time;
            sampleRate = app.SampleRate;
            target_eventIDX = app.TargetEvents;

            if strcmp(app.FilenameDropDown.Value, app.FilenameDropDown.Placeholder)
                error("Filename not selected. Please select filename from drop down and Load Data again.")
            elseif strcmp(app.SubjectIDEditField.Value, app.SubjectIDEditField.Placeholder)
                error("Subject ID not entered. Please enter subject ID and Load Data again.")
            elseif strcmp(app.TimePointDropDown.Value, app.TimePointDropDown.Placeholder)
                error("Time point not selected. Please select time point from drop down and Load Data again.")
            end

            %% CHANGE THESE
            % Define baseline window size and step size
            % sr * window (s) = # of samples
            window_ms = 500; % ms
            step_ms = 50; % ms
            startSearchTime = -1.250;

            windowStart = -2350; % ms
            windowStop = -1350; % ms
            windowSize = windowStop - windowStart; % leave this

            aFq = 10; % lower bound of filter (Hz)
            bFq = 500; % upper bound of filter (Hz)
            envelope_lowpassHz = 20; % low-pass filter cut-off for EMG envelope

            thresholdSize = 2; % how many SDs above the baseline mean to determine onset threshold
            dur = 0.030; % s; duration signal needs to be held 2SD above baseline average to determine onset
            dur2 = 0.070; %s; subsequent duration signal needs to be held 1SD above baseline average to confirm onset

            % Get today's date
            today = datetime('today');
            app.FormattedDate = datestr(today, 'yyyymmdd'); %#ok<DATST> % format date
            app.FormattedDate = char(app.FormattedDate); % convert to string

            %% ---------------------------------------------------------------------%%
            %% Pre-Processing
            %% ---------------------------------------------------------------------%%
            %% Bandpass Filter & Rectify
            fprintf("\nApplying Bandpass Filter and Rectifying EMG signal...\n")
            % [ProcessedEMG, events_processed] = preprocess_emg(ConditionData, events, [aFq bFq], sampleRate, 'butter');
            [ProcessedEMG2] = preprocess_emg_app(app.ConditionData, events, [aFq bFq], sampleRate, 'butter');

            nyq_freq = sampleRate / 2;
            [b2, a2] = butter(4, [(aFq/nyq_freq) (bFq/nyq_freq)], 'bandpass'); % bandpass butterworth filter

            Ch1Filtered = filtfilt(b2, a2, app.Ch1EMG');
            Ch1Filtered = abs(Ch1Filtered);

            Ch2Filtered = filtfilt(b2, a2, app.Ch2EMG');
            Ch2Filtered = abs(Ch2Filtered);

            fprintf("EMG Bandpass filtered and Rectified!\n")

            %% ---------------------------------------------------------------------%%
            %% Notch Filter
            % Account for power line interference at 60 Hz
            % https://www.mathworks.com/help/signal/ug/remove-the-60-hz-hum-from-a-signal.html
            %% ---------------------------------------------------------------------%%
            fprintf("\nApplying 60Hz notch Filter...\n")

            d = designfilt('bandstopiir','FilterOrder',2, ...
                'HalfPowerFrequency1', 59, 'HalfPowerFrequency2', 61, ...
                'DesignMethod', 'butter', 'SampleRate', sampleRate);

            app.ProcessedEMG = struct();
            for e = 1:length(events)
                [~, cols] = size(ProcessedEMG2.(events{e}));
                for trial = 1:cols
                    processed = ProcessedEMG2.(events{e})(:, trial);
                    notch = filtfilt(d, processed);
                    app.ProcessedEMG.(events{e})(:, trial) = notch;
                end
            end

            Ch1Notched = filtfilt(d, Ch1Filtered);
            Ch2Notched = filtfilt(d, Ch2Filtered);

            fprintf("EMG notched filtered!\n")

            %% ---------------------------------------------------------------------%%
            %% Smooth Signal
            %% ---------------------------------------------------------------------%%
            fprintf("\nSmoothing Processed EMG signal...\n")
            app.SmoothedEMG = struct();
            % EnvelopeEMG = struct();

            % events_smoothed = events + "_smoothed";
            % events_envelope = events + "_envelope";
            % Ch1EventsSmoothed = events_smoothed(1:length(events) / 2);
            % Ch2EventsSmoothed = events_smoothed((length(events) / 2) + 1:end);

            % for i = 1:length(ConditionData)
            clear b a
            for e = 1:length(events)
                [~, cols] = size(app.ProcessedEMG.(events{e}));

                % [b, a] = butter(6, 5 / nyq_freq, 'low'); % butterworth filter
                [b, a] = butter(6, envelope_lowpassHz / (sampleRate / 2), 'low'); % butterworth filter
                for trial = 1:cols
                    processed_data = app.ProcessedEMG.(events{e})(:, trial); % EMG recordings for each condition in a given trial
                    LowPassEMG = filtfilt(b, a, processed_data);
                    app.SmoothedEMG.(events{e})(:, trial) = LowPassEMG;
                end
            end

            clear b2 a2
            [b2, a2] = butter(6, envelope_lowpassHz / (sampleRate / 2), 'low'); % butterworth filter
            Ch1Smooth = filtfilt(b2, a2, Ch1Notched);
            Ch2Smooth = filtfilt(b2, a2, Ch2Notched);
            fprintf("EMG Smoothed!\n\n")


            %% ---------------------------------------------------------------------%%
            %% Get Max EMG
            %% ---------------------------------------------------------------------%%
            fprintf("Get max EMG (mV) for each trial...\n")
            app.MaxEMG = struct();

            startMaxSearch = -1.250;
            startMaxSearchIDX = find(time == startMaxSearch);
            finalTime = length(time);

            for e = 1:length(events)
                [~, cols] = size(app.SmoothedEMG.(events{e}));

                for trial = 1:cols
                    tempEMG = app.SmoothedEMG.(events{e})(startMaxSearchIDX:finalTime, trial);
                    maxEMG = max(tempEMG, [], 'all');
                    maxIDX = find(app.SmoothedEMG.(events{e})(:, trial) == maxEMG, 1);
                    app.MaxEMG.(events{e})(1, trial) = maxIDX;
                    app.MaxEMG.(events{e})(2, trial) = maxEMG;
                end
            end

            app.Ch1RecordingMax = max(Ch1Smooth, [], 'all');
            app.Ch2RecordingMax = max(Ch2Smooth, [], 'all');

            if app.Ch1RecordingMax > app.Ch2RecordingMax
                app.RecordingMax = app.Ch1RecordingMax;
            elseif app.Ch1RecordingMax < app.Ch2RecordingMax
                app.RecordingMax = app.Ch2RecordingMax;
            end

            fprintf("Max EMG (mV) stored in app.MaxEMG\n\n")

            %% ---------------------------------------------------------------------%%
            %% Sliding Average to detect Baseline
            % Sanity check:
            % Number of Samples = 2000Hz (sr) × 3s (pre-stim dur) = 6000 samples
            %% ---------------------------------------------------------------------%%
            fprintf("Starting EMG Burst Onset detection...\n")
            BaselineEMG = struct();

            % events_baseline = events + "_baseline";

            for e = 1:length(events)

                % Baseline [-3, 0)
                baselineIDX = time <= 0;
                BaselineEMG.(events{e}) = app.SmoothedEMG.(events{e})(baselineIDX, :);
                % BaselineEMG.(events{e}) = app.ProcessedEMG.(events{e})(baselineIDX, :);
                % BaselineEMG.(events{e}) = RawEMG.(events{e})(baselineIDX, :);

            end

            app.BaselineStats = struct();

            % Define window size and step size
            % sr * window (s) = # of samples
            window = sampleRate * (window_ms/1000);
            step = sampleRate * (step_ms/1000);

            % total_trials = 0;
            for e = 1:length(events)
                [~, cols] = size(BaselineEMG.(events{e}));

                % total_trials = total_trials + size(BaselineEMG.(events{e}), 2);

                for trial = 1:cols
                    data = BaselineEMG.(events{e})(:, trial);

                    % Initialize an array to store the averages
                    num_windows = round((length(data) - window) / step);
                    windowAverages = zeros(1, num_windows);
                    % windowMedians = zeros(1, num_windows);
                    windowSTD = zeros(1, num_windows);
                    windowKeys = [];
                    windowKeysStart = zeros(num_windows, 1);
                    windowKeysStop = zeros(num_windows, 1);
                    windowMin = zeros(1, num_windows);
                    windowMax = zeros(1, num_windows);

                    % windowStartValues = zeros(total_trials, 1);

                    % Loop through the data using the sliding window
                    for i = 1:num_windows
                        start = ((i - 1) * step) + 1;
                        stop = start + window;

                        % Compute the mean for the current window
                        windowAverages(i) = mean(data(start:stop));
                        % windowMedians(i) = median(data(start:stop), 1);
                        windowSTD(i) = std(data(start:stop));
                        windowKeysStart(i) = time(start);
                        windowKeysStop(i) = time(stop);
                        windowMin(i) = min(data(start:stop), [], 'all');
                        windowMax(i) = max(data(start:stop), [], 'all');

                        key_format = sprintf("(%.4f, %.4f)", time(start), time(stop));
                        windowKeys = [windowKeys; key_format];

                    end

                    % Find the minimum average
                    min_average = min(windowAverages, [], 'all');
                    baselineIDX = find(windowAverages == min_average);
                    % min_median = min(windowMedians, [], 'all');
                    % baselineIDX = find(windowMedians == min_median, 1); % need to specify take the first index where min_median is found

                    app.BaselineStats.(events{e})(1, trial) = min_average;
                    % app.BaselineStats.(events{e})(1, trial) = min_median;
                    app.BaselineStats.(events{e})(2, trial) = windowSTD(baselineIDX);
                    app.BaselineStats.(events{e})(3, trial) = windowKeysStart(baselineIDX);
                    app.BaselineStats.(events{e})(4, trial) = windowKeysStop(baselineIDX);
                    app.BaselineStats.(events{e})(5, trial) = windowMin(baselineIDX);
                    app.BaselineStats.(events{e})(6, trial) = windowMax(baselineIDX);

                end
            end

            %% ---------------------------------------------------------------------%%
            %% Get Set Window Baseline
            % Sanity check:
            % Number of Samples = 2000Hz (sr) × 3s (pre-stim dur) = 6000 samples
            %% ---------------------------------------------------------------------%%
            SetWindowBaselineEMG = struct();

            for e = 1:length(events)

                % Baseline [-3, 0)
                baselineIDX = time <= 0;
                SetWindowBaselineEMG.(events{e}) = app.SmoothedEMG.(events{e})(baselineIDX, :);

            end

            app.SetWindowBaselineStats = struct();

            windowStartIDX = find(time == (windowStart/1000));
            windowStopIDX = find(time == (windowStop/1000));

            for e = 1:length(events)
                [~, cols] = size(SetWindowBaselineEMG.(events{e}));

                % total_trials = total_trials + size(BaselineEMG.(events_baseline{e}), 2);

                for trial = 1:cols
                    data = SetWindowBaselineEMG.(events{e})(:, trial);

                    % Find the Baseline EMG average
                    baselineAVG =  mean(data(windowStartIDX:windowStopIDX));
                    baselineSTD =  std(data(windowStartIDX:windowStopIDX));

                    app.SetWindowBaselineStats.(events{e})(1, trial) = baselineAVG;
                    app.SetWindowBaselineStats.(events{e})(2, trial) = baselineSTD;
                    app.SetWindowBaselineStats.(events{e})(3, trial) = time(windowStartIDX);
                    app.SetWindowBaselineStats.(events{e})(4, trial) = time(windowStopIDX);


                end
            end

            %% ---------------------------------------------------------------------%%
            %% Get Onset
            % Theshold = z-score; 2 SD
            % dur = 30ms
            %% ---------------------------------------------------------------------%%
            app.EMGBurstOnsets = struct();

            close all;

            % for e = 1:length(events)
            for e = target_eventIDX
                [~, cols] = size(app.SmoothedEMG.(events{e}));

                for trial = 1:cols
                    clear tempEMG tempAvg tempSD
                    tempEMG = app.SmoothedEMG.(events{e})(:, trial);
                    tempAvg = app.BaselineStats.(events{e})(1, trial);
                    tempSD = app.BaselineStats.(events{e})(2, trial);

                    clear onsetThreshold aboveThreshold
                    onsetThreshold = tempAvg + (tempSD * thresholdSize);
                    % tempMax = app.BaselineStats.(events{e})(6, trial);
                    % tempMin = app.BaselineStats.(events{e})(5, trial);
                    % onsetThreshold = percentBaselineRange * (tempMax - tempMin);
                    % onsetThreshold = onsetThreshold * zSize;
                    samplesToSearch = ceil(dur * sampleRate) + 1;
                    samplesToSearch2 = ceil(dur2 * sampleRate) + samplesToSearch;

                    aboveThreshold = tempEMG > onsetThreshold;
                    aboveThreshold2 = tempEMG > (tempAvg + tempSD);
                    startSearchIDX = find(time == startSearchTime); % start search at -500ms

                    app.EMGBurstOnsets.(events{e})(1, trial) = 0;
                    app.EMGBurstOnsets.(events{e})(2, trial) = 0;

                    firstThresholdIndex = [];
                    for i = startSearchIDX:(length(tempEMG) - samplesToSearch2)
                        if all(aboveThreshold(i:i + samplesToSearch))
                            if all(aboveThreshold2(i + samplesToSearch + 1: i + samplesToSearch2))
                                firstThresholdIndex = i;
                                app.EMGBurstOnsets.(events{e})(1, trial) = firstThresholdIndex;
                                app.EMGBurstOnsets.(events{e})(2, trial) = time(firstThresholdIndex);
                                break;
                            end

                        end
                    end
                end
            end

            %% ---------------------------------------------------------------------%%
            %% Get Onset Set Window
            % Theshold = z-score; 2 SD
            % dur = 30ms
            %% ---------------------------------------------------------------------%%
            app.SetWindowEMGBurstOnsets = struct();

            close all;

            for e = target_eventIDX
                [~, cols] = size(app.SmoothedEMG.(events{e}));

                for trial = 1:cols
                    clear tempEMG tempAvg tempSD
                    tempEMG = app.SmoothedEMG.(events{e})(:, trial);
                    tempAvg = app.SetWindowBaselineStats.(events{e})(1, trial);
                    tempSD = app.SetWindowBaselineStats.(events{e})(2, trial);

                    clear onsetThreshold aboveThreshold
                    onsetThreshold = tempAvg + (tempSD * thresholdSize);
                    samplesToSearch = ceil(dur * sampleRate) + 1;
                    samplesToSearch2 = ceil(dur2 * sampleRate) + samplesToSearch;
                    aboveThreshold = tempEMG > onsetThreshold;
                    aboveThreshold2 = tempEMG > (tempAvg + tempSD);
                    startSearchIDX = find(time == startSearchTime); % start search at -1250ms

                    app.SetWindowEMGBurstOnsets.(events{e})(1, trial) = 0;
                    app.SetWindowEMGBurstOnsets.(events{e})(2, trial) = 0;

                    firstThresholdIndex = [];
                    for i = startSearchIDX:(length(tempEMG) - samplesToSearch2)
                        if all(aboveThreshold(i:i + samplesToSearch))
                            if all(aboveThreshold2(i + samplesToSearch + 1: i + samplesToSearch2))
                                firstThresholdIndex = i;
                                app.SetWindowEMGBurstOnsets.(events{e})(1, trial) = firstThresholdIndex;
                                app.SetWindowEMGBurstOnsets.(events{e})(2, trial) = time(firstThresholdIndex);
                                break;
                            end
                        end
                    end
                end
            end
            fprintf("Onsets detected and stored!\n")


            %% ---------------------------------------------------------------------%%
            %% Calculate AUC
            %% ---------------------------------------------------------------------%%
            fprintf("\nCalculating AUC...\n")
            AUC = struct(); % row 1 = 500ms after onset, row 2 = onset to end (3000ms)
            SetWindowAUC = struct();
            window500ms = ceil(0.500 * sampleRate) + 1; % in samples
            % window3000ms = ceil(3.000 * sampleRate); % in samples

            for e = target_eventIDX
                [~, cols] = size(app.EMGBurstOnsets.(events{e}));

                for trial = 1:cols
                    onsetIDX = app.EMGBurstOnsets.(events{e})(1, trial);
                    onsetTime = app.EMGBurstOnsets.(events{e})(2, trial);

                    onsetIDXSetWindow = app.SetWindowEMGBurstOnsets.(events{e})(1, trial);
                    onsetTimeSetWindow = app.SetWindowEMGBurstOnsets.(events{e})(2, trial);

                    tempEMG = app.SmoothedEMG.(events{e})(:, trial);

                    time_ms = time * 1000;
                    tempEMGMatrix = [time' tempEMG];

                    stop500 = onsetIDX + window500ms;
                    stop500SetWindow = onsetIDXSetWindow + window500ms;
                    % stop3000 = length(time);
                    % stop3000 = onsetIDX + window3000ms;

                    AUC.(events{e})(1, trial) = 0;
                    AUC.(events{e})(2, trial) = 0;
                    % AUC.(events{e})(3, trial) = 0;
                    AUC.(events{e})(4, trial) = 0;

                    SetWindowAUC.(events{e})(1, trial) = 0;
                    SetWindowAUC.(events{e})(2, trial) = 0;
                    % SetWindowAUC.(events{e})(3, trial) = 0;
                    SetWindowAUC.(events{e})(4, trial) = 0;

                    if onsetIDX > 0 && stop500 <= length(tempEMGMatrix)
                        auc500 = trapz(tempEMGMatrix(onsetIDX:stop500, 1) * 1000, tempEMGMatrix(onsetIDX:stop500, 2));
                        AUC.(events{e})(1, trial) = auc500; % multiple by 1000 to get in ms
                    end

                    if onsetIDXSetWindow > 0 && stop500SetWindow <= length(tempEMGMatrix)
                        auc500SetWindow = trapz(tempEMGMatrix(onsetIDXSetWindow:stop500SetWindow, 1) * 1000, tempEMGMatrix(onsetIDXSetWindow:stop500SetWindow, 2));
                        SetWindowAUC.(events{e})(1, trial) = auc500SetWindow; % multiple by 1000 to get in ms
                    end

                    if onsetIDX > 0
                        duration = (time(end) * 1000) - (onsetTime * 1000);

                        auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                        aucDurNorm = auc3000 / duration;

                        AUC.(events{e})(2, trial) = aucDurNorm;
                        % AUC.(events{e})(3, trial) = aucDurNorm / app.RecordingMax; % multiple by 1000 to get in ms; normalize by duration
                        AUC.(events{e})(4, trial) = auc3000;

                    end

                    if onsetIDXSetWindow > 0
                        durationSetWindow = (time(end) * 1000) - (onsetTimeSetWindow * 1000);

                        auc3000SetWindow = trapz(tempEMGMatrix(onsetIDXSetWindow:end, 1) * 1000, tempEMGMatrix(onsetIDXSetWindow:end, 2));
                        aucDurNormSetWindow = auc3000SetWindow / durationSetWindow;

                        SetWindowAUC.(events{e})(2, trial) = aucDurNormSetWindow;
                        % SetWindowAUC.(events{e})(3, trial) = aucDurNormSetWindow / app.RecordingMax; % multiple by 1000 to get in ms; normalize by duration
                        SetWindowAUC.(events{e})(4, trial) = auc3000SetWindow;

                    end

                end
            end

            fprintf("AUC calculated\n")

            %% ---------------------------------------------------------------------%%
            %% Mod Ratio
            %% ---------------------------------------------------------------------%%
            fprintf("\nCalculating Mod Ratio...\n")
            ModRatio = struct(); % row 1 = 500ms after onset, row 2 = onset to end (3000ms)
            SetWindowModRatio = struct(); % row 1 = 500ms after onset, row 2 = onset to end (3000ms)

            for e = target_eventIDX
                [~, cols] = size(AUC.(events{e}));

                for trial = 1:cols
                    auc500 = AUC.(events{e})(1, trial);
                    % auc3000 = AUC.(events{e})(2, trial);
                    baselineData = app.BaselineStats;
                    baseline = baselineData.(events{e})(1, trial);

                    auc500SetWindow = SetWindowAUC.(events{e})(1, trial);
                    % auc3000SetWindow = AUC.(events{e})(2, trial);
                    baselineDataSetWindow = app.SetWindowBaselineStats;
                    baselineSetWindow = baselineDataSetWindow.(events{e})(1, trial);

                    ModRatio.(events{e})(1, trial) = 0;
                    ModRatio.(events{e})(2, trial) = 0;
                    SetWindowModRatio.(events{e})(1, trial) = 0;
                    SetWindowModRatio.(events{e})(2, trial) = 0;

                    if auc500 > 0
                        modRatio500 = (auc500) / (baseline * 500); % per Lang and Bastian, 1999; Wagner et al., 2007
                        ModRatio.(events{e})(1, trial) = modRatio500;
                    end

                    % if auc3000 > 0
                    %     modRatio3000 = auc3000 / (baseline * 3000); % per Lang and Bastian, 1999; Wagner et al., 2007
                    %     ModRatio.(events{e})(2, trial) = modRatio3000; % per Lang and Bastian, 1999; Wagner et al., 2007
                    % end

                    if auc500SetWindow > 0
                        modRatio500SetWindow = (auc500SetWindow) / (baselineSetWindow * 500); % per Lang and Bastian, 1999; Wagner et al., 2007
                        SetWindowModRatio.(events{e})(1, trial) = modRatio500SetWindow;
                    end

                    % if auc3000SetWindow > 0
                    %     modRatio3000SetWindow = auc3000SetWindow / (baselineSetWindow * 3000); % per Lang and Bastian, 1999; Wagner et al., 2007
                    %     SetWindowModRatio.(events{e})(2, trial) = modRatio3000SetWindow; % per Lang and Bastian, 1999; Wagner et al., 2007
                    % end
                end
            end

            fprintf("Mod Ratio calculated\n\n")

            %% ---------------------------------------------------------------------%%
            %% Antagonist AUC and mod ration (onset - end of block)
            %% ---------------------------------------------------------------------%%
            AntagonistAUC = struct();
            SetWindowAntagonistAUC = struct();
            antagonist_events = app.TargetEvents(end-1:end);

            for e = 1:length(antagonist_events)
                agonist_event = target_eventIDX(e);
                antagonist_event = antagonist_events(e);
                [~, cols] = size(app.SmoothedEMG.(events{antagonist_event}));

                for trial = 1:cols
                    trialOnset = app.EMGBurstOnsets.(events{agonist_event})(2, trial);
                    trialOnsetIDX = find(time == trialOnset);

                    tempSmooth = app.SmoothedEMG.(events{antagonist_event})(:, trial);
                    antagonist_smooth = [time' tempSmooth];

                    AntagonistAUC.(events{antagonist_event})(1, trial) = 0;
                    AntagonistAUC.(events{antagonist_event})(2, trial) = 0;
                    if trialOnset ~= 0
                        duration = (time(end) * 1000) - (trialOnset * 1000);

                        antagonist_auc = trapz(antagonist_smooth(trialOnsetIDX:end, 1) * 1000, antagonist_smooth(trialOnsetIDX:end, 2));
                        antagonist_aucDurNorm = antagonist_auc / duration;

                        AntagonistAUC.(events{antagonist_event})(1, trial) = antagonist_auc;
                        AntagonistAUC.(events{antagonist_event})(2, trial) = antagonist_aucDurNorm;
                    else
                        AntagonistAUC.(events{antagonist_event})(1, trial) = 0;
                        AntagonistAUC.(events{antagonist_event})(2, trial) = 0;
                    end

                    trialOnsetSet = app.SetWindowEMGBurstOnsets.(events{agonist_event})(2, trial);
                    trialOnsetIDXSet = find(time == trialOnsetSet);


                    SetWindowAntagonistAUC.(events{antagonist_event})(1, trial) = 0;
                    SetWindowAntagonistAUC.(events{antagonist_event})(2, trial) = 0;
                    if trialOnsetSet ~= 0
                        duration = (time(end) * 1000) - (trialOnsetSet * 1000);

                        antagonist_aucSet = trapz(antagonist_smooth(trialOnsetIDXSet:end, 1) * 1000, antagonist_smooth(trialOnsetIDXSet:end, 2));
                        aucDurNormSet = antagonist_aucSet / duration;

                        SetWindowAntagonistAUC.(events{antagonist_event})(1, trial) = antagonist_aucSet;
                        SetWindowAntagonistAUC.(events{antagonist_event})(2, trial) = aucDurNormSet;
                    else
                        SetWindowAntagonistAUC.(events{antagonist_event})(1, trial) = 0;
                        SetWindowAntagonistAUC.(events{antagonist_event})(2, trial) = 0;
                    end
                end
            end


            %% ---------------------------------------------------------------------%%
            %% Time from onset to peak
            %% ---------------------------------------------------------------------%%
            Time2Peak = struct();
            SetWindowTime2Peak = struct();

            for e = target_eventIDX
                [~, cols] = size(app.EMGBurstOnsets.(events{e}));

                for trial = 1:cols
                    onset = app.EMGBurstOnsets.(events{e})(2, trial) * 1000;
                    peak = time(app.MaxEMG.(events{e})(1, trial)) * 1000;
                    Time2Peak.(events{e})(1, trial) = peak;
                    Time2Peak.(events{e})(2, trial) = onset;
                    diff = peak - onset;

                    if onset == 0
                        Time2Peak.(events{e})(3, trial) = 0;
                    else
                        Time2Peak.(events{e})(3, trial) = diff;
                    end

                    onsetSetWindow = app.SetWindowEMGBurstOnsets.(events{e})(2, trial) * 1000;
                    peakSetWindow = time(app.MaxEMG.(events{e})(1, trial)) * 1000;
                    SetWindowTime2Peak.(events{e})(1, trial) = peakSetWindow;
                    SetWindowTime2Peak.(events{e})(2, trial) = onsetSetWindow;
                    diff2 = peakSetWindow - onsetSetWindow;

                    if onsetSetWindow == 0
                        SetWindowTime2Peak.(events{e})(3, trial) = 0;
                    else
                        SetWindowTime2Peak.(events{e})(3, trial) = diff2;
                    end
                end

            end

            %% ---------------------------------------------------------------------%%
            %%  Track NT
            %% ---------------------------------------------------------------------%%
            app.NoTrial = struct();
            app.SetWindowNoTrial = struct();

            for e = target_eventIDX
                [~, cols] = size(app.EMGBurstOnsets.(events{e}));

                for trial = 1:cols
                    onset = app.EMGBurstOnsets.(events{e})(2, trial) * 1000;
                    app.NoTrial.(events{e})(trial, 1) = "";
                    if onset == 0
                        app.NoTrial.(events{e})(trial, 1) = "no onset detected";
                    end

                    onsetSetWindow = app.SetWindowEMGBurstOnsets.(events{e})(2, trial) * 1000;
                    app.SetWindowNoTrial.(events{e})(trial, 1) = "";
                    if onsetSetWindow == 0
                        app.SetWindowNoTrial.(events{e})(trial, 1) = "no onset detected";
                    end
                end

            end

            %% ---------------------------------------------------------------------%%
            %%  Track Max - Baseline
            %% ---------------------------------------------------------------------%%
            app.MaxBaselineDiff = struct();
            app.SetWindowMaxBaselineDiff = struct();

            for e = target_eventIDX
                [~, cols] = size(app.MaxEMG.(events{e}));

                for trial = 1:cols
                    tempMax = app.MaxEMG.(events{e})(2, trial);
                    tempBaseline = app.BaselineStats.(events{e})(1, trial);
                    tempDiff = tempMax - tempBaseline;

                    if tempDiff == 0
                        app.MaxBaselineDiff.(events{e})(trial, 1) = 0;
                    else
                        app.MaxBaselineDiff.(events{e})(trial, 1) = tempDiff;
                    end

                    tempBaseline2 = app.SetWindowBaselineStats.(events{e})(1, trial);
                    tempDiff2 = tempMax - tempBaseline2;

                    if tempDiff2 == 0
                        app.SetWindowMaxBaselineDiff.(events{e})(trial, 1) = 0;
                    else
                        app.SetWindowMaxBaselineDiff.(events{e})(trial, 1) = tempDiff2;
                    end

                end

            end

            %% ---------------------------------------------------------------------%%
            %% Save Everything
            %% ---------------------------------------------------------------------%%
            numRowsRF = length(app.EMGBurstOnsets.(events{app.RF_IDX})(2, :));
            TrialListRF = 1:numRowsRF;
            app.BestBaselineSummaryDataCh1RF = cell(numRowsRF, 17);  % Preallocate as a cell array
            app.BestBaselineSummaryDataCh1RF(:, 1) = num2cell(TrialListRF'); % Convert numeric to cell
            app.BestBaselineSummaryDataCh1RF(:, 2) = num2cell(zeros(length(app.EMGBurstOnsets.(events{app.RF_IDX})(2, :)), 1));
            app.BestBaselineSummaryDataCh1RF(:, 3) = num2cell(round(double(app.EMGBurstOnsets.(events{app.RF_IDX})(2, :)' * 1000), 1)); % Onset Times
            app.BestBaselineSummaryDataCh1RF(:, 4) = num2cell(round(double(Time2Peak.(events{app.RF_IDX})(1, :)), 1))'; % Peak Time
            app.BestBaselineSummaryDataCh1RF(:, 5) = num2cell(round(double(Time2Peak.(events{app.RF_IDX})(3, :)), 1))'; % Time to Peak (peak - onset)
            app.BestBaselineSummaryDataCh1RF(:, 6) = num2cell(AUC.(events{app.RF_IDX})(1, :))'; % AUC 500ms after onset
            app.BestBaselineSummaryDataCh1RF(:, 7) = num2cell(ModRatio.(events{app.RF_IDX})(1, :))'; % Mod Ratio 500ms after onset
            app.BestBaselineSummaryDataCh1RF(:, 8) = num2cell(AUC.(events{app.RF_IDX})(4, :))'; % AUC onset to end of block
            app.BestBaselineSummaryDataCh1RF(:, 9) = num2cell(AntagonistAUC.(events{app.RF_AntagonistIDX})(1, :))'; % Antagonist AUC onset to end of block
            app.BestBaselineSummaryDataCh1RF(:, 10) = num2cell(AUC.(events{app.RF_IDX})(2, :))'; % Duration Normalized AUC
            app.BestBaselineSummaryDataCh1RF(:, 11) = num2cell(AntagonistAUC.(events{app.RF_AntagonistIDX})(2, :))'; % Antagonist Duration Normalized AUC
            % app.BestBaselineSummaryDataCh1RF(:, 9) = num2cell(ModRatio.(events{app.RF_IDX})(2, :))'; % Mod Ratio 3000ms after onset
            app.BestBaselineSummaryDataCh1RF(:, 12) = num2cell(AUC.(events{app.RF_IDX})(2, :) / app.Ch1RecordingMax)'; % Maximum Normalized
            app.BestBaselineSummaryDataCh1RF(:, 13) = num2cell(AntagonistAUC.(events{app.RF_AntagonistIDX})(2, :) / app.Ch2RecordingMax)'; % Maximum Normalized; should be antagonist channel max
            % app.BestBaselineSummaryDataCh1RF(:, 11) = num2cell(AUC.(events{app.RF_IDX})(4, :))'; % AUC 3000ms after onset; Choice Probability
            app.BestBaselineSummaryDataCh1RF(:, 14) = num2cell(app.BaselineStats.(events{app.RF_IDX})(1, :)); % baseline average
            app.BestBaselineSummaryDataCh1RF(:, 15) = num2cell(app.MaxEMG.(events{app.RF_IDX})(2, :))'; % max sEMG signal
            app.BestBaselineSummaryDataCh1RF(:, 16) = num2cell(app.MaxBaselineDiff.(events{app.RF_IDX})(:, 1)); % Numeric subtraction
            % app.BestBaselineSummaryDataCh1RF(:, 15) = {''}; % comments for no trial detected
            app.BestBaselineSummaryDataCh1RF(:, 17) = cellstr(app.NoTrial.(events{app.RF_IDX})(:, 1)); % comments for no trial detected
            app.AllDataCh1RF = app.BestBaselineSummaryDataCh1RF;

            numRowsRE = length(app.EMGBurstOnsets.(events{app.RE_IDX})(2, :));
            TrialListRE = 1:numRowsRE;
            app.BestBaselineSummaryDataCh2RE = cell(numRowsRE, 17);  % Preallocate as a cell array
            app.BestBaselineSummaryDataCh2RE(:, 1) = num2cell(TrialListRE'); % Convert numeric to cell
            app.BestBaselineSummaryDataCh2RE(:, 2) = num2cell(zeros(length(app.EMGBurstOnsets.(events{app.RE_IDX})(2, :)), 1));
            app.BestBaselineSummaryDataCh2RE(:, 3) = num2cell(round(double(app.EMGBurstOnsets.(events{app.RE_IDX})(2, :)' * 1000), 1)); % Onset Times
            app.BestBaselineSummaryDataCh2RE(:, 4) = num2cell(round(double(Time2Peak.(events{app.RE_IDX})(1, :)), 1))'; % Peak Time
            app.BestBaselineSummaryDataCh2RE(:, 5) = num2cell(round(double(Time2Peak.(events{app.RE_IDX})(3, :)), 1))'; % Time to Peak (peak - onset)
            app.BestBaselineSummaryDataCh2RE(:, 6) = num2cell(AUC.(events{app.RE_IDX})(1, :))'; % AUC 500ms after onset
            app.BestBaselineSummaryDataCh2RE(:, 7) = num2cell(ModRatio.(events{app.RE_IDX})(1, :))'; % Mod Ratio 500ms after onset
            app.BestBaselineSummaryDataCh2RE(:, 8) = num2cell(AUC.(events{app.RE_IDX})(4, :))'; % AUC onset to end of block
            app.BestBaselineSummaryDataCh2RE(:, 9) = num2cell(AntagonistAUC.(events{app.RE_AntagonistIDX})(1, :))'; % Antagonist AUC onset to end of block
            app.BestBaselineSummaryDataCh2RE(:, 10) = num2cell(AUC.(events{app.RE_IDX})(2, :))'; % Duration Normalized AUC
            app.BestBaselineSummaryDataCh2RE(:, 11) = num2cell(AntagonistAUC.(events{app.RE_AntagonistIDX})(2, :))'; % Antagonist Duration Normalized AUC
            % app.BestBaselineSummaryDataCh2RE(:, 9) = num2cell(ModRatio.(events{app.RE_IDX})(2, :))'; % Mod Ratio 3000ms after onset
            app.BestBaselineSummaryDataCh2RE(:, 12) = num2cell(AUC.(events{app.RE_IDX})(2, :) / app.Ch2RecordingMax)'; % Maximum Normalized
            app.BestBaselineSummaryDataCh2RE(:, 13) = num2cell(AntagonistAUC.(events{app.RE_AntagonistIDX})(2, :) / app.Ch1RecordingMax)'; % Maximum Normalized; should be antagonist channel max
            % app.BestBaselineSummaryDataCh2RE(:, 11) = num2cell(AUC.(events{app.RE_IDX})(4, :))'; % AUC 3000ms after onset; Choice Probability
            app.BestBaselineSummaryDataCh2RE(:, 14) = num2cell(app.BaselineStats.(events{app.RE_IDX})(1, :)); % baseline average
            app.BestBaselineSummaryDataCh2RE(:, 15) = num2cell(app.MaxEMG.(events{app.RE_IDX})(2, :))'; % max sEMG signal
            app.BestBaselineSummaryDataCh2RE(:, 16) = num2cell(app.MaxBaselineDiff.(events{app.RE_IDX})(:, 1)); % Numeric subtraction
            % app.BestBaselineSummaryDataCh2RE(:, 15) = {''}; % comments for no trial detected
            app.BestBaselineSummaryDataCh2RE(:, 17) = cellstr(app.NoTrial.(events{app.RE_IDX})(:, 1)); % comments for no trial detected
            app.AllDataCh2RE = app.BestBaselineSummaryDataCh2RE;

            %% Set Window Data
            numRowsRFSetWindow = length(app.SetWindowEMGBurstOnsets.(events{app.RF_IDX})(2, :));
            SetWindowTrialListRF = 1:numRowsRFSetWindow;
            app.SetWindowSummaryDataCh1RF = cell(numRowsRFSetWindow, 17);  % Preallocate as a cell array
            app.SetWindowSummaryDataCh1RF(:, 1) = num2cell(SetWindowTrialListRF'); % Convert numeric to cell
            app.SetWindowSummaryDataCh1RF(:, 2) = num2cell(zeros(length(app.SetWindowEMGBurstOnsets.(events{app.RF_IDX})(2, :)), 1));
            app.SetWindowSummaryDataCh1RF(:, 3) = num2cell(round(double(app.SetWindowEMGBurstOnsets.(events{app.RF_IDX})(2, :)' * 1000), 1)); % Onset Times
            app.SetWindowSummaryDataCh1RF(:, 4) = num2cell(round(double(SetWindowTime2Peak.(events{app.RF_IDX})(1, :)), 1))'; % Peak Time
            app.SetWindowSummaryDataCh1RF(:, 5) = num2cell(round(double(SetWindowTime2Peak.(events{app.RF_IDX})(3, :)), 1))'; % Time to Peak (peak - onset)
            app.SetWindowSummaryDataCh1RF(:, 6) = num2cell(SetWindowAUC.(events{app.RF_IDX})(1, :))'; % AUC 500ms after onset
            app.SetWindowSummaryDataCh1RF(:, 7) = num2cell(SetWindowModRatio.(events{app.RF_IDX})(1, :))'; % Mod Ratio 500ms after onset
            app.SetWindowSummaryDataCh1RF(:, 8) = num2cell(SetWindowAUC.(events{app.RF_IDX})(4, :))'; % AUC onset to end of block
            app.SetWindowSummaryDataCh1RF(:, 9) = num2cell(SetWindowAntagonistAUC.(events{app.RF_AntagonistIDX})(1, :))'; % Antagonist AUC onset to end of block
            app.SetWindowSummaryDataCh1RF(:, 10) = num2cell(SetWindowAUC.(events{app.RF_IDX})(2, :))'; % Duration Normalized AUC
            app.SetWindowSummaryDataCh1RF(:, 11) = num2cell(SetWindowAntagonistAUC.(events{app.RF_AntagonistIDX})(2, :))'; % Antagonist Duration Normalized AUC
            % app.SetWindowSummaryDataCh1RF(:, 9) = num2cell(SetWindowModRatio.(events{app.RF_IDX})(2, :))'; % Mod Ratio 3000ms after onset
            app.SetWindowSummaryDataCh1RF(:, 12) = num2cell(SetWindowAUC.(events{app.RF_IDX})(2, :) / app.Ch1RecordingMax)'; % Maximum Normalized
            app.SetWindowSummaryDataCh1RF(:, 13) = num2cell(SetWindowAntagonistAUC.(events{app.RF_AntagonistIDX})(2, :) / app.Ch2RecordingMax)'; % Maximum Normalized; should be antagonist channel max
            % app.SetWindowSummaryDataCh1RF(:, 11) = num2cell(SetWindowAUC.(events{app.RF_IDX})(4, :))'; % AUC 3000ms after onset; Choice Probability
            app.SetWindowSummaryDataCh1RF(:, 14) = num2cell(app.SetWindowBaselineStats.(events{app.RF_IDX})(1, :)); % baseline average
            app.SetWindowSummaryDataCh1RF(:, 15) = num2cell(app.MaxEMG.(events{app.RF_IDX})(2, :))'; % max sEMG signal
            app.SetWindowSummaryDataCh1RF(:, 16) = num2cell(app.SetWindowMaxBaselineDiff.(events{app.RF_IDX})(:, 1)); % Numeric subtraction
            % app.SetWindowSummaryDataCh1RF(:, 15) = {''}; % comments for no trial detected
            app.SetWindowSummaryDataCh1RF(:, 17) = cellstr(app.SetWindowNoTrial.(events{app.RF_IDX})(:, 1)); % comments for no trial detected
            app.SetWindowAllDataCh1RF = app.SetWindowSummaryDataCh1RF;

            numRowsRESetWindow = length(app.SetWindowEMGBurstOnsets.(events{app.RE_IDX})(2, :));
            SetWindowTrialListRE = 1:numRowsRESetWindow;
            app.SetWindowSummaryDataCh2RE = cell(numRowsRESetWindow, 17);  % Preallocate as a cell array
            app.SetWindowSummaryDataCh2RE(:, 1) = num2cell(SetWindowTrialListRE'); % Convert numeric to cell
            app.SetWindowSummaryDataCh2RE(:, 2) = num2cell(zeros(length(app.SetWindowEMGBurstOnsets.(events{app.RE_IDX})(2, :)), 1));
            app.SetWindowSummaryDataCh2RE(:, 3) = num2cell(round(double(app.SetWindowEMGBurstOnsets.(events{app.RE_IDX})(2, :)' * 1000), 1)); % Onset Times
            app.SetWindowSummaryDataCh2RE(:, 4) = num2cell(round(double(SetWindowTime2Peak.(events{app.RE_IDX})(1, :)), 1))'; % Peak Time
            app.SetWindowSummaryDataCh2RE(:, 5) = num2cell(round(double(SetWindowTime2Peak.(events{app.RE_IDX})(3, :)), 1))'; % Time to Peak (peak - onset)
            app.SetWindowSummaryDataCh2RE(:, 6) = num2cell(SetWindowAUC.(events{app.RE_IDX})(1, :))'; % AUC 500ms after onset
            app.SetWindowSummaryDataCh2RE(:, 7) = num2cell(SetWindowModRatio.(events{app.RE_IDX})(1, :))'; % Mod Ratio 500ms after onset
            app.SetWindowSummaryDataCh2RE(:, 8) = num2cell(SetWindowAUC.(events{app.RE_IDX})(4, :))'; % AUC onset to end of block
            app.SetWindowSummaryDataCh2RE(:, 9) = num2cell(SetWindowAntagonistAUC.(events{app.RE_AntagonistIDX})(1, :))'; % Antagonist AUC onset to end of block
            app.SetWindowSummaryDataCh2RE(:, 10) = num2cell(SetWindowAUC.(events{app.RE_IDX})(2, :))'; % Duration Normalized AUC
            app.SetWindowSummaryDataCh2RE(:, 11) = num2cell(SetWindowAntagonistAUC.(events{app.RE_AntagonistIDX})(2, :))'; % Antagonist Duration Normalized AUC
            % app.SetWindowSummaryDataCh2RE(:, 9) = num2cell(SetWindowModRatio.(events{app.RE_IDX})(2, :))'; % Mod Ratio 3000ms after onset
            app.SetWindowSummaryDataCh2RE(:, 12) = num2cell(SetWindowAUC.(events{app.RE_IDX})(2, :) / app.Ch2RecordingMax)'; % Maximum Normalized
            app.SetWindowSummaryDataCh2RE(:, 13) = num2cell(SetWindowAntagonistAUC.(events{app.RE_AntagonistIDX})(2, :) / app.Ch1RecordingMax)'; % Maximum Normalized; should be antagonist channel max
            % app.SetWindowSummaryDataCh2RE(:, 11) = num2cell(SetWindowAUC.(events{app.RE_IDX})(4, :))'; % AUC 3000ms after onset; Choice Probability
            app.SetWindowSummaryDataCh2RE(:, 14) = num2cell(app.SetWindowBaselineStats.(events{app.RE_IDX})(1, :)); % baseline average
            app.SetWindowSummaryDataCh2RE(:, 15) = num2cell(app.MaxEMG.(events{app.RE_IDX})(2, :))'; % max sEMG signal
            app.SetWindowSummaryDataCh2RE(:, 16) = num2cell(app.SetWindowMaxBaselineDiff.(events{app.RE_IDX})(:, 1)); % Numeric subtraction
            % app.SetWindowSummaryDataCh2RE(:, 15) = {''}; % comments for no trial detected
            app.SetWindowSummaryDataCh2RE(:, 17) = cellstr(app.SetWindowNoTrial.(events{app.RE_IDX})(:, 1)); % comments for no trial detected
            app.SetWindowAllDataCh2RE = app.SetWindowSummaryDataCh2RE;

            app.EventDropDown.Value = app.EventDropDown.Items(2);
            app.CurrentEvent = app.EventDropDown.Value;
            app.CurrentTrial = 1;
            trialText = sprintf("Trial %02d", app.CurrentTrial);
            app.TrialEditField.Value = trialText;

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.TrialCount = numRowsRF;
                app.MaxEditField.Value = app.Ch1RecordingMax;
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.TrialCount = numRowsRE;
                app.MaxEditField.Value = app.Ch2RecordingMax;
            end

            % fprintf("\nSaving everything...")
            % data_filename = sprintf('%s_%s_%s-Arm_EMG-Burst-Onset', timepoint, subject, recording_arm);

            % if strcmp(recording_arm_lower, affected_arm_lower)
            %     output_file = sprintf('%s/%s.mat', affected_dir, data_filename);
            % elseif strcmp(recording_arm_lower, unaffected_arm_lower)
            %     output_file = sprintf('%s/%s.mat', unaffected_dir, data_filename);
            % end

            % output_file = sprintf('%s/%s.mat', app.AnalysisDir, data_filename);


            %% ---------------------------------------------------------------------%%
            %% Clean up things you don't need
            %% ---------------------------------------------------------------------%%
            % vars = {
            %     'a', 'affected_arm_lower', 'affected_dir', 'app.AnalysisDir', 'ax1', 'ax2',...
            %     'b', 'baselineIDX', 'baselineStart', 'cols', 'condition_file',...
            %     'condition_name_baseline', 'condition_name_processed', 'condition_name_smoothed',...
            %     'conditions_dir', 'counts', 'data', 'data_filename', 'dir', 'dir_list',...
            %     'e', 'eventEMGBurstOnset', 'events_baseline', 'events_processed', 'events_smoothed',...
            %     'EventsToConditions', 'FigDir', 'figures_dir', 'filename_png', 'filename_png2',...
            %     'filename_png3', 'firstOnset', 'i', 'idx', 'l', 'lgd', 'LowPassEMG', 'min_average',...
            %     'OnsetFigureTitle', 'OnsetPlotTitle', 'processed_data', 'recording_app.AnalysisDir',...
            %     'recording_arm_lower', 'root', 'samplesToSearch', 'start', 'startSearchIDX', 'step',...
            %     'stop', 'target_eventIDX', 'tempAvg', 'tempData', 'tempEMG', 'tempSD', 'today',...
            %     'trial', 'unaffected_arm_lower', 'window', 'windowMax', 'windowMin', 'windowStarts',...
            %     'windowStartValues', 'windowSTD', 'x_fill_ax1', 'x_fill_ax2', 'y_fill_ax1',...
            %     'y_fill_ax2', 'y_lim_ax1', 'y_lim_ax2', 'bins', 'samples'
            %     };
            % clear(vars{:});
            % clear vars
            %
            % save(output_file);

            app.LoadingText.Visible = 'off';
            pause(.75);
            app.AnalyzeButton.Visible = "on";

            app.OverrideButton.Visible = 'on';
            app.SetNewOnsetButton.Visible = 'off';
            app.LowestBaselineButton.Visible = 'on';
            app.SetWindowButton.Visible = 'on';
            % app.AnalysisOptionsPanel.Visible = "on";
            app.SaveResultsButton.Visible = 'on';
            app.SaveFiguresButton.Visible = 'on';
            app.NoTrialButton.Visible = 'on';

            app.RawEMGVisibleCheckBox.Value = true;
            app.ProcessedEMGVisibleCheckBox.Value = true;
            app.BestBaselineVisibleCheckBox.Value = true;
            app.SetWindowVisibleCheckBox.Value = true;
            app.BestBaselineOnsetVisibleCheckBox.Value = true;
            app.SetWindowOnsetVisibleCheckBox.Value = true;

            if isempty(app.TempCh1RF)
                app.TempCh1RF = app.BestBaselineSummaryDataCh1RF;
            end

            if isempty(app.TempCh2RE)
                app.TempCh2RE = app.BestBaselineSummaryDataCh2RE;
            end

            if isempty(app.SetWindowTempCh1RF)
                app.SetWindowTempCh1RF = app.SetWindowSummaryDataCh1RF;
            end

            if isempty(app.SetWindowTempCh2RE)
                app.SetWindowTempCh2RE = app.SetWindowSummaryDataCh2RE;
            end

            %% Update UITable with data
            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                if isempty(app.TempCh1RF)
                    app.TempCh1RF = app.UITable.Data;
                end
                % app.UITable.Data(:, 13) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                % app.AllDataCh1RF = app.UITable.Data;

                % % Automatic update of comments
                % % Get the existing data table from the UITable
                % dataTable = app.UITable.Data;
                % % Convert the numeric column to a cell array to handle mixed types
                % tempColumn = num2cell(dataTable{:, 15});
                % % Replace any cells containing 1 with the string 'no onset detected'
                % tempColumn(cell2mat(tempColumn) == 1) = {"no onset detected"};
                % % Update the table column with the modified cell array
                % dataTable{:, columnIndex} = tempColumn;
                % % Update the UITable data
                % app.UITable.Data = dataTable;

                %% Update plots
                plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                % app.RawEMGxLim = xlim(app.RawEMGPlot);
                % app.RawEMGyLim = ylim(app.RawEMGPlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                % hold off

                plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                % app.ProcessedEMGxLim = xlim(app.ProcessedEMGPlot);
                % app.ProcessedEMGyLim = ylim(app.ProcessedEMGPlot);
                ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                % hold off

                plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                plotYLim = ylim(app.BestBaselinePlot);
                ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                % app.BestBaselinexLim = xlim(app.BestBaselinePlot);
                % app.BestBaselineyLim = ylim(app.BestBaselinePlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                % hold off

                setWindowOnset = app.SetWindowAllDataCh1RF{app.CurrentTrial, 3} / 1000;
                plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                plotYLim2 = ylim(app.SetWindowPlot);
                ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                app.plotSetWindowConstants();

                if setWindowOnset ~= 0
                    app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                else
                    delete(app.SetWindowOnsetLine)
                end
                
                % app.SetWindowxLim = xlim(app.SetWindowPlot);
                % app.SetWindowyLim = ylim(app.SetWindowPlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                % hold off
                % if app.BestBaselineOnsetVisibleCheckBox.Value
                %     app.plotBestBaselineOnsets();
                % end

                % app.plotBestBaselineConstants();
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                if isempty(app.TempCh2RE)
                    app.TempCh2RE = app.UITable.Data;
                end
                % app.UITable.Data(:, 13) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                % app.AllDataCh2RE = app.UITable.Data;

                % % Automatic update of comments
                % % Get the existing data table from the UITable
                % dataTable = app.UITable.Data;
                % % Convert the numeric column to a cell array to handle mixed types
                % tempColumn = num2cell(dataTable{:, 15});
                % % Replace any cells containing 1 with the string 'no onset detected'
                % tempColumn(cell2mat(tempColumn) == 1) = {"no onset detected"};
                % % Update the table column with the modified cell array
                % dataTable{:, columnIndex} = tempColumn;
                % % Update the UITable data
                % app.UITable.Data = dataTable;

                %% Update plots
                plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                % app.RawEMGxLim = xlim(app.RawEMGPlot);
                % app.RawEMGyLim = ylim(app.RawEMGPlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                % hold off

                plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                % app.ProcessedEMGxLim = xlim(app.ProcessedEMGPlot);
                % app.ProcessedEMGyLim = ylim(app.ProcessedEMGPlot);
                ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                % hold off

                plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                plotYLim = ylim(app.BestBaselinePlot);
                ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                % app.BestBaselinexLim = xlim(app.BestBaselinePlot);
                % app.BestBaselineyLim = ylim(app.BestBaselinePlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                % hold off

                setWindowOnset = app.SetWindowAllDataCh2RE{app.CurrentTrial, 3} / 1000;
                plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                plotYLim2 = ylim(app.SetWindowPlot);
                ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                app.plotSetWindowConstants();

                if setWindowOnset ~= 0
                    app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                else
                    delete(app.SetWindowOnsetLine)
                end

                % app.SetWindowxLim = xlim(app.SetWindowPlot);
                % app.SetWindowyLim = ylim(app.SetWindowPlot);
                % hold on
                % xline(time(app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3}), 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                % hold off

                % if app.BestBaselineOnsetVisibleCheckBox.Value
                %     app.plotBestBaselineOnsets();
                % end
                %
                % app.plotBestBaselineConstants();
                % else
                %     app.UITable.Data = [];
                %     cla(app.RawEMGPlot, 'reset');
                %     cla(app.ProcessedEMGPlot, 'reset');
                %     cla(app.SetWindowPlot, 'reset');
                %     cla(app.BestBaselinePlot, 'reset');
                %     app.formatPlots();
            end

            % app.resetPlotLimits();
            app.plotBestBaselineConstants();
            app.plotBestBaselineOnsets();
            app.plotSetWindowConstants();
            app.plotSetWindowOnsets();
            app.formatPlots();

            % % Get the number of rows in app.AllDataCh1RF
            % numRowsRF = size(app.AllDataCh1RF, 1);
            % numRowsRE = size(app.AllDataCh2RE, 1);
            %
            % % Create a new column with repmat of data details
            % app.newColumnRF = repmat({[app.SubID '_' app.TimePoint '_' app.Events{app.RF_IDX}]}, numRowsRF, 1);
            % app.newColumnRE = repmat({[app.SubID '_' app.TimePoint '_' app.Events{app.RE_IDX}]}, numRowsRE, 1);
            % app.ExcelHeaders = ['EventNames'; app.UITable.ColumnName(:)];
            % % Add the new column as the first column
            % app.AllDataCh1RF = [app.newColumnRF, app.AllDataCh1RF];
            % app.AllDataCh2RE = [app.newColumnRE, app.AllDataCh2RE];
            %
            % combinedData = [app.AllDataCh1RF; app.AllDataCh2RE];
            % app.CombinedData = cell2table(combinedData);
        end

        % Value changed function: EventDropDown
        function EventDropDownValueChanged(app, event)
            app.CurrentEvent = app.EventDropDown.Value;

            if strcmp(app.CurrentEvent, '–')
                app.CurrentTrial = 0;
                app.TrialEditField.Value = app.TrialEditField.Placeholder;
                app.MaxEditField.Value = 0;
                app.RawEMGVisibleCheckBox.Value = false;
                app.ProcessedEMGVisibleCheckBox.Value = false;
                app.BestBaselineVisibleCheckBox.Value = false;
                app.SetWindowVisibleCheckBox.Value = false;
                app.BestBaselineOnsetVisibleCheckBox.Value = false;
                app.SetWindowOnsetVisibleCheckBox.Value = false;

                app.AnalyzeButton.Visible = 'off';
                app.OverrideButton.Visible = 'off';
                app.SetNewOnsetButton.Visible = 'off';
                app.LowestBaselineButton.Visible = 'off';
                app.SetWindowButton.Visible = 'off';
                app.SaveResultsButton.Visible = 'off';
                app.SaveFiguresButton.Visible = 'off';
            else
                app.CurrentTrial = 1;
                trialText = sprintf("Trial %02d", app.CurrentTrial);
                app.TrialEditField.Value = trialText;
                app.RawEMGVisibleCheckBox.Value = true;
                app.ProcessedEMGVisibleCheckBox.Value = true;
                app.BestBaselineVisibleCheckBox.Value = true;
                app.SetWindowVisibleCheckBox.Value = true;
                app.BestBaselineOnsetVisibleCheckBox.Value = true;
                app.SetWindowOnsetVisibleCheckBox.Value = true;

                app.AnalyzeButton.Visible = 'on';
                app.OverrideButton.Visible = 'on';
                app.SetNewOnsetButton.Visible = 'off';
                app.LowestBaselineButton.Visible = 'on';
                app.SetWindowButton.Visible = 'on';
                app.SaveResultsButton.Visible = 'on';
                app.SaveFiguresButton.Visible = 'on';
            end

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch1RecordingMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh1RF{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end

                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch2RecordingMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh2RE{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end

        end

        % Button pushed function: PreviousTrialButton
        function PreviousTrialButtonPushed(app, event)

            if app.CurrentTrial > 1
                app.CurrentTrial = app.CurrentTrial - 1;
                app.UITable.Selection = app.CurrentTrial;

                trialText = sprintf("Trial %02d", app.CurrentTrial);
                app.TrialEditField.Value = trialText;
            end

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch1RecordingMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh1RF{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end

                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch2RecordingMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh2RE{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end

        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.TrialCount = size(app.BestBaselineSummaryDataCh1RF, 1);
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.TrialCount = size(app.BestBaselineSummaryDataCh2RE, 1);
            end

            if app.CurrentTrial > 0 && app.CurrentTrial < app.TrialCount
                app.CurrentTrial = app.CurrentTrial + 1;
                app.UITable.Selection = app.CurrentTrial;

                trialText = sprintf("Trial %02d", app.CurrentTrial);
                app.TrialEditField.Value = trialText;
            end

            app.SetNewOnsetButton.Visible = 'off';
            app.OverrideButton.Visible = 'on';

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch1RecordingMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh1RF{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end

                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch2RecordingMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh2RE{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end

            %     if app.CurrentEvent == app.Events{app.RF_IDX}
            %         %% Update plots
            %         if app.RawEMGVisibleCheckBox.Value
            %             cla(app.RawEMGPlot, 'reset');
            %             plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
            %         end
            %         if app.ProcessedEMGVisibleCheckBox.Value
            %             cla(app.ProcessedEMGPlot, 'reset');
            %             plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
            %             ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
            %         end
            %         if app.BestBaselineVisibleCheckBox.Value
            %             cla(app.BestBaselinePlot, 'reset');
            %             plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87])
            %             plotYLim = ylim(app.BestBaselinePlot);
            %             ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
            %             app.plotBestBaselineConstants();
            %         end
            %         if app.SetWindowVisibleCheckBox.Value
            %             cla(app.SetWindowPlot, 'reset');
            %             plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87])
            %             plotYLim2 = ylim(app.SetWindowPlot);
            %             ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
            %         end
            %
            %         % app.resetPlotLimits();
            %         app.formatPlots();
            %         if app.BestBaselineOnsetVisibleCheckBox.Value
            %             app.plotBestBaselineOnsets();
            %         end
            %
            %     elseif app.CurrentEvent == app.Events{app.RE_IDX}
            %
            %         %% Update plots
            %         if app.RawEMGVisibleCheckBox.Value
            %             cla(app.RawEMGPlot, 'reset');
            %             plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
            %         end
            %         if app.ProcessedEMGVisibleCheckBox.Value
            %             cla(app.ProcessedEMGPlot, 'reset');
            %             plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
            %             ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
            %         end
            %         if app.BestBaselineVisibleCheckBox.Value
            %             cla(app.BestBaselinePlot, 'reset');
            %             plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87])
            %             plotYLim = ylim(app.BestBaselinePlot);
            %             ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
            %             app.plotBestBaselineConstants();
            %         end
            %         if app.SetWindowVisibleCheckBox.Value
            %             cla(app.SetWindowPlot, 'reset');
            %             plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87])
            %             plotYLim2 = ylim(app.SetWindowPlot);
            %             ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
            %         end
            %
            %         % app.resetPlotLimits();
            %         app.formatPlots();
            %         if app.BestBaselineOnsetVisibleCheckBox.Value
            %             app.plotBestBaselineOnsets();
            %         end
            %
            %     % else
            %         % cla(app.RawEMGPlot, 'reset');
            %         % cla(app.ProcessedEMGPlot, 'reset');
            %         % cla(app.SetWindowPlot, 'reset');
            %         % cla(app.BestBaselinePlot, 'reset');
            %         % app.formatPlots();
            %     end
            % end
            %
            % if app.BestBaselineOnsetVisibleCheckBox.Value
            %     app.plotBestBaselineOnsets();
            %     % app.plotBestBaselineWindow();
            %     app.plotBestBaselineConstants();
            % end

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            trial = event.Indices;
            app.CurrentTrial = trial(1);
            trialText = sprintf("Trial %02d", app.CurrentTrial);
            app.TrialEditField.Value = trialText;

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch1RecordingMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh1RF{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end

                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch2RecordingMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh2RE{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end

        end

        % Value changed function: RawEMGVisibleCheckBox
        function RawEMGVisibleCheckBoxValueChanged(app, event)
            if ~app.RawEMGVisibleCheckBox.Value
                cla(app.RawEMGPlot, 'reset');
                app.formatPlots();
            else
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                    app.plotBestBaselineConstants
                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                end
                if app.BestBaselineOnsetVisibleCheckBox.Value
                    app.plotBestBaselineOnsets();
                    % app.plotBestBaselineWindow();
                end
                app.formatPlots();
            end
        end

        % Value changed function: ProcessedEMGVisibleCheckBox
        function ProcessedEMGVisibleCheckBoxValueChanged(app, event)
            if ~app.ProcessedEMGVisibleCheckBox.Value
                cla(app.ProcessedEMGPlot, 'reset');
                app.formatPlots();
            else
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                    ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                    ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                end
                if app.BestBaselineOnsetVisibleCheckBox.Value
                    app.plotBestBaselineOnsets();
                    % app.plotBestBaselineWindow();
                end
                app.formatPlots();
            end
        end

        % Value changed function: BestBaselineVisibleCheckBox
        function BestBaselineVisibleCheckBoxValueChanged(app, event)
            if ~app.BestBaselineVisibleCheckBox.Value
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            else
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    cla(app.BestBaselinePlot, 'reset');
                    plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87])
                    plotYLim = ylim(app.BestBaselinePlot);
                    ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    app.plotBestBaselineConstants()

                    if app.BestBaselineOnsetVisibleCheckBox.Value
                        app.plotBestBaselineOnsets();
                    end

                    app.formatPlots();

                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    cla(app.BestBaselinePlot, 'reset');
                    plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87])
                    plotYLim = ylim(app.BestBaselinePlot);
                    ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                    app.plotBestBaselineConstants()

                    if app.BestBaselineOnsetVisibleCheckBox.Value
                        app.plotBestBaselineOnsets();
                    end

                    app.formatPlots();

                end

            end

        end

        % Value changed function: SetWindowVisibleCheckBox
        function SetWindowVisibleCheckBoxValueChanged(app, event)
            if ~app.SetWindowVisibleCheckBox.Value
                cla(app.SetWindowPlot, 'reset');
                app.formatPlots();
            else
                if app.CurrentEvent == app.Events{app.RF_IDX}
                    cla(app.SetWindowPlot, 'reset');
                    plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87])
                    plotYLim2 = ylim(app.SetWindowPlot);
                    ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])

                    app.plotSetWindowConstants();

                    if app.SetWindowOnsetVisibleCheckBox.Value
                        app.plotSetWindowOnsets();
                    end

                    app.formatPlots();
                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    cla(app.SetWindowPlot, 'reset');
                    plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87])
                    plotYLim2 = ylim(app.SetWindowPlot);
                    ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])

                    app.plotSetWindowConstants();

                    if app.SetWindowOnsetVisibleCheckBox.Value
                        app.plotSetWindowOnsets();
                    end

                    app.formatPlots();
                end

            end

        end

        % Value changed function: BestBaselineOnsetVisibleCheckBox
        function BestBaselineOnsetVisibleCheckBoxValueChanged(app, event)
            if ~app.BestBaselineOnsetVisibleCheckBox.Value
                % Delete only xline objects from each plot
                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
            else
                %% Update UITable with data
                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                % delete(app.SetWindowOnsetLine)

                if app.CurrentEvent == app.Events{app.RF_IDX}

                    if isempty(app.TempCh1RF)
                        app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                    else
                        app.UITable.Data = app.TempCh1RF;
                    end
                    app.TempCh1RF = app.UITable.Data;

                    if app.TempCh1RF{app.CurrentTrial, 3} ~= 0
                        % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                        % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                        app.MaxEditField.Value = app.Ch1RecordingMax;

                        onset_ms = app.TempCh1RF{app.CurrentTrial, 3} / 1000;
                        setWindowOnset = app.SetWindowAllDataCh1RF{app.CurrentTrial, 3} / 1000;

                        %% Update plots
                        if app.RawEMGVisibleCheckBox.Value
                            cla(app.RawEMGPlot, 'reset')
                            plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                        end

                        if app.ProcessedEMGVisibleCheckBox.Value
                            cla(app.ProcessedEMGPlot, 'reset')
                            plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        end

                        if app.BestBaselineVisibleCheckBox.Value
                            cla(app.BestBaselinePlot, 'reset')
                            plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                            plotYLim = ylim(app.BestBaselinePlot);
                            ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                            app.plotBestBaselineConstants();
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end

                        % if app.SetWindowVisibleCheckBox.Value
                        %     cla(app.SetWindowPlot, 'reset')
                        %     plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        %     plotYLim2 = ylim(app.SetWindowPlot);
                        %     ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        %     app.plotSetWindowConstants();
                        %     if setWindowOnset ~= 0
                        %         app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        %     else
                        %         delete(app.SetWindowOnsetLine)
                        %     end
                        % 
                        % end


                        % if app.BestBaselineOnsetVisibleCheckBox.Value
                        %     app.plotBestBaselineOnsets();
                        % end

                        app.formatPlots();
                    else
                        %% Update plots
                        if app.RawEMGVisibleCheckBox.Value
                            cla(app.RawEMGPlot, 'reset')
                            plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            cla(app.ProcessedEMGPlot, 'reset')
                            plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        end

                        if app.BestBaselineVisibleCheckBox.Value
                            cla(app.BestBaselinePlot, 'reset')
                            plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                            plotYLim = ylim(app.BestBaselinePlot);
                            ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                            app.plotBestBaselineConstants();
                        end
                        % if app.SetWindowVisibleCheckBox.Value
                        %     cla(app.SetWindowPlot, 'reset')
                        %     plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        %     plotYLim2 = ylim(app.SetWindowPlot);
                        %     ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        %     app.plotSetWindowConstants();
                        % end
                    end


                elseif app.CurrentEvent == app.Events{app.RE_IDX}
                    if isempty(app.TempCh2RE)
                        app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                    else
                        app.UITable.Data = app.TempCh2RE;
                    end
                    app.TempCh2RE = app.UITable.Data;

                    if app.TempCh2RE{app.CurrentTrial, 3} ~= 0
                        % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                        % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                        app.MaxEditField.Value = app.Ch2RecordingMax;

                        onset_ms = app.TempCh2RE{app.CurrentTrial, 3} / 1000;
                        setWindowOnset = app.SetWindowAllDataCh2RE{app.CurrentTrial, 3} / 1000;

                        %% Update plots
                        if app.RawEMGVisibleCheckBox.Value
                            cla(app.RawEMGPlot, 'reset')
                            plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            cla(app.ProcessedEMGPlot, 'reset')
                            plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                        end
                        if app.BestBaselineVisibleCheckBox.Value
                            cla(app.BestBaselinePlot, 'reset')
                            plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                            plotYLim = ylim(app.BestBaselinePlot);
                            ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                            app.plotBestBaselineConstants();
                            app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        % if app.SetWindowVisibleCheckBox.Value
                        %     cla(app.SetWindowPlot, 'reset')
                        %     plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        %     plotYLim2 = ylim(app.SetWindowPlot);
                        %     ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        %     app.plotSetWindowConstants();
                        %     if setWindowOnset ~= 0
                        %         app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        %     else
                        %         delete(app.SetWindowOnsetLine)
                        %     end
                        % end

                        % if app.BestBaselineOnsetVisibleCheckBox.Value
                        %     app.plotBestBaselineOnsets();
                        % end
                        app.formatPlots();
                    else
                        %% Update plots
                        if app.RawEMGVisibleCheckBox.Value
                            cla(app.RawEMGPlot, 'reset')
                            plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            cla(app.ProcessedEMGPlot, 'reset')
                            plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                            ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                        end

                        if app.BestBaselineVisibleCheckBox.Value
                            cla(app.BestBaselinePlot, 'reset')
                            plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                            plotYLim = ylim(app.BestBaselinePlot);
                            ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                            app.plotBestBaselineConstants();

                        end
                        % if app.SetWindowVisibleCheckBox.Value
                        %     cla(app.SetWindowPlot, 'reset')
                        %     plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        %     plotYLim2 = ylim(app.SetWindowPlot);
                        %     ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        %     app.plotSetWindowConstants();
                        % end


                    end

                else
                    app.UITable.Data = [];
                    cla(app.RawEMGPlot, 'reset');
                    cla(app.ProcessedEMGPlot, 'reset');
                    cla(app.SetWindowPlot, 'reset');
                    cla(app.BestBaselinePlot, 'reset');
                    app.formatPlots();
                end
            end
        end

        % Value changed function: SetWindowOnsetVisibleCheckBox
        function SetWindowOnsetVisibleCheckBoxValueChanged(app, event)
            if ~app.SetWindowOnsetVisibleCheckBox.Value
                % Delete only xline objects from each plot
                % delete(app.RawEMGOnsetLine)
                % delete(app.ProcessedEMGOnsetLine)
                delete(app.SetWindowOnsetLine)
            else
                % app.plotBestBaselineWindow();
                app.plotSetWindowOnsets();
            end
        end

        % Button pushed function: UncheckAllButton
        function UncheckAllButtonPushed(app, event)

            app.RawEMGVisibleCheckBox.Value = false;
            app.ProcessedEMGVisibleCheckBox.Value = false;
            app.BestBaselineVisibleCheckBox.Value = false;
            app.SetWindowVisibleCheckBox.Value = false;
            app.BestBaselineOnsetVisibleCheckBox.Value = false;
            app.SetWindowOnsetVisibleCheckBox.Value = false;

            app.RawEMGVisibleCheckBoxValueChanged();
            app.ProcessedEMGVisibleCheckBoxValueChanged();
            app.BestBaselineVisibleCheckBoxValueChanged();
            app.SetWindowVisibleCheckBoxValueChanged();
            app.BestBaselineVisibleCheckBoxValueChanged();
            app.SetWindowOnsetVisibleCheckBoxValueChanged();

            app.formatPlots()
        end

        % Button pushed function: CheckAllButton
        function CheckAllButtonPushed(app, event)

            app.RawEMGVisibleCheckBox.Value = true;
            app.ProcessedEMGVisibleCheckBox.Value = true;
            app.BestBaselineVisibleCheckBox.Value = true;
            app.SetWindowVisibleCheckBox.Value = true;
            app.BestBaselineOnsetVisibleCheckBox.Value = true;
            app.SetWindowOnsetVisibleCheckBox.Value = true;

            app.RawEMGVisibleCheckBoxValueChanged();
            app.ProcessedEMGVisibleCheckBoxValueChanged();
            app.BestBaselineVisibleCheckBoxValueChanged();
            app.SetWindowVisibleCheckBoxValueChanged();
            app.BestBaselineVisibleCheckBoxValueChanged();
            app.SetWindowOnsetVisibleCheckBoxValueChanged();

            %% Update UITable with data
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}

                if isempty(app.TempCh1RF)
                    app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                else
                    app.UITable.Data = app.TempCh1RF;
                end
                app.TempCh1RF = app.UITable.Data;

                if app.TempCh1RF{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch1RecordingMax;

                    onset_ms = app.TempCh1RF{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh1RF{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end

                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end

                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end

                    end


                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end

                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RF_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end
                end


            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                if isempty(app.TempCh2RE)
                    app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                else
                    app.UITable.Data = app.TempCh2RE;
                end
                app.TempCh2RE = app.UITable.Data;

                if app.TempCh2RE{app.CurrentTrial, 3} ~= 0
                    % app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                    % app.UITable.Data(:, end) = cellstr(app.NoTrial.(app.CurrentEvent)(:, 1));
                    app.MaxEditField.Value = app.Ch2RecordingMax;

                    onset_ms = app.TempCh2RE{app.CurrentTrial, 3} / 1000;
                    setWindowOnset = app.SetWindowAllDataCh2RE{app.CurrentTrial, 3} / 1000;

                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all')]); % set y-min to 0; rectified to min is always 0
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, onset_ms, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                        if setWindowOnset ~= 0
                            app.SetWindowOnsetLine = xline(app.SetWindowPlot, setWindowOnset, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        else
                            delete(app.SetWindowOnsetLine)
                        end
                    end

                    % if app.BestBaselineOnsetVisibleCheckBox.Value
                    %     app.plotBestBaselineOnsets();
                    % end
                    app.formatPlots();
                else
                    %% Update plots
                    if app.RawEMGVisibleCheckBox.Value
                        cla(app.RawEMGPlot, 'reset')
                        plot(app.RawEMGPlot, app.Time, app.RawEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Raw EMG

                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        cla(app.ProcessedEMGPlot, 'reset')
                        plot(app.ProcessedEMGPlot, app.Time, app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial)) % Pre-Processed EMG
                        ylim(app.ProcessedEMGPlot, [0, max(app.ProcessedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), [], 'all') + 0.02]); % set y-min to 0; rectified to min is always 0
                    end

                    if app.BestBaselineVisibleCheckBox.Value
                        cla(app.BestBaselinePlot, 'reset')
                        plot(app.BestBaselinePlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim = ylim(app.BestBaselinePlot);
                        ylim(app.BestBaselinePlot, [-0.001, plotYLim(2)])
                        app.plotBestBaselineConstants();

                    end
                    if app.SetWindowVisibleCheckBox.Value
                        cla(app.SetWindowPlot, 'reset')
                        plot(app.SetWindowPlot, app.Time, app.SmoothedEMG.(app.Events{app.RE_IDX})(:, app.CurrentTrial), 'Color', 1/255*[0,104,87]) % Pre-Processed EMG
                        plotYLim2 = ylim(app.SetWindowPlot);
                        ylim(app.SetWindowPlot, [-0.001, plotYLim2(2)])
                        app.plotSetWindowConstants();
                    end


                end

            else
                app.UITable.Data = [];
                cla(app.RawEMGPlot, 'reset');
                cla(app.ProcessedEMGPlot, 'reset');
                cla(app.SetWindowPlot, 'reset');
                cla(app.BestBaselinePlot, 'reset');
                app.formatPlots();
            end
        end

        % Button pushed function: ResetTableButton
        function ResetTableButtonPushed(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            app.SetNewOnsetButton.Visible = 'off';
            app.OverrideButton.Visible = 'on';

            % app.CurrentTrial = 1;
            % trialText = sprintf("Trial %02d", app.CurrentTrial);
            % app.TrialEditField.Value = trialText;
            % app.UITable.Selection = app.CurrentTrial;

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.UITable.Data = app.BestBaselineSummaryDataCh1RF;
                app.TempCh1RF = app.UITable.Data;

                app.plotBestBaselineConstants();
                app.plotBestBaselineOnsets();
                app.plotSetWindowConstants();
                app.plotSetWindowOnsets();
                app.formatPlots();
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.UITable.Data = app.BestBaselineSummaryDataCh2RE;
                app.TempCh2RE = app.UITable.Data;

                app.plotBestBaselineConstants();
                app.plotBestBaselineOnsets();
                app.plotSetWindowConstants();
                app.plotSetWindowOnsets();
                app.formatPlots();
            end
        end

        % Button pushed function: LowestBaselineButton
        function LowestBaselineButtonPushed(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}
                % app.TempCh1RF = app.UITable.Data;
                % restore calculated columns
                app.TempCh1RF(app.CurrentTrial, :) = app.BestBaselineSummaryDataCh1RF(app.CurrentTrial, :);
                app.UITable.Data = app.TempCh1RF;
                app.TempCh1RF = app.UITable.Data;

                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)

                if app.UITable.Data{app.CurrentTrial, 3} == 0
                    delete(app.RawEMGOnsetLine)
                    delete(app.ProcessedEMGOnsetLine)
                    delete(app.BestBaselineOnsetLine)
                else
                    if app.RawEMGVisibleCheckBox.Value
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                end

                if app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 3} == 0
                    delete(app.SetWindowOnsetLine)
                else
                    if app.SetWindowOnsetVisibleCheckBox.Value
                        delete(app.SetWindowOnsetLine)
                        app.SetWindowOnsetLine = xline(app.SetWindowPlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end

                end
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                % app.TempCh2RE = app.UITable.Data;
                % restore calculated columns
                app.TempCh2RE(app.CurrentTrial, :) = app.BestBaselineSummaryDataCh2RE(app.CurrentTrial, :);
                app.UITable.Data = app.TempCh2RE;
                app.TempCh2RE = app.UITable.Data;

                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)

                if app.UITable.Data{app.CurrentTrial, 3} == 0
                    delete(app.RawEMGOnsetLine)
                    delete(app.ProcessedEMGOnsetLine)
                    delete(app.BestBaselineOnsetLine)
                else
                    if app.RawEMGVisibleCheckBox.Value
                        app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.ProcessedEMGVisibleCheckBox.Value
                        app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                    if app.BestBaselineVisibleCheckBox.Value
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                end

                if app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 3} == 0
                    delete(app.SetWindowOnsetLine)
                else
                    if app.SetWindowOnsetVisibleCheckBox.Value
                        delete(app.SetWindowOnsetLine)
                        app.SetWindowOnsetLine = xline(app.SetWindowPlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end

                end
            end


        end

        % Button pushed function: SetWindowButton
        function SetWindowButtonPushed(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.SetWindowTempCh1RF = app.UITable.Data;
                % restore calculated columns
                app.SetWindowTempCh1RF(app.CurrentTrial, :) = app.SetWindowSummaryDataCh1RF(app.CurrentTrial, :);
                app.SetWindowTempCh1RF(app.CurrentTrial, 2) = {-1};
                if app.SetWindowTempCh1RF{app.CurrentTrial, 3} == 0
                    app.SetWindowTempCh1RF{app.CurrentTrial, 5} = 0;
                end
                app.UITable.Data = app.SetWindowTempCh1RF;
                app.SetWindowTempCh1RF = app.UITable.Data;

                if app.UITable.Data{app.CurrentTrial, 3} == 0
                    delete(app.RawEMGOnsetLine)
                    delete(app.ProcessedEMGOnsetLine)
                    % delete(app.BestBaselineOnsetLine)
                    delete(app.SetWindowOnsetLine)
                else
                    delete(app.SetWindowOnsetLine)
                    app.plotSetWindowConstants();
                    if app.SetWindowOnsetVisibleCheckBox.Value
                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        if app.RawEMGVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.SetWindowSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        app.plotSetWindowOnsets();
                    end
                end

                if app.UITable.Data{app.CurrentTrial, 3} ~= 0
                    if app.BestBaselineOnsetVisibleCheckBox.Value
                        delete(app.BestBaselineOnsetLine)
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                end
                app.TempCh1RF = app.UITable.Data;

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.SetWindowTempCh2RE = app.UITable.Data;
                % restore calculated columns
                app.SetWindowTempCh2RE(app.CurrentTrial, :) = app.SetWindowSummaryDataCh2RE(app.CurrentTrial, :);
                app.SetWindowTempCh2RE(app.CurrentTrial, 2) = {-1};
                if app.SetWindowTempCh2RE{app.CurrentTrial, 3} == 0
                    app.SetWindowTempCh2RE{app.CurrentTrial, 5} = 0;
                end
                app.UITable.Data = app.SetWindowTempCh2RE;
                app.SetWindowTempCh2RE = app.UITable.Data;

                if app.UITable.Data{app.CurrentTrial, 3} == 0
                    delete(app.RawEMGOnsetLine)
                    delete(app.ProcessedEMGOnsetLine)
                    % delete(app.BestBaselineOnsetLine)
                    delete(app.SetWindowOnsetLine)
                else
                    delete(app.SetWindowOnsetLine)
                    app.plotSetWindowConstants();
                    if app.SetWindowOnsetVisibleCheckBox.Value
                        delete(app.RawEMGOnsetLine)
                        delete(app.ProcessedEMGOnsetLine)
                        if app.RawEMGVisibleCheckBox.Value
                            app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        if app.ProcessedEMGVisibleCheckBox.Value
                            app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.SetWindowSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                        end
                        app.plotSetWindowOnsets();
                    end
                end
                if app.UITable.Data{app.CurrentTrial, 3} ~= 0
                    if app.BestBaselineOnsetVisibleCheckBox.Value
                        delete(app.BestBaselineOnsetLine)
                        app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.BestBaselineSummaryDataCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
                    end
                end
                app.TempCh2RE = app.UITable.Data;

            end

            % % delete(app.RawEMGOnsetLine)
            % % delete(app.ProcessedEMGOnsetLine)
            % if app.CurrentEvent == app.Events{app.RF_IDX}
            %     app.SetWindowTempCh1RF = app.UITable.Data;
            %     app.plotSetWindowConstants();
            %
            %     if app.SetWindowOnsetVisibleCheckBox.Value
            %         % change override to -1 for Set Window replacement
            %         app.SetWindowTempCh1RF(app.CurrentTrial, :) = app.SetWindowSummaryDataCh1RF(app.CurrentTrial, :);
            % app.SetWindowTempCh1RF(app.CurrentTrial, 2) = {-1};
            % if app.SetWindowTempCh1RF{app.CurrentTrial, 3} == 0
            %     app.SetWindowTempCh1RF{app.CurrentTrial, 5} = 0;
            % end
            %         app.UITable.Data = app.SetWindowTempCh1RF;
            %         % app.plotSetWindowOnsets();
            %
            %         % if app.RawEMGVisibleCheckBox.Value
            %         %     app.RawEMGOnsetLine = xline(app.RawEMGOnsetLine, app.SetWindowTempCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            %         % end
            %         % if app.ProcessedEMGVisibleCheckBox.Value
            %         %     app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGOnsetLine, app.SetWindowTempCh1RF{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            %         % end
            %     end
            %     app.formatPlots();
            %
            % elseif app.CurrentEvent == app.Events{app.RE_IDX}
            %     app.SetWindowTempCh2RE = app.UITable.Data;
            %     if app.SetWindowOnsetVisibleCheckBox.Value
            %         app.plotSetWindowConstants();
            %
            %         % change override to -1 for Set Window replacement
            %         app.SetWindowTempCh2RE(app.CurrentTrial, :) = app.SetWindowSummaryDataCh2RE(app.CurrentTrial, :);
            %         app.SetWindowTempCh2RE(app.CurrentTrial, 2) = {-1};
            %         if app.SetWindowTempCh2RE{app.CurrentTrial, 3} == 0
            %             app.SetWindowTempCh2RE{app.CurrentTrial, 5} = 0;
            %         end
            %         app.UITable.Data = app.SetWindowTempCh2RE;
            %         app.plotSetWindowOnsets();
            %
            %         % if app.RawEMGVisibleCheckBox.Value
            %         %     app.RawEMGOnsetLine = xline(app.RawEMGOnsetLine, app.SetWindowTempCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            %         % end
            %         % if app.ProcessedEMGVisibleCheckBox.Value
            %         %     app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGOnsetLine, app.SetWindowTempCh2RE{app.CurrentTrial, 3} / 1000, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            %         % end
            %     end
            %     app.formatPlots();
            % end

        end

        % Button pushed function: NoTrialButton
        function NoTrialButtonPushed(app, event)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)
            delete(app.SetWindowOnsetLine)

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.TempCh1RF = app.UITable.Data;
                % sprintf("\nTrial %d updating...\n", app.CurrentTrial)

                % change override to 1
                app.TempCh1RF(app.CurrentTrial, 2) = {1};

                % change onset to 0
                app.TempCh1RF(app.CurrentTrial, 3) = {0};

                % change comment
                app.TempCh1RF(app.CurrentTrial, end) = {'no onset detected'};

                % change Time-to-Peak
                app.TempCh1RF(app.CurrentTrial, 5) = {0};

                % change AUC and mod ratio
                app.TempCh1RF(app.CurrentTrial, 6) = {0};
                app.TempCh1RF(app.CurrentTrial, 7) = {0};
                app.TempCh1RF(app.CurrentTrial, 8) = {0};
                app.TempCh1RF(app.CurrentTrial, 9) = {0};
                app.TempCh1RF(app.CurrentTrial, 10) = {0};
                app.TempCh1RF(app.CurrentTrial, 11) = {0};
                app.TempCh1RF(app.CurrentTrial, 12) = {0};
                app.TempCh1RF(app.CurrentTrial, 13) = {0};
                % sprintf("\nTrial %d updated!", app.CurrentTrial)

                baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                peak = app.TempCh1RF{app.CurrentTrial, 15};
                diff = peak - baseline;
                app.TempCh1RF(app.CurrentTrial, 14) = {baseline}; % set baseline to best 500ms window baseline
                app.TempCh1RF(app.CurrentTrial, 16) = {diff}; % set baseline to best 500ms window baseline

                app.UITable.Data = app.TempCh1RF;

                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.SetWindowOnsetLine)

            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.TempCh2RE = app.UITable.Data;
                % sprintf("\nTrial %d updating...\n", app.CurrentTrial)

                % change override to 1
                app.TempCh2RE(app.CurrentTrial, 2) = {1};

                % change onset to 0
                app.TempCh2RE(app.CurrentTrial, 3) = {0};

                % change comment
                app.TempCh2RE(app.CurrentTrial, end) = {'no onset detected'};

                % change Time-to-Peak
                app.TempCh2RE(app.CurrentTrial, 5) = {0};

                % change AUC and mod ratio
                app.TempCh2RE(app.CurrentTrial, 6) = {0};
                app.TempCh2RE(app.CurrentTrial, 7) = {0};
                app.TempCh2RE(app.CurrentTrial, 8) = {0};
                app.TempCh2RE(app.CurrentTrial, 9) = {0};
                app.TempCh2RE(app.CurrentTrial, 10) = {0};
                app.TempCh2RE(app.CurrentTrial, 11) = {0};
                app.TempCh2RE(app.CurrentTrial, 12) = {0};
                app.TempCh2RE(app.CurrentTrial, 13) = {0};

                % sprintf("\nTrial %d updated!", app.CurrentTrial)

                baseline = app.BaselineStats.(app.CurrentEvent)(1, app.CurrentTrial);
                peak = app.TempCh2RE{app.CurrentTrial, 15};
                diff = peak - baseline;
                app.TempCh2RE(app.CurrentTrial, 14) = {baseline}; % set baseline to best 500ms window baseline
                app.TempCh2RE(app.CurrentTrial, 16) = {diff}; % set baseline to best 500ms window baseline

                app.UITable.Data = app.TempCh2RE;

                delete(app.RawEMGOnsetLine)
                delete(app.ProcessedEMGOnsetLine)
                delete(app.BestBaselineOnsetLine)
                delete(app.SetWindowOnsetLine)

            end
        end

        % Button pushed function: OverrideButton
        function OverrideButtonPushed(app, event)
            % delete(app.RawEMGOnsetLine)
            % delete(app.ProcessedEMGOnsetLine)
            % delete(app.BestBaselineOnsetLine)
            delete(app.NewOnset);
            app.formatPlots();

            app.NewOnset = drawcrosshair('Parent', app.BestBaselinePlot, 'LineWidth', 0.75, 'Color', [0 0.4470 0.7410]);
            % app.OverrideX = app.NewOnset.Position(1);
            % app.OverrideY = app.NewOnset.Position(2);
            % newPointsPrint = sprintf("Override at (%.3f, %.3f)\n\n", app.OverrideX, app.OverrideY);
            % fprintf(newPointsPrint)
            app.OverrideButton.Visible = 'off';
            app.SetNewOnsetButton.Visible = 'on';
            % app.SetWindowPlot.Visible = 'off';

        end

        % Button pushed function: SetNewOnsetButton
        function SetNewOnsetButtonButtonPushed(app, event)
            app.SetNewOnsetButton.Visible = 'off';
            app.OverrideButton.Visible = 'on';
            % app.SetWindowPlot.Visible = 'on';

            app.OverrideX = app.NewOnset.Position(1);
            app.OverrideY = app.NewOnset.Position(2);
            newPointsPrint = sprintf("Override at (%.3f, %.3f)\n\n", app.OverrideX, app.OverrideY);
            fprintf(newPointsPrint)

            delete(app.NewOnset)
            delete(app.RawEMGOnsetLine)
            delete(app.ProcessedEMGOnsetLine)
            delete(app.BestBaselineOnsetLine)

            if app.RawEMGVisibleCheckBox.Value
                app.RawEMGOnsetLine = xline(app.RawEMGPlot, app.OverrideX, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            end
            if app.ProcessedEMGVisibleCheckBox.Value
                app.ProcessedEMGOnsetLine = xline(app.ProcessedEMGPlot, app.OverrideX, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            end
            if app.BestBaselineVisibleCheckBox.Value
                app.BestBaselineOnsetLine = xline(app.BestBaselinePlot, app.OverrideX, 'k--', 'LineWidth', 1.25, 'Color', 'k');
            end
            app.formatPlots();

            onset_ms = app.OverrideX * 1000; % onset in ms
            roundedOnset = round((onset_ms/ 1000) / 0.0005) * 0.0005;
            % roundedOnset_ms = roundedOnset * 1000;

            time = app.Time;

            if app.CurrentEvent == app.Events{app.RF_IDX}
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                app.TempCh1RF = app.UITable.Data;

                % update Override status
                app.TempCh1RF(app.CurrentTrial, 2) = {1};
                % update Onset
                app.TempCh1RF(app.CurrentTrial, 3) = {onset_ms};

                % update time-to-peak
                peak = app.TempCh1RF{app.CurrentTrial, 4};
                time2peak = peak - onset_ms;
                app.TempCh1RF(app.CurrentTrial, 5) = {time2peak};

                % update auc 500ms post onset
                window500ms = ceil(0.500 * app.SampleRate) + 1; % in samples
                onsetIDX = find(time == roundedOnset);
                stop500 = onsetIDX + window500ms;

                tempEMG = app.SmoothedEMG.(app.CurrentEvent)(:, app.CurrentTrial);
                antagonist_smooth = app.SmoothedEMG.(app.Events{app.RF_AntagonistIDX})(:, app.CurrentTrial);

                tempEMGMatrix = [time' tempEMG];
                antagonist_temp = [time' antagonist_smooth];

                auc500 = trapz(tempEMGMatrix(onsetIDX:stop500, 1) * 1000, tempEMGMatrix(onsetIDX:stop500, 2));
                app.TempCh1RF(app.CurrentTrial, 6) = {auc500};

                baselineData = app.BaselineStats;
                baseline = baselineData.(app.CurrentEvent)(1, app.CurrentTrial);

                % update mod ratio for auc 500ms post onset
                modRatio500 = 0;
                if auc500 > 0
                    modRatio500 = (auc500) / (baseline * 500); % per Lang and Bastian, 1999; Wagner et al., 2007
                end
                app.TempCh1RF(app.CurrentTrial, 7) = {modRatio500};

                % update auc from onset to end of block
                auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                app.TempCh1RF(app.CurrentTrial, 8) = {auc3000};
                antagonist_auc3000 = trapz(antagonist_temp(onsetIDX:end, 1) * 1000, antagonist_temp(onsetIDX:end, 2));
                app.TempCh1RF(app.CurrentTrial, 9) = {antagonist_auc3000};

                duration = (time(end) * 1000) - onset_ms;
                aucDurNorm = auc3000 / duration;
                antagonist_aucDurNorm = antagonist_auc3000 / duration;
                app.TempCh1RF(app.CurrentTrial, 10) = {aucDurNorm};
                app.TempCh1RF(app.CurrentTrial, 11) = {antagonist_aucDurNorm};

                aucMaxNorm = aucDurNorm / app.Ch1RecordingMax;
                antagonist_aucMaxNorm = antagonist_aucDurNorm / app.Ch2RecordingMax;
                app.TempCh1RF(app.CurrentTrial, 12) = {aucMaxNorm};
                app.TempCh1RF(app.CurrentTrial, 13) = {antagonist_aucMaxNorm}; % should be channel of antagonist

                % update comment / reason for override
                ff = fixfocus;
                prompt = {'Enter comment/reason for override:'};
                dlgtitle = 'Override Comment';
                fieldsize = [1 85];
                definput = {''};
                overrideComment = inputdlg(prompt, dlgtitle, fieldsize, definput);
                delete(ff);
                % data = load(fullfile(path, filename)); % loads data stored in condition file
                % app.FilenameEditField.Value = filename;
                drawnow; % puts UI Figure at the forefront
                figure(app.UIFigure) % puts UI Figure at the forefront
                app.TempCh1RF(app.CurrentTrial, end) = overrideComment;

                app.UITable.Data = app.TempCh1RF;
            elseif app.CurrentEvent == app.Events{app.RE_IDX}
                app.UITable.ColumnFormat = {'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'numeric', 'char'};
                app.TempCh2RE = app.UITable.Data;

                % update Override status
                app.TempCh2RE(app.CurrentTrial, 2) = {1};
                % update Onset
                app.TempCh2RE(app.CurrentTrial, 3) = {onset_ms};

                % update time-to-peak
                peak = app.TempCh2RE{app.CurrentTrial, 4};
                time2peak = peak - onset_ms;
                app.TempCh2RE(app.CurrentTrial, 5) = {time2peak};

                % update auc 500ms post onset
                window500ms = ceil(0.500 * app.SampleRate) + 1; % in samples
                onsetIDX = find(time == roundedOnset);
                stop500 = onsetIDX + window500ms;

                tempEMG = app.SmoothedEMG.(app.CurrentEvent)(:, app.CurrentTrial);
                antagonist_smooth = app.SmoothedEMG.(app.Events{app.RE_AntagonistIDX})(:, app.CurrentTrial);

                tempEMGMatrix = [time' tempEMG];
                antagonist_temp = [time' antagonist_smooth];

                auc500 = trapz(tempEMGMatrix(onsetIDX:stop500, 1) * 1000, tempEMGMatrix(onsetIDX:stop500, 2));
                app.TempCh2RE(app.CurrentTrial, 6) = {auc500};

                baselineData = app.BaselineStats;
                baseline = baselineData.(app.CurrentEvent)(1, app.CurrentTrial);

                % update mod ratio for auc 500ms post onset
                modRatio500 = 0;
                if auc500 > 0
                    modRatio500 = (auc500) / (baseline * 500); % per Lang and Bastian, 1999; Wagner et al., 2007
                end
                app.TempCh2RE(app.CurrentTrial, 7) = {modRatio500};

                % update auc from onset to end of block
                auc3000 = trapz(tempEMGMatrix(onsetIDX:end, 1) * 1000, tempEMGMatrix(onsetIDX:end, 2));
                app.TempCh2RE(app.CurrentTrial, 8) = {auc3000};
                antagonist_auc3000 = trapz(antagonist_temp(onsetIDX:end, 1) * 1000, antagonist_temp(onsetIDX:end, 2));
                app.TempCh2RE(app.CurrentTrial, 9) = {antagonist_auc3000};

                duration = (time(end) * 1000) - onset_ms;
                aucDurNorm = auc3000 / duration;
                antagonist_aucDurNorm = antagonist_auc3000 / duration;
                app.TempCh2RE(app.CurrentTrial, 10) = {aucDurNorm};
                app.TempCh2RE(app.CurrentTrial, 11) = {antagonist_aucDurNorm};

                aucMaxNorm = aucDurNorm / app.Ch2RecordingMax;
                antagonist_aucMaxNorm = antagonist_aucDurNorm / app.Ch1RecordingMax; % should be channel of antagonist
                app.TempCh2RE(app.CurrentTrial, 12) = {aucMaxNorm};
                app.TempCh2RE(app.CurrentTrial, 13) = {antagonist_aucMaxNorm};

                % update comment / reason for override
                ff = fixfocus;
                prompt = {'Enter comment/reason for override:'};
                dlgtitle = 'Override Comment';
                fieldsize = [1.5 85];
                definput = {''};
                overrideComment = inputdlg(prompt, dlgtitle, fieldsize, definput);
                delete(ff);
                % data = load(fullfile(path, filename)); % loads data stored in condition file
                % app.FilenameEditField.Value = filename;
                drawnow; % puts UI Figure at the forefront
                figure(app.UIFigure) % puts UI Figure at the forefront
                app.TempCh2RE(app.CurrentTrial, end) = overrideComment;

                app.UITable.Data = app.TempCh2RE;
            end


        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            fprintf("\nSaving everything...\n\n")
            data_filename = sprintf('%s_%s_%s-Arm_EMG-Burst-Onset', app.SubID, app.TimePoint, app.RecordingArm);
            app.AnalysisDir = sprintf('%s/%s_%s_EMG-Burst-Onset_%s', app.ResultsDir, app.TimePoint, app.SubID, app.FormattedDate);

            % Loop through and create directories if they do not exist
            fprintf("\nCreating directories...\n")

            if ~exist(app.AnalysisDir, 'dir')
                mkdir(app.AnalysisDir);
            end

            % add to path to access for analysis
            addpath(app.AnalysisDir)

            fprintf("Directories created!\n")
            savepath

            % Save data into .mat file
            OutputMat = sprintf('%s/%s.mat', app.AnalysisDir, data_filename);
            save(OutputMat);

            % save data into xlsx file
            OutputExcel = sprintf('%s/%s.xlsx', app.AnalysisDir, data_filename);

            % Get the number of rows in app.AllDataCh1RF
            if isempty(app.TempCh1RF)
                numRowsRF = size(app.AllDataCh1RF, 1);
            else
                numRowsRF = size(app.TempCh1RF, 1);
            end

            if isempty(app.TempCh2RE)
                numRowsRE = size(app.AllDataCh2RE, 1);
            else
                numRowsRE = size(app.TempCh2RE, 1);
            end

            % Create a new column with repmat of data details
            app.newColumnRF = repmat({[app.SubID '_' app.TimePoint '_' app.Events{app.RF_IDX}]}, numRowsRF, 1);
            app.newColumnRE = repmat({[app.SubID '_' app.TimePoint '_' app.Events{app.RE_IDX}]}, numRowsRE, 1);
            app.ExcelHeaders = ['EventNames'; app.UITable.ColumnName(:)];

            % Add the new column as the first column
            if isempty(app.TempCh1RF)
                app.AllDataCh1RF = [app.newColumnRF, app.AllDataCh1RF];
                app.BestBaselineAllDataCh1RF = [app.newColumnRF, app.BestBaselineSummaryDataCh1RF];
                app.SetWindowAllDataCh1RF = [app.newColumnRF, app.SetWindowSummaryDataCh1RF];
            else
                app.AllDataCh1RF = [app.newColumnRF, app.TempCh1RF];
                app.BestBaselineAllDataCh1RF = [app.newColumnRF, app.BestBaselineSummaryDataCh1RF];
                app.SetWindowAllDataCh1RF = [app.newColumnRF, app.SetWindowSummaryDataCh1RF];
            end
            if isempty(app.TempCh2RE)
                app.AllDataCh2RE = [app.newColumnRE, app.AllDataCh2RE];
                app.BestBaselineAllDataCh2RE = [app.newColumnRE, app.BestBaselineSummaryDataCh2RE];
                app.SetWindowAllDataCh2RE = [app.newColumnRE, app.SetWindowSummaryDataCh2RE];
            else
                app.AllDataCh2RE = [app.newColumnRE, app.TempCh2RE];
                app.BestBaselineAllDataCh2RE = [app.newColumnRE, app.BestBaselineSummaryDataCh2RE];
                app.SetWindowAllDataCh2RE = [app.newColumnRE, app.SetWindowSummaryDataCh2RE];
            end

            combinedData = [app.AllDataCh1RF; app.AllDataCh2RE];
            app.CombinedData = cell2table(combinedData);

            % Create headers as a cell array
            app.CombinedData.Properties.VariableNames = app.ExcelHeaders;

            bestCombinedData = [app.BestBaselineAllDataCh1RF; app.BestBaselineAllDataCh2RE];
            app.BestBaselineCombinedData = cell2table(bestCombinedData);
            % Create headers as a cell array
            app.BestBaselineCombinedData.Properties.VariableNames = app.ExcelHeaders;

            setCombinedData = [app.SetWindowAllDataCh1RF; app.SetWindowAllDataCh2RE];
            app.SetWindowCombinedData = cell2table(setCombinedData);
            % Create headers as a cell array
            app.SetWindowCombinedData.Properties.VariableNames = app.ExcelHeaders;

            % Write the data to the CSV file
            writetable(app.CombinedData, OutputExcel, 'WriteVariableNames', true, 'Sheet', 'UserAnalysis');
            writetable(app.BestBaselineCombinedData, OutputExcel, 'WriteVariableNames', true, 'Sheet', 'BestBaseline (500ms)');
            writetable(app.SetWindowCombinedData, OutputExcel, 'WriteVariableNames', true, 'Sheet', 'SetWindow (-2350 – -1350ms)');

            app.SaveResultsButton.Visible = 'off';
            pause(0.5)
            app.ResultsSavedText.Visible = 'on';
            fprintf("\nAnalysis saved!\n")
            pause(1.5)
            app.ResultsSavedText.Visible = 'off';
            pause(0.5)
            app.SaveResultsButton.Visible = 'on';


        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed(app, event)
            close all;
            fprintf("\nSaving figures...\n")

            for e = app.TargetEvents(1:2)
                if e == app.RF_IDX
                    tempData = app.TempCh1RF;
                elseif e == app.RE_IDX
                    tempData = app.TempCh2RE;
                end

                [~, cols] = size(app.SmoothedEMG.(app.Events{e}));

                for trial = 1:cols
                    clear tempEMG tempAvg tempSD
                    % tempEMG = app.SmoothedEMG.(app.Events{e})(:, trial);
                    tempAvg = app.BaselineStats.(app.Events{e})(1, trial);
                    tempSD = app.BaselineStats.(app.Events{e})(2, trial);

                    clear onsetThreshold aboveThreshold
                    onsetThreshold = tempAvg + (tempSD * app.ThresholdSize);
                    % samplesToSearch = ceil(app.Dur * app.SampleRate) + 1;
                    % samplesToSearch2 = ceil(app.Dur2 * app.SampleRate) + samplesToSearch;
                    %
                    % aboveThreshold = tempEMG > onsetThreshold;
                    % aboveThreshold2 = tempEMG > (tempAvg + tempSD);
                    % startSearchIDX = find(app.Time == app.StartSearchTime); % start search at -500ms

                    % app.EMGBurstOnsets.(app.Events{e})(1, trial) = 0;
                    % app.EMGBurstOnsets.(app.Events{e})(2, trial) = 0;
                    %
                    % firstThresholdIndex = [];
                    % for i = startSearchIDX:(length(tempEMG) - samplesToSearch2)
                    %     if all(aboveThreshold(i:i + samplesToSearch))
                    %         if all(aboveThreshold2(i + samplesToSearch + 1: i + samplesToSearch2))
                    %             firstThresholdIndex = i;
                    %             app.EMGBurstOnsets.(app.Events{e})(1, trial) = firstThresholdIndex;
                    %             app.EMGBurstOnsets.(app.Events{e})(2, trial) = app.Time(firstThresholdIndex);
                    %             break;
                    %         end
                    %
                    %     end
                    % end
                    OnsetPlotTitle = sprintf("%s %s Trial %d (%s) %s-Arm – EMG Burst onset", app.SubID, app.Events{e}, trial, app.TimePoint, app.RecordingArm);
                    OnsetFigureTitle = sprintf("%s %s Trial %d – EMG Burst onset", app.SubID, app.Events{e}, trial);
                    eventEMGBurstOnset = figure('Name', OnsetFigureTitle);

                    clear ax1 ax2 y_lim_ax1 y_lim_ax2
                    % First subplot for Smoothed EMG
                    ax1 = subplot(9, 5, 1:20, 'Parent', eventEMGBurstOnset);
                    hold on;
                    plot(app.Time, app.SmoothedEMG.(app.Events{e})(:, trial), 'Color', 1/255*[0,104,87], 'LineWidth', 1, 'DisplayName', 'Smoothed EMG', 'HandleVisibility', 'off'); % Plot test data
                    % Plot baseline rectangle for onset threshold optimization
                    baselineStart = app.BaselineStats.(app.Events{e})(3, trial);
                    maxIDX = app.MaxEMG.(app.Events{e})(1, trial);
                    maxEMG = app.MaxEMG.(app.Events{e})(2, trial);
                    y_lim_ax1 = get(ax1, 'YLim');
                    ylim(ax1, [0, y_lim_ax1(2)])
                    % Define the x and y coordinates for the filled region
                    x_fill_ax1 = [baselineStart, baselineStart + (500/1000), baselineStart + (500/1000), baselineStart];
                    y_fill_ax1 = [0, 0, y_lim_ax1(2), y_lim_ax1(2)];

                    % Use fill to create the shaded area
                    fill(x_fill_ax1, y_fill_ax1, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'Parent', ax1, 'DisplayName', 'Baseline Window');
                    plot(app.Time, app.SmoothedEMG.(app.Events{e})(:, trial), 'Color', 1/255*[0,104,87], 'LineWidth', 1, 'HandleVisibility', 'off'); % Plot test data

                    % Plot horizontal line for onsetThreshold
                    yline(onsetThreshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25, 'DisplayName', 'Onset Threshold');
                    % yline(tempAvg, 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');
                    % yline(onsetThreshold, 'o--', 'LineWidth', 1.25, 'DisplayName', 'Low Threshold');
                    % yline(tempAvg, '--', 'Color', 1/255*[1 1 1], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');

                    maxText = sprintf('Max = %.3f', maxEMG);
                    xline(app.Time(maxIDX), 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', maxText)

                    % Plot vertical line at app.Time(firstIndex), if it exists
                    firstThresholdIndex = app.EMGBurstOnsets.(app.Events{e})(1, trial);
                    time = app.Time;

                    onsets = tempData(:, 3);
                    roundedOnset = round((onsets{trial} / 1000) / 0.0005) * 0.0005;

                    if roundedOnset ~= 0
                        xline(roundedOnset, 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                        fprintf('\t%s | Trial %02d:\t%.1f ms\n', app.Events{e}, trial, (time(firstThresholdIndex)*1000));
                    else
                        fprintf('\t%s | Trial %02d:\t0.0 ms – NO ONSET DETECTED\n', app.Events{e}, trial);
                    end

                    % Add labels and legend
                    % xlabel('Time (s)');
                    % ylabel('EMG Signal (mV)');
                    lgd = legend('show', 'Location', 'northoutside', 'Orientation', 'horizontal');
                    lgd.FontSize = 10;
                    title(ax1, "Smoothed EMG", 'FontSize', 12, 'FontWeight', 'bold');

                    % Second subplot for Processed EMG
                    ax2 = subplot(9, 5, 26:45, 'Parent', eventEMGBurstOnset);
                    hold on;
                    plot(app.Time, app.ProcessedEMG.(app.Events{e})(:, trial), 'DisplayName', 'Processed Data', 'HandleVisibility', 'off')
                    % Plot baseline rectangle for onset threshold optimization
                    % Use fill to create the shaded area
                    y_lim_ax2 = get(ax2, 'YLim');
                    ylim(ax2, [0, y_lim_ax2(2)])
                    % Define the x and y coordinates for the filled region
                    x_fill_ax2 = [baselineStart, baselineStart + (500/1000), baselineStart + (500/1000), baselineStart];
                    y_fill_ax2 = [0, 0, y_lim_ax2(2), y_lim_ax2(2)];
                    fill(x_fill_ax2, y_fill_ax2, [0.85 0.85 0.85], 'EdgeColor', 'none', 'FaceAlpha', 0.5, 'Parent', ax2, 'DisplayName', 'Baseline Window');
                    plot(app.Time, app.ProcessedEMG.(app.Events{e})(:, trial), 'Color', [0, 0.4470, 0.7410], 'LineWidth', 1.25, 'HandleVisibility', 'off')

                    % Add labels and legend
                    % xlabel('Time (s)');
                    % ylabel('EMG Signal (mV)');
                    % title('Processed EMG');
                    % lgd = legend('show');
                    % lgd.FontSize = 8; % Smaller font size

                    hold on;
                    yline(onsetThreshold, 'LineStyle', '-.', 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 1.25, 'DisplayName', 'Onset Threshold');
                    % yline(tempAvg, 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');
                    % yline(onsetThreshold, 'r--', 'LineWidth', 1.25, 'DisplayName', 'Onset Threshold');
                    % yline(tempAvg, '--', 'Color', 1/255*[1 1 1], 'LineWidth', 1.25, 'DisplayName', 'Baseline Average');

                    xline(time(maxIDX), 'LineStyle', '-.', 'Color', [0.6350 0.0780 0.1840], 'LineWidth', 1.25, 'DisplayName', maxText)

                    % Plot vertical line at app.Time(firstIndex), if it exists
                    if roundedOnset ~= 0
                        xline(roundedOnset, 'k--', 'LineWidth', 1.25, 'DisplayName', 'Onset Time');
                    end
                    hold off;
                    title(ax2, "Processed EMG", 'FontSize', 12, 'FontWeight', 'bold');

                    % Title of whole figure
                    sgtitle(OnsetPlotTitle, 'FontSize', 14, 'FontWeight', 'bold')

                    % Shared labels
                    l = axes(eventEMGBurstOnset, 'visible', 'off');
                    l.XLabel.Visible = 'on';
                    xlabel(l, 'Time (s)', 'FontSize', 14, 'FontWeight', 'bold');
                    l.YLabel.Visible = 'on';
                    ylabel(l, 'EMG (mV)', 'FontSize', 14, 'FontWeight', 'bold',...
                        'Position', [-0.085, 0.5, 0]);

                    % Save the figure
                    filename_png = sprintf("%s_%s_Trial-%02d_%s_%s-Arm_EMGBurstOnset.png", app.Events{e}, app.SubID, trial, app.TimePoint, app.RecordingArm);
                    dir_name = app.Events{e}(end - 1:end);

                    if app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_LeftButton
                        app.AffectedArm = 'L';
                    elseif app.PareticLimbButtonGroup.SelectedObject == app.PareticLimb_RightButton
                        app.AffectedArm = 'R';
                    end

                    if strcmp(app.RecordingArm, app.AffectedArm)
                        FigDir = sprintf('%s/%s-%s (Affected)', app.AnalysisDir, app.RecordingArm, dir_name);
                    else
                        FigDir = sprintf('%s/%s-%s (Unaffected)', app.AnalysisDir, app.RecordingArm, dir_name);
                    end

                    if ~exist(FigDir, 'dir')
                        mkdir(FigDir);
                    end
                    saveas(eventEMGBurstOnset, fullfile(FigDir, filename_png));
                    % filename_fig = sprintf("%s_%s_Trial-%d_%s_%s-Arm_RawEMG.fig", eventName, app.SubID, trial, timepoint, affected_arm);
                    % saveas(eventRawEMG, fullfile(EE_fig_dir, filename_fig));
                    close(eventEMGBurstOnset); % comment out for sanity check
                end
            end
            fprintf("\nAll figures saved!\n")

            drawnow; % puts UI Figure at the forefront
            figure(app.UIFigure) % puts UI Figure at the forefront
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadxdfFilesMenu = uimenu(app.FileMenu);
            app.LoadxdfFilesMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadxdfFilesMenuSelected, true);
            app.LoadxdfFilesMenu.Text = 'Load .xdf Files';
            app.LoadConditionFileDirectoryMenu = uimenu(app.FileMenu);
            app.LoadConditionFileDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConditionFileDirectoryMenuSelected, true);
            app.LoadConditionFileDirectoryMenu.Text = 'Load Condition File Directory';
            app.RefreshMenu = uimenu(app.UIFigure);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.LoadAnalysisInformationPanel = uipanel(app.UIFigure);
            app.LoadAnalysisInformationPanel.Title = 'Load Analysis Information';
            app.LoadingText = uilabel(app.LoadAnalysisInformationPanel);
            app.LoadingText.Text = 'Performing Calculations...';
            app.LoadDataButton = uibutton(app.LoadAnalysisInformationPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.SubjectIDEditFieldLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.SubjectIDEditFieldLabel.Text = 'SubjectID';
            app.SubjectIDEditField = uieditfield(app.LoadAnalysisInformationPanel, 'text');
            app.SubjectIDEditField.CharacterLimits = [0 7];
            app.SubjectIDEditField.Placeholder = 'MAPS000';
            app.TimePointDropDownLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.TimePointDropDownLabel.Text = 'Time Point';
            app.TimePointDropDown = uidropdown(app.LoadAnalysisInformationPanel);
            app.TimePointDropDown.Items = {'–', 'T0', 'T1', 'T3', 'T6'};
            app.TimePointDropDown.Placeholder = '–';
            app.TimePointDropDown.Value = '–';
            app.PareticLimbButtonGroup = uibuttongroup(app.LoadAnalysisInformationPanel);
            app.PareticLimbButtonGroup.Title = 'Paretic Limb';
            app.PareticLimb_LeftButton = uiradiobutton(app.PareticLimbButtonGroup);
            app.PareticLimb_LeftButton.Text = 'Left';
            app.PareticLimb_LeftButton.Value = true;
            app.PareticLimb_RightButton = uiradiobutton(app.PareticLimbButtonGroup);
            app.PareticLimb_RightButton.Text = 'Right';
            app.RecordingLimbButtonGroup = uibuttongroup(app.LoadAnalysisInformationPanel);
            app.RecordingLimbButtonGroup.Title = 'Recording Limb';
            app.RecordingLimb_LeftButton = uiradiobutton(app.RecordingLimbButtonGroup);
            app.RecordingLimb_LeftButton.Text = 'Left';
            app.RecordingLimb_LeftButton.Value = true;
            app.RecordingLimb_RightButton = uiradiobutton(app.RecordingLimbButtonGroup);
            app.RecordingLimb_RightButton.Text = 'Right';
            app.AnalyzeButton = uibutton(app.LoadAnalysisInformationPanel, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.FilenameDropDownLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.FilenameDropDownLabel.Text = 'Filename';
            app.FilenameDropDown = uidropdown(app.LoadAnalysisInformationPanel);
            app.FilenameDropDown.Items = {'condition-filename.mat', '–'};
            app.FilenameDropDown.ValueChangedFcn = createCallbackFcn(app, @FilenameDropDownValueChanged, true);
            app.FilenameDropDown.Placeholder = 'condition-filename.mat';
            app.FilenameDropDown.Value = 'condition-filename.mat';
            app.ResultsDirectoryEditFieldLabel = uilabel(app.LoadAnalysisInformationPanel);
            app.ResultsDirectoryEditFieldLabel.Text = 'Results Directory';
            app.ResultsDirectoryEditField = uieditfield(app.LoadAnalysisInformationPanel, 'text');
            app.ResultsDirectoryEditField.Placeholder = '/Users/username/Documents/MAPL/results-directory/';
            app.ChangeDirectoryButton = uibutton(app.LoadAnalysisInformationPanel, 'push');
            app.ChangeDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDirectoryButtonPushed, true);
            app.ChangeDirectoryButton.Text = 'Change Directory';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Trial'; 'Override'; 'EMG Onset (ms)'; 'Peak Time (ms)'; 'Time-to-Peak (ms)'; 'AUC (500ms)'; 'Mod Ratio (500ms)'; 'AUC (3000ms)'; 'Antagonist AUC (3000ms)'; 'AUC (Dur Norm)'; 'Antagonist AUC (Dur Norm)'; 'AUC (Max Norm)'; 'Antagonist AUC (Max Norm)'; 'Trial Baseline Avg (mV)'; 'Trial Max (mV)'; 'Max-Baseline (mV)'; 'Comments'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.SelectionType = 'row';
            app.UITable.ColumnEditable = true;
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Panel = uipanel(app.UIFigure);
            app.ProcessedEMGPlot = uiaxes(app.Panel);
            title(app.ProcessedEMGPlot, 'Processed EMG')
            xlabel(app.ProcessedEMGPlot, 'Time (s)')
            ylabel(app.ProcessedEMGPlot, 'EMG (mV)')
            app.BestBaselinePlot = uiaxes(app.Panel);
            title(app.BestBaselinePlot, 'Smoothed EMG – Lowest Baseline Average')
            xlabel(app.BestBaselinePlot, 'Time (s)')
            ylabel(app.BestBaselinePlot, 'EMG (mV)')
            app.SetWindowPlot = uiaxes(app.Panel);
            title(app.SetWindowPlot, 'Smoothed EMG – Set Window')
            xlabel(app.SetWindowPlot, 'Time (s)')
            ylabel(app.SetWindowPlot, 'EMG (mV)')
            app.RawEMGPlot = uiaxes(app.Panel);
            title(app.RawEMGPlot, 'Raw EMG')
            xlabel(app.RawEMGPlot, 'Time (s)')
            ylabel(app.RawEMGPlot, 'EMG (mV)')
            app.AnalysisOptionsPanel = uipanel(app.UIFigure);
            app.AnalysisOptionsPanel.Title = 'Analysis Options';
            app.SetNewOnsetButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.SetNewOnsetButton.ButtonPushedFcn = createCallbackFcn(app, @SetNewOnsetButtonButtonPushed, true);
            app.SetNewOnsetButton.Text = 'Set New Onset';
            app.ResultsSavedText = uilabel(app.AnalysisOptionsPanel);
            app.ResultsSavedText.Text = 'Results Saved!';
            app.FiguresSavedText = uilabel(app.AnalysisOptionsPanel);
            app.FiguresSavedText.Text = 'Figures Saved!';
            app.LowestBaselineButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.LowestBaselineButton.ButtonPushedFcn = createCallbackFcn(app, @LowestBaselineButtonPushed, true);
            app.LowestBaselineButton.Text = 'Lowest Baseline';
            app.SetWindowButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.SetWindowButton.ButtonPushedFcn = createCallbackFcn(app, @SetWindowButtonPushed, true);
            app.SetWindowButton.Text = 'Set Window';
            app.OverrideButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.OverrideButton.ButtonPushedFcn = createCallbackFcn(app, @OverrideButtonPushed, true);
            app.OverrideButton.Text = 'Override';
            app.SaveResultsButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            app.SaveFiguresButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFiguresButton.Text = 'Save Figures';
            app.NoTrialButton = uibutton(app.AnalysisOptionsPanel, 'push');
            app.NoTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NoTrialButtonPushed, true);
            app.NoTrialButton.Text = 'No Trial';
            app.FigureVisibilityPanel = uipanel(app.UIFigure);
            app.FigureVisibilityPanel.Title = 'Figure Visibility';
            app.RawEMGVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.RawEMGVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @RawEMGVisibleCheckBoxValueChanged, true);
            app.RawEMGVisibleCheckBox.Text = 'Raw Plot';
            app.BestBaselineVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.BestBaselineVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @BestBaselineVisibleCheckBoxValueChanged, true);
            app.BestBaselineVisibleCheckBox.Text = 'Lowest Baseline';
            app.BestBaselineOnsetVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.BestBaselineOnsetVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @BestBaselineOnsetVisibleCheckBoxValueChanged, true);
            app.BestBaselineOnsetVisibleCheckBox.Text = 'Lowest Onset';
            app.ProcessedEMGVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.ProcessedEMGVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @ProcessedEMGVisibleCheckBoxValueChanged, true);
            app.ProcessedEMGVisibleCheckBox.Text = 'Processed Plot';
            app.SetWindowVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.SetWindowVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetWindowVisibleCheckBoxValueChanged, true);
            app.SetWindowVisibleCheckBox.Text = 'Set Window';
            app.SetWindowOnsetVisibleCheckBox = uicheckbox(app.FigureVisibilityPanel);
            app.SetWindowOnsetVisibleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SetWindowOnsetVisibleCheckBoxValueChanged, true);
            app.SetWindowOnsetVisibleCheckBox.Text = 'Set Onset';
            app.UncheckAllButton = uibutton(app.FigureVisibilityPanel, 'push');
            app.UncheckAllButton.ButtonPushedFcn = createCallbackFcn(app, @UncheckAllButtonPushed, true);
            app.UncheckAllButton.Text = 'Uncheck All';
            app.CheckAllButton = uibutton(app.FigureVisibilityPanel, 'push');
            app.CheckAllButton.ButtonPushedFcn = createCallbackFcn(app, @CheckAllButtonPushed, true);
            app.CheckAllButton.Text = 'Check All';
            app.EventTrialNavigationPanel = uipanel(app.UIFigure);
            app.EventTrialNavigationPanel.Title = 'Event & Trial Navigation';
            app.PreviousTrialButton = uibutton(app.EventTrialNavigationPanel, 'push');
            app.PreviousTrialButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousTrialButtonPushed, true);
            app.PreviousTrialButton.Text = '<';
            app.NextTrialButton = uibutton(app.EventTrialNavigationPanel, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Text = '>';
            app.TrialEditField = uieditfield(app.EventTrialNavigationPanel, 'text');
            app.TrialEditField.Placeholder = '–';
            app.EventDropDown = uidropdown(app.EventTrialNavigationPanel);
            app.EventDropDown.Items = {'–', 'Item 1', 'Item 2', 'Item 3', 'Item 4'};
            app.EventDropDown.ValueChangedFcn = createCallbackFcn(app, @EventDropDownValueChanged, true);
            app.EventDropDown.Value = '–';
            app.EventDropDownLabel = uilabel(app.EventTrialNavigationPanel);
            app.EventDropDownLabel.Text = 'Event';
            app.ResetTableButton = uibutton(app.EventTrialNavigationPanel, 'push');
            app.ResetTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTableButtonPushed, true);
            app.ResetTableButton.Text = 'Reset Table';
            app.mVLabel = uilabel(app.UIFigure);
            app.mVLabel.Text = 'mV';
            app.MaxEditFieldLabel = uilabel(app.UIFigure);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAPS_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end