classdef Modified_DIG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        OpenNewFileMenu                 matlab.ui.container.Menu
        CustomMenu                      matlab.ui.container.Menu
        GeorgetownMenu                  matlab.ui.container.Menu
        RochesterMenu                   matlab.ui.container.Menu
        CCFMenu                         matlab.ui.container.Menu
        MCRRMenu                        matlab.ui.container.Menu
        LoadxdffileMenu                 matlab.ui.container.Menu
        RefreshMenu                     matlab.ui.container.Menu
        ProcessPanel                    matlab.ui.container.Panel
        NALabel                         matlab.ui.control.Label
        NonRectifiedLabel               matlab.ui.control.Label
        ProcessButton                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        InvertCheckBox                  matlab.ui.control.CheckBox
        VelocityCheckBox                matlab.ui.control.CheckBox
        DelayCheckBox                   matlab.ui.control.CheckBox
        msEditField                     matlab.ui.control.NumericEditField
        msEditFieldLabel                matlab.ui.control.Label
        HzEditField_3                   matlab.ui.control.NumericEditField
        HzEditField_3Label              matlab.ui.control.Label
        LowPassCheckBox                 matlab.ui.control.CheckBox
        RectifyCheckBox                 matlab.ui.control.CheckBox
        TKEOCheckBox                    matlab.ui.control.CheckBox
        HzEditField_2                   matlab.ui.control.NumericEditField
        HzEditField_2Label              matlab.ui.control.Label
        NotchCheckBox                   matlab.ui.control.CheckBox
        Label                           matlab.ui.control.Label
        HzEditField                     matlab.ui.control.NumericEditField
        HzEditFieldLabel                matlab.ui.control.Label
        EditField                       matlab.ui.control.NumericEditField
        BandPassCheckBox                matlab.ui.control.CheckBox
        FactorEditField                 matlab.ui.control.NumericEditField
        FactorEditFieldLabel            matlab.ui.control.Label
        DownSampleCheckBox              matlab.ui.control.CheckBox
        EnableCheckBox                  matlab.ui.control.CheckBox
        ConditionsListBox               matlab.ui.control.ListBox
        ConditionsListBoxLabel          matlab.ui.control.Label
        MultiConditionAnalysisCheckBox  matlab.ui.control.CheckBox
        ConditionMeanCheckBox           matlab.ui.control.CheckBox
        InitializationButton            matlab.ui.control.Button
        EventTypePanel                  matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        Button                          matlab.ui.control.RadioButton
        BlocksFramesButton              matlab.ui.control.RadioButton
        EventsegTTLButton               matlab.ui.control.RadioButton
        CommentsButton                  matlab.ui.control.RadioButton
        ScaleButton                     matlab.ui.control.Button
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MinLabel                        matlab.ui.control.Label
        XMax                            matlab.ui.control.EditField
        XMin                            matlab.ui.control.EditField
        YMax                            matlab.ui.control.EditField
        YMin                            matlab.ui.control.EditField
        ResetScaleButton                matlab.ui.control.Button
        PleaseCiteLabel                 matlab.ui.control.Label
        Citation                        matlab.ui.control.Label
        EventNameListBoxLabel           matlab.ui.control.Label
        EventNameListBox                matlab.ui.control.ListBox
        ChangepathButton                matlab.ui.control.Button
        DefineConditionsPanel           matlab.ui.container.Panel
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ImportEventsButton              matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        SampleRateHzEditField           matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        TrialSelectPanel                matlab.ui.container.Panel
        DefineConditionButton           matlab.ui.control.Button
        OKButton                        matlab.ui.control.Button
        MultiChanListBox                matlab.ui.control.ListBox
        Path                            matlab.ui.control.EditField
        FileNameLabel                   matlab.ui.control.Label
        SetEventWindowPanel             matlab.ui.container.Panel
        SelectMultipleTrialsButton      matlab.ui.control.Button
        PostCue                         matlab.ui.control.EditField
        PostCueLabel                    matlab.ui.control.Label
        PreCue                          matlab.ui.control.EditField
        PreCueDursLabel                 matlab.ui.control.Label
        TrialSpinner                    matlab.ui.control.Spinner
        TrialSpinnerLabel               matlab.ui.control.Label
        ChannelSpinner                  matlab.ui.control.Spinner
        ChannelSpinnerLabel             matlab.ui.control.Label
        FindallEventsButton             matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        %%% TMS data analysis %%%
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        %{
        % OFFICIAL NAME
        % This is a demo of .mat TMS data exported from LabChart analysis.The main
        % function of the demo includes:
        %
        %
        % Edited by David Cunningham and Bei Zhang from Case Western Reserve University/MetroHealth.
        %   Email:dxc536@case.edu,bxz266@case.edu
        %   Date: 2021/04/23
        %   Version 1.01
        %}

        labChartData        % data package of the TMS data exported from LabChart
        AcqknowledgeData    % data package of the TMS data exported from Acqknowledge
        SignalData          % data package of the TMS data exported from Signal
        BrainVisionData     % data package of the TMS data exported from BrainVision
        SpikeData           % data package of the TMS data exported from Spike
        CustomData          % data package of the TMS data exported from your unique software (e.g. labview)
        BrainSightData      % data package of stereotaxix data exported from brainsight
        BrainSightDataWave  % data package of stereotaxix data exported from brainsight with EMG
        origSampleRate      % define the sample rate variable
        origTime            % save the time when find all events. Because time can be changed in following process
        eventInfo           % define the event/trial information
        totChannel          % define the channel number variable
        totEvent            % define the event/trial number variable
        totBlock            % define the block number variable
        UnprocessedData     % define the data before processed.
        unit                % define the unit of the TMS data
        EventType           % define the type of event 1/2/3 refers to comment/event/block ???
        allData             % define the matrix which saves data of all channels and all trials
        reloadInd           % define a mark to reveal if it is the first time to load the data. If the data is saved, next time just call
        fileName            % name of the data
        dataType            % data type. 1-labChart; 2-Acqknowledge; 3-Signal; 4-BrainVision
        time                % time points of the data
        timeUnit            % unit of the time
        MultiChan           % selected multiple channels
        clickCount          % count the clicks of 'Select Multiple Trials' button
        chosenData          % data to plot
        meanData            % mean data of multiple selected trials
        DSData              % save the down sampled data
        DSTime              % save the down sampled time
        DelayTime           % save the delayed time
        BPData              % save the bandpass data
        NotData             % save the data after Notch filter
        LPData              % save the Low Pass data
        dwnSampleRate       % save the down samplerate
        TKEOData            % save TKEO data
        VelocityData        % save Velocity Data
        RectifyData         % save rectified data
        InvertData          % save inverted data
        DataToPlot          % save the plot data
        TimeToPlot          % save the time vector to plot
        ProcessInd          % data is pre-processed or not
        onsetLine           % onset line to determine the silent period
        offsetLine          % offset line to determine the silent period
        overRideMark        % if override button is clicked
        EMGBurstoverRideMark% if override button in EMG Burst detection is clicked
        rMEPoverrideMark    % resting MEP override
        overRideInd         % save the override onset and offset index
        dataOut             % variables to export
        tempH               % temporally saves the lines of the figure in mV
        WastedTrials        % counts of wasted trials
        Patch               % Patch the prestimulation period
        AnalyzeInd          % Analyze button clicked
        PreStimMean         % Pre stimulation mean
        Ztrans              % if Ztrans is clicked
        Prolific            % if Prolific is clicked
        TrialNum_CurrentGroup  % trial numbers when hit 'add new group'. Create new groups and assignl trials to them
        TrialName_CurrentGroup % group name when hit 'add new group'
        conditionData       % save the condition data if condition applied
        conditionMean       % save the mean data of all conditions in the condition list
        conditionTotalEvent % save the number of trials within the condition
        EventName           % save the event name
        allConditionEvents  % save all event names to the conditions
        allConditionData    % save the data to all condition and event names
        conditionInd        % save the condition index when select multiple conditions
        LowThresh           % Contains Remove LowThresh Value for Active Conditions
        HighThresh          % Contains Remove HighThresh Value for Active Conditions
        LowThreshRest       % Contains Remove LowThresh Value for Rest Conditions
        HighThreshRest      % Contains Remove HighThresh Value for Rest Conditions
        toolboxDir          % Locates where full toolbox directory and contents
        DataDir             % Define Data Directory
        removeIDX           %stores removed trials for group analysis
        conditionIndRmIDX
        boltzY              % RC Boltzman Fit
        boltzX              % RC Boltzman Fit
        rcData              % RC Trial x Trial MEP
        percentMT           % RC X axis
        RC                  % used to save mat file
        OnsetThrePlot       % Contains threshold onset plot
        OffsetThrePlot      % Contains threshold offset plot
        PreMeanPlot         % Contains premean plot
        wave                % Contains raw data for Spike type data
        tick                % Contains tick information for Spike type data
        tickcat             % Contains tick information for Spike type data
        TableRow            % Contains UITable information to delete
        condition           % Contains condition IDX for MultiSelect
        conditionOutcome    % Contains condition outcome for MultiSelect
        On
        importEvent
        stream              % Contains custom data
        ENVDataToPlot
        EMGAnalysisOR
        BrainsightTrialNum
        EDENData
        allDataPosition
        force               %Used to Store Rochester Force Levels
        allForce            %Used to Store Rochester Force Levels In Condition File
        Sulzer              %Custom Sulzer Data
        timeTemp            %Stores Condition Times for Shifting
        x1
        y1
        ThresholdInfo
        SubjectParentFolder
        runner_sr
        %{
        % The meaning of related papameters to the "labChartdata" are listed below:
        %     {'blocktimes'       } --- time of day at which each block was
        %       records.
        %     {'com'              } --- 1. comment channel. -1 means the
        %       comment is in all channels
        %                               2. comment block. the block number
        %                                   the comment was made in.
        %                               3. comment tick posision. The comment
        %                                   position realative to the block start position.
        %                               4. comment type. "1"-comments,
        %                                   "2"-event markers.
        %                               5. comment text map. Indices that
        %                                   refer to entries in "context"
        %     {'context'          } --- information about comments. Each
        %       comment corresponds to one row.
        %     {'data'             } --- all the data points
        %     {'datastart'        } --- the start index of every channel and block s
        %     {'dataend'          } --- end index of every channel and block
        %     {'titles'           } --- channel names
        %     {'rangemin'         } --- minimal range of each block
        %     {'rangemax'         } --- maximal range of each block
        %     {'unittext'         } --- unit of the data
        %     {'unittextmap'      } --- the content of "unittext". If units
        %       across the blocks are the same, each string is only exported
        %       once. '-1' refers to an empty channel
        %     {'blocktimes'       } --- duration of every block
        %     {'tickrate'         } --- tickrate (maximum sample rate) of
        %       each block
        %     {'titles'           } --- names of each channel. Empty
        %     channels will also have a name
        %
        %     {'samplerate'       } --- sample rate of every channel and block
        %     {'firstsampleoffset'} ---
        %     {'comtext'          } --- names of the
        events/comments/blockss

        
        % For empty channel:
        %       samplerate = 0
        %       datastart and dataend indices = -1
        %       unittextmap = -1
        %       rangemax = rangemin = 0
        %}

    end

    methods (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Pointer = 'arrow';
            app.UIFigure.Resize = 'off';
            app.UIFigure.AutoResizeChildren = 'off';
            %             set(app.UIAxes,'defaultLegendAutoUpdate','off'); % stop legend autoupdate
            % %             set(fig,'defaultLegendAutoUpdate','off');
            % app resize according to screen resolution
            %             screenSize = get(groot,'ScreenSize');
            %             screenWidth = screenSize(3);
            %             screenHeight = screenSize(4);
            %             left = screenWidth*0.1;
            %             bottom = screenHeight*0.1;
            %             width = screenWidth*0.7;
            %             height = screenHeight*0.7;
            %             drawnow;
            %             app.UIFigure.Position = [left bottom width height];
            %             warning('off','all')
            %
            app.UIFigure.Resize = 'on';
            %             app.UIFigure.AutoResizeChildren = 'on';
            isdeployed


            %%%%%%%%%%%%%%%%%
            app.reloadInd = 0;                  % it is possible the user load the conditions without loading any file
            app.ProcessInd = 0;


            %!!!!!!!!!!!!! Save startup.m to userpath!!!!!!!!!!!!!!!!!!!!!!%
            %app.toolboxDir = what('TMSAnalysisToolBox'); %defines directory for saving folders
            strcat(userpath,'/SavedSettings.mat');
            if isfile(ans)
                load(ans)
                app.toolboxDir = SavedSettings.toolboxpath;
            else
                msgbox('Define TMSAnalysisToolBox Directory: Directory will be saved for next startup. Redifine directory using the "Define Results Directory" button ')
                app.toolboxDir = uigetdir; %Defines where open file directs you to
                SavedSettings.toolboxpath = app.toolboxDir;
                strcat(userpath,'/SavedSettings');
                save(ans,'SavedSettings');
            end
            app.DataDir = pwd; %Sets data directory to current directory, can be changed with Define Data Directory button
            %=====================================================================================================================================
            % add path if toolbox has not been added to path

            %             p_array=strsplit(path(),pathsep);
            %             if any(strcmp(p_array,[pwd,'/Support Toolbox/PROLIFIC']))
            %             else
            %                 pause(0.5)
            %                 fprintf('Add toolbox to path, please wait...')
            %                 h = msgbox('Add toolbox to path, please wait...');
            %                 addpath(genpath('Support Toolbox/PROLIFIC'));
            %                 delete(h)
            %                 fprintf('Add path finished')
            %             end
            %
            %             if any(strcmp(p_array,[pwd,'/Support Toolbox/fieldtrip-20191127']))
            %             else
            %                 pause(0.5)
            %                 fprintf('Add toolbox to path, please wait...')
            %                 h = msgbox('Add toolbox to path, please wait...');
            %                 addpath(genpath('Support Toolbox/fieldtrip-20191127'));              % toolbox 'fieldtrip' is used here to read BrainVision data
            %                 delete(h)
            %                 fprintf('Add path finished')
            %             end

            %===================================================================================================================================

        end

        % Callback function: ChannelSpinner, FindallEventsButton, 
        % ...and 1 other component
        function FindallEventsButtonPushed(app, event)
            prePointsNum = str2double(app.PreCue.Value) * app.origSampleRate;        % pre and post duration in points
            postPointsNum = str2double(app.PostCue.Value) * app.origSampleRate;

            % app.TrialsnotfoundEditField.Visible = 0;
            % app.TrialsnotfoundEditFieldLabel.Visible = 0;
            % app.ConditionsListBox.Visible = 0;
            % app.ConditionsListBoxLabel.Visible  = 0;

            switch app.dataType
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 1                                                      % data type is labChart
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    % if the event type is comment based

                    if app.reloadInd == 0
                        app.time = [];

                        % Event type is 'Comment'
                        if app.CommentsButton.Value == true
                            % if choose comment based
                            % put all trials data of all channels into a cell
                            % type variable "allData", the following operations just call the varible

                            % determine if the comment name is matched
                            app.EventName = app.EventNameListBox.Value;
                            eventNameInd = find(strcmp(app.EventName, cellstr(app.labChartData.comtext)));     % find event name in the comtext, figure out its sequence number
                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end


                            PEvents = app.eventInfo(app.eventInfo(:,5) == eventNameInd, :);                % find trials under the event name save as Positive Events
                            %%%%%%%%%%%%FORBILAT
                            %temp1 = [2000; diff(Pvents(:,3))];

                            %keepidx = temp1<0 | temp1>=2000;

                            %PEvents = Pvents(keepidx,:);

                            clear temp1 keepidx
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                            app.totEvent = size(PEvents,1);                                                % total event number change with data size under different event name


                            indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                                app.labChartData.dataend, 'UniformOutput', false);                 % convert start and endpoint to index matrix. The output is a cell matrix/
                            indMatrix = cell2mat(indMatrix);

                            % save the data of all trials into one matirx
                            app.allData = cell(app.totChannel,app.totEvent);            % initialize to pre-distribute the space to accelerate the loading speed

                            for iChannel = 1 :app.totChannel
                                %                                 oneChannelData = zeros(app.totEvent,prePointsNum+postPointsNum+1);                                          % initialize the matrix

                                % For a specified channel, save the data of all trails into a
                                % matrix.
                                for nTrial = 1 : app.totEvent

                                    tempTriggerInd = PEvents(nTrial, 3);                                                              % temporally save the comment index relative to the start point of this block
                                    temBlockNum = PEvents(nTrial, 2);                                                                 % temporally save the block number

                                    tempStartPoint = app.labChartData.datastart(iChannel, temBlockNum);                                     % locate the start point of this trail
                                    tempPrePoint = tempTriggerInd -prePointsNum;                                                            % pre duration start point in respect to the trigger index
                                    % Input of editFiled (Text) is string. Need to trans to double
                                    tempPostPoint = tempTriggerInd + postPointsNum;                                                        % post duration start point in respect to the trigger index

                                    oneTrialData = app.labChartData.data(tempStartPoint+tempPrePoint:tempStartPoint+tempPostPoint);         % data of the trial in the channel and the block
                                    app.allData{iChannel,nTrial} = oneTrialData;                                                                % save the data of all trails into one matrix
                                end
                                %                                 app.allData{iChannel} = oneChannelData;                                                                     % save the data of every channel into a cell type variable
                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if the event type is event(e.g. TTL) based

                        if app.EventsegTTLButton.Value == true
                            % save the event location with respect to the
                            % choose channel.
                            app.totEvent = length(app.eventInfo);
                            app.totBlock = size(app.labChartData.datastart,2);                                                              % save the block numbers
                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                            %                             delay = app.Delay.Value * app.origSampleRate/1000;
                            % find index of every block and every channel
                            indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                                app.labChartData.dataend, 'UniformOutput', false);                 % convert start and endpoint to index matrix. The output is a cell matrix
                            indMatrix = cell2mat(indMatrix);
                            eventChanData = app.labChartData.data(indMatrix(app.EventChannel.Value,:));

                            % find all events index in event Channel
                            %                             aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold
                            aboveThreshNorm = eventChanData > app.ThresholdmV.Value;
                            aboveThreshNorm      = diff(aboveThreshNorm, [], 2)';
                            aboveThreshOnset       = find(aboveThreshNorm(:)== 1);                % 1 means 0 jump to 1. The first point >.5 （rising edge of a pulse）
                            %                             aboveThreshOnset(aboveThreshOnset<delay) = [];

                            if isempty(aboveThreshOnset) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            % search interval
                            aboveThreshOnset(find(diff(aboveThreshOnset)<ISI)+1) = [];
                            eventInd = aboveThreshOnset;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                msgbox('No Trials Found')
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.
                            app.allData = cell(app.totChannel,app.totEvent);               % initialize to pre-distribute the space to accelerate the loading speed
                            if eventInd(1)-prePointsNum < 0
                                msgbox('Pre Duration is too large');
                                return
                            end

                            if eventInd(end)+postPointsNum>length(indMatrix(end,:))
                                msgbox('Post Duration is too large');
                                return
                            end

                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) indMatrix(iChannel,x-prePointsNum:x+postPointsNum),...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                oneChanData = arrayfun(@(x) app.labChartData.data(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                app.allData(iChannel,:) = oneChanData';
                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if the event type is block based

                        if app.BlocksFramesButton.Value == true
                            app.totEvent = length(app.labChartData.datastart);                                      % Here the trials refer to the blocks

                            app.allData = cell(app.totChannel,app.totEvent);                                                   % initialize to pre-distribute the space to accelerate the loading speed

                            indMatrix = arrayfun(@(x,y) x:y,app.labChartData.datastart,...
                                app.labChartData.dataend, 'UniformOutput', false);                                  % convert start and endpoint to index matrix. The output is a cell matrix

                            app.allData = arrayfun(@(x) app.labChartData.data(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                indMatrix, 'UniformOutput', false);

                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                    end
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        app.time = (1:length(app.chosenData))/app.origSampleRate;
                        app.origTime = app.time -(app.PreTriggerTime.Value/1000);
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 2                                                      % data type is Acqknowledge
                    if app.reloadInd == 0
                        app.totEvent = [];
                        % save data from original data in channels
                        app.allData = cell(app.totChannel,1);
                        for iChannel = 1 : app.totChannel
                            app.allData{iChannel} = app.AcqknowledgeData.data(:,iChannel)';                                  % transpose to uniform the format
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if event type is comment


                        if app.BlocksFramesButton.Value == true
                            %app.chosenData = app.allData{app.ChannelSpinner.Value};
                            prePointsNum = app.PreTriggerTime.Value/app.AcqknowledgeData.isi;
                            postPointsNum = app.PostTriggerTime.Value/app.AcqknowledgeData.isi;
                            totWindow = prePointsNum + postPointsNum;
                            app.totEvent = size(app.allData{iChannel},2)/totWindow;

                            allData = cell(app.totChannel,1);
                            for iChannel = 1 : app.totChannel
                                allData{iChannel} = app.AcqknowledgeData.data(:,iChannel)';                                  % transpose to uniform the format
                            end


                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1 : app.totChannel


                                tempData = reshape(app.AcqknowledgeData.data(:,iChannel),totWindow,{});

                                app.allData(iChannel,:) = num2cell(tempData,1);


                            end


                            a = cellfun(@transpose,app.allData,'UniformOutput',false);
                            app.allData = a;


                        end
                        app.time = ((1-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if event type is event(TTL) based

                        if app.EventsegTTLButton.Value == true

                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                            eventChanData = app.allData{app.EventChannel.Value};

                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            % search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end

                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            if eventInd(end)+postPointsNum>length(app.allData{app.EventChannel.Value})
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font si
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                tempData = app.AcqknowledgeData.data(:, iChannel);
                                endArray = size(tempData,1);

                                preFill = cellfun(@(x) sum(x<=0), oneChanInd, 'UniformOutput', false);                                %Stores which cells are beyond predur limits
                                postFill = cellfun(@(x) sum(x>=endArray), oneChanInd, 'UniformOutput', false);                        %Stores which cells are beyond PostCue limits
                                oneChanInd = cellfun(@(x) x(x>0), oneChanInd, 'UniformOutput', false);
                                oneChanInd = cellfun(@(x) x(x<endArray), oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x) tempData(cell2num(x))',...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x,y,z) [nan(1,(cell2num(y))),cell2num(x),nan(1,(cell2num(z)))],...                    %Fills in NaN at pre and post dur if necessary
                                    oneChanData, preFill, postFill, 'UniformOutput', false);


                                app.allData(iChannel,:) = oneChanData';


                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                    end

                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};

                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        %\app.time = (1:length(app.chosenData))/app.origSampleRate;
                        app.origTime = app.time;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 3                                                           % data type is Signal
                    if app.reloadInd == 0

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % event type is comment
                        if app.CommentsButton.Value == true

                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % event type is event(e.g., TTL)
                        if app.EventsegTTLButton.Value == true
                            tempData = app.allData;                                                                                     % app.allData will be redefined
                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points

                            eventChanData = tempData(app.EventChannel.Value,:);
                            eventChanData = cell2mat(eventChanData);
                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            % search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end

                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            if eventInd(end)+postPointsNum>length(eventChanData)
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.


                                oneChanData = cell2mat(tempData(iChannel, :));
                                oneChanData = arrayfun(@(x) oneChanData(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                app.allData(iChannel,:) = oneChanData';
                            end

                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if app.BlocksFramesButton.Value == true && isempty(app.SignalData.frameinfo) == 0                                    % if it is block based, adapt the time scale accroding to the pre-Trigger time

                            if isfield(app.SignalData.frameinfo, 'label') == 1
                                CommentNames = extractfield(app.SignalData.frameinfo,'label');
                                app.EventName = app.EventNameListBox_2.Value;
                                eventNameInd = find(strcmp(app.EventName, cellstr(CommentNames)));     % find event name in the comtext, figure out its sequence number
                            else

                                CommentNames = extractfield(app.SignalData.frameinfo,'state');
                                CommentNames = strsplit(num2str(CommentNames));
                                app.EventName = app.EventNameListBox_2.Value;
                                eventNameInd = find(strcmp(app.EventName, cellstr(CommentNames)));
                                %state = strsplit(num2str(state));
                                %app.EventNameListBox_2.Items = cellstr(state);
                                %app.EventNameListBox_2.Visible = 1;
                            end

                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end

                            % in case preduration beyond default pretrigger
                            % time
                            DefaultPreTriggerTime = double(app.SignalData.start);
                            if prePointsNum + DefaultPreTriggerTime*app.origSampleRate>0        % the input pre-duration must be within the default pre-trigger time
                                msgbox('Please re-enter pre-duration')
                                return;
                            end


                            PEvents = app.SignalData.frameinfo(eventNameInd, :);                % find trials under the event name save as Positive Events
                            app.totEvent = length(PEvents);                                                % total events change with data size under different event name
                            app.allData = cell(app.totChannel,app.totEvent); DefaultPreTriggerTime
                            TriggerInd = abs(DefaultPreTriggerTime)*app.origSampleRate;
                            for iChannel = 1 : app.totChannel
                                for nTrial = 1 : app.totEvent
                                    tempData(:,:) = app.SignalData.values(:,iChannel,eventNameInd(nTrial)); % 3 dimensions to 1
                                    app.allData{iChannel,nTrial} = tempData';
                                    app.allData{iChannel,nTrial} = app.allData{iChannel,nTrial}((1+TriggerInd-prePointsNum):(TriggerInd+postPointsNum));
                                end
                            end
                            app.time = (1:length(tempData))/app.origSampleRate;                           % time according to different data length. Points in signal is saved as int32

                            app.time = app.time((1+TriggerInd-prePointsNum):(TriggerInd+postPointsNum));
                            app.time = app.time-(app.PreTriggerTime.Value/1000);


                            app.reloadInd = app.reloadInd + 1;
                            app.origTime = app.time;

                        else
                            CommentNames = app.SignalData.StateInfo;
                            app.EventName = app.EventNameListBox_2.Value;
                            eventNameInd = find(strcmp(app.EventName, cellstr(CommentNames)));


                            DefaultPreTriggerTime = double(app.SignalData.start);
                            if prePointsNum + DefaultPreTriggerTime*app.origSampleRate>0        % the input pre-duration must be within the default pre-trigger time
                                msgbox('Please re-enter pre-duration')
                                return;
                            end


                            PEvents = app.SignalData.StateInfo(:, eventNameInd);                % find trials under the event name save as Positive Events
                            app.totEvent = length(PEvents);                                                % total events change with data size under different event name
                            TriggerInd = abs(DefaultPreTriggerTime)*app.origSampleRate;
                            app.allData = cell(app.totChannel,app.totEvent);
                            for iChannel = 1 : app.totChannel
                                for nTrial = 1 : app.totEvent
                                    tempData(:,:) = app.SignalData.values(:,iChannel,eventNameInd(nTrial)); % 3 dimensions to 1
                                    app.allData{iChannel,nTrial} = tempData';
                                end
                            end
                            app.reloadInd = app.reloadInd + 1;
                            app.time = (1:size(app.SignalData.CfsFile.Chans(1).DS(1).Data,1)).* (app.SignalData.CfsFile.Chans(1).DS(1).xScale);
                            app.origTime = app.time-(abs(DefaultPreTriggerTime));
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    end

                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 4                                                      % data type is BrainVision
                    if app.reloadInd == 0

                        app.totEvent = [];
                        % save data from original data in channels
                        app.allData = cell(app.totChannel,1);
                        for iChannel = 1 : app.totChannel
                            app.allData{iChannel} = app.BrainVisionData.data(iChannel,:)';                                  % transpose to uniform the format
                        end


                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        if app.CommentsButton.Value == true
                            app.EventName = app.EventNameListBox.Value;
                            idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));


                            empty = isempty(idx);
                            if empty == 1
                                msgbox('No Trials Found')
                                return
                            end
                            %% One Column Array for all Event IDX
                            PEvents = app.eventInfo(idx);
                            app.allData = cell(app.totChannel,length(PEvents));
                            for iChannel = 1:app.totChannel
                                for nTrial = 1:length(PEvents)
                                    temptrialIDX = PEvents(nTrial);
                                    tempTrialfVals = app.BrainVisionData.data(iChannel,:);
                                    endArray = length(tempTrialfVals);
                                    window = tempTrialfVals(max(1,temptrialIDX-prePointsNum):min(endArray,temptrialIDX+postPointsNum));
                                    trialfVals= [nan(1,1+prePointsNum-temptrialIDX),window,nan(1,(temptrialIDX+postPointsNum)-numel(tempTrialfVals))];
                                    app.allData{iChannel,nTrial} = trialfVals;


                                    %app.allData{iChannel,nTrial} = app.BrainVisionData.data(iChannel,PEvents(nTrial)-prePointsNum:PEvents(nTrial)+postPointsNum);
                                end
                            end
                            app.totEvent = length(PEvents);
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length

                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if block based
                        if app.BlocksFramesButton.Value == true
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if event type is event(TTL) based

                        if app.EventsegTTLButton.Value == true
                            tempData = app.allData;                                                                                     % app.allData will be redefined
                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points

                            eventChanData = tempData(app.EventChannel.Value,:);
                            eventChanData = cell2mat(eventChanData);
                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            % search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end

                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return;
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            if eventInd(end)+postPointsNum > length(eventChanData)
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel


                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                tempData = app.BrainVisionData.data(iChannel, :);
                                endArray = size(tempData,2);

                                preFill = cellfun(@(x) sum(x<=0), oneChanInd, 'UniformOutput', false);                                %Stores which cells are beyond predur limits
                                postFill = cellfun(@(x) sum(x>=endArray), oneChanInd, 'UniformOutput', false);                        %Stores which cells are beyond PostCue limits
                                oneChanInd = cellfun(@(x) x(x>0), oneChanInd, 'UniformOutput', false);
                                oneChanInd = cellfun(@(x) x(x<endArray), oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x) tempData(cell2num(x))',...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x,y,z) [nan(1,(cell2num(y))),cell2num(x)',nan(1,(cell2num(z)))],...                    %Fills in NaN at pre and post dur if necessary
                                    oneChanData, preFill, postFill, 'UniformOutput', false);


                                app.allData(iChannel,:) = oneChanData';
                                %                                 oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                %                                     eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                %                                 % Must be transposed in column, or all events would be in one row.
                                %
                                %                                 oneChanData = cell2mat(tempData(iChannel, :));
                                %                                 oneChanData = arrayfun(@(x) oneChanData(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                %                                     oneChanInd, 'UniformOutput', false);
                                %
                                %                                 app.allData(iChannel,:) = oneChanData';
                            end

                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                        end
                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                    end
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    if app.BlocksFramesButton.Value == true                                   % if it is block based, adapt the time scale accroding to the pre-Trigger time
                        app.time = (1:length(app.chosenData))/app.origSampleRate;
                    end
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                case 5                                                      % data type is SpikeData
                    if app.reloadInd == 0

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        % if event type is comment based
                        if app.CommentsButton.Value == true
                            %idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));
                            %app.EventName = app.EventNameListBox.Value;

                            empty = isempty(app.eventInfo);
                            if empty == 1
                                msgbox('No Trials Found')
                                return
                            end
                            %% One Column Array for all Event IDX
                            PEvents = app.eventInfo;
                            app.totEvent = length(PEvents);
                            base = CEDS64TimeBase(app.SpikeData.hdr);
                            div = CEDS64ChanDiv(app.SpikeData.hdr,app.wave(app.ChannelSpinner.Value));
                            app.allData = cell(app.totChannel,length(PEvents));
                            app.time = (0-str2num(app.PreCue.Value)):base*div:(0+str2num(app.PostCue.Value));
                            for iChannel = 1:app.totChannel
                                for nTrial = 1:length(PEvents)
                                    temptrialIDX=find(app.tick{1}==PEvents(nTrial));
                                    tempTrialfVals = app.SpikeData.data{iChannel};
                                    endArray = length(tempTrialfVals);
                                    window = tempTrialfVals(max(1,temptrialIDX-prePointsNum):min(endArray,temptrialIDX+postPointsNum));
                                    trialfVals= [nan(1,1+prePointsNum-temptrialIDX),window,nan(1,(temptrialIDX+postPointsNum)-numel(tempTrialfVals))];
                                    app.allData{iChannel,nTrial} = trialfVals;

                                end

                            end
                            app.reloadInd = app.reloadInd + 1;
                        end

                    end

                    % if event type is block based
                    if app.BlocksFramesButton.Value == true
                        if app.reloadInd == 0
                            base = CEDS64TimeBase(app.SpikeData.hdr);
                            div = CEDS64ChanDiv(app.SpikeData.hdr,app.wave(app.ChannelSpinner.Value));

                            app.time = (0-str2num(app.PreCue.Value)):base*div:(0+str2num(app.PostCue.Value));
                            app.allData = cell(app.totChannel,app.totEvent);
                            postPointsNum = postPointsNum*div;
                            prePointsNum = prePointsNum*div;
                            for iChannel =1: app.totChannel
                                for iTrial = 1:app.totEvent
                                    [fRead, fVals, fTime ] = CEDS64ReadWaveF(app.SpikeData.hdr, app.wave(iChannel), app.eventInfo(iTrial), (app.eventInfo(iTrial))-prePointsNum, (app.eventInfo(iTrial))+postPointsNum+div);
                                    app.allData{iChannel,iTrial} = fVals';
                                end
                            end
                        end
                    end
                    if app.EventsegTTLButton.Value == true
                        %%%%%%%%%%%%%%%%%%%%%%%%%%%

                        if app.reloadInd == 0
                            app.totEvent = [];
                            % save data from original data in channels
                            app.allData = cell(app.totChannel,1);
                            for iChannel = 1 : app.totChannel
                                app.allData(iChannel) = app.SpikeData.data(iChannel,:)';                                  % transpose to uniform the format
                            end

                            %%%%%%%%%%%%%%%%%%%%%%%%%%%
                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                            eventChanData = app.allData{app.EventChannel.Value};

                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            % search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end

                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            if eventInd(end)+postPointsNum>length(app.allData{app.EventChannel.Value})
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font si
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                tempData = cell2num(app.SpikeData.data(iChannel,:));
                                endArray = size(tempData,2);


                                preFill = cellfun(@(x) sum(x<=0), oneChanInd, 'UniformOutput', false);                                %Stores which cells are beyond predur limits
                                postFill = cellfun(@(x) sum(x>=endArray), oneChanInd, 'UniformOutput', false);                        %Stores which cells are beyond PostCue limits
                                oneChanInd = cellfun(@(x) x(x>0), oneChanInd, 'UniformOutput', false);
                                oneChanInd = cellfun(@(x) x(x<endArray), oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x) tempData(cell2num(x))',...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x,y,z) [nan(1,(cell2num(y))),cell2num(x)',nan(1,(cell2num(z)))],...                    %Fills in NaN at pre and post dur if necessary
                                    oneChanData, preFill, postFill, 'UniformOutput', false);


                                app.allData(iChannel,:) = oneChanData';


                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end
                    end

                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    app.reloadInd = app.reloadInd + 1;
                    app.origTime = app.time;


                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                case 6                                                      % data type is BrainSight
                    if app.reloadInd == 0
                        if app.CommentsButton.Value == true
                            %idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));
                            %app.EventName = app.EventNameListBox.Value;

                            prePointsNum = round(str2double(app.PreCue.Value) * app.origSampleRate);        % pre and post duration in points
                            postPointsNum = round(str2double(app.PostCue.Value) * app.origSampleRate);

                            app.EventName = app.EventNameListBox.Value;
                            eventNameInd = find(strcmp(app.EventName, cellstr(app.CustomData.eventname)));     % find event name in the comtext, figure out its sequence number
                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end
                            PEvents =app.CustomData.cueIDX(eventNameInd);                % find trials under the event name save as Positive Events 
                            positionEvents = app.CustomData.positionTypeIDX(eventNameInd);
                            app.totEvent = length(PEvents);                                                % total event number change with data size under different event name

                            empty = isempty(app.eventInfo);
                            if empty == 1
                                msgbox('No Trials Found')
                                return
                            end
                            %% One Column Array for all Event IDX


                            app.allData = cell(app.totChannel,length(PEvents));
                            app.allDataPosition = cell(app.totChannel,length(positionEvents));
                            % = cell(1,length(PEvents));

                            % runner_sr = str2double(app.stream{1, 2}.info.nominal_srate(:));
                            % emg_sr = str2double(app.stream{1, 5}.info.nominal_srate(:));
                            % 
                            % % Desired window: ±100 samples of EMG → 100 ms
                            % desired_window_ms = 100 / emg_sr * 1000;  % 100 ms
                            % % Convert to equivalent number of samples in position data
                            % window_samples_position = round((desired_window_ms / 1000) * runner_sr);  % e.g., 10 samples
                            % 
                            % % Convert EMG-sample window to time
                            % window_ms = 100 / emg_sr * 1000;           % 100 ms
                            % window_time_s = window_ms / 1000;          % 0.1 seconds
                            % 
                            % % Convert time to number of position samples
                            % window_samples_pos = round(window_time_s * runner_sr);  % e.g., 10 samples

                            desired_offset_ms = 50;  % user-defined, adjustable
                            % runner_sr = 1 / round(mean(diff(aligned_runner_time)), 4); % actual sampling rate in Hz
                            % runner_sr = 2000;
                            offset_samples = round((desired_offset_ms / 1000) * app.runner_sr);

                            %adding a 1 to the first element to allow for < in for loop
                            levelTime = [1,app.CustomData.levelTime];
                            level = app.CustomData.level(1,:);
                            level = [1,level];
                            for iChannel = 1:app.totChannel % iChannel = iterator for Ch1 (Flexor) or Ch2 (Extensor)
                                for nTrial = 1:length(PEvents) % nTrial = iterator for each trial of the target event
                                    tempTrialfVals = app.CustomData.data; % temp for EMG data
                                    tempTrialPosition = app.CustomData.positionActual; % target position 
                                    tempTrialPositionTime = app.CustomData.positionTime; % time of target position
                                    endArray = length(tempTrialfVals); % size of EMG data
                                    endpositionArray = length(tempTrialPosition); % size of position data (has lower sample rate than the EMG; different sized arrays)
                                    window = tempTrialfVals(iChannel,max(1,PEvents(nTrial)-prePointsNum):min(endArray,PEvents(nTrial)+postPointsNum));
                                    % positionWindow =tempTrialPosition(max(1,positionEvents(nTrial)-100):min(endpositionArray,positionEvents(nTrial)+100));
                                    % positionWindow =tempTrialPosition(max(1,positionEvents(nTrial)-offset_samples):min(endpositionArray,positionEvents(nTrial)+offset_samples));
                                    positionWindow =tempTrialPosition(max(1,positionEvents(nTrial)):min(endpositionArray,positionEvents(nTrial)));
                                    % % Now extract:
                                    % positionWindow = tempTrialPosition(max(1, positionEvents(nTrial) - window_samples_position) : ...
                                    %     min(endpositionArray, positionEvents(nTrial) + window_samples_position));
                                    
                                    % positionTimeWindow = tempTrialPositionTime(max(1,positionEvents(nTrial)-100):min(endpositionArray,positionEvents(nTrial)+100));
                                    % positionTimeWindow = tempTrialPositionTime(max(1,positionEvents(nTrial)-offset_samples):min(endpositionArray,positionEvents(nTrial)+offset_samples));
                                    positionTimeWindow = tempTrialPositionTime(max(1,positionEvents(nTrial)):min(endpositionArray,positionEvents(nTrial)));
                                    % % Then extract the time window around the event
                                    % positionTimeWindow = tempTrialPositionTime( ...
                                    %     max(1, positionEvents(nTrial) - window_samples_pos) : ...
                                    %     min(endpositionArray, positionEvents(nTrial) + window_samples_pos));
                                    
                                    %levelTemp = (iChannel,max(1,PEvents(nTrial)-prePointsNum):min(endArray,PEvents(nTrial)+postPointsNum));
                                    trialfVals= [nan(1,1+prePointsNum-PEvents(nTrial)),window,nan(1,(PEvents(nTrial)+postPointsNum)-numel(tempTrialfVals))];
                                    %positionfVals = [nan(1,1+100-positionEvents(nTrial)),positionTimeWindow,nan(1,(positionEvents(nTrial)+100)-numel(tempTrialPosition))];
                                    app.allData{iChannel,nTrial} = trialfVals;
                                    app.allDataPosition{1,nTrial} = positionWindow;
                                    app.allDataPosition{2,nTrial} = positionTimeWindow;

                                    for i = 2:size(levelTime,2)
                                        if positionTimeWindow(1) < levelTime(i) && positionTimeWindow(1) > levelTime(i-1)
                                            %{1,nTrial} = level(i);
                                            break
                                        end
                                    end


                                end

                            end


                            % if app.FigureCheckCheckBox.Value == 1
                            %     figure;
                            %     plot(app.CustomData.positionTime,(app.CustomData.positionActual))
                            %     hold on
                            %     rochData = plot(app.CustomData.emgTime,app.CustomData.data');
                            %     rochLines = [rochData(1) rochData(2)];
                            % 
                            % 
                            % 
                            %     for i = 1:length(PEvents)
                            %         x1 = xline(app.CustomData.emgTime(PEvents(i)));
                            %         x1.Color ='red';
                            %         x1.LineWidth = 2;
                            %     end
                            % 
                            %     for i = 1:size(app.CustomData.levelTime,2)
                            %         x1 = xline(app.CustomData.levelTime(i));
                            %         x1.Color ='blue';
                            %         x1.LineWidth = 5;
                            %     end
                            % 
                            %     x2 = xline(app.CustomData.eventTime);
                            % 
                            %     legend(rochLines,"Ch1","Ch2")
                            %     %legend(num2str(cell2mat(%app.force)));
                            % 
                            %     hold off
                            % 
                            % end

                            app.totEvent = length(PEvents);
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length

                        end
                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    end
                    app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);
                    app.origTime = app.time;
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                case 7

                    if app.BlocksFramesButton.Value == true
                        if app.reloadInd == 0

                            app.allData = app.BrainSightDataWave;

                        end
                        app.reloadInd = app.reloadInd + 1;
                    end
                    app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);
                    app.origTime = app.time;
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                case 10


                    %%%%%%%%%%%%%%%%%%Block Frame%%%%%%%%%%%%
                    if app.BlocksFramesButton.Value == true
                        if app.reloadInd == 0

                            app.allData = app.CustomData.exoForce;

                        end
                        app.reloadInd = app.reloadInd + 1;
                    end
                    app.totEvent = 1;
                    app.time = ((1:postPointsNum)./app.origSampleRate);
                    app.origTime = app.time;
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};

                case 8

                   if app.reloadInd == 0
                        app.totEvent = [];
                        % save data from original data in channels
                        app.allData = cell(app.totChannel,1);
                       
                        app.time = app.EDENData.data_eogclean.time{1,1};
                        for iChannel = 1 : app.totChannel
                            app.allData{1} = app.EDENData.data(1,:);                                  % transpose to uniform the format
                        end


                        app.allData = app.allData{1,1};


                        restructure = {};

                        for i = 1:size(app.allData,2)

                            restructure{end+1} = app.allData{1,i}(1,:);

                            for p = 2:size(app.allData{1,1},1)
                                restructure{end+1} = app.allData{1,i}(p,:);
                            end

                        end
                       app.totEvent = size(app.allData,2);
                       app.allData = reshape(restructure,size(app.allData{1,1},1),size(app.allData,2));


                        if app.BlocksFramesButton.Value == true
                            if app.reloadInd == 0

                                app.allData = app.allData;

                            end
                            app.reloadInd = app.reloadInd + 1;
                        end
                        app.time = app.time
                        app.origTime = app.time;
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                        if app.EventsegTTLButton.Value == true

                            ISI = app.SearchISIs.Value *app.origSampleRate ;                                                                % define the search gap between adjacent event points
                            eventChanData = app.allData{app.EventChannel.Value};

                            % find all events index in event Channel
                            aboveThresh = find(eventChanData > app.ThresholdmV.Value);                                                  % locate the points above the threshold

                            if isempty(aboveThresh) == 1                                                                                % when no points above the threshold skip to next block
                                app.totEvent = 0;                                                                                       % event number in this block is zero
                            end

                            %{
                            
                            
                            % from the first point of aboveThresh, set
                            % a search interval. The following points
                            % within the search interval will be
                            % eliminated. For example, a = [1 2 3 6],
                            % and search interval is 4, then from the
                            % first point of a, any point smaller then
                            % a(1) + 4 = 5 will be deleted. Then a is
                            % becoming a = [1 6].
                            %}

                            %search interval
                            for i = 1: length(aboveThresh)
                                if i>length(aboveThresh)
                                    continue;
                                end
                                aboveThresh(aboveThresh<=(aboveThresh(i)+ISI) & aboveThresh>aboveThresh(i)) = [];
                            end


                            %                              down = diff(aboveThresh);
                            %                              aboveThresh = aboveThresh(down>2);


                            eventInd = aboveThresh;                             % save index of the event channel
                            app.totEvent = length(eventInd);                    % save the number of events in the event channel
                            if isempty(eventInd) == 1
                                mh = msgbox('No Trials Found');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                                return
                            end

                            % according to the saved event location
                            % of the choosen channel, save the data of
                            % every event in each channel.

                            if eventInd(1)-prePointsNum < 0
                                mh =msgbox('Pre Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font size
                            end

                            if eventInd(end)+postPointsNum>length(app.allData{app.EventChannel.Value})
                                mh =msgbox('Post Duration is too large');
                                th = findall(mh, 'Type', 'Text');                   % get handle to text within msgbox
                                th.FontSize = 14;                                   % Change the font si
                            end

                            app.allData = cell(app.totChannel, app.totEvent);
                            for iChannel = 1:app.totChannel                     % for each channel
                                oneChanInd = arrayfun(@(x) x-prePointsNum:x+postPointsNum,...
                                    eventInd, 'UniformOutput', false)';                                                                % find index of every event period, according to the event index and pre- post-duration
                                % Must be transposed in column, or all events would be in one row.
                                tempData = app.EDENData.data(iChannel,:);
                                endArray = size(tempData,2);

                                preFill = cellfun(@(x) sum(x<=0), oneChanInd, 'UniformOutput', false);                                %Stores which cells are beyond predur limits
                                postFill = cellfun(@(x) sum(x>=endArray), oneChanInd, 'UniformOutput', false);                        %Stores which cells are beyond PostCue limits
                                oneChanInd = cellfun(@(x) x(x>0), oneChanInd, 'UniformOutput', false);
                                oneChanInd = cellfun(@(x) x(x<endArray), oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x) tempData(cell2num(x)),...                         % tempData saves data of all blocks, but need to convert to a uniformed matrix--app.allData
                                    oneChanInd, 'UniformOutput', false);

                                oneChanData = arrayfun(@(x,y,z) [nan(1,(cell2num(y))),cell2num(x),nan(1,(cell2num(z)))],...                    %Fills in NaN at pre and post dur if necessary
                                    oneChanData, preFill, postFill, 'UniformOutput', false);


                                app.allData(iChannel,:) = oneChanData;


                            end
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length
                            app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                        end

                        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                        app.reloadInd = app.reloadInd + 1;

                    end
                    
                    
                    if app.BlocksFramesButton.Value == true %Need to check if this works for all block

                    else
                        app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);
                        app.origTime = app.time;
                    end
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};


                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                case 9                                                      % data type is Georgetown
                    if app.reloadInd == 0
                        if app.CommentsButton.Value == true
                            %idx = find(strcmp(app.EventName,app.BrainVisionData.comtext));
                            %app.EventName = app.EventNameListBox.Value;


                            prePointsNum = round(str2double(app.PreCue.Value)*app.origSampleRate);        % pre and post duration in points
                            postPointsNum = round(str2double(app.PostCue.Value)*app.origSampleRate);

                            app.EventName = app.EventNameListBox.Value;
                            eventNameInd = find(strcmp(app.EventName, cellstr(app.CustomData.eventname)));     % find event name in the comtext, figure out its sequence number
                            % transform 'comtext' from char to cell, so the matrix does not require the same dimension
                            if ~eventNameInd
                                msgbox('No Trials Found');
                                return
                            end
                            PEvents = app.CustomData.time(eventNameInd);                % find trials under the event name save as Positive Events
                            app.totEvent = length(PEvents);                                                % total event number change with data size under different event name

                            empty = isempty(app.eventInfo);
                            if empty == 1
                                msgbox('No Trials Found')
                                return
                            end
                            %% One Column Array for all Event IDX

                            app.totEvent = length(PEvents);
                            app.allData = cell(app.totChannel,length(PEvents));

                            for iChannel = 1:app.totChannel
                                for nTrial = 1:length(PEvents)
                                    tempTrialfVals = app.CustomData.data';
                                    endArray = length(tempTrialfVals);
                                    window = tempTrialfVals(max(1,PEvents(nTrial)-prePointsNum):min(endArray,PEvents(nTrial)+postPointsNum));
                                    trialfVals= [nan(1,1+prePointsNum-PEvents(nTrial)),window,nan(1,(PEvents(nTrial)+postPointsNum)-numel(tempTrialfVals))];
                                    app.allData{iChannel,nTrial} = trialfVals;
                                end
                            end

                            app.totEvent = length(PEvents);
                            app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);                           % time according to different data length

                        end
                        app.reloadInd = app.reloadInd + 1;
                        app.origTime = app.time;
                        app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                    end
                    app.time = ((0-prePointsNum:0+postPointsNum)./app.origSampleRate);
                    app.origTime = app.time;
                    app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % plot the figure

            cla(app.UIAxes);
            app.UIAxes.Toolbar.Visible = 'on';
            app.Patch = [];
            app.AnalyzeInd = 0;   % reset Analyze button in case reselection
            app.DataToPlot = app.chosenData;
            app.TimeToPlot = app.origTime;
            app.UnprocessedData = app.chosenData;


            legend(app.UIAxes,'off');
            plot(app.UIAxes, app.TimeToPlot, app.DataToPlot,'Color','k');                                                        % Plot data of the one seleted trail, but only the first time.
            % The following click would call the Trial/Channel spinner to
            % directly plot the figure.
            app.UIAxes.XLabel.String = app.timeUnit;
            app.UIAxes.YLabel.String = app.unit;
            % plot in mV first, then change the unit accordingly
            % app.UIAxes.YLabel.String = app.mVButton.Text;
            app.SampleRateHzEditField.Value = app.origSampleRate;

            app.tempH = [];
            h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            app.tempH = zeros(size(h,1),length(h(1).YData));
            for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                app.tempH(iLine,:) = h(iLine).YData(1,:);
            end
            % % change the unit
            % ButtonGroupUnitsSelectionChanged(app, event)
            % % if pre-process toolbox is enabled, then directly plot
            % % processed data
            % if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
            %     ProcessButtonPushed(app, event);
            % end
            % app.uVButton.Enable = 'on';
            % app.mVButton.Enable = 'on';
            % app.VButton.Enable = 'on';

            % 
            % app.YMin.Value = num2str(app.UIAxes.YLim(1));
            % app.YMax.Value = num2str(app.UIAxes.YLim(2));
            % app.XMin.Value = num2str(app.UIAxes.XLim(1));
            % app.XMax.Value = num2str(app.UIAxes.XLim(2));

            % %%%Retians Low Pass Envelope In EMG Analysis Option
            % if app.LowPassEnvelopeCheckBox_3.Value == 1
            %     LowPassEnvelopeCheckBox_3ValueChanged(app, event)
            % end


        end

        % Value changed function: ChannelSpinner
        function ChannelSpinnerValueChanged(app, event)
            app.ChannelSpinner.Limits = [1 app.totChannel];                                                   % app.totChannel saves the number of total channel
            if app.reloadInd ~=0
                cla(app.UIAxes);
                app.chosenData = app.allData{app.ChannelSpinner.Value,app.TrialSpinner.Value};
                app.DataToPlot = app.chosenData;
                app.TimeToPlot = app.time;
                plot(app.UIAxes, app.TimeToPlot, app.DataToPlot,'Color','k');                                                        % Plot data of the one seleted trail, but only the first time.
                FindallEventsButtonPushed(app, event)
            end

            %             app.tempH = [];
            %             h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
            %             for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
            %                 app.tempH(iLine,:) = h(iLine).YData(1,:);
            %             end
            %             % change the unit
            %             ButtonGroupUnitsSelectionChanged(app, event)
        end

        % Value changed function: MultiChanListBox, TrialSpinner
        function TrialSpinnerValueChanged(app, event)

            if app.ConditionsListBox.Visible == 1
                OKButtonPushed(app, event)
            else
                % Trial spinner and MultiChanListBox share the same spinner

                TrailsLimit = app.totEvent;                                                                       % app.eventInfo saves the matrix of event information
                app.TrialSpinner.Limits = [1 TrailsLimit];

                % ensure figure be consistent along multi-channels selecting.
                if isempty(app.MultiChan)
                    FindallEventsButtonPushed(app, event)
                elseif length(app.MultiChan)>1

                elseif app.MultiChan ~= app.TrialSpinner.Value
                else
                    FindallEventsButtonPushed(app, event)
                end
            end

        end

        % Value changed function: PreCue
        function PreCueValueChanged(app, event)
            app.PreCue.Value;
            % app.XMin.Value = strcat('-',app.PreCue.Value);
            % InitializationButtonPushed(app, event)

        end

        % Value changed function: PostCue
        function PostCueValueChanged(app, event)
            app.PostCue.Value;
            % app.XMax.Value = app.PostCue.Value;
            % whenever change the PostCue, refind event
            % InitializationButtonPushed(app, event)

        end

        % Value changed function: Path
        function PathValueChanged(app, event)
            app.Path.Value;

        end

        % Size changed function: EventTypePanel
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

            % initialize parameters
            app.PreCue.Enable = 'off';
            app.PostCue.Enable = 'off';
            InitializationButtonPushed(app, event);

            % app.CommentsButton.Value = true;
            % if the data is comment based
            if app.CommentsButton.Value == true
                app.PreCue.Enable = 'on';
                app.PostCue.Enable = 'on';
                app.EventNameListBox.Visible = 'off';
                app.EventNameListBoxLabel.Visible = 'off';
                % app.Panel_17.Visible = 0;
                if app.dataType == 1
                    app.EventNameListBox.Items = cellstr(app.labChartData.comtext);
                end

                if app.dataType == 4
                    comtext = app.BrainVisionData.comtext;
                    comtext(cellfun(@isempty,comtext))=[];            % clear empty point
                    app.EventNameListBox.Items = cellstr(unique(comtext));
                end
            else
                app.EventNameListBox.Visible = 'off';
                app.EventNameListBoxLabel.Visible = 'off';
            end

            if app.dataType == 5
                app.EventNameListBox.Items = cellstr(app.SignalData.event);
            end

            if app.dataType == 6
                app.CustomData.eventname = app.eventInfo;
                app.CustomData.event = unique(app.CustomData.eventname);
                app.CustomData.eventname = app.CustomData.eventname;
                events = app.CustomData.event;
                app.EventNameListBox.Items = events;
            end

            %  if the data is event based
            if app.EventsegTTLButton.Value == true
                % app.Panel_17.Visible = 'on';
                app.PreCue.Enable = 'on';
                app.PostCue.Enable = 'on';
                app.TrialSpinner.Visible = 'on';
                % app.Panel_17.Visible = 0;
            else
                % app.Panel_17.Visible = 'off';
            end

            if app.dataType == 9
                app.CustomData.eventname = app.CustomData.target;
                app.CustomData.event = unique(app.CustomData.eventname);
                app.CustomData.eventname = strsplit(num2str(app.CustomData.eventname));
                events = strsplit(num2str(app.CustomData.event));
                app.EventNameListBox.Items = events;
            end


            % if block based

            if app.BlocksFramesButton.Value == true
                % app.Panel_17.Visible = 0;
                app.PreCue.Enable = 'off';
                app.PostCue.Enable = 'off';
                app.EventNameListBox_2.Visible = 0;

                if app.dataType == 2
                    app.TrialSpinner.Visible = 'on';
                    app.SelectMultipleTrialsButton.Enable = 'on';
                    app.PreCue.Enable = 'off';
                    app.PostCue.Enable = 'off';

                elseif app.dataType == 5
                    app.PreCue.Enable = 'on';
                    app.PostCue.Enable = 'on';

                else
                    app.TrialSpinner.Visible = 'on';
                    app.SelectMultipleTrialsButton.Enable = 'on';
                end


                if app.dataType == 3 && app.BlocksFramesButton.Value == true && isempty(app.SignalData.frameinfo) == 0

                    if isfield(app.SignalData.frameinfo, 'label') == 1
                        unique(extractfield(app.SignalData.frameinfo,'label'));
                        app.EventNameListBox_2.Items = cellstr(ans);
                        app.EventNameListBox_2.Visible = 1;
                    else
                        state = unique(extractfield(app.SignalData.frameinfo,'state'));
                        state = strsplit(num2str(state));
                        app.EventNameListBox_2.Items = cellstr(state);
                        app.EventNameListBox_2.Visible = 1;
                    end

                    app.PreTriggerTime.Value = abs(double(app.SignalData.start)) *1000;
                    app.PreTriggerTime.Enable = 0;
                    app.PreCue.Enable = 'on';
                    app.PostCue.Enable = 'on';

                elseif app.dataType == 3 && isempty(app.SignalData) ~=1
                    unique(app.SignalData.StateInfo);
                    app.EventNameListBox_2.Items = cellstr(ans);
                    app.EventNameListBox_2.Visible = 1;
                    app.PreCue.Enable = 'off';
                    app.PostCue.Enable = 'off';
                else
                    %app.EventNameListBox_2.Visible = 0;
                    %app.PreCue.Enable = 'on';
                    %app.PostCue.Enable = 'on';
                end


                if app.dataType == 7 %brainsight

                    app.EventNameListBox_2.Items = {'Sample'};
                    app.EventNameListBox_2.Visible = 1;
                    %app.PreTriggerTime.Value = abs(double(app.SignalData.start)) *1000;
                    app.PreTriggerTime.Enable = 0;
                    app.PreCue.Enable = 'off';
                    app.PostCue.Enable = 'off';

                end

                if app.dataType == 10 %Exoforce

                    app.EventNameListBox_2.Items = {'File'};
                    app.EventNameListBox_2.Visible = 1;
                    %app.PreTriggerTime.Value = abs(double(app.SignalData.start)) *1000;
                    app.PreTriggerTime.Enable = 0;
                    app.PreCue.Enable = 'off';
                    app.PostCue.Enable = 'off';

                end


            else
                %app.Panel_17.Visible = 0;

                %app.PreCue.Enable = 'on';
                %app.PostCue.Enable = 'on';
            end

        end

        % Value changed function: EventNameListBox
        function EventNameListBoxValueChanged(app, event)
            value = app.EventNameListBox.Value;
            % initialize
            InitializationButtonPushed(app, event)  % whenever choose another event name, find event again instead of call the saved data matrix

            % if conditions are applied, automatically find events when switch event name
            if app.DefineConditionsPanel.Visible == 1
                FindallEventsButtonPushed(app, event);
            end
        end

        % Button pushed function: SelectMultipleTrialsButton
        function SelectMultipleTrialsButtonPushed(app, event)
            app.clickCount  = app.clickCount + 1;
            if isempty(app.totEvent) == 1   %No trail found
                msgbox('Not Applicable');
                return;
            end
            if rem(app.clickCount,2)                    % rem(app.clickCount,2) means multiple trials. Click again, multiple selection panel cancel
                % app.TrialSelectPanel.Visible = 'on';
                app.TrialSpinner.Enable = 'off';
                app.ChannelSpinner.Enable = 'off';
                tempEvents = 1 : app.totEvent;
                if app.dataType == 7
                    app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), BrainsightTrialNum);
                else
                    app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
                end


                % low pass envelope for silent period is only available for
                % single trial, take average is only for multiple trials
                % app.AnalyzetheAverageCheckBox.Enable = 1;

                % app.LowPassEnvelopeCheckBox.Value = 0;
                app.SelectMultipleTrialsButton.Text = 'Click to Select Single Trial';
            else                                % means when single trial
                % app.AnalyzetheAverageCheckBox.Enable = 0;
                % app.AnalyzetheAverageCheckBox.Value = 0;
                app.MultiChan = [];
                app.meanData = [];
                app.MultiChanListBox.Value = {};
                app.TrialSelectPanel.Visible = 'off';
                app.TrialSpinner.Enable = 'on';
                app.ChannelSpinner.Enable = 'on';
                app.ConditionsListBox.Visible = 0;
                FindallEventsButtonPushed(app, event);              %
                app.SelectMultipleTrialsButton.Text = 'Select Multiple Trials';
                if app.EnableCheckBox.Value == true && app.ProcessInd == 1
                    ProcessButtonPushed(app, event);
                else
                    % change the scale accordingly
                    % plot in mV first. Then change the units accordingly
                    cla(app.UIAxes);
                    plot(app.UIAxes,app.TimeToPlot,app.DataToPlot,'Color','k');
                    if ~isempty(app.MultiChanListBox.Value)
                        hold(app.UIAxes,'on')
                        plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                        hold(app.UIAxes,'off')
                    end
                    % app.UIAxes.YLabel.String = app.mVButton.Text;

                    app.tempH = [];
                    h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                    for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                        app.tempH(iLine,:) = h(iLine).YData(1,:);
                    end                    % change the unit
                    % ButtonGroupUnitsSelectionChanged(app, event)

                end

            end

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)

            %
            %             set(app.UIAxes,'WindowButtonDownFcn',  @mouseDown);
            %              set(app.UIAxes,'WindowButtonMotionFcn',@mouseMove);
            %              set(app.UIAxes,'WindowButtonUpFcn',    @mouseUp);

            %              hLineToDrag = [];
            %
            %
            %             function mouseDown(hObject,~)
            %
            %                 % is the mouse down event within the axes?
            %                 if IsCursorInControl(hObject, app.UIAxes)
            %
            %                     currentPoint   = get(hAxes,'CurrentPoint');
            %                     xCurrentPoint  = currentPoint(2,1);
            %
            %                     for k=1:length(app.onsetLine)
            %
            %                         xVertLineCoord = get(app.onsetLine(k),'XData');
            %
            %                         if abs(xCurrentPoint - xVertLineCoord(1)) < 0.1
            %                             hLineToDrag = app.onsetLine(k);
            %                             break;
            %                         end
            %                     end
            %                 end
            %             end


            %%%%%% Override in MEP and SP detection
            if app.overRideMark == 1  % if hit override
                % call the mouse

                currPt = app.UIAxes.CurrentPoint;

                app.overRideInd = [app.overRideInd,currPt(1,1)];
                overRideOnset = app.overRideInd(1);           % overRideOnset and overRideOffset here are time not indext, need to convert to index
                if length(app.overRideInd) == 2
                    overRideOffset = app.overRideInd(2);
                    app.UIFigure.Pointer = 'arrow';
                end
                if length(app.overRideInd) > 2  % in case two more than two clicks occur
                    msgbox('please re-override')
                end
                % plot
                %hold(app.UIAxes,'on');
                if length(app.overRideInd) == 1  % the first line is the onset
                    app.onsetLine = xline(app.UIAxes,overRideOnset,'r','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOnset');
                end
                if length(app.overRideInd)>=2 % the second line is the offset
                    app.offsetLine = xline(app.UIAxes,overRideOffset,'b','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOffset');
                end
                hold(app.UIAxes,'off');
                legend(app.UIAxes)
                % calculate onset and offset after override click
                if length(app.overRideInd)>=2
                    % define parameters
                    SPData = app.DataToPlot;                           % save the data the SP(Silent Period) Data
                    tempRemovedDataToPlot = app.DataToPlot;            %retains individual trials after trials are removed
                    tempRemovedMeanDataToPlot = app.meanData;          %retains average signal after trials are removed


                    if app.ProcessInd == 1
                        NoRecData =  app.LPData;                           % Non rectify data saved
                    else
                        NoRecData =  SPData;
                    end
                    SPTime = app.TimeToPlot;
                    if app.ProcessInd == 1
                        sampleRate = app.dwnSampleRate;
                    else
                        sampleRate = app.origSampleRate;
                    end
                    preStimDurInd = find(SPTime >= (app.PreStimPeriodmsEditField.Value/1000) & SPTime<= app.EditField_3.Value/1000);
                    %                   minDurPoints = round(app.MinDurmsEditField.Value/1000*sampleRate);
                    %                   maxDurPoints = round(app.MinDurmsEditField_2.Value/1000*sampleRate);

                    % define pre-stimulation data in case multiple trials selected
                    tempData = SPData;
                    tempData = num2cell(tempData,2);
                    preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
                    preStimData = cell2mat(preStimData);

                    if app.RemoveCheckBox.Value == 1 %removes trials based on pre-difined thresholds
                        % find average and std of the Pre-stimulation Period
                        preStimMean = sum(preStimData')/(size(preStimData,2));
                        preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                        retainIDX = (find(preStimMean > app.LowThresh.Value & preStimMean < app.HighThresh.Value)); %Find IDX inbetween Low and HighThresh
                        removeIDX = (find(preStimMean < app.LowThresh.Value | preStimMean > app.HighThresh.Value)); %Find IDX outside Low and HighThresh


                        if isempty(app.conditionInd) ~= 1
                            app.conditionIndRmIDX = app.conditionInd;               %!Changes conditionInd to remove trials for group trial analysis
                            for i = 1:length(app.conditionIndRmIDX)                 %|
                                a = cell2num(app.conditionIndRmIDX(i));             %|
                                app.conditionIndRmIDX(i) = {setdiff(a, removeIDX)}; %|
                            end                                                     %|
                            %|


                            N = cellfun(@numel,app.conditionIndRmIDX);             %|
                            app.conditionIndRmIDX = mat2cell(1:sum(N),1,N);        %! Changes conditionInd to remove trials for group trial analysis                                                    %!
                        end

                        removed = length(preStimMean)-length(retainIDX); %Counts the number of removed trials
                        removed = num2str(removed);
                        app.RemovedCount.Text = removed; % Displays number of removed trials
                        SPData = SPData(retainIDX,:); %Remove Rect IDX that are not within threshold
                        NoRecData = NoRecData(retainIDX,:);  %Remove NonRect IDX that are not within treshold
                        tempMultiChan = app.MultiChan(retainIDX); %retains trial numbers after removal
                        tempRemovedDataToPlot = SPData; %retains individual trials after trials are removed
                        tempRemovedMeanDataToPlot = sum(SPData)/size(SPData,1); %retains average signal after trials are removed
                        tempData = SPData;
                        tempData = num2cell(tempData,2);
                        preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false); %Redifine prestimData based on removed trials
                        preStimData = cell2mat(preStimData);

                        if str2num(removed) == length(preStimMean)
                            msgbox('No Trials Retianed')
                            return;
                        end
                    end

                    % analyze the average
                    if app.AnalyzetheAverageCheckBox.Value == 1
                        SPData = sum(SPData)/size(SPData,1);
                        preStimData = sum(preStimData)/size(preStimData,1);
                        NoRecData = sum(NoRecData)/size(NoRecData,1);
                    end

                    preStimMean = sum(preStimData')/(size(preStimData,2));
                    preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                    app.PreStimMeanEditField.Value = double(mean(preStimMean)); %Displays 1) Mean of all Single Trial 2) Mean of average
                    app.PreStimStdEditField.Value = double(mean(preStimStd));   %Displays 1) Mean of all Single Trial STD 2) Average Signal STD

                    %Set Output Mean and STD Display
                    if app.uVButton.Value == true
                        app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value*1000;
                        app.PreStimStdEditField.Value = app.PreStimStdEditField.Value*1000;
                    end
                    if app.mVButton.Value == true
                        app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value;
                        app.PreStimStdEditField.Value = app.PreStimStdEditField.Value;
                    end
                    if app.VButton.Value == true
                        app.PreStimMeanEditField.Value = app.PreStimMeanEditField.Value/1000;
                        app.PreStimStdEditField.Value = app.PreStimStdEditField.Value/1000;
                    end

                    % find onset and offset
                    find(SPTime>=overRideOnset);
                    onsetInd(1,1:size(SPData,1)) = ans(1);
                    find(SPTime>=overRideOffset);
                    offsetInd(1,1:size(SPData,1)) = ans(1);

                    % other analysis
                    % for active MEP and SP
                    for iTrial = 1:size(SPData,1)
                        iTrial
                        SPDur(1,iTrial) = abs((SPTime(onsetInd(1,iTrial))-SPTime(offsetInd(1,iTrial))))*1000;    % Silent Period duration (ms)
                        inverseAreaSP = trapz(SPData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)));

                        if strcmp(app.ListBox.Value, 'Silent Period')
                            spPercentDecrease(1,iTrial) = 100-(mean(SPData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)))/preStimMean(iTrial)*100); % percent of silent period mean value decreased compared with pre stimulation period
                            areaMeanSP = abs(preStimMean(iTrial)*length(onsetInd(1,iTrial):offsetInd(1,iTrial)));        % mean preStimulation times length of silent period duration equals the area
                            areaSP(1,iTrial) = (1-inverseAreaSP/areaMeanSP)*100;      % area of slient period
                        elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                            %mepAmp(1,iTrial) = (max(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)))/preStimMean(iTrial))*100;
                            mepAmp(1,iTrial) = max(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial))) - min(NoRecData(iTrial,onsetInd(1,iTrial):offsetInd(1,iTrial)));
                            areaSP(1,iTrial) = inverseAreaSP;      % area of MEP
                        end
                    end

                    %set output
                    if size(SPData,1) == 1
                        app.NALabel.Text = num2str(SPTime(onsetInd)*1000);         % the unit for onset time is ms
                        app.NALabel_2.Text = num2str((SPTime(offsetInd)-SPTime(onsetInd))*1000);
                        if strcmp(app.ListBox.Value, 'Silent Period')
                            app.NALabel_3.Text = num2str(spPercentDecrease);
                        elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')

                            if app.uVButton.Value == true
                                mepAmp = mepAmp*1000;
                                app.DecreaseLabel.Text = 'Amplitude (uV)';
                            end
                            if app.mVButton.Value == true
                                mepAmp = mepAmp;
                                app.DecreaseLabel.Text = 'Amplitude (mV)';
                            end
                            if app.VButton.Value == true
                                mepAmp = mepAmp/1000;
                                app.DecreaseLabel.Text = 'Amplitude (V)';
                            end
                            app.NALabel_3.Text = num2str(mepAmp);

                        end
                        app.NALabel_4.Text = num2str(areaSP);
                    else
                        app.NALabel.Text = 'N/A';
                        app.NALabel_2.Text = 'N/A';
                        app.NALabel_3.Text = 'N/A';
                        app.NALabel_4.Text = 'N/A';
                    end


                    % save the variables
                    app.dataOut = [];
                    app.dataOut.fileName = app.Path.Value;
                    app.dataOut.AnalyzeOption = app.ListBox.Value;
                    app.dataOut.mPreStim = preStimMean;
                    app.dataOut.SPOnset=SPTime(onsetInd)*1000;
                    app.dataOut.SPDur=SPDur;
                    app.dataOut.AreaSP=areaSP;
                    if strcmp(app.ListBox.Value, 'Silent Period')
                        app.dataOut.spPercentDecrease = spPercentDecrease;
                    elseif strcmp(app.ListBox.Value, 'Active Motor Evoke Potential')
                        app.dataOut.mepAmp = mepAmp;
                    end
                    app.dataOut.ChNum=app.ChannelSpinner.Value;
                    if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                        if app.RemoveCheckBox.Value == 0
                            app.dataOut.TrialNum=app.MultiChan; %output all selected trial numbers
                        else
                            app.dataOut.TrialNum=tempMultiChan; %output retained trial numbers after removal
                        end
                    else
                        app.dataOut.TrialNum=app.TrialSpinner.Value;
                    end

                    app.dataOut.sdOn=app.SDonsetEditField.Value;
                    app.dataOut.sdOff=app.SDoffsetEditField.Value;
                    app.dataOut.minDurSP=app.MinDurmsEditField.Value;
                    app.dataOut.maxDurSP=app.MinDurmsEditField_2.Value;
                    app.dataOut.startTimeSP=app.StartTimemsEditField.Value;
                    app.dataOut.preStimDur=app.EditField_3.Value - app.PreStimPeriodmsEditField.Value;
                    app.dataOut.preStimStartTime = app.PreStimPeriodmsEditField.Value;
                    app.dataOut.LPEnvelopHz = app.HzEditField_4.Value;
                    app.dataOut.OR=app.overRideMark;                % if override, then OR = 1;
                    app.dataOut.MCD = app.MCDCheckBox.Value;
                    app.dataOut.MCDconstant = app.MCDConstantEditField.Value;

                    t = [datetime('now')];
                    app.dataOut.Date = datestr(t);
                    app.dataOut.Unit = app.unit;
                    app.dataOut.SampleRate = sampleRate;

                    if app.RemoveCheckBox.Value == 1
                        app.dataOut.RemovedCount = removed;
                        app.dataOut.LowThresh = app.LowThreshEditField.Value;
                        app.dataOut.HighThresh = app.HighThreshEditField.Value;
                    else
                        app.dataOut.RemovedCount = 'NA';
                        app.dataOut.LowThresh = 'NA';
                        app.dataOut.HighThresh = 'NA';
                    end
                    app.overRideMark = 0;
                end
            end

            %%%%%% Override in EMG burst detection
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if app.EMGBurstoverRideMark == 1   % if hit override
                data = app.RectifyData;                    % app.RectifyData saves the processed data
                time = app.DelayTime;
                sampleRate = app.dwnSampleRate;

                cla(app.UIAxes);
                % plot in mV first. Then change the unit accordingly
                legend(app.UIAxes,'AutoUpdate','off');
                if app.ClearBackgroundLinesCheckBox_3.Value == true
                else
                    plot(app.UIAxes,time,data,'Color','k');
                end


                if app.AnalyzetheAverageCheckBox_3.Value == 1
                    if size(data,1)>1
                        hold(app.UIAxes,'on');
                        data = sum(data)/size(data,1);
                        AveragePlot = plot(app.UIAxes,time,data,'Color','k',"LineWidth",2);
                    end
                    app.ClearBackgroundLinesCheckBox_3.Visible = 1;
                else %  clear background lines can only be checked when take average is checked
                    app.ClearBackgroundLinesCheckBox_3.Visible = 0;
                    app.ClearBackgroundLinesCheckBox_3.Value = 0;
                end
                if size(data,1)>1
                    msgbox('Only for single trial or average data')
                    return;
                end

                currPt = app.UIAxes.CurrentPoint;

                app.overRideInd = [app.overRideInd,currPt(1,1)];
                overRideOnset = app.overRideInd(1);           % overRideOnset and overRideOffset here are time not indext, need to convert to index
                if length(app.overRideInd) == 1
                    overRideOffset = app.overRideInd(1);
                    app.UIFigure.Pointer = 'arrow';
                end
                if length(app.overRideInd) > 1  % in case two more than two clicks occur
                    msgbox('please re-override')
                end
                % plot
                hold(app.UIAxes,'on');
                app.onsetLine = xline(app.UIAxes,overRideOnset,'k','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOnset');
                app.OnsetmsEditField.Value = overRideOnset*1000;
                hold(app.UIAxes,'off');
                legend(app.UIAxes)


                % save variables
                app.dataOut = [];
                app.dataOut.fileName = app.Path.Value;
                app.dataOut.ChNum=app.ChannelSpinner.Value;
                if ~isempty(app.MultiChanListBox.Value)
                    app.dataOut.TrialNum=app.MultiChan;
                else
                    app.dataOut.TrialNum=app.TrialSpinner.Value;
                end
                app.dataOut.AnalyzeOption = app.ListBox.Value;
                app.dataOut.OR=app.EMGBurstoverRideMark;                % if override, then OR = 1;
                app.dataOut.lowPassFreqZ = 'N/A';
                app.dataOut.Zsd = 'N/A';
                app.dataOut.EMGburstOn = overRideOnset*1000;
                app.dataOut.BurstMethod = 'N/A';
                app.dataOut.SampleRate = sampleRate;
                app.dataOut.StartTime = 'N/A';
                t = [datetime('now')];
                app.dataOut.Date = datestr(t);
                app.dataOut.modulationRatio = 'N/A';
                app.dataOut.peakEnv = 'N/A';
                app.dataOut.emgOnset = 'N/A';
                app.dataOut.timeToPeak = 'N/A';

                app.EMGBurstoverRideMark = 0;
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % EMG Envelope
            if app.EMGAnalysisOR == 1


                %Define Data
                if app.LowPassEnvelopeCheckBox_3.Value == 0 && app.SmoothDataCheckBox.Value == 0
                    EMGData = app.DataToPlot;                           % save the data the SP(Silent Period) Data
                    EMGTime = app.TimeToPlot;
                else
                    EMGData = app.ENVDataToPlot;
                    EMGTime = app.TimeToPlot;
                end


                %determine sample Rate
                if app.ProcessInd == 1
                    sampleRate = app.dwnSampleRate;
                else
                    sampleRate = app.origSampleRate;
                end

                % determine if it is multiple trials
                if size(EMGData,1) == 1
                    singleTrial = 1;
                else
                    singleTrial = 0;
                end

                preStimDurInd = find(EMGTime >= (app.PreStimPeriodmsEditField.Value/1000) & EMGTime<= app.EditField_3.Value/1000);
                minDurPointsOn = round(app.MinDurmsEditField_3.Value/1000*sampleRate);    % min duration to determine onset


                tempData = EMGData;       % EMGData would be used to decide: 1. single trial select  2. Multiple channel button clicked but only one trial selected
                tempData = num2cell(tempData,2);
                preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
                preStimData = cell2mat(preStimData);

                % calculate average
                if app.AnalyzetheAverageCheckBox_3.Value == 1
                    EMGData = sum(EMGData)/size(EMGData,1);
                    preStimData = sum(preStimData)/size(preStimData,1);
                    app.ClearBackgroundLinesCheckBox.Visible = 1;
                else %  clear background lines can only be checked when take average is checked
                    app.ClearBackgroundLinesCheckBox.Visible = 0;
                    app.ClearBackgroundLinesCheckBox.Value = 0;
                end


                % find average and std of the Pre-stimulation Period
                preStimMean = mean(preStimData');
                preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                app.PreStimMeanEditField.Value = double(mean(preStimMean)); %Displays 1) Mean of all Single Trial 2) Mean of average
                app.PreStimStdEditField.Value = double(mean(preStimStd));   %Displays 1) Mean of all Single Trial STD 2) Average Signal STD
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                % figure plot
                cla(app.UIAxes);
                % plot in mV first. Then change the unit accordingly
                legend(app.UIAxes,'AutoUpdate','off');
                plot(app.UIAxes,app.TimeToPlot,EMGData,'Color','k',"LineWidth",2);
                if app.AnalyzetheAverageCheckBox.Value == true     % here SP data is the mean of all trials
                    AveragePlot = plot(app.UIAxes,app.TimeToPlot,EMGData,'Color','k',"LineWidth",2);
                end
                hold(app.UIAxes,'on')

                % draw prestimulation mean
                if size(EMGData,1) == 1 % exclude the situation that multiple trials selected but dont analyze average
                    app.PreMeanPlot = plot(app.UIAxes,app.TimeToPlot,repmat(preStimMean,1,length(app.TimeToPlot)),'Color','k','DisplayName', 'PreStimMean');
                end

                StartTimeInd = find(app.TimeToPlot <= app.StartTimemsEditField_4.Value/1000);

                app.On = xline(app.UIAxes,EMGTime(StartTimeInd(end)),'k','LineStyle','--',"LineWidth",1,'DisplayName', 'Start Time');
                hold(app.UIAxes,'off');

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % find onset, if single trial
                if singleTrial == 1
                    lowEndLimit = preStimMean + preStimStd*app.SDonsetEditField_2.Value;
                    %
                    hold(app.UIAxes,'on')
                    app.OnsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(lowEndLimit,1,length(app.TimeToPlot)),'--','Color','r','DisplayName', 'onsetThreshold');
                    %
                    hold(app.UIAxes,'off');
                    app.tempH = [];
                    h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                    for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                        app.tempH(iLine,:) = h(iLine).YData(1,:);
                    end            % change the unit
                    % ButtonGroupUnitsSelectionChanged(app, event)
                end
                %
                %                 OnThreshold = lowEndLimit; % onset threshold
                %                 StartTimeInd = find(EMGTime <= app.StartTimemsEditField_4.Value/1000);
                %                 EMGData(1:StartTimeInd(end)-1) = OnThreshold;
                %                 tempIndAboveThresh = find(EMGData > OnThreshold); % find index where data of iTrial above onset threshold for MEP Latency
                %
                %
                %                 if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                %                     msgbox ('no trial found')
                %                     return;
                %                 end
                %
                %                 tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of every session above/below threshold
                %
                %                 if length(tempIndAboveThresh) == length(EMGData(StartTimeInd(end)-1:end))-1
                %                     onsetInd = StartTimeInd(end)+1;
                %                 elseif isempty(tempBreakPointInd) == 1
                %                     tempBreakPointInd = 1;                           % only one pick detected, then the first point
                %                     (tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd))+1;       % duration of the single session
                %                     if ans>minDurPointsOn
                %                         onsetInd = tempIndAboveThresh(1);
                %                     else
                %                         msgbox ('no trial found')
                %                     end
                %                 else            % more than one session found
                %                     tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above threshold
                %                     tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];        % end index
                %                     tempEndInd-tempStartInd+1;          % save the duration of every session above threshold
                %                     tempIndMeetReq = find(ans>minDurPointsOn);     % the duration of every session above/below threshold.
                %
                %                     if isempty(tempIndMeetReq) == 1
                %                         msgbox ('no trial found')
                %                         return;
                %                     else
                %                         onsetInd = tempStartInd(tempIndMeetReq(1));
                %                     end
                %                 end
                %             else
                %                 %%%%%%%%%%%%%%%%%%%%%%%%PUT IN MULTITRIALDATA
                %                 modulationRatio = zeros(1,size(EMGData,1));          % if choose multiple trials, onsetUbd, offsetInd, SPDur, areaSP and mepAmp saved but not displayed
                %                 peakEnv = zeros(1,size(EMGData,1));
                %                 emgOnset = zeros(1,size(EMGData,1));
                %
                %
                %                 for iTrial = 1:size(EMGData,1)
                %                     tempData = EMGData(iTrial,:);
                %
                %
                %                      lowEndLimit = preStimMean + preStimStd*app.SDonsetEditField_2.Value;
                %
                %                 %hold(app.UIAxes,'on')
                %                 %app.OnsetThrePlot = plot(app.UIAxes,app.TimeToPlot,repmat(lowEndLimit,1,length(app.TimeToPlot)),'--','Color','r','DisplayName', 'onsetThreshold');
                %
                %                 %hold(app.UIAxes,'off');
                %                 %app.tempH = [];
                %                 %h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                %                 %for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                %                 %    app.tempH(iLine,:) = h(iLine).YData(1,:);
                %                 %end            % change the unit
                %                 %ButtonGroupUnitsSelectionChanged(app, event)
                %
                %                 OnThreshold = lowEndLimit; % onset threshold
                %                 StartTimeInd = find(EMGTime <= app.StartTimemsEditField_4.Value/1000);
                %                 tempData(1:StartTimeInd(end)-1) = OnThreshold;
                %                 tempIndAboveThresh = find(tempData > OnThreshold); % find index where data of iTrial above onset threshold for MEP Latency
                %
                %
                %                 if isempty(tempIndAboveThresh) == 1               % if every point is below threshold
                %                     msgbox ('no trial found')
                %                     return;
                %                 end
                %
                %                 tempBreakPointInd = find(diff(tempIndAboveThresh)~=1);        % find last point index of every session above/below threshold
                %
                %                 if length(tempIndAboveThresh) == length(tempData(StartTimeInd(end)-1:end))-1
                %                     onsetInd = StartTimeInd(end)+1;
                %                 elseif isempty(tempBreakPointInd) == 1
                %                     tempBreakPointInd = 1;                           % only one pick detected, then the first point
                %                     (tempIndAboveThresh(end)-tempIndAboveThresh(tempBreakPointInd))+1;       % duration of the single session
                %                     if ans>minDurPointsOn
                %                         onsetInd = tempIndAboveThresh(1);
                %                     elsefind==
                %                         msgbox ('no trial found')
                %                     end
                %                 else            % more than one session found
                %                     tempStartInd = [tempIndAboveThresh(1),tempIndAboveThresh(tempBreakPointInd(1:end)+1)];   % save the start point index of every session above threshold
                %                     tempEndInd = [tempIndAboveThresh(tempBreakPointInd),tempIndAboveThresh(end)];        % end index
                %                     tempEndInd-tempStartInd+1;          % save the duration of every session above threshold
                %                     tempIndMeetReq = find(ans>minDurPointsOn);     % the duration of every session above/below threshold.
                %
                %                     if isempty(tempIndMeetReq) == 1
                %                         msgbox ('no trial found')
                %                         return;
                %                     else
                %                         onsetInd = tempStartInd(tempIndMeetReq(1));
                %                     end
                %                end
                %                end
                %            end

                currPt = app.UIAxes.CurrentPoint;
                app.overRideInd = [app.overRideInd,currPt(1,1)];
                onsetInd = app.overRideInd(1);
                [~,onsetInd] = (min(abs(EMGTime - onsetInd)));
                %onsetInd = find(EMGTime == round(onsetInd,1));
                app.UIFigure.Pointer = 'arrow';
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % plot onset and offset
                hold(app.UIAxes,'on')
                for iTrial = 1:length(onsetInd)
                    if iTrial == 1
                        legend(app.UIAxes,'AutoUpdate','on');
                    else
                        legend(app.UIAxes,'AutoUpdate','off');
                    end
                    app.onsetLine(iTrial) = xline(app.UIAxes,EMGTime(onsetInd(iTrial)),'r','LineStyle','--',"LineWidth",2,'DisplayName', 'onset');
                end


                % ButtonGroupUnitsSelectionChanged(app, event)
                hold(app.UIAxes,'on')
                h = findobj(app.UIAxes,'Type','line');
                modRatioOn = EMGTime(onsetInd(iTrial));
                modRatioOff = EMGTime(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate));
                Lim = get(app.UIAxes,'Ylim');
                maxLim = max(Lim);
                minLim = min(Lim);
                v2 = [modRatioOn minLim;modRatioOff minLim;modRatioOff maxLim; modRatioOn maxLim];
                f2 = [1 2 3 4];
                app.Patch = patch('Faces',f2,'Vertices',v2,'FaceColor','green','FaceAlpha',.1,'Parent',app.UIAxes,'DisplayName', 'ModRatio');   %denote the prestimulation period with patch                % patch the pre stimulation durations

                hold(app.UIAxes,'off')
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                if singleTrial == 1
                    modulationRatio = trapz(EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))))/(preStimMean*round(app.modRatioWindow.Value/1000*sampleRate));
                    rawModAUC = trapz(EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))));
                    %rawPreStimAUC = (preStimMean*round(app.modRatioWindow.Value/1000*sampleRate));
            rawPreStimAUCRect = (preStimMean*round(app.modRatioWindow.Value/1000*sampleRate));
            rawPreStimAUC = trapz(preStimData);
            rawPreStimMean = preStimMean;


                    [tempPeakEnv,idx] = max((EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate)))));
                    peakEnv = mean(EMGData((onsetInd+idx)-(round(10/1000*sampleRate)):(onsetInd+idx)+(round(10/1000*sampleRate))));
                    emgOnset = (EMGTime(onsetInd(iTrial)))*1000;
                    timeToPeak = idx/sampleRate*1000;

                    window = (EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))));

                    [rowsize colsize]=size(window);
                    for i=1:rowsize
                        [angfreq(i),power(i)]=medfreq(window);
                        % Convert normalized angular frequency to Hz
                        freq(i)=angfreq(i)/pi*(sampleRate/2);
                    end
                    medianPowerFreq=[freq'];
                    emgMean = mean(window);
                    emgRMS = rms(window);
                    app.MDFText.Text = num2str(medianPowerFreq,'%.1f');


                    %time = (EMGTime((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))))/(preStimMean*round(app.modRatioWindow.Value/1000*sampleRate));
                    %window = (EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))))/(preStimMean*round(app.modRatioWindow.Value/1000*sampleRate));
                    time = (EMGTime((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))));
                    window = (EMGData((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))));
                    
                    timeToPeak = idx/sampleRate*1000;
                    windowtoPeak = window(1:idx);
                    peakTime = time(1:idx);

                    %figure;plot(peakTime,windowtoPeak)
                    P = polyfit(peakTime,windowtoPeak,1);
                    slope = P(1)
                    intercept = P(2)
                    yfit = P(1)*peakTime+P(2);  % P(1) is the slope and P(2) is the intercept
                    %hold on;
                    %plot(peakTime,yfit,'r-.')


            [rowsize colsize]=size(window);
            for i=1:rowsize
                [angfreq(i),power(i)]=medfreq(window);
                % Convert normalized angular frequency to Hz
                freq(i)=angfreq(i)/pi*(sampleRate/2);
            end
            medianPowerFreq=[freq']
            app.MDFText.Text = num2str(medianPowerFreq,'%.1f');

            emgMean = mean(window);
            emgRMS = rms(window);


                    %                 Y = fft(EMGData);
                    %                 P2 = abs(Y/colsize);
                    %                 P1 = P2(1:colsize/2+1);
                    %                 P1(2:end-1) = 2*P1(2:end-1);
                    %                 f = sampleRate*(0:(colsize/2))/colsize;
                    %
                    %                 figure;plot(f,P1)
                    %                 title('Single-Sided Amplitude Spectrum of X(t)')
                    %                 xlabel('f (Hz)')
                    %                 ylabel('|P1(f)|')
                    %                 title('Single-Sided Amplitude Spectrum of X(t)')


                    maxRatioOn = (EMGTime((onsetInd+idx)-(round(10/1000*sampleRate))));
                    maxRatioOff = (EMGTime((onsetInd+idx)+(round(10/1000*sampleRate))));
                    Lim = get(app.UIAxes,'Ylim');
                    maxLim = max(Lim);
                    minLim = min(Lim);
                    v2 = [maxRatioOn minLim;maxRatioOff minLim;maxRatioOff maxLim; maxRatioOn maxLim];
                    f2 = [1 2 3 4];
                    app.Patch = patch('Faces',f2,'Vertices',v2,'FaceColor','red','FaceAlpha',.3,'Parent',app.UIAxes,'DisplayName', 'MaxPoint');   %denote the prestimulation period with patch                % patch the pre stimulation durations

                    app.NALabel_15.Text = num2str(modulationRatio,'%.2f');
                    app.NALabel_14.Text = num2str(emgOnset,'%.1f');
                    app.NALabel_16.Text = num2str(peakEnv,'%.2e');
                    app.NALabel_17.Text = num2str(timeToPeak);
                    app.dataOut.timeToPeak = timeToPeak;
                    app.dataOut.slope = 1;
                    app.dataOut.intercept = 1;
                    app.dataOut.modulationRatio = modulationRatio;
                    app.dataOut.rawModAUC = rawModAUC;
                    app.dataOut.rawPreStimAUC = rawPreStimAUC;
                    app.dataOut.rawPreStimMean = rawPreStimMean;
                    app.dataOut.rawPreStimAUCRect = rawPreStimAUCRect;
                    app.dataOut.emgOnset = emgOnset;
                    app.dataOut.peakEnv = peakEnv;
                    app.dataOut.preStimMean = preStimMean;


                else
                    %medianPowerFreq = zeros(1,size(EMGData,1));
                    emgMean = [];
                    emgRMS = [];
                    peakEnv = [];
                    slope = [];
                    intercept = [];
                    for i = 1:size(tempData,1)
                        time = (EMGTime((onsetInd(iTrial)):(onsetInd(iTrial)+ round(app.modRatioWindow.Value/1000*sampleRate))));
                        window = (EMGData(i,(onsetInd(1)):(onsetInd(1)+ round(app.modRatioWindow.Value/1000*sampleRate))));
                        [rowsize colsize]=size(window);
                        emgMean(end+1) = mean(window);
                        emgRMS(end+1) = rms(window);
                        [tempPeakEnv,idx] = max(EMGData(i,(onsetInd(1)):(onsetInd(1)+ round(app.modRatioWindow.Value/1000*sampleRate))));
                        peakEnv(end+1) = mean(EMGData(i,(onsetInd+idx)-(round(10/1000*sampleRate)):(onsetInd+idx)+(round(10/1000*sampleRate))));
                        for a=1:rowsize
                            [angfreq(a),power(a)]=medfreq(window);
                            % Convert normalized angular frequency to Hz
                            freq(i)=angfreq(a)/pi*(sampleRate/2);
                        end
                        %medianPowerFreq=[freq'];

                        app.dataOut.preStimMean = preStimMean;
                        app.dataOut.modulationRatio = 'NA';
                        app.dataOut.emgOnset = 'NA';
                        app.dataOut.peakEnv = peakEnv;
                        app.dataOut.timeToPeak = 'NA';
                        
                        timeToPeak = idx/sampleRate*1000;
                        windowtoPeak = window(1:idx);
                        peakTime = time(1:idx);
                        
                        plot(peakTime,windowtoPeak)
                        P = polyfit(peakTime,windowtoPeak,1);
                        slope(end+1) = P(1);
                        intercept(end+1) = P(2);
                        yfit = P(1)*peakTime+P(2);  % P(1) is the slope and P(2) is the intercept
                        hold on;
                        plot(peakTime,yfit,'r-.')
                        
                        app.dataOut.slope = 'na'
                        app.dataOut.intercept = 'na'
                        

                    end


                end


                app.dataOut.preStimMean = preStimMean;
                medianPowerFreq = [freq']

                hold(app.UIAxes,'on')
                h = findobj(app.UIAxes,'Type','line');


                app.dataOut.emgMean = emgMean;
                app.dataOut.emgRMS = emgRMS;
                app.dataOut.medianPowerFreq = medianPowerFreq;
                app.dataOut.lowPassFreqZ = app.HzEditField_6.Value;
                app.dataOut.SampleRate = sampleRate;
                app.dataOut.StartTime = app.StartTimemsEditField_4.Value;
                t = [datetime('now')];
                app.dataOut.Date = datestr(t);
                app.dataOut.OR= app.EMGAnalysisOR;                % if override, then OR = 1;
                app.dataOut.AnalyzeOption = app.ListBox.Value;
                app.dataOut.SampleRate = sampleRate;
                % app.dataOut.TrialNum=app.MultiChanListBox.Value;
                app.dataOut.fileName = app.Path.Value;
                %app.dataOut.ChNum=app.ChannelSpinner.Value;
                app.dataOut.preStimMean = preStimMean;


                app.dataOut.ChNum=app.ChannelSpinner.Value;
                if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                    if app.RemoveCheckBox.Value == 0
                        app.dataOut.TrialNum=app.MultiChan; %output all selected trial numbers
                    else
                        app.dataOut.TrialNum=tempMultiChan; %output retained trial numbers after removal
                    end
                else
                    app.dataOut.TrialNum=app.TrialSpinner.Value;
                end


                %%%%OutPut from Other Buttons

                app.dataOut.Zsd = 'N';
                app.dataOut.EMGburstOn = 'N';
                app.dataOut.BurstMethod = 'Envelope';

                %%%%%
                app.EMGAnalysisOR= 0;

            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % rMEP
            if app.rMEPoverrideMark == 1   % if hit override
                currPt = app.UIAxes.CurrentPoint;
                app.overRideInd = [app.overRideInd,currPt(1,1)];
                overRideOnset = app.overRideInd(1);           % overRideOnset and overRideOffset here are time not indext, need to convert to index
                if length(app.overRideInd) == 2
                    overRideOffset = app.overRideInd(2);
                    app.UIFigure.Pointer = 'arrow';
                end
                if length(app.overRideInd) > 2  % in case two more than two clicks occur
                    msgbox('please re-override')
                end

                % plot
                hold(app.UIAxes,'on');
                if length(app.overRideInd) == 1  % the first line is the onset
                    app.onsetLine = xline(app.UIAxes,overRideOnset,'r','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOnset');
                end
                if length(app.overRideInd)>=2 % the second line is the offset
                    app.offsetLine = xline(app.UIAxes,overRideOffset,'b','LineStyle','--',"LineWidth",2,'DisplayName', 'overrideOffset');
                end
                hold(app.UIAxes,'off');
                legend(app.UIAxes)

                % calculate onset and offset after override click
                if length(app.overRideInd)>=2
                    % define parameters
                    SPTime = app.TimeToPlot;
                    SPData = app.DataToPlot;
                    tempRemovedDataToPlot = app.DataToPlot;            %retains individual trials after trials are removed
                    tempRemovedMeanDataToPlot = app.meanData;          %retains average signal after trials are removed


                    if app.ProcessInd == 1
                        NoRecData =  app.LPData;                           % Non rectify data saved
                    else
                        NoRecData =  SPData;
                    end
                    if app.ProcessInd == 1
                        sampleRate = app.dwnSampleRate;
                    else
                        sampleRate = app.origSampleRate;
                    end


                    preStimDurInd = find(SPTime >= (app.PreStimPeriodmsEditField.Value/1000) & SPTime<= app.EditField_3.Value/1000);
                    minDurPointsOn = round(app.MinDurmsEditField.Value/1000*sampleRate);    % min duration to determine onset
                    minDurPointsOff = round(app.MinDurmsEditField_2.Value/1000*sampleRate);    % min duration to determine offset

                    % define pre-stimulation data in case multiple trials selected
                    tempData = SPData;       % SPData would be used to decide: 1. single trial select  2. Multiple channel button clicked but only one trial selected
                    tempData = num2cell(tempData,2);
                    preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false);
                    preStimData = cell2mat(preStimData);

                    preStimMean = sum(preStimData')/(size(preStimData,2));
                    app.PreStimMean = preStimMean; %Nabila Fix
                    preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                    app.PreStimMeanEditField.Value = mean(preStimMean); %Displays 1) Mean of all Single Trial 2) Mean of average
                    app.PreStimStdEditField.Value = mean(preStimStd);   %Displays 1) Mean of all Single Trial STD 2) Average Signal STD

                    if app.RemoveCheckBox_2.Value == 1 %removes trials based on pre-difined thresholds
                        % find average and std of the Pre-stimulation Period
                        preStimMean = sum(preStimData')/(size(preStimData,2));
                        preStimStd = std(preStimData,0,2);           % calculate standard deviation of every row (default is column)
                        retainIDX = (find(preStimMean > app.LowThreshRest.Value & preStimMean < app.HighThreshRest.Value)); %Find IDX inbetween Low and HighThresh
                        removed = length(preStimMean)-length(retainIDX); %Counts the number of removed trials
                        removed = num2str(removed);
                        app.RemovedCount_2.Text = removed; % Displays number of removed trials
                        SPData = SPData(retainIDX,:); %Remove Rect IDX that are not within threshold
                        NoRecData = NoRecData(retainIDX,:);  %Remove NonRect IDX that are not within treshold
                        tempMultiChan = app.MultiChan(retainIDX); %retains trial numbers after removal
                        tempRemovedDataToPlot = SPData; %retains individual trials after trials are removed
                        tempRemovedMeanDataToPlot = sum(SPData)/size(SPData,1); %retains average signal after trials are removed
                        tempData = SPData;
                        tempData = num2cell(tempData,2);
                        preStimData = cellfun(@(x) x(preStimDurInd), tempData, 'UniformOutput', false); %Redifine prestimData based on removed trials
                        preStimData = cell2mat(preStimData);

                        if str2num(removed) == length(preStimMean)
                            msgbox('No Trials Retained')
                            return;
                        end
                    end

                    % calculate average
                    if app.AnalyzetheAverageCheckBox_2.Value == 1
                        SPData = sum(SPData)/size(SPData,1);
                        NoRecData = sum(NoRecData)/size(NoRecData,1);
                        app.ClearBackgroundLinesCheckBox_2.Visible = 1;
                    else %  clear background lines can only be checked when take average is checked
                        app.ClearBackgroundLinesCheckBox_2.Visible = 0;
                        app.ClearBackgroundLinesCheckBox_2.Value = 0;
                    end

                    % find onset and offset

                    find(SPTime>=overRideOnset);
                    emgon(1,1:size(SPData,1)) = ans(1);
                    find(SPTime>=overRideOffset);
                    emgoff(1,1:size(SPData,1)) = ans(1);

                    % set output
                    if size(SPData,1) == 1      % single trial or averaged
                        rMEPonsetTime = SPTime(emgon(1))*1000;      % onset time, only saves the first onset if multiple onsets detected
                        rMEPoffsetTime = SPTime(emgoff(1))*1000;
                        mepAmp = max(NoRecData(emgon(1):emgoff(1))) - min(NoRecData(emgon(1):emgoff(1)));
                        rMEPduration = rMEPoffsetTime - rMEPonsetTime;
                        inverseAreaSP = trapz(SPData(emgon(1):emgoff(1)));      % must be SPData, because lowPassEnv is only for onset and offset determination

                        app.NALabel_5.Text = num2str(rMEPonsetTime);      % onset time, only saves the first onset if multiple onsets detected
                        app.NALabel_6.Text = num2str(rMEPduration);  % duration
                        app.NALabel_7.Text = num2str(mepAmp);
                        app.NALabel_8.Text = num2str(inverseAreaSP);
                    elseif size(SPData,1)>1
                        for iTrial = 1:size(SPData,1)
                            rMEPonsetTime(1,iTrial) = SPTime(emgon(iTrial))*1000;      % onset time, only saves the first onset if multiple onsets detected
                            rMEPoffsetTime(1,iTrial) = SPTime(emgoff(iTrial))*1000;
                            mepAmp(1,iTrial) = max(NoRecData(iTrial,(emgon(iTrial):emgoff(iTrial)))) - min(NoRecData(iTrial,(emgon(iTrial):emgoff(iTrial))));
                            rMEPduration(1,iTrial) = rMEPoffsetTime(1,iTrial) - rMEPonsetTime(1,iTrial);
                            inverseAreaSP(1,iTrial) = trapz(SPData(iTrial,(emgon(iTrial):emgoff(iTrial))));      % must be SPData, because lowPassEnv is only for onset and offset determination
                        end

                        app.NALabel_5.Text = 'N/A';      % onset time, only saves the first onset if multiple onsets detected
                        app.NALabel_6.Text = 'N/A';  % duration
                        app.NALabel_7.Text = 'N/A';
                        app.NALabel_8.Text = 'N/A';

                    end

                    % save the variables
                    app.dataOut = [];
                    app.dataOut.fileName = app.Path.Value;
                    app.dataOut.AnalyzeOption = app.ListBox.Value;
                    app.dataOut.rMEPonset= rMEPonsetTime;
                    app.dataOut.rMEPoffset = rMEPoffsetTime;
                    app.dataOut.rMEPdur=rMEPduration;
                    app.dataOut.Area=inverseAreaSP;
                    app.dataOut.mepAmp = mepAmp;
                    app.dataOut.lowPassFreqZ = 'N/A';
                    app.dataOut.Zsd = 'N/A';
                    app.dataOut.StartTime = 'N/A';
                    app.dataOut.mPreStim = preStimMean;
                    app.dataOut.ChNum=app.ChannelSpinner.Value;
                    if ~isempty(app.MultiChanListBox.Value) || ~isempty(app.ConditionsListBox.Value)
                        if app.RemoveCheckBox_2.Value == 0
                            app.dataOut.TrialNum=app.MultiChan; %output all selected trial numbers
                        else
                            app.dataOut.TrialNum=tempMultiChan; %output retained trial numbers after removal
                        end
                    else
                        app.dataOut.TrialNum=app.TrialSpinner.Value;
                    end

                    app.dataOut.LPEnvelopHz = app.HzEditField_5.Value;
                    app.dataOut.OR=app.rMEPoverrideMark;                % if override, then OR = 1;
                    t = [datetime('now')];
                    app.dataOut.Date = datestr(t);
                    app.dataOut.Unit = app.unit;
                    app.dataOut.SampleRate = sampleRate;

                    if app.RemoveCheckBox_2.Value == 1
                        app.dataOut.RemovedCount = removed;
                        app.dataOut.LowThresh = app.LowThreshEditField_2.Value;
                        app.dataOut.HighThresh = app.HighThreshEditField_2.Value;
                    else
                        app.dataOut.RemovedCount = 'NA';
                        app.dataOut.LowThresh = 'NA';
                        app.dataOut.HighThresh = 'NA';
                    end


                    app.rMEPoverrideMark = 0;
                end

            end
        end

        % Size changed function: TrialSelectPanel
        function TrialSelectPanelSizeChanged(app, event)
            position = app.TrialSelectPanel.Position;

        end

        % Value changed function: SampleRateHzEditField
        function SampleRateHzEditFieldValueChanged(app, event)
            value = app.SampleRateHzEditField.Value;

        end

        % Button pushed function: DefineConditionButton
        function DefineConditionButtonPushed(app, event)
            app.DefineConditionsPanel.Visible = 1;

            % when apply conditions, can not be processed, in case of been
            % processed two times
            % app.EnableCheckBox.Value = 0;
            % EnableCheckBoxValueChanged(app, event)
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)


            app.TableRow(end+1) = event.Indices(1);
            TableRow = app.TableRow;
            index = event.Indices(1);
            TableRowLast = app.TableRow(end);

            rmIndex = find(TableRow(1:end-1) == index);

            if isempty(rmIndex) == 1
                s = uistyle('BackgroundColor','yellow');
                addStyle(app.UITable,s,'row',TableRow(end));
            else
                app.TableRow = app.TableRow(find(app.TableRow~=index));
                s = uistyle('BackgroundColor','white');
                addStyle(app.UITable,s,'row',index);
            end


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            prompt = {'Enter Filename:'};
            dlgtitle = 'Filename';
            dims = [1 35];
            definput = {'ID_Timepoint_Arm'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            ConditionInfo = app.UITable.Data;
            EventInfo = app.EventName;
            ConditionData = app.allConditionData;
            ForceData = app.allForce; %Rochester Specific
            FileName = app.Path;
            EventsToConditions = app.UITable.Data(:, 7);
            time = app.origTime; %Was app.time - made values = 0(?);
            % time = app.time;
   
            
            if app.ProcessInd == 1
                sampleRate = app.dwnSampleRate;
            else
                sampleRate = app.origSampleRate;
            end

            % check if the data saving folder exists, if no creat one
            conditionsDir = strcat(app.SubjectParentFolder,'/Conditions');
            if exist(conditionsDir, 'dir') ~= 7    % if 'Result' folder is not detected; 7 refers to folder type
                mkdir(conditionsDir);
            end
            OldFolder = pwd;
            cd (conditionsDir)
            % save([char(answer),'.mat'], 'ConditionInfo','EventInfo','ConditionData', 'sampleRate','FileName','EventsToConditions','time');
            %% Save channel data - ANH Last Commit 10/10/2024
            % Ch1_Flexor_EMG = rochData(1);
            % Ch2_Extensor_EMG = rochData(2);            

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Level')
                    h=p;
                    break
                end
            end
            
            for p=1:length(app.stream)
            
                a=app.stream{1,p}.info.type;
                if isequal(a ,'EMG') && length(app.stream{1,p}.time_series)>100
                    b=p;
                    break
                end
            end      
            
            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Events')
                    j=p;
                    break
                end
            end
            
            Events = app.stream{1,j}.time_series;
            EventsTime = app.stream{1,j}.time_stamps;
            
            % EMG_Data = app.CustomData.data;            
            EMG_Data = double(app.stream{1, b}.time_series);
            EMG_Data(all(~EMG_Data, 2), :) = [];           
            Ch1EMG = EMG_Data(1, :); % Channel 1 = Flexor
            Ch2EMG = EMG_Data(2, :); % Channel 2 = Extensor
            EMG_Time = app.stream{1, b}.time_stamps;
            Thresholds = app.stream{1,h}.time_series(1:2,:);
            ThresholdsTime = app.stream{1,h}.time_stamps;
            streams = app.stream;
            
            %% Segment by level time
            % time = EMG_Time(1);
            % num=1;
            % for n=1:length(Thresholds)
            %     time=[time,ThresholdsTime(n)];
            %     num=[num,n];
            % end
            % %disp(["There are "+(length(num)-1)+" seperated recordings. "])
            
            %% Save separations
            b = nan(1,length(ThresholdsTime));
            for n=1:length(ThresholdsTime)
                for m=1:length(EMG_Time)
                    if -(EMG_Time(m)-ThresholdsTime(n))<10e-5
                        b(n)=m;
                        break
                    end
                end
            end
            b=[b,length(EMG_Time)];
            
            EMG_Threshold = cell(2, length(ThresholdsTime));
            % First segment: from index 1 to the first level_time value
            EMG_Threshold{1, 1} = EMG_Data(1, b(1):b(2));
            EMG_Threshold{2, 1} = EMG_Data(2, b(1):b(2));
            
            % Loop through the rest of the level_time array and create segments
            for i = 1:(length(b) - 2)
                clear start stop 
                start = b(i);
                stop = b(i+1) - 1;
                EMG_Threshold{1, i} = EMG_Data(1, start:stop);
                EMG_Threshold{2, i} = EMG_Data(2, start:stop);
                % check = sprintf('\nStart = %i, Stop = %i\n', start, stop);
                % fprintf(check)
            end
            
            clear lastStart lastStop 
            lastStart = b(end - 1);
            lastStop = b(end);
            % check = sprintf('\nStart = %i, Stop = %i\n', lastStart, lastStop);
            % fprintf(check)
            EMG_Threshold{1, end} = EMG_Data(1, lastStart:lastStop);
            EMG_Threshold{2, end} = EMG_Data(2, lastStart:lastStop);
            
            Ch1EMG_Thresholds = EMG_Threshold(1, :);
            Ch2EMG_Thresholds = EMG_Threshold(2, :);
            
            Ch1Thresholds = Thresholds(1, :);
            Ch2Thresholds = Thresholds(2, :);
            
            %% Get max values
            % Ch1MaxWholeRecording = max(abs(Ch1EMG));
            % Ch2MaxWholeRecording = max(abs(Ch2EMG));
            % 
            % Ch1MaxValues = zeros(2, length(Thresholds));
            % Ch2MaxValues = zeros(2, length(Thresholds));
            % 
            % for i = 1:length(Thresholds)
            %     Ch1MaxValues(1, i) = Thresholds(1, i);
            %     Ch1MaxValues(2, i) = max(abs(Ch1EMG_Thresholds{i}));
            %     Ch2MaxValues(1, i) = Thresholds(2, i);
            %     Ch2MaxValues(2, i) = max(abs(Ch2EMG_Thresholds{i}));
            % end

            % % Initialize max values for Ch1 and Ch2
            % Ch1Max = -1;
            % Ch2Max = -1;
            Ch1Max = max(abs(Ch1EMG), [], 'all');
            Ch2Max = max(abs(Ch2EMG), [], 'all');
            EventThresholds = app.ThresholdInfo;

            % 
            % % Get the field names of the RawEMG struct
            % fieldNames = fieldnames(RawEMG);
            % nFields = length(fieldNames);
            % 
            % % Loop through the fields containing Ch1 data (first half of fields)
            % for i = 1:(nFields / 2)
            %     data = RawEMG.(fieldNames{i});  % Access the data for the current field
            %     maxVal = max(abs(data), [], 'all');  % Find the max value in the entire array
            %     Ch1Max = max(Ch1Max, maxVal);   % Update overall max for Ch1
            % end
            % 
            % % Loop through the fields containing Ch2 data (second half of fields)
            % for i = (nFields / 2 + 1):nFields
            %     data = RawEMG.(fieldNames{i});  % Access the data for the current field
            %     maxVal = max(abs(data), [], 'all');  % Find the max value in the entire array
            %     Ch2Max = max(Ch2Max, maxVal);   % Update overall max for Ch2
            % end

            %% Get calibration values
            % technically calibration and tutorial
            % using first level index to segment data
            % CalibrationStopIDX = find(strcmp(Events, 'calibration ext stop'));
            % CalibrationStopTime = EventsTime(CalibrationStopIDX);
            % CalibrationIDX = find(CalibrationStopTime == emg_time);
            
            % PreGameTime = EMG_Time(1:b(1) - 1);
            % 
            % PreGameEMG = cell(2, length(PreGameTime));
            % PreGameEMG{1, 1} = EMG_Data(1, 1:length(PreGameTime));
            % PreGameEMG{2, 1} = EMG_Data(2, 1:length(PreGameTime));
            % 
            % Ch1PreGameMax = max(abs(PreGameEMG{1}));
            % Ch2PreGameMax = max(abs(PreGameEMG{2}));
            
            save([char(answer),'.mat'], 'ConditionInfo','ConditionData', 'sampleRate','EventsToConditions',...
                'EMG_Data', 'EMG_Time', 'ThresholdsTime', 'Thresholds', 'EMG_Threshold', 'Ch1EMG_Thresholds', 'Ch2EMG_Thresholds',...
                'Ch1EMG', 'Ch2EMG', 'Events', 'EventsTime', 'Ch1Thresholds', 'Ch2Thresholds', 'Ch1Max', 'Ch2Max', 'time', 'EventThresholds', 'streams');
            
            cd(OldFolder);
            drawnow; %Puts UI Figure at the forfront
            

            %% in case of double click
            app.SaveButton.Enable = 0;      % save condition button can only be avaliable when apply button clicked
            pause(0.5)
            app.SaveButton.Enable = 1;      % save condition button can only be avaliable when apply button clicked

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)


            app.TableRow = [];
            s = uistyle('BackgroundColor','white');
            addStyle(app.UITable,s,'table','');

            app.UITable.Data = [];
            app.allConditionData = [];
            app.conditionData = [];
            app.conditionInd = [];

            % app.GroupAnalysisPanel.Visible = 0;
            % app.RecruitmentCurvePanel.Visible = 0;

            app.ConditionsListBox.Visible = 0;
            app.ConditionsListBoxLabel.Visible  = 0;
            app.TrialSelectPanel.Visible = 0;
            % app.MultiConditionAnalysisCheckBox.Visible = 0;
            % app.ConditionMeanCheckBox.Visible = 0;

            cla(app.UIAxes);

            if app.dataType ~= 6
                FindallEventsButtonPushed(app, event)
            end


        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;

        end

        % Button pushed function: ChangepathButton
        function ChangepathButtonPushed(app, event)

            app.toolboxDir = uigetdir; %Defines where open file directs you to
            SavedSettings.toolboxpath = app.toolboxDir;
            strcat(userpath,'/SavedSettings');
            save(ans,'SavedSettings');
        end

        % Button pushed function: ImportEventsButton
        function ImportEventsButtonPushed(app, event)

            app. importEvent = 1;
            for i = 1: size(app.EventNameListBox.Items,2)
                app.EventNameListBox.Value = cell2mat(app.EventNameListBox.Items(i));
                InitializationButtonPushed(app, event);     % whenever choose another event name, find event again instead of call the saved data matrix
                FindallEventsButtonPushed(app, event);
                TrialofThisGroup = 1 : app.totEvent;

                if size(TrialofThisGroup,2) ~= 1

                    TrialofThisGroup = num2str(TrialofThisGroup);
                    channel  = num2str(app.ChannelSpinner.Value);
                    event_name = app.EventNameListBox.Value(2:end);
                    event_temp = "Ch" + channel + event_name;
                    event_temp = char(event_temp);

                    groupInfo = {TrialofThisGroup,event_temp,channel,[],[],[],app.EventNameListBox.Value};

                    app.UITable.Data = [app.UITable.Data;groupInfo];

                    % initialization
                    % app.TrialsnotfoundEditField.Visible = 0;
                    % app.TrialsnotfoundEditFieldLabel.Visible = 0;
                    % app.Ztrans = 0;
                    % app.Prolific = 0;
                    %             app.ConditionsListBox.Visible = 1;
                    %             app.ConditionsListBoxLabel.Visible  = 1;

                    % extract condition trials
                    length(app.UITable.Data(:,4));       % find condition name
                    ConditionTrials = app.UITable.Data(ans,1);          % find condition trials to chosen condition name
                    %ConditionTrials = str2num(cell2num(ConditionTrials));
                    ConditionTrials = str2num(cell2mat(ConditionTrials));

                    % define chosen data
                    legend(app.UIAxes,'off');
                    app.chosenData = cell2mat(app.allData(app.ChannelSpinner.Value,ConditionTrials)');
                    app.conditionData = app.chosenData;                                     % save the condition data
                    app.conditionTotalEvent = size(app.conditionData,1);
                    app.meanData = sum(app.chosenData)/size(app.chosenData,1);

                    app.DataToPlot = app.chosenData;
                    app.TimeToPlot = app.time;

                    % if data been pre-processed, plot by pre-process pannel
                    % if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                    %     ProcessButtonPushed(app, event);
                    % else
                    % change the scale accordingly
                    % plot in mV first, the change the unit if need
                    cla(app.UIAxes);
                    app.AnalyzeInd = 0;
                    % app.Patch = [];
                    plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
                    if size(ConditionTrials,2)
                        % if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
                        % else
                        hold(app.UIAxes,'on')
                        %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
                        hold(app.UIAxes,'off')
                        % end
                    end
                    % app.UIAxes.YLabel.String = app.mVButton.Text;
                    app.tempH = [];
                    h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
                    for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
                        app.tempH(iLine,:) = h(iLine).YData(1,:);
                    end                    % change the unit
                    % ButtonGroupUnitsSelectionChanged(app, event)
                    % end

                    % save the data to the condition
                    if length(app.UITable.Data(:,2)) == 1
                        app.allConditionData = {{app.chosenData}};                     % save the data as cell, it is possible data cross different files have different data length
                        if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
                            app.allConditionEvents = {app.EventName};
                        end
                    else
                        app.allConditionData = [app.allConditionData; {{app.chosenData}}];
                        if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
                            app.allConditionEvents = [app.allConditionEvents;app.EventName];
                        end
                    end

                    % display trials of this condition in trial select list box
                    tempEvents = 1 : app.conditionTotalEvent;
                    app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
                    % ListBoxValueChanged3(app, event)


                else
                end


            end


%             app. importEvent = 1;
%             for i = 1: size(app.EventNameListBox_2.Items,2)
%                 app.EventNameListBox_2.Value = cell2num(app.EventNameListBox_2.Items(i));
%                 InitializationButtonPushed(app, event);     % whenever choose another event name, find event again instead of call the saved data matrix
%                 FindallEventsButtonPushed(app, event);
%                 TrialofThisGroup = 1 : app.totEvent;
%                 TrialofThisGroup = num2str(TrialofThisGroup);
%                 channel  = num2str(app.ChannelSpinner.Value);
%                 groupInfo = {TrialofThisGroup,app.EventNameListBox_2.Value,channel,[],[],[],app.EventNameListBox_2.Value};
% 
%                 app.UITable.Data = [app.UITable.Data;groupInfo];
% 
%                 % initialization
%                 app.TrialsnotfoundEditField.Visible = 0;
%                 app.TrialsnotfoundEditFieldLabel.Visible = 0;
%                 app.Ztrans = 0;
%                 app.Prolific = 0;
%                 %             app.ConditionsListBox.Visible = 1;
%                 %             app.ConditionsListBoxLabel.Visible  = 1;
% 
%                 % extract condition trials
%                 length(app.UITable.Data(:,4));       % find condition name
%                 ConditionTrials = app.UITable.Data(ans,1);          % find condition trials to chosen condition name
%                 %ConditionTrials = str2num(cell2num(ConditionTrials));
%                 ConditionTrials = str2num(cell2mat(ConditionTrials));
% 
%                 % define chosen data
%                 legend(app.UIAxes,'off');
%                 app.chosenData = cell2mat(app.allData(app.ChannelSpinner.Value,ConditionTrials)');
%                 app.conditionData = app.chosenData;                                     % save the condition data
%                 app.conditionTotalEvent = size(app.conditionData,1);
%                 app.meanData = sum(app.chosenData)/size(app.chosenData,1);
% 
%                 app.DataToPlot = app.chosenData;
%                 app.TimeToPlot = app.time;
% 
%                 % if data been pre-processed, plot by pre-process pannel
%                 if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
%                     ProcessButtonPushed(app, event);
%                 else
%                     % change the scale accordingly
%                     % plot in mV first, the change the unit if need
%                     cla(app.UIAxes);
%                     app.AnalyzeInd = 0;
%                     app.Patch = [];
%                     plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
%                     if size(ConditionTrials,2)
%                         if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
%                         else
%                             hold(app.UIAxes,'on')
%                             plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
%                             hold(app.UIAxes,'off')
%                         end
%                     end
%                     app.UIAxes.YLabel.String = app.mVButton.Text;
%                     app.tempH = [];
%                     h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
%                     for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
%                         app.tempH(iLine,:) = h(iLine).YData(1,:);
%                     end                    % change the unit
%                     ButtonGroupUnitsSelectionChanged(app, event)
%                 end
% 
%                 % save the data to the condition
%                 if length(app.UITable.Data(:,2)) == 1
%                     app.allConditionData = {{app.chosenData}};                     % save the data as cell, it is possible data cross different files have different data length
%                     if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
%                         app.allConditionEvents = {app.EventName};
%                     end
%                 else
%                     app.allConditionData = [app.allConditionData; {{app.chosenData}}];
%                     if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
%                         app.allConditionEvents = [app.allConditionEvents;app.EventName];
%                     end
%                 end
% 
%                 % display trials of this condition in trial select list box
%                 tempEvents = 1 : app.conditionTotalEvent;
%                 app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
%                 ListBoxValueChanged3(app, event)
% 
%             end
% 
%             answer = questdlg('Load New Group Name','Load New Group Name','Yes','No','No');
%             switch answer
%                 case 'Yes'
%                     ld = 0;
%                 case 'No'
%                     ld = 1;
%             end
% 
%             if ld == 0
%                 opts = spreadsheetImportOptions("NumVariables",50); %Can include upto 50 cells in excel
%                 strcat(app.toolboxDir,'\*.xlsx');
%                 [fname, path] = uigetfile(ans);
%                 strcat(path,fname)
%                 loadIn = (readmatrix(ans, opts,"UseExcel", false));
%                 tempRemove =all(cellfun('isempty',loadIn)); %removes empty cells
%                 loadIn(:,tempRemove)=[];
%                 drawnow; %Puts UI Figure at the forfront
%                 figure(app.UIFigure) %Puts UI Figure at the forfront
%                 groupName = [];
%                 for iSample = 1:size(loadIn,1)
%                     groupName = [groupName,{strjoin(loadIn(iSample,:),'_')}]; %Combine Cells and delim by '_'
%                 end
%                 app.UITable.Data(:,2) = groupName;
% 
%             else
%             end
% 
%             
%             
            
%             app. importEvent = 1;
%             for i = 1: size(app.EventNameListBox.Items,2)
%                 app.EventNameListBox.Value = cell2num(app.EventNameListBox.Items(i));
%                 InitializationButtonPushed(app, event);     % whenever choose another event name, find event again instead of call the saved data matrix
%                 FindallEventsButtonPushed(app, event);
%                 TrialofThisGroup = 1 : app.totEvent;
% 
%                 if size(TrialofThisGroup,2) ~= 1
% 
%                     TrialofThisGroup = num2str(TrialofThisGroup);
%                     channel  = num2str(app.ChannelSpinner.Value);
%                     groupInfo = {TrialofThisGroup,app.EventNameListBox.Value,channel,[],[],[],app.EventNameListBox.Value};
% 
%                     app.UITable.Data = [app.UITable.Data;groupInfo];
% 
%                     % initialization
%                     app.TrialsnotfoundEditField.Visible = 0;
%                     app.TrialsnotfoundEditFieldLabel.Visible = 0;
%                     app.Ztrans = 0;
%                     app.Prolific = 0;
%                     %             app.ConditionsListBox.Visible = 1;
%                     %             app.ConditionsListBoxLabel.Visible  = 1;
% 
%                     % extract condition trials
%                     length(app.UITable.Data(:,4));       % find condition name
%                     ConditionTrials = app.UITable.Data(ans,1);          % find condition trials to chosen condition name
%                     %ConditionTrials = str2num(cell2num(ConditionTrials));
%                     ConditionTrials = str2num(cell2mat(ConditionTrials));
% 
%                     % define chosen data
%                     legend(app.UIAxes,'off');
%                     app.chosenData = cell2mat(app.allData(app.ChannelSpinner.Value,ConditionTrials)');
%                     app.conditionData = app.chosenData;                                     % save the condition data
%                     app.conditionTotalEvent = size(app.conditionData,1);
%                     app.meanData = sum(app.chosenData)/size(app.chosenData,1);
% 
%                     app.DataToPlot = app.chosenData;
%                     app.TimeToPlot = app.time;
% 
%                     % if data been pre-processed, plot by pre-process pannel
%                     if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
%                         ProcessButtonPushed(app, event);
%                     else
%                         % change the scale accordingly
%                         % plot in mV first, the change the unit if need
%                         cla(app.UIAxes);
%                         app.AnalyzeInd = 0;
%                         app.Patch = [];
%                         plot(app.UIAxes,app.TimeToPlot,app.DataToPlot);
%                         if size(ConditionTrials,2)
%                             if app.EnableCheckBox.Value == true  && app.ProcessInd == 1
%                             else
%                                 hold(app.UIAxes,'on')
%                                 %plot(app.UIAxes,app.TimeToPlot,app.meanData,'Color','k',"LineWidth",2);
%                                 hold(app.UIAxes,'off')
%                             end
%                         end
%                         app.UIAxes.YLabel.String = app.mVButton.Text;
%                         app.tempH = [];
%                         h = findobj(app.UIAxes,'Type','line');   % select every line in the figure
%                         for iLine = 1:size(h,1)                 % app.tempH temporally save the lines of the figure in mV
%                             app.tempH(iLine,:) = h(iLine).YData(1,:);
%                         end                    % change the unit
%                         ButtonGroupUnitsSelectionChanged(app, event)
%                     end
% 
%                     % save the data to the condition
%                     if length(app.UITable.Data(:,2)) == 1
%                         app.allConditionData = {{app.chosenData}};                     % save the data as cell, it is possible data cross different files have different data length
%                         if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
%                             app.allConditionEvents = {app.EventName};
%                         end
%                     else
%                         app.allConditionData = [app.allConditionData; {{app.chosenData}}];
%                         if isempty(app.EventName) ~=1                                      % in some cases, event name does not apply
%                             app.allConditionEvents = [app.allConditionEvents;app.EventName];
%                         end
%                     end
% 
%                     % display trials of this condition in trial select list box
%                     tempEvents = 1 : app.conditionTotalEvent;
%                     app.MultiChanListBox.Items = arrayfun(@(x) cellstr(num2str(x)), tempEvents);     % put available trials into list box
%                     ListBoxValueChanged3(app, event)
% 
% 
% 
%                 else
%                 end
% 
% 
% 
% 
% 
%             end
% 
%             %%%%%ORIGINAL WAY TO CHANGE GROUP NAME
%             %                              answer = questdlg('Load New Group Name','Load New Group Name','Yes','No','No');
%             %                     switch answer
%             %                         case 'Yes'
%             %                             ld = 0;
%             %                         case 'No'
%             %                             ld = 1;
%             %                     end
%             %
%             %                     if ld == 0
%             %                         opts = spreadsheetImportOptions("NumVariables",50); %Can include upto 50 cells in excel
%             %                         strcat(app.toolboxDir,'/*.xlsx');
%             %                         [fname, path] = uigetfile(ans);
%             %                         strcat(path,fname)
%             %                         loadIn = (readmatrix(ans, opts,"UseExcel", false));
%             %                         tempRemove =all(cellfun('isempty',loadIn)); %removes empty cells
%             %                         loadIn(:,tempRemove)=[];
%             %                         drawnow; %Puts UI Figure at the forfront
%             %                         figure(app.UIFigure) %Puts UI Figure at the forfront
%             %                         groupName = [];
%             %                         for iSample = 1:size(loadIn,1)
%             %                             groupName = [groupName,{strjoin(loadIn(iSample,:),'_')}]; %Combine Cells and delim by '_'
%             %                         end
%             %                         app.UITable.Data(:,2) = groupName;
%             %
%             %                     else
%             %                     end


        end

        % Menu selected function: RochesterMenu
        function RochesterMenuSelected(app, event)
            RefreshMenuSelected(app, [])

            app.dataType = 6;
            trunk = 0; %helps determine if first trial is removed
            % app.FigureCheckCheckBox.Visible = 'on';
            %====================================================================================================%
            %% Load the raw .xdf file
            ff = fixfocus;
            [fname,path]=uigetfile( ...
                {'*.xdf;*.mat', 'XDF and MAT Files (*.xdf, *.mat)';
                '*.xdf',       'XDF Files (*.xdf)';
                '*.mat',       'MAT Files (*.mat)';
                '*.*',         'All Files (*.*)'} ...
                );
            delete(ff)
            file=[path, fname];

            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Loading EVA File',...
                'Indeterminate','on');

            if strcmp(file(end-3:end), ".xdf")
                app.stream = load_xdf(file);
            elseif strcmp(file(end-3:end), ".mat")
                load(file);
                app.stream = cropped_streams;
            end

            % Close dialog box
            close(d)

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %% Get directory information
            % parts = strsplit(path, '/');
            % file_id = parts{end-2};
            % file_parts = strsplit(file_id, '_');
            % id = strjoin(file_parts, ' ');
            [exp_folder, ~] = fileparts(path);
            [app.SubjectParentFolder, ~] = fileparts(exp_folder);

            %% Extract data from individual streams from .xdf file
            for p=1:length(app.stream)

                a=app.stream{1,p}.info.type;
                if isequal(a ,'EMG') && length(app.stream{1,p}.time_series)>100
                    b=p; % just finds which index is associated with the 'EMG' data stream
                    break
                end
            end

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Runner') && length(app.stream{1,p}.time_series)>10
                    %   if isequal(a ,'Slide') && length(app.stream{1,p}.time_series)>10
                    c=p; % just finds which index is associated with the 'Runner' data stream
                    break
                end
            end

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Events')
                    j=p; % just finds which index is associated with the 'Events' data stream
                    break
                end
            end
            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Level')
                    h=p; % just finds which index is associated with the 'Level' data stream
                    break 
                end
            end
            level = app.stream{1,h}.time_series(1:2,:);
            level_time=app.stream{1,h}.time_stamps;

            events=app.stream{1,j}.time_series;
            events_time=app.stream{1,j}.time_stamps;
            emg=app.stream{1,b}.time_series;
            emg_ch1 = emg(2,:); % channel 1
            emg_ch2 = emg(4,:); % channel 2            
            emg( all(~emg,2), : ) = [];
            app.origSampleRate=str2double(app.stream{1,b}.info.nominal_srate);
            emg_time = app.stream{1,b}.time_stamps;
            % position_time_raw=app.stream{1,c}.time_stamps;
            position_time=app.stream{1,c}.time_stamps;
            % position=app.stream{1,c}.time_series(2, :);
            position=app.stream{1,c}.time_series;

            %% Seperation - Multiple trail in one recording seperation
            time = emg_time(1);
            num=1;
            found_stop = false;
            for n=1:length(events)
                if events(n) == "STOP" % consider changing to just last index
                    time=[time,events_time(n)];
                    num=[num,n];
                    found_stop = true;         
                end
            end
            
            % Aaron's changes
            if ~found_stop
                time = [time, events_time(end)];
                num = [num, length(events)];
            end

            %disp(["There are "+(length(num)-1)+" seperated recordings. "])

            % %% Back to David's code
            % %-------------------------------------------------------------------------------------------------------------------------------------------------%
            % %% Save seperations
            % b=[1];
            % for n=2:length(time)-1
            %     for m=1:length(emg_time)
            %         if -(emg_time(m)-time(n))<10e-5
            %             b=[b,m];
            %             break
            %         end
            %     end
            % end
            % b=[b,length(emg_time)];
            % 
            % %app.origSampleRate = str2double(app.stream{1,5}.info.nominal_srate);
            % 
            % %Find Event Index expected zone started
            % 
            % startIDX = find(strcmp(events,'game start'));
            % stopIDX = find(strcmp(events,'STOP')); % consider changing this to last index instead of finding 'STOP' index
            % 
            % % Aaron's changes
            % if isempty(stopIDX)
            %     stopIDX = length(events);
            % end
            % 
            % allEventIDX = find(strcmp(events,'expected zone start'));
            % %%Need to get to game start with 2 runs
            % if startIDX >=2
            %     newEventIDX = [];
            %     for i = 1:length(startIDX)
            %         tempEventIDX = allEventIDX(allEventIDX>startIDX(i) & allEventIDX<stopIDX(i));
            %         newEventIDX = [newEventIDX, tempEventIDX(4:end)];
            %     end
            %     %%%%%%%%%%%%%%%%%%%%
            % else
            %     eventIDX = find(strcmp(events,'expected zone start'));
            %     eventIDX = eventIDX(eventIDX>startIDX);
            %     newEventIDX = eventIDX(4:end);
            % end
            % eventTime = events_time(newEventIDX);
            % 
            % rEMGb=round(emg_time(:),3);
            % rEMGa=round(eventTime,3);
            % [~,emgCueIDX]=ismembertol(rEMGa,rEMGb);
            % 
            % positionActual = position(2,:); %Is it 1 or 2?
            % rPositionb=round(position_time(:),1);
            % rPositiona=round(eventTime,1);
            % [~,positionTypeIDX]=ismembertol(rPositiona,rPositionb);
            % 
            % %% Back to David's code           
            % %%%LabelEvents
            % totEvent = size(eventTime,2);
            % r2 = ones(1,totEvent);
            % eventTimelabel = [eventTime;r2]';
            % 
            % totLevel = size(level_time,2);
            % r2 = zeros(1,totLevel);
            % levelTimelabel = [level_time;r2]';
            % 
            % allLabels = [eventTimelabel;levelTimelabel];
            % 
            % allLabelssorted = sortrows(allLabels);
            % 
            % t = find(allLabelssorted(:,2) == 0);
            % 
            % allLabelssorted(t+1,2)=3;
            % 
            % if allLabelssorted(end,1) == 0
            %     allLabelssorted(end,:) = [];
            % end
            % 
            % 
            % %%%%
            % %
            % %             figure;plot(position_time,positionActual)
            % % %
            % %             for i = 1:length(eventTime)
            % %                 x1 = xline(eventTime(i));
            % %                 x1.Color ='red';
            % %                 x1.LineWidth = 1;
            % %             end
            % %
            % %             for i = 1:size(level,2)
            % %                 x1 = xline(level_time(i));
            % %                 x1.Color ='blue';
            % %                 x1.LineWidth = 2;
            % %             end
            % %
            % %
            % %
            % %             x2 = xline(events_time,'-',events);
            % 
            % %             if positionTypeIDX(1) == 0
            % %                 positionTypeIDX = positionTypeIDX(2:end);
            % %                 trunk = 1;
            % %                 msgbox('Warning: First Trial Removed')
            % %             end
            % 
            % % % Desired time offset (e.g., same as EMG 100 samples = 100 ms)
            % % runner_sr = str2double(app.stream{1, 2}.info.nominal_srate(:));
            % % emg_sr = str2double(app.stream{1, 5}.info.nominal_srate(:));
            % % offset_ms = 100 / emg_sr * 1000;         % 100 ms
            % % offset_s = offset_ms / 1000;             % 0.1 s
            % % offset_samples_pos = round(offset_s * runner_sr);   % e.g., 10 samples if fs_pos = 100
            % % 
            % % % Apply safe indexing
            % % idx = min(length(positionActual), positionTypeIDX + offset_samples_pos);
            % % positionState = positionActual(idx);
            % 
            % positionState = positionActual((positionTypeIDX+100)); %captures the value without risk of finding index before
            % 
            % 
            % runTemp = emgCueIDX;
            % size(b,2)
            % r = 1;
            % for i = 2:size(b,2)
            %     runTemp(runTemp <= b(i) & runTemp >= r) = r;
            %     r = r+1;
            % end
            % 
            % tempAllLabelssorted = allLabelssorted;
            % storeRun = [];
            % for i = 1:(length(num)-1)
            % 
            %     find(runTemp == i,1,'first')
            %     storeRun(end+1) = ans;
            %     allLabelssorted(ans,2)=3;
            % 
            % end
            % %runTemp = string(runTemp);
            % 
            % %%%%
            % rHand = {};
            % for i = 1:(length(num)-1)
            %     iTemp = string(i);
            %     groupName = menu('Run'+iTemp+'_Hand?','Left','Right');
            %     Run = string(i);
            %     if groupName == 1
            %         Hand = "Le";
            %     else
            %         Hand = "Ri";
            %     end
            %     rHand{end+1} = append(Hand);
            % end
            % 
            % 
            % a = positionState;
            % nAll = {}
            % for i = 1:(length(num)-1)
            %     r1 = find(runTemp==i);
            %     a =double(a);
            %     m = num2cell(a,1);
            %     m= cellfun(@(x)  num2str(x), m, 'UniformOutput', false);
            %     if rHand{i} == "Ri"
            %         m1 = replace(m(min(r1):max(r1)),'0','F'); % left lane for right hand is flex
            %         m1 = replace(m1,'1','R'); % middle lane for right hand is rest
            %         m1 = replace(m1,'2','E'); % right lane for right hand is extend
            % 
            %         N1 ={};
            %         for idx = 1:(length(m1)-1)
            %             N1 =[N1,[m1{idx} m1{idx+1}]];
            %         end
            % 
            %         N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);
            % 
            %     else
            %         m1 = replace(m(min(r1):max(r1)),'0','E'); % left lane for left hand is extend
            %         m1 = replace(m1,'1','R'); % middle lane for left hand is rest
            %         m1 = replace(m1,'2','F'); % right lane for left hand is flex
            % 
            %         N1 ={};
            %         for idx = 1:(length(m1)-1)
            %             N1 =[N1,[m1{idx} m1{idx+1}]];
            %         end
            % 
            %         N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);
            % 
            % 
            %     end
            %     nAll = [nAll,N1];
            % end
            % 
            % positionState = string(nAll);
            % 
            % out = allLabelssorted(all(allLabelssorted,2),:);
            % first = find(out(:,2) == 3);
            % 
            % 
            % %%%%%
            % app.CustomData.data = double(emg);
            % %app.CustomData.cueIDX = emgCueIDX;
            % app.CustomData.emgTime = emg_time;
            % app.CustomData.positionActual = positionActual;
            % app.CustomData.positionTime = position_time;
            % %app.CustomData.eventTime = eventTime;
            % app.CustomData.level = level;
            % app.CustomData.levelTime = level_time;
            % %app.CustomData.positionTypeIDX = positionTypeIDX;
            % 
            % %positionState = string(positionState);
            % 
            % 
            % %             if trunk == 1
            % %                 runTemp = runTemp(2:end);
            % %                 trunk = 0;
            % %
            % %             end
            % 
            % for i = 1:(length(num)-1)
            % 
            %     find(runTemp == i,1,'first')
            %     runTemp(ans)=[];
            % 
            % end
            % 
            % tempFirst = first;
            % for i = 1:(length(num)-1)
            % 
            %     tempFirst(find(tempFirst ==storeRun(i)))=[];
            % 
            % end
            % 
            % 
            % 
            % 
            % 
            % 
            % runTemp = string(runTemp);
            % 
            % comment = strcat(runTemp,positionState);
            % ttEmp = strcat(runTemp,positionState);
            % 
            % drawnow; %Puts UI Figure at the forfront
            % figure(app.UIFigure) %Puts UI Figure at the forfront
            % 
            % 
            % 
            % 
            % comment(tempFirst)=[];
            % 
            % 
            % 
            % emgCueIDX(first)=[];
            % eventTime(first)=[];
            % positionTypeIDX(first) = [] ;
            % 
            % app.Path.Value = fname;
            % app.totChannel = size(emg,1);
            % app.eventInfo = comment;
            % app.totEvent = size(comment,2);
            % app.unit = 'V';
            % app.CustomData.cueIDX = emgCueIDX;
            % app.CustomData.eventTime = eventTime;
            % app.CustomData.positionTypeIDX = positionTypeIDX;
            % 
            % app.CustomData.TrialLevel = zeros(3, length(app.CustomData.eventTime));
            % app.CustomData.TrialLevel(1, :) = app.CustomData.eventTime;
            % 
            % if round(level(1, end), 2) == 0.20 || round(level(1, end), 2) == 0.21
            %     level(1, end) = level(1, end) * -1;
            % % else 
            % %     level(1, end) = level(1, end);
            % end
            % 
            % if round(level(2, end), 2) == 0.20 || round(level(2, end), 2) == 0.21
            %     level(2, end) = level(2, end) * -1;
            % % else
            % %     level(1, end) = level(1, end);
            % end
            % 
            % level_counter = 1;
            % % for i = 1:length(app.CustomData.eventTime)
            % %     if app.CustomData.eventTime(i) >= app.CustomData.levelTime(level_counter)
            % %         level_counter = level_counter + 1;
            % %     end
            % % 
            % %     app.CustomData.TrialLevel(2, i) = level(1, level_counter); % channel 1
            % %     app.CustomData.TrialLevel(3, i) = level(2, level_counter); % channel 2
            % % end
            % 
            % for i = 1:length(app.CustomData.eventTime)
            %     % Increment level_counter only if it doesn’t exceed length of levelTime
            %     while level_counter < length(app.CustomData.levelTime) && ...
            %             app.CustomData.eventTime(i) >= app.CustomData.levelTime(level_counter)
            %         level_counter = level_counter + 1;
            %     end
            % 
            %     % If level_counter exceeds the number of level columns, cap it
            %     if level_counter > size(level, 2)
            %         app.CustomData.TrialLevel(2, i) = level(1, end); % channel 1
            %         app.CustomData.TrialLevel(3, i) = level(2, end); % channel 2
            %     else
            %         app.CustomData.TrialLevel(2, i) = level(1, level_counter); % channel 1
            %         app.CustomData.TrialLevel(3, i) = level(2, level_counter); % channel 2
            %     end
            % end
            % 
            % % Get unique comment values as strings
            % channel_events = "Ch" + comment;
            % uniqueEvents = unique(channel_events);
            % 
            % % Initialize a struct
            % app.ThresholdInfo = struct();
            % 
            % % Loop through each unique comment and find indices
            % for i = 1:length(uniqueEvents)
            %     currentComment = uniqueEvents(i); % currentComment is now a string
            %     % indicesStruct.(string(currentComment)) = find(channel_events == currentComment);
            %     eventTrialIDX = find(channel_events == currentComment);
            %     ch1TrialThresh = app.CustomData.TrialLevel(2, eventTrialIDX);
            %     ch2TrialThresh = app.CustomData.TrialLevel(3, eventTrialIDX);
            % 
            %     app.ThresholdInfo.(string(currentComment))(1, :) = eventTrialIDX;
            %     app.ThresholdInfo.(string(currentComment))(2, :) = ch1TrialThresh;
            %     app.ThresholdInfo.(string(currentComment))(3, :) = ch2TrialThresh;
            % end
            % 
            % switch app.unit
            %     case 'V'                    % other units?
            %         unitsMutiple = 1000;
            %         app.CustomData.data = app.CustomData.data .* unitsMutiple;
            % end
            % app.unit = 'mV';

            %% Aaron's modifications to David's code
            %-------------------------------------------------------------------------------------------------------------------------------------------------%
            %% Adjusting Events and Runner timestamps
            position_temp = position(2, :);
            blockStartIDX = find(strcmp(events,'expected zone start'));
            blockStopIDX = find(strcmp(events,'expected zone stop'));

            % events_dt = diff(events_time);
            % events_dt = [0 events_dt];
            % 
            % events_time_adjusted = events_dt;
            % mask = round(events_dt, 2) == 3 | round(events_dt, 2) == 0.5;
            % events_time_adjusted(mask) = round(events_dt(mask), 2);
            % 
            % 
            % events_fixed = [events_time(1)];
            % i = 2;
            % while i <= length(events_time)
            %     adj = events_fixed(i - 1) + events_time_adjusted(i);
            %     events_fixed(i) = adj;
            %     i = i + 1;
            % end

            %% Initial interpolation
            startIDX = find(strcmp(events, 'START'));
            
            start_time = events_fixed(startIDX);
            % offset = start_time - position_time(1);
            % runner_time_offset = position_time + offset;

            blockStartTimes_fixed = events_fixed(blockStartIDX);
            blockStopTimes_fixed = events_fixed(blockStopIDX);

            % target_times = [blockStartTimes_fixed blockStopTimes_fixed]; % add the tutorial times
            target_times = blockStartTimes_fixed;
            [target_times_sorted, ~] = sort(target_times);

            states = interp1(position_time, position_temp, target_times_sorted, 'previous');
            states(isnan(states)) = 1;

            % change first state following the start of the level to be 'rest' (1)
            for i = 1:length(level_time)
                % Find first index in blockStopTimes where time > level_time(i)
                idx = find(target_times_sorted > level_time(i), 1, 'first');

                if ~isempty(idx) && idx > 1
                    % Set previous index to 1
                    states(idx-1) = 1;
                elseif idx == 1
                    % If first target_times is already > level_time(i), do nothing
                    % (cannot go to previous index)
                    continue
                else
                    % If level_time is after all blockStopTimes, set the last one
                    states(end) = 1;
                end
            end

            stretched_states = interp1(target_times_sorted, states, position_time, 'previous', 'extrap');
            % stretched_states(isnan(stretched_states)) = 1;
            nan_mask = find(isnan(stretched_states));
            stretched_states(nan_mask) = position_temp(nan_mask);

            %% Identify All 'expected zone stop' Times
            game_start_idx = find(strcmp(events, 'game start'));
            target_zone_indices = find(strcmp(events, 'expected zone start'));
            target_zone_times = events_fixed(target_zone_indices(:));

            % target_zone_times = [events_fixed(game_start_idx) level_time target_zone_times];
            % [target_zone_times, ~] = sort(target_zone_times);

            target_zone_times = unique(target_zone_times(:));
            target_zone_times = target_zone_times(target_zone_times <= position_time(end));

            %% Preallocate Aligned Output
            aligned_runner_time = position_time;                 % Same length
            aligned_runner_position = nan(size(position_temp));

            %% 1️⃣ Handle the START Segment (before first stop)
            if ~isempty(target_zone_times)
                first_boundary = target_zone_times(1);
                start_mask = position_time < first_boundary;

                if any(start_mask)
                    target_value = mode(position_temp(start_mask));
                    aligned_runner_position(start_mask) = target_value;
                end
            end

            %% 2️⃣ Handle the MAIN Segments
            for seg = 1:length(target_zone_times) - 1
                t_start = target_zone_times(seg);
                t_end = target_zone_times(seg + 1);

                runner_mask = position_time >= t_start & position_time < t_end;

                if any(runner_mask)
                    target_value = mode(position_temp(runner_mask));
                    aligned_runner_position(runner_mask) = target_value;
                end
            end

            %% 3️⃣ Handle the END Segment (after the final stop)
            last_boundary = target_zone_times(end);
            tail_mask = aligned_runner_time >= last_boundary;

            if any(tail_mask)
                target_value = mode(position_temp(tail_mask));
                aligned_runner_position(tail_mask) = target_value;
            end

            % adjusted_mask = aligned_runner_time < level_time(1);
            % aligned_runner_position(adjusted_mask) = stretched_states(adjusted_mask);

            % %% Set first indices until first expected zone start to 'rest' (1)
            % level_first_block_diff = nan(1, numel(level_time));
            % level_first_block_idx = nan(1, numel(level_time));
            % 
            % for i = 1:numel(level_time) - 1
            %     % Find first event after the level_time
            %     idx = find(events_time > level_time(i), 1, 'first');
            %     level_first_block_idx(i) = idx;
            %     if ~isempty(idx)
            %         level_first_block_diff(i) = events_time(idx) - level_time(i);
            %     end
            % end

            % % Loop from second level
            % for i = 2:length(level_time)
            %     % Get the start and end times
            %     start_time = level_time(i);
            %     end_time = events_fixed(level_first_block_idx(i));
            % 
            %     % Find indices in aligned_runner_time within the range
            %     idx_range = aligned_runner_time >= start_time & aligned_runner_time < end_time;
            % 
            %     % Set aligned_runner_position to 1
            %     aligned_runner_position(idx_range) = 1;
            % end

            % levels_with_start = [0 level];
            levels_time_with_start = [events_fixed(game_start_idx) level_time];
            
            clear level_first_block_idx level_first_block_diff
            level_first_block_idx = [];
            level_first_block_diff = [];

            i = 1;
            while i <= numel(levels_time_with_start) && levels_time_with_start(i) < events_fixed(end)
                if i == 1
                    % Find the *7th* event after the first level time
                    idx_all = find(events_fixed > levels_time_with_start(i));
                    if numel(idx_all) >= 7
                        idx = idx_all(7);
                    else
                        idx = idx_all(end);  % fallback to last available
                        warning('Less than 7 events after levels_time_with_start(1); using last available.');
                    end
                else
                    % Regular case: find first event after level time
                    idx = find(events_fixed > levels_time_with_start(i), 1, 'first');
                end

                level_first_block_idx(i) = idx;
                level_first_block_diff(i) = events_fixed(idx) - levels_time_with_start(i);

                i = i + 1;
            end


            i = 1;
            while i <= numel(levels_time_with_start) && levels_time_with_start(i) < events_fixed(end)
                if i == 1
                    % Special case:
                    % start_level = 6th event *after* levels_time_with_start(1)
                    idx_all = find(events_fixed > levels_time_with_start(i));
                    if numel(idx_all) >= 6
                        start_level = events_fixed(idx_all(6));
                    else
                        start_level = events_fixed(idx_all(end)); % fallback
                        warning('Less than 6 events after levels_time_with_start(1); using last available.');
                    end
                    end_time = levels_time_with_start(i);
                else
                    % Regular case
                    start_level = levels_time_with_start(i);
                    end_time = events_fixed(level_first_block_idx(i));
                end

                % Apply range to aligned_runner_time
                idx_range = aligned_runner_time >= start_level & aligned_runner_time <= end_time;

                % Set aligned_runner_position to 1 within this range
                aligned_runner_position(idx_range) = 1;

                i = i + 1;
            end
            
            clear large_gap_level_idx
            large_gap_level_idx = [];
            level_gap_nonoutliers = find(level_first_block_diff < 35);
            level_first_block_diff_cleaned = level_first_block_diff(level_gap_nonoutliers);
            average_start = mean(level_first_block_diff_cleaned);

            for i = 2:length(level_first_block_diff) - 1
                if level_first_block_diff(i) > average_start
                    large_gap_level_idx = [large_gap_level_idx i];
                    mask_start = find(aligned_runner_time >= levels_time_with_start(i), 1, 'first');
                    mask_end = find(aligned_runner_time <= levels_time_with_start(i + 1), 1, 'last');
                    % adjusted_mask = any(aligned_runner_time >= levels_time_with_start(i) & aligned_runner_time <= levels_time_with_start(i + 1));
                    aligned_runner_position(mask_start:mask_end) = stretched_states(mask_start:mask_end);
                end
            end

            events_time = events_fixed;

            %% Save seperations
            b=[1];
            for n=2:length(time)-1
                for m=1:length(emg_time)
                    if -(emg_time(m)-time(n))<10e-5
                        b=[b,m];
                        break
                    end
                end
            end
            b=[b,length(emg_time)];

            %app.origSampleRate = str2double(app.stream{1,5}.info.nominal_srate);

            %Find Event Index expected zone started

            clear startIDX stopIDX
            startIDX = find(strcmp(events,'game start'));
            stopIDX = find(strcmp(events,'STOP')); % consider changing this to last index instead of finding 'STOP' index

            % Aaron's changes
            if isempty(stopIDX)
                stopIDX = length(events);
            end

            allEventIDX = find(strcmp(events,'expected zone start'));
            %%Need to get to game start with 2 runs
            if startIDX >=2
                newEventIDX = [];
                for i = 1:length(startIDX)
                    tempEventIDX = allEventIDX(allEventIDX>startIDX(i) & allEventIDX<stopIDX(i));
                    newEventIDX = [newEventIDX, tempEventIDX(4:end)];
                end
                %%%%%%%%%%%%%%%%%%%%
            else
                eventIDX = find(strcmp(events,'expected zone start'));
                eventIDX = eventIDX(eventIDX>startIDX);
                newEventIDX = eventIDX(4:end);
            end
            eventTime = events_time(newEventIDX);

            rEMGb=round(emg_time(:),3);
            rEMGa=round(eventTime,3);
            [~,emgCueIDX]=ismembertol(rEMGa,rEMGb);

            % positionActual = position(2, :); %Is it 1 or 2?
            positionActual = aligned_runner_position;
            rPositionb=round(aligned_runner_time(:),1);
            rPositiona=round(eventTime,1);
            [~,positionTypeIDX]=ismembertol(rPositiona,rPositionb);

            %%%LabelEvents
            totEvent = size(eventTime,2);
            r2 = ones(1,totEvent);
            eventTimelabel = [eventTime;r2]';

            totLevel = size(level_time,2);
            r2 = zeros(1,totLevel);
            levelTimelabel = [level_time;r2]';

            allLabels = [eventTimelabel;levelTimelabel];

            allLabelssorted = sortrows(allLabels);

            t = find(allLabelssorted(:,2) == 0);

            allLabelssorted(t+1,2)=3;

            if allLabelssorted(end,1) == 0
                allLabelssorted(end,:) = [];
            end

            %% Apply safe offset to avoid pre-block misalignment
            % runner_sr = str2double(app.stream{1, 2}.info.nominal_srate(:));
            % offset_samples = round(4 * runner_sr);  % 50 ms offset
            % 
            % Initial indexing attempt
            % idx = positionTypeIDX + offset_samples;
            % 
            % Find which indices are out of bounds
            % outOfBounds = idx > length(positionActual) | idx < 1;
            % 
            % For those, use fallback of positionTypeIDX + alt offset
            % offset_samples_alt = round(0.25 * runner_sr);  % 500 ms offset
            % idx(outOfBounds) = positionTypeIDX(outOfBounds) + offset_samples_alt;
            % 
            % Final safeguard: ensure all indices are within valid bounds
            % idx = min(max(1, idx), length(positionActual));
            % 
            % Now safely index into positionActual
            % positionState = positionActual(idx);
            % 
            % % positionState = positionActual((positionTypeIDX + offset_samples)); %captures the value without risk of finding index before
            % positionState = positionActual((positionTypeIDX + 100));
            % positionState = positionActual((positionTypeIDX)); % use this since we are aligned runner_position to event time stamps

            desired_offset_ms = 550;  % ← user-defined, adjustable
            runner_sr = 1 / mean(diff(aligned_runner_time)); % actual sampling rate in Hz
            offset_samples = round((desired_offset_ms / 1000) * runner_sr);

            idx = positionTypeIDX + offset_samples;
            idx = min(max(1, idx), length(positionActual));  % bounds-safe
            positionState = positionActual(idx);

            runTemp = emgCueIDX;
            size(b,2)
            r = 1;
            for i = 2:size(b,2)
                runTemp(runTemp <= b(i) & runTemp >= r) = r;
                r = r+1;
            end

            tempAllLabelssorted = allLabelssorted;
            storeRun = [];
            for i = 1:(length(num)-1)

                find(runTemp == i,1,'first')
                storeRun(end+1) = ans;
                allLabelssorted(ans,2)=3;

            end
            %runTemp = string(runTemp);

            %%%%
            rHand = {};
            for i = 1:(length(num)-1)
                iTemp = string(i);
                groupName = menu('Run'+iTemp+'_Hand?','Left','Right');
                Run = string(i);
                if groupName == 1
                    Hand = "Le";
                else
                    Hand = "Ri";
                end
                rHand{end+1} = append(Hand);
            end


            a = positionState;
            nAll = {}
            for i = 1:(length(num)-1)
                r1 = find(runTemp==i);
                a =double(a);
                m = num2cell(a,1);
                m= cellfun(@(x)  num2str(x), m, 'UniformOutput', false);
                if rHand{i} == "Ri"
                    m1 = replace(m(min(r1):max(r1)),'0','F'); % left lane for right hand is flex
                    m1 = replace(m1,'1','R'); % middle lane for right hand is rest
                    m1 = replace(m1,'2','E'); % right lane for right hand is extend

                    N1 ={};
                    for idx = 1:(length(m1)-1)
                        N1 =[N1,[m1{idx} m1{idx+1}]];
                    end

                    N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);

                else
                    m1 = replace(m(min(r1):max(r1)),'0','E'); % left lane for left hand is extend
                    m1 = replace(m1,'1','R'); % middle lane for left hand is rest
                    m1 = replace(m1,'2','F'); % right lane for left hand is flex

                    N1 ={};
                    for idx = 1:(length(m1)-1)
                        N1 =[N1,[m1{idx} m1{idx+1}]];
                    end

                    N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);


                end
                nAll = [nAll,N1];
            end

            positionState = string(nAll);

            out = allLabelssorted(all(allLabelssorted,2),:);
            first = find(out(:,2) == 3);


            %%%%%
            app.CustomData.data = double(emg);
            %app.CustomData.cueIDX = emgCueIDX;
            app.CustomData.emgTime = emg_time;
            app.CustomData.positionActual = positionActual;
            app.CustomData.positionTime = aligned_runner_time;
            %app.CustomData.eventTime = eventTime;
            app.CustomData.level = level;
            app.CustomData.levelTime = level_time;
            %app.CustomData.positionTypeIDX = positionTypeIDX;

            %positionState = string(positionState);


            %             if trunk == 1
            %                 runTemp = runTemp(2:end);
            %                 trunk = 0;
            %
            %             end

            for i = 1:(length(num)-1)

                find(runTemp == i,1,'first')
                runTemp(ans)=[];

            end

            tempFirst = first;
            for i = 1:(length(num)-1)

                tempFirst(find(tempFirst ==storeRun(i)))=[];

            end

            runTemp = string(runTemp);

            comment = strcat(runTemp,positionState);
            ttEmp = strcat(runTemp,positionState);

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %% David's code
            % comment(tempFirst)=[];

            % emgCueIDX(first)=[];
            % eventTime(first)=[];
            % positionTypeIDX(first) = [] ;
            
            %% Aaron's update
            valid_tempFirst = tempFirst(tempFirst >= 1 & tempFirst <= numel(comment));
            comment(valid_tempFirst) = [];

            valid_first = first(first >= 1 & first <= numel(emgCueIDX));

            emgCueIDX(valid_first) = [];
            eventTime(valid_first) = [];
            positionTypeIDX(valid_first) = [];

            % app.Path.Value = fname;
            app.totChannel = size(emg,1);
            app.eventInfo = comment;
            app.totEvent = size(comment,2);
            app.unit = 'V';
            app.CustomData.cueIDX = emgCueIDX;
            app.CustomData.eventTime = eventTime;
            app.CustomData.positionTypeIDX = positionTypeIDX;

            app.CustomData.TrialLevel = zeros(3, length(app.CustomData.eventTime));
            app.CustomData.TrialLevel(1, :) = app.CustomData.eventTime;

            if round(level(1, end), 2) == 0.20 || round(level(1, end), 2) == 0.21
                level(1, end) = level(1, end) * -1;
            % else 
            %     level(1, end) = level(1, end);
            end

            if round(level(2, end), 2) == 0.20 || round(level(2, end), 2) == 0.21
                level(2, end) = level(2, end) * -1;
            % else
            %     level(1, end) = level(1, end);
            end

            level_counter = 1;
            % for i = 1:length(app.CustomData.eventTime)
            %     if app.CustomData.eventTime(i) >= app.CustomData.levelTime(level_counter)
            %         level_counter = level_counter + 1;
            %     end
            % 
            %     app.CustomData.TrialLevel(2, i) = level(1, level_counter); % channel 1
            %     app.CustomData.TrialLevel(3, i) = level(2, level_counter); % channel 2
            % end

            for i = 1:length(app.CustomData.eventTime)
                % Increment level_counter only if it doesn’t exceed length of levelTime
                while level_counter < length(app.CustomData.levelTime) && ...
                        app.CustomData.eventTime(i) >= app.CustomData.levelTime(level_counter)
                    level_counter = level_counter + 1;
                end

                % If level_counter exceeds the number of level columns, cap it
                if level_counter > size(level, 2)
                    app.CustomData.TrialLevel(2, i) = level(1, end); % channel 1
                    app.CustomData.TrialLevel(3, i) = level(2, end); % channel 2
                else
                    app.CustomData.TrialLevel(2, i) = level(1, level_counter); % channel 1
                    app.CustomData.TrialLevel(3, i) = level(2, level_counter); % channel 2
                end
            end

            % Get unique comment values as strings
            channel_events = "Ch" + comment;
            uniqueEvents = unique(channel_events);

            % Initialize a struct
            app.ThresholdInfo = struct();

            % Loop through each unique comment and find indices
            for i = 1:length(uniqueEvents)
                currentComment = uniqueEvents(i); % currentComment is now a string
                % indicesStruct.(string(currentComment)) = find(channel_events == currentComment);
                eventTrialIDX = find(channel_events == currentComment);
                ch1TrialThresh = app.CustomData.TrialLevel(2, eventTrialIDX);
                ch2TrialThresh = app.CustomData.TrialLevel(3, eventTrialIDX);

                app.ThresholdInfo.(string(currentComment))(1, :) = eventTrialIDX;
                app.ThresholdInfo.(string(currentComment))(2, :) = ch1TrialThresh;
                app.ThresholdInfo.(string(currentComment))(3, :) = ch2TrialThresh;
            end

            switch app.unit
                case 'V'                    % other units?
                    unitsMutiple = 1000;
                    app.CustomData.data = app.CustomData.data .* unitsMutiple;
            end
            app.unit = 'mV';
            
            %% Aaron's Code: Artificial carry out the rest of the steps of DIG
            %% 1. Press comments radio button
            % Set the selected object to 'CommentsButton'
            app.ButtonGroup.SelectedObject = app.CommentsButton;

            % Call the callback manually
            ButtonGroupSelectionChanged(app, []);

            %% 2. set Pre Dur (s) and Post Dur to 3
            app.PreCue.Value = '3';
            PreCueValueChanged(app, [])

            app.PostCue.Value = '3';
            PostCueValueChanged(app, [])

            % 3. Press 'Find all Events' then 'Select Multiple Trials'
            FindallEventsButtonPushed(app, [])
            SelectMultipleTrialsButtonPushed(app, [])

            % 4. Select 'Define Condition'
            DefineConditionButtonPushed(app, [])

            % 5. Press 'Import Events' button
            ImportEventsButtonPushed(app, [])

            % 6. Press 'Click to Select Single'
            SelectMultipleTrialsButtonPushed(app, [])

            % 7. Change 'Channel #' to 2
            app.ChannelSpinner.Value = 2;
            ChannelSpinnerValueChanged(app, [])

            % 8. Press 'Import Events' button again
            ImportEventsButtonPushed(app, [])

            % 9. Define Results Directory
            % find way to save it to parent directory
            app.toolboxDir = app.SubjectParentFolder; % defined at top of callback

            parts = strsplit(app.SubjectParentFolder, '/');
            file_id = parts(end-3:end);
            % file_parts = strsplit(file_id, '_');
            id = strjoin(file_id, '/');

            app.Path.Value = id;
            % 10. Press green 'Save' button? might just keep user doing
            % this manually
            % SaveButtonPushed(app, [])
            
        end

        % Menu selected function: LoadxdffileMenu
        function LoadxdffileMenuSelected(app, event)
            RefreshMenuSelected(app, [])

            app.dataType = 6;
            trunk = 0; %helps determine if first trial is removed
            % app.FigureCheckCheckBox.Visible = 'on';
            %====================================================================================================%
            %% Load the raw .xdf file
            ff = fixfocus;
            [fname,path]=uigetfile( ...
                {'*.xdf;*.mat', 'XDF and MAT Files (*.xdf, *.mat)';
                '*.xdf',       'XDF Files (*.xdf)';
                '*.mat',       'MAT Files (*.mat)';
                '*.*',         'All Files (*.*)'} ...
                );
            delete(ff)
            file=[path, fname];

            fig = app.UIFigure;
            d = uiprogressdlg(fig,'Title','Loading EVA File',...
                'Indeterminate','on');

            if strcmp(file(end-3:end), ".xdf")
                % app.stream = load_xdf(file); % resamples the xdf data
                app.stream = load_xdf(file, 'HandleJitterRemoval', false); % uses the regular sampling rate
            elseif strcmp(file(end-3:end), ".mat")
                load(file);
                app.stream = cropped_streams;
            end

            % Close dialog box
            close(d)

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %% Get directory information
            % parts = strsplit(path, '/');
            % file_id = parts{end-2};
            % file_parts = strsplit(file_id, '_');
            % id = strjoin(file_parts, ' ');
            [exp_folder, ~] = fileparts(path);
            [app.SubjectParentFolder, ~] = fileparts(exp_folder);

            %% Extract data from individual streams from .xdf file
            for p=1:length(app.stream)

                a=app.stream{1,p}.info.type;
                if isequal(a ,'EMG') && length(app.stream{1,p}.time_series)>100
                    b=p; % just finds which index is associated with the 'EMG' data stream
                    break
                end
            end

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Runner') && length(app.stream{1,p}.time_series)>10
                    %   if isequal(a ,'Slide') && length(app.stream{1,p}.time_series)>10
                    c=p; % just finds which index is associated with the 'Runner' data stream
                    break
                end
            end

            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Events')
                    j=p; % just finds which index is associated with the 'Events' data stream
                    break
                end
            end
            for p=1:length(app.stream)
                a=app.stream{1,p}.info.type;
                if isequal(a ,'Level')
                    h=p; % just finds which index is associated with the 'Level' data stream
                    break 
                end
            end
            level = app.stream{1,h}.time_series(1:2,:);
            level_time=app.stream{1,h}.time_stamps;

            events=app.stream{1,j}.time_series;
            events_time=app.stream{1,j}.time_stamps;
            emg=app.stream{1,b}.time_series;
            emg_ch1 = emg(2,:); % channel 1
            emg_ch2 = emg(4,:); % channel 2            
            emg( all(~emg,2), : ) = [];
            % temp_emg(1, :) = emg(2, :);
            % temp_emg(2, :) = emg(1, :);
            % emg = temp_emg;
            app.origSampleRate=str2double(app.stream{1,b}.info.nominal_srate);
            emg_time = app.stream{1,b}.time_stamps;
            % position_time_raw=app.stream{1,c}.time_stamps;
            position_time=app.stream{1,c}.time_stamps;
            % position=app.stream{1,c}.time_series(2, :);
            position=app.stream{1,c}.time_series;

            %% Seperation - Multiple trail in one recording seperation
            time = emg_time(1);
            num=1;
            found_stop = false;
            for n=1:length(events)
                if events(n) == "STOP" % consider changing to just last index
                    time=[time,events_time(n)];
                    num=[num,n];
                    found_stop = true;         
                end
            end
            
            % Aaron's changes
            if ~found_stop
                time = [time, events_time(end)];
                num = [num, length(events)];
            end

            %disp(["There are "+(length(num)-1)+" seperated recordings. "])

            % %% Back to David's code
            % %-------------------------------------------------------------------------------------------------------------------------------------------------%
            % %% Save seperations
            % b=[1];
            % for n=2:length(time)-1
            %     for m=1:length(emg_time)
            %         if -(emg_time(m)-time(n))<10e-5
            %             b=[b,m];
            %             break
            %         end
            %     end
            % end
            % b=[b,length(emg_time)];
            % 
            % %app.origSampleRate = str2double(app.stream{1,5}.info.nominal_srate);
            % 
            % %Find Event Index expected zone started
            % 
            % startIDX = find(strcmp(events,'game start'));
            % stopIDX = find(strcmp(events,'STOP')); % consider changing this to last index instead of finding 'STOP' index
            % 
            % % Aaron's changes
            % if isempty(stopIDX)
            %     stopIDX = length(events);
            % end
            % 
            % allEventIDX = find(strcmp(events,'expected zone start'));
            % %%Need to get to game start with 2 runs
            % if startIDX >=2
            %     newEventIDX = [];
            %     for i = 1:length(startIDX)
            %         tempEventIDX = allEventIDX(allEventIDX>startIDX(i) & allEventIDX<stopIDX(i));
            %         newEventIDX = [newEventIDX, tempEventIDX(4:end)];
            %     end
            %     %%%%%%%%%%%%%%%%%%%%
            % else
            %     eventIDX = find(strcmp(events,'expected zone start'));
            %     eventIDX = eventIDX(eventIDX>startIDX);
            %     newEventIDX = eventIDX(4:end);
            % end
            % eventTime = events_time(newEventIDX);
            % 
            % rEMGb=round(emg_time(:),3);
            % rEMGa=round(eventTime,3);
            % [~,emgCueIDX]=ismembertol(rEMGa,rEMGb);
            % 
            % positionActual = position(2,:); %Is it 1 or 2?
            % rPositionb=round(position_time(:),1);
            % rPositiona=round(eventTime,1);
            % [~,positionTypeIDX]=ismembertol(rPositiona,rPositionb);
            % 
            % %% Back to David's code           
            % %%%LabelEvents
            % totEvent = size(eventTime,2);
            % r2 = ones(1,totEvent);
            % eventTimelabel = [eventTime;r2]';
            % 
            % totLevel = size(level_time,2);
            % r2 = zeros(1,totLevel);
            % levelTimelabel = [level_time;r2]';
            % 
            % allLabels = [eventTimelabel;levelTimelabel];
            % 
            % allLabelssorted = sortrows(allLabels);
            % 
            % t = find(allLabelssorted(:,2) == 0);
            % 
            % allLabelssorted(t+1,2)=3;
            % 
            % if allLabelssorted(end,1) == 0
            %     allLabelssorted(end,:) = [];
            % end
            % 
            % 
            % %%%%
            % %
            % %             figure;plot(position_time,positionActual)
            % % %
            % %             for i = 1:length(eventTime)
            % %                 x1 = xline(eventTime(i));
            % %                 x1.Color ='red';
            % %                 x1.LineWidth = 1;
            % %             end
            % %
            % %             for i = 1:size(level,2)
            % %                 x1 = xline(level_time(i));
            % %                 x1.Color ='blue';
            % %                 x1.LineWidth = 2;
            % %             end
            % %
            % %
            % %
            % %             x2 = xline(events_time,'-',events);
            % 
            % %             if positionTypeIDX(1) == 0
            % %                 positionTypeIDX = positionTypeIDX(2:end);
            % %                 trunk = 1;
            % %                 msgbox('Warning: First Trial Removed')
            % %             end
            % 
            % % % Desired time offset (e.g., same as EMG 100 samples = 100 ms)
            % % runner_sr = str2double(app.stream{1, 2}.info.nominal_srate(:));
            % % emg_sr = str2double(app.stream{1, 5}.info.nominal_srate(:));
            % % offset_ms = 100 / emg_sr * 1000;         % 100 ms
            % % offset_s = offset_ms / 1000;             % 0.1 s
            % % offset_samples_pos = round(offset_s * runner_sr);   % e.g., 10 samples if fs_pos = 100
            % % 
            % % % Apply safe indexing
            % % idx = min(length(positionActual), positionTypeIDX + offset_samples_pos);
            % % positionState = positionActual(idx);
            % 
            % positionState = positionActual((positionTypeIDX+100)); %captures the value without risk of finding index before
            % 
            % 
            % runTemp = emgCueIDX;
            % size(b,2)
            % r = 1;
            % for i = 2:size(b,2)
            %     runTemp(runTemp <= b(i) & runTemp >= r) = r;
            %     r = r+1;
            % end
            % 
            % tempAllLabelssorted = allLabelssorted;
            % storeRun = [];
            % for i = 1:(length(num)-1)
            % 
            %     find(runTemp == i,1,'first')
            %     storeRun(end+1) = ans;
            %     allLabelssorted(ans,2)=3;
            % 
            % end
            % %runTemp = string(runTemp);
            % 
            % %%%%
            % rHand = {};
            % for i = 1:(length(num)-1)
            %     iTemp = string(i);
            %     groupName = menu('Run'+iTemp+'_Hand?','Left','Right');
            %     Run = string(i);
            %     if groupName == 1
            %         Hand = "Le";
            %     else
            %         Hand = "Ri";
            %     end
            %     rHand{end+1} = append(Hand);
            % end
            % 
            % 
            % a = positionState;
            % nAll = {}
            % for i = 1:(length(num)-1)
            %     r1 = find(runTemp==i);
            %     a =double(a);
            %     m = num2cell(a,1);
            %     m= cellfun(@(x)  num2str(x), m, 'UniformOutput', false);
            %     if rHand{i} == "Ri"
            %         m1 = replace(m(min(r1):max(r1)),'0','F'); % left lane for right hand is flex
            %         m1 = replace(m1,'1','R'); % middle lane for right hand is rest
            %         m1 = replace(m1,'2','E'); % right lane for right hand is extend
            % 
            %         N1 ={};
            %         for idx = 1:(length(m1)-1)
            %             N1 =[N1,[m1{idx} m1{idx+1}]];
            %         end
            % 
            %         N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);
            % 
            %     else
            %         m1 = replace(m(min(r1):max(r1)),'0','E'); % left lane for left hand is extend
            %         m1 = replace(m1,'1','R'); % middle lane for left hand is rest
            %         m1 = replace(m1,'2','F'); % right lane for left hand is flex
            % 
            %         N1 ={};
            %         for idx = 1:(length(m1)-1)
            %             N1 =[N1,[m1{idx} m1{idx+1}]];
            %         end
            % 
            %         N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);
            % 
            % 
            %     end
            %     nAll = [nAll,N1];
            % end
            % 
            % positionState = string(nAll);
            % 
            % out = allLabelssorted(all(allLabelssorted,2),:);
            % first = find(out(:,2) == 3);
            % 
            % 
            % %%%%%
            % app.CustomData.data = double(emg);
            % %app.CustomData.cueIDX = emgCueIDX;
            % app.CustomData.emgTime = emg_time;
            % app.CustomData.positionActual = positionActual;
            % app.CustomData.positionTime = position_time;
            % %app.CustomData.eventTime = eventTime;
            % app.CustomData.level = level;
            % app.CustomData.levelTime = level_time;
            % %app.CustomData.positionTypeIDX = positionTypeIDX;
            % 
            % %positionState = string(positionState);
            % 
            % 
            % %             if trunk == 1
            % %                 runTemp = runTemp(2:end);
            % %                 trunk = 0;
            % %
            % %             end
            % 
            % for i = 1:(length(num)-1)
            % 
            %     find(runTemp == i,1,'first')
            %     runTemp(ans)=[];
            % 
            % end
            % 
            % tempFirst = first;
            % for i = 1:(length(num)-1)
            % 
            %     tempFirst(find(tempFirst ==storeRun(i)))=[];
            % 
            % end
            % 
            % 
            % 
            % 
            % 
            % 
            % runTemp = string(runTemp);
            % 
            % comment = strcat(runTemp,positionState);
            % ttEmp = strcat(runTemp,positionState);
            % 
            % drawnow; %Puts UI Figure at the forfront
            % figure(app.UIFigure) %Puts UI Figure at the forfront
            % 
            % 
            % 
            % 
            % comment(tempFirst)=[];
            % 
            % 
            % 
            % emgCueIDX(first)=[];
            % eventTime(first)=[];
            % positionTypeIDX(first) = [] ;
            % 
            % app.Path.Value = fname;
            % app.totChannel = size(emg,1);
            % app.eventInfo = comment;
            % app.totEvent = size(comment,2);
            % app.unit = 'V';
            % app.CustomData.cueIDX = emgCueIDX;
            % app.CustomData.eventTime = eventTime;
            % app.CustomData.positionTypeIDX = positionTypeIDX;
            % 
            % app.CustomData.TrialLevel = zeros(3, length(app.CustomData.eventTime));
            % app.CustomData.TrialLevel(1, :) = app.CustomData.eventTime;
            % 
            % if round(level(1, end), 2) == 0.20 || round(level(1, end), 2) == 0.21
            %     level(1, end) = level(1, end) * -1;
            % % else 
            % %     level(1, end) = level(1, end);
            % end
            % 
            % if round(level(2, end), 2) == 0.20 || round(level(2, end), 2) == 0.21
            %     level(2, end) = level(2, end) * -1;
            % % else
            % %     level(1, end) = level(1, end);
            % end
            % 
            % level_counter = 1;
            % % for i = 1:length(app.CustomData.eventTime)
            % %     if app.CustomData.eventTime(i) >= app.CustomData.levelTime(level_counter)
            % %         level_counter = level_counter + 1;
            % %     end
            % % 
            % %     app.CustomData.TrialLevel(2, i) = level(1, level_counter); % channel 1
            % %     app.CustomData.TrialLevel(3, i) = level(2, level_counter); % channel 2
            % % end
            % 
            % for i = 1:length(app.CustomData.eventTime)
            %     % Increment level_counter only if it doesn’t exceed length of levelTime
            %     while level_counter < length(app.CustomData.levelTime) && ...
            %             app.CustomData.eventTime(i) >= app.CustomData.levelTime(level_counter)
            %         level_counter = level_counter + 1;
            %     end
            % 
            %     % If level_counter exceeds the number of level columns, cap it
            %     if level_counter > size(level, 2)
            %         app.CustomData.TrialLevel(2, i) = level(1, end); % channel 1
            %         app.CustomData.TrialLevel(3, i) = level(2, end); % channel 2
            %     else
            %         app.CustomData.TrialLevel(2, i) = level(1, level_counter); % channel 1
            %         app.CustomData.TrialLevel(3, i) = level(2, level_counter); % channel 2
            %     end
            % end
            % 
            % % Get unique comment values as strings
            % channel_events = "Ch" + comment;
            % uniqueEvents = unique(channel_events);
            % 
            % % Initialize a struct
            % app.ThresholdInfo = struct();
            % 
            % % Loop through each unique comment and find indices
            % for i = 1:length(uniqueEvents)
            %     currentComment = uniqueEvents(i); % currentComment is now a string
            %     % indicesStruct.(string(currentComment)) = find(channel_events == currentComment);
            %     eventTrialIDX = find(channel_events == currentComment);
            %     ch1TrialThresh = app.CustomData.TrialLevel(2, eventTrialIDX);
            %     ch2TrialThresh = app.CustomData.TrialLevel(3, eventTrialIDX);
            % 
            %     app.ThresholdInfo.(string(currentComment))(1, :) = eventTrialIDX;
            %     app.ThresholdInfo.(string(currentComment))(2, :) = ch1TrialThresh;
            %     app.ThresholdInfo.(string(currentComment))(3, :) = ch2TrialThresh;
            % end
            % 
            % switch app.unit
            %     case 'V'                    % other units?
            %         unitsMutiple = 1000;
            %         app.CustomData.data = app.CustomData.data .* unitsMutiple;
            % end
            % app.unit = 'mV';

            %% Aaron's modifications to David's code
            %-------------------------------------------------------------------------------------------------------------------------------------------------%
            %% Adjusting Events and Runner timestamps
            % position_temp = position(2, :);
            % blockStartIDX = find(strcmp(events,'expected zone start'));
            % blockStopIDX = find(strcmp(events,'expected zone stop'));
            % 
            % events_dt = diff(events_time);
            % events_dt = [0 events_dt];
            % 
            % events_time_adjusted = events_dt;
            % mask = round(events_dt, 2) == 3 | round(events_dt, 2) == 0.5;
            % events_time_adjusted(mask) = round(events_dt(mask), 2);
            % 
            % 
            % events_fixed = [events_time(1)];
            % i = 2;
            % while i <= length(events_time)
            %     adj = events_fixed(i - 1) + events_time_adjusted(i);
            %     events_fixed(i) = adj;
            %     i = i + 1;
            % end
            % 
            % %% Initial interpolation
            % startIDX = find(strcmp(events, 'START'));
            % 
            % % start_time = events_fixed(startIDX);
            % % offset = start_time - position_time(1);
            % % runner_time_offset = position_time + offset;
            % 
            % blockStartTimes_fixed = events_fixed(blockStartIDX);
            % blockStopTimes_fixed = events_fixed(blockStopIDX);
            % 
            % % target_times = [blockStartTimes_fixed blockStopTimes_fixed]; % add the tutorial times
            % target_times = blockStartTimes_fixed;
            % [target_times_sorted, ~] = sort(target_times);
            % 
            % states = interp1(position_time, position_temp, target_times_sorted, 'previous');
            % states(isnan(states)) = 1;
            % 
            % % change first state following the start of the level to be 'rest' (1)
            % for i = 1:length(level_time)
            %     % Find first index in blockStopTimes where time > level_time(i)
            %     idx = find(target_times_sorted > level_time(i), 1, 'first');
            % 
            %     if ~isempty(idx) && idx > 1
            %         % Set previous index to 1
            %         states(idx-1) = 1;
            %     elseif idx == 1
            %         % If first target_times is already > level_time(i), do nothing
            %         % (cannot go to previous index)
            %         continue
            %     else
            %         % If level_time is after all blockStopTimes, set the last one
            %         states(end) = 1;
            %     end
            % end
            % 
            % stretched_states = interp1(target_times_sorted, states, position_time, 'previous', 'extrap');
            % % stretched_states(isnan(stretched_states)) = 1;
            % nan_mask = find(isnan(stretched_states));
            % stretched_states(nan_mask) = position_temp(nan_mask);
            % 
            % %% Identify All 'expected zone stop' Times
            game_start_idx = find(strcmp(events, 'game start'));
            % target_zone_indices = find(strcmp(events, 'expected zone start'));
            % target_zone_times = events_fixed(target_zone_indices(:));
            % 
            % % target_zone_times = [events_fixed(game_start_idx) level_time target_zone_times];
            % % [target_zone_times, ~] = sort(target_zone_times);
            % 
            % target_zone_times = unique(target_zone_times(:));
            % target_zone_times = target_zone_times(target_zone_times <= position_time(end));
            % 
            % %% Preallocate Aligned Output
            % aligned_runner_time = position_time;                 % Same length
            % aligned_runner_position = position(2, :);
            % 
            % %% 1️⃣ Handle the START Segment (before first stop)
            % if ~isempty(target_zone_times)
            %     first_boundary = target_zone_times(1);
            %     start_mask = position_time < first_boundary;
            % 
            %     if any(start_mask)
            %         target_value = mode(position_temp(start_mask));
            %         aligned_runner_position(start_mask) = target_value;
            %     end
            % end
            % 
            % %% 2️⃣ Handle the MAIN Segments
            % for seg = 1:length(target_zone_times) - 1
            %     t_start = target_zone_times(seg);
            %     t_end = target_zone_times(seg + 1);
            % 
            %     runner_mask = position_time >= t_start & position_time < t_end;
            % 
            %     if any(runner_mask)
            %         target_value = mode(position_temp(runner_mask));
            %         aligned_runner_position(runner_mask) = target_value;
            %     end
            % end
            % 
            % %% 3️⃣ Handle the END Segment (after the final stop)
            % last_boundary = target_zone_times(end);
            % tail_mask = aligned_runner_time >= last_boundary;
            % 
            % if any(tail_mask)
            %     target_value = mode(position_temp(tail_mask));
            %     aligned_runner_position(tail_mask) = target_value;
            % end
            % 
            % % adjusted_mask = aligned_runner_time < level_time(1);
            % % aligned_runner_position(adjusted_mask) = stretched_states(adjusted_mask);
            % 
            %% Set first indices until first expected zone start to 'rest' (1)
            level_first_block_diff = nan(1, numel(level_time));
            level_first_block_idx = nan(1, numel(level_time));

            for i = 1:numel(level_time) - 1
                % Find first event after the level_time
                idx = find(events_time > level_time(i), 1, 'first');
                level_first_block_idx(i) = idx;
                if ~isempty(idx)
                    level_first_block_diff(i) = events_time(idx) - level_time(i);
                end
            end

            % % Loop from second level
            % for i = 2:length(level_time)
            %     % Get the start and end times
            %     start_time = level_time(i);
            %     end_time = events_time(level_first_block_idx(i));
            % 
            %     % Find indices in aligned_runner_time within the range
            %     idx_range = aligned_runner_time >= start_time & aligned_runner_time < end_time;
            % 
            %     % Set aligned_runner_position to 1
            %     aligned_runner_position(idx_range) = 1;
            % end

            % levels_with_start = [0 level];
            levels_time_with_start = [events_time(game_start_idx) level_time];

            clear level_first_block_idx level_first_block_diff
            level_first_block_idx = [];
            level_first_block_diff = [];

            i = 1;
            while i <= numel(levels_time_with_start) && levels_time_with_start(i) < events_time(end)
                if i == 1
                    % Find the *7th* event after the first level time
                    idx_all = find(events_time > levels_time_with_start(i));
                    if numel(idx_all) >= 7
                        idx = idx_all(7);
                    else
                        idx = idx_all(end);  % fallback to last available
                        warning('Less than 7 events after levels_time_with_start(1); using last available.');
                    end
                else
                    % Regular case: find first event after level time
                    idx = find(events_time > levels_time_with_start(i), 1, 'first');
                end

                level_first_block_idx(i) = idx;
                level_first_block_diff(i) = events_time(idx) - levels_time_with_start(i);

                i = i + 1;
            end

            aligned_runner_time = position_time;
            aligned_runner_position = position(2, :);

            i = 1;
            while i <= numel(levels_time_with_start) && levels_time_with_start(i) < events_time(end)
                if i == 1
                    % Special case:
                    % start_level = 6th event *after* levels_time_with_start(1)
                    idx_all = find(events_time > levels_time_with_start(i));
                    if numel(idx_all) >= 6
                        start_level = events_time(idx_all(6));
                    else
                        start_level = events_time(idx_all(end)); % fallback
                        warning('Less than 6 events after levels_time_with_start(1); using last available.');
                    end
                    end_time = levels_time_with_start(i);
                else
                    % Regular case
                    start_level = levels_time_with_start(i);
                    end_time = events_time(level_first_block_idx(i));
                end

                % Apply range to aligned_runner_time
                idx_range = aligned_runner_time >= start_level & aligned_runner_time <= end_time;

                % Set aligned_runner_position to 1 within this range
                aligned_runner_position(idx_range) = 1;

                i = i + 1;
            end

            % clear large_gap_level_idx
            % large_gap_level_idx = [];
            % level_gap_nonoutliers = find(level_first_block_diff < 35);
            % level_first_block_diff_cleaned = level_first_block_diff(level_gap_nonoutliers);
            % average_start = mean(level_first_block_diff_cleaned);
            % 
            % for i = 2:length(level_first_block_diff) - 1
            %     if level_first_block_diff(i) > average_start
            %         large_gap_level_idx = [large_gap_level_idx i];
            %         mask_start = find(aligned_runner_time >= levels_time_with_start(i), 1, 'first');
            %         mask_end = find(aligned_runner_time <= levels_time_with_start(i + 1), 1, 'last');
            %         % adjusted_mask = any(aligned_runner_time >= levels_time_with_start(i) & aligned_runner_time <= levels_time_with_start(i + 1));
            %         aligned_runner_position(mask_start:mask_end) = stretched_states(mask_start:mask_end);
            %     end
            % end
            % 
            % events_time = events_fixed;

            %% Save seperations
            b=[1];
            for n=2:length(time)-1
                for m=1:length(emg_time)
                    if -(emg_time(m)-time(n))<10e-5
                        b=[b,m];
                        break
                    end
                end
            end
            b=[b,length(emg_time)];

            %app.origSampleRate = str2double(app.stream{1,5}.info.nominal_srate);

            %Find Event Index expected zone started

            clear startIDX stopIDX
            startIDX = find(strcmp(events,'game start'));
            stopIDX = find(strcmp(events,'STOP')); % consider changing this to last index instead of finding 'STOP' index

            % Aaron's changes
            if isempty(stopIDX)
                stopIDX = length(events);
            end

            allEventIDX = find(strcmp(events,'expected zone start'));
            %%Need to get to game start with 2 runs
            if startIDX >=2
                newEventIDX = [];
                for i = 1:length(startIDX)
                    tempEventIDX = allEventIDX(allEventIDX>startIDX(i) & allEventIDX<stopIDX(i));
                    newEventIDX = [newEventIDX, tempEventIDX(4:end)];
                end
                %%%%%%%%%%%%%%%%%%%%
            else
                eventIDX = find(strcmp(events,'expected zone start'));
                eventIDX = eventIDX(eventIDX > startIDX);
                newEventIDX = eventIDX(4:end);
            end
            eventTime = events_time(newEventIDX);

            rEMGb=round(emg_time(:), 1);
            rEMGa=round(eventTime, 1);
            [~,emgCueIDX]=ismembertol(rEMGa,rEMGb);

            % positionActual = position(2, :); %Is it 1 or 2?
            positionActual = aligned_runner_position;
            rPositionb=round(aligned_runner_time(:), 1);
            rPositiona=round(eventTime, 1);
            [~,positionTypeIDX]=ismembertol(rPositiona,rPositionb);

            %%%LabelEvents
            totEvent = size(eventTime,2);
            r2 = ones(1,totEvent);
            eventTimelabel = [eventTime;r2]';

            totLevel = size(level_time,2);
            r2 = zeros(1,totLevel);
            levelTimelabel = [level_time;r2]';

            allLabels = [eventTimelabel;levelTimelabel];

            allLabelssorted = sortrows(allLabels);

            t = find(allLabelssorted(:,2) == 0);

            allLabelssorted(t+1,2)=3;

            if allLabelssorted(end,1) == 0
                allLabelssorted(end,:) = [];
            end

            %% Apply safe offset to avoid pre-block misalignment
            % runner_sr = str2double(app.stream{1, 2}.info.nominal_srate(:));
            % offset_samples = round(4 * runner_sr);  % 50 ms offset
            % 
            % Initial indexing attempt
            % idx = positionTypeIDX + offset_samples;
            % 
            % Find which indices are out of bounds
            % outOfBounds = idx > length(positionActual) | idx < 1;
            % 
            % For those, use fallback of positionTypeIDX + alt offset
            % offset_samples_alt = round(0.25 * runner_sr);  % 500 ms offset
            % idx(outOfBounds) = positionTypeIDX(outOfBounds) + offset_samples_alt;
            % 
            % Final safeguard: ensure all indices are within valid bounds
            % idx = min(max(1, idx), length(positionActual));
            % 
            % Now safely index into positionActual
            % positionState = positionActual(idx);
            % 
            % % positionState = positionActual((positionTypeIDX + offset_samples)); %captures the value without risk of finding index before
            % positionState = positionActual((positionTypeIDX + 100));
            positionState = positionActual((positionTypeIDX)); % use this since we are aligned runner_position to event time stamps

            % desired_offset_ms = 50;  % user-defined, adjustable
            % app.runner_sr = 1 / round(mean(diff(aligned_runner_time)), 4); % actual sampling rate in Hz
            % offset_samples = round((desired_offset_ms / 1000) * app.runner_sr);
            % 
            % idx = positionTypeIDX + offset_samples;
            % idx = min(max(1, idx), length(positionActual));  % bounds-safe
            % positionState = positionActual(idx);

            % app.origSampleRate = 1 / round(mean(diff(emg_time)), 4); % actual sampling rate in Hz


            runTemp = emgCueIDX;
            size(b,2)
            r = 1;
            for i = 2:size(b,2)
                runTemp(runTemp <= b(i) & runTemp >= r) = r;
                r = r+1;
            end

            tempAllLabelssorted = allLabelssorted;
            storeRun = [];
            for i = 1:(length(num)-1)

                find(runTemp == i,1,'first')
                storeRun(end+1) = ans;
                allLabelssorted(ans,2)=3;

            end
            %runTemp = string(runTemp);

            %%%%
            rHand = {};
            for i = 1:(length(num)-1)
                iTemp = string(i);
                groupName = menu('Run'+iTemp+'_Hand?','Left','Right');
                Run = string(i);
                if groupName == 1
                    Hand = "Le";
                else
                    Hand = "Ri";
                end
                rHand{end+1} = append(Hand);
            end


            a = positionState;
            nAll = {};
            for i = 1:(length(num)-1)
                r1 = find(runTemp==i);
                a =double(a);
                m = num2cell(a,1);
                m= cellfun(@(x)  num2str(x), m, 'UniformOutput', false);
                if rHand{i} == "Ri"
                    m1 = replace(m(min(r1):max(r1)),'0','F'); % left lane for right hand is flex
                    m1 = replace(m1,'1','R'); % middle lane for right hand is rest
                    m1 = replace(m1,'2','E'); % right lane for right hand is extend

                    N1 ={};
                    for idx = 1:(length(m1)-1)
                        N1 =[N1,[m1{idx} m1{idx+1}]];
                    end

                    N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);

                else
                    m1 = replace(m(min(r1):max(r1)),'0','E'); % left lane for left hand is extend
                    m1 = replace(m1,'1','R'); % middle lane for left hand is rest
                    m1 = replace(m1,'2','F'); % right lane for left hand is flex

                    N1 ={};
                    for idx = 1:(length(m1)-1)
                        N1 =[N1,[m1{idx} m1{idx+1}]];
                    end

                    N1 = cellfun(@(x)  append(rHand{i},x), N1, 'UniformOutput', false);


                end
                nAll = [nAll,N1];
            end

            positionState = string(nAll);

            out = allLabelssorted(all(allLabelssorted,2),:);
            first = find(out(:,2) == 3);


            %%%%%
            app.CustomData.data = double(emg);
            %app.CustomData.cueIDX = emgCueIDX;
            app.CustomData.emgTime = emg_time;
            app.CustomData.positionActual = positionActual;
            app.CustomData.positionTime = aligned_runner_time;
            %app.CustomData.eventTime = eventTime;
            app.CustomData.level = level;
            app.CustomData.levelTime = level_time;
            %app.CustomData.positionTypeIDX = positionTypeIDX;

            %positionState = string(positionState);


            %             if trunk == 1
            %                 runTemp = runTemp(2:end);
            %                 trunk = 0;
            %
            %             end

            for i = 1:(length(num)-1)

                find(runTemp == i,1,'first')
                runTemp(ans)=[];

            end

            tempFirst = first;
            for i = 1:(length(num)-1)

                tempFirst(find(tempFirst ==storeRun(i)))=[];

            end

            runTemp = string(runTemp);

            comment = strcat(runTemp,positionState);
            ttEmp = strcat(runTemp,positionState);

            drawnow; %Puts UI Figure at the forfront
            figure(app.UIFigure) %Puts UI Figure at the forfront

            %% David's code
            % comment(tempFirst)=[];

            % emgCueIDX(first)=[];
            % eventTime(first)=[];
            % positionTypeIDX(first) = [] ;
            
            %% Aaron's update
            valid_tempFirst = tempFirst(tempFirst >= 1 & tempFirst <= numel(comment));
            comment(valid_tempFirst) = [];

            valid_first = first(first >= 1 & first <= numel(emgCueIDX));

            emgCueIDX(valid_first) = [];
            eventTime(valid_first) = [];
            positionTypeIDX(valid_first) = [];

            % app.Path.Value = fname;
            app.totChannel = size(emg,1);
            app.eventInfo = comment;
            app.totEvent = size(comment,2);
            app.unit = 'V';
            app.CustomData.cueIDX = emgCueIDX;
            app.CustomData.eventTime = eventTime;
            app.CustomData.positionTypeIDX = positionTypeIDX;

            app.CustomData.TrialLevel = zeros(3, length(app.CustomData.eventTime));
            app.CustomData.TrialLevel(1, :) = app.CustomData.eventTime;

            if size(level, 2) > 1
                if round(level(1, end), 2) == 0.20 || round(level(1, end), 2) == 0.21
                    level(1, end) = level(1, end) * -1;
                end

                if round(level(2, end), 2) == 0.20 || round(level(2, end), 2) == 0.21
                    level(2, end) = level(2, end) * -1;

                end
            else
                if round(level(1, end), 2) == 0.20 || round(level(1, end), 2) == 0.21
                    level(1, end) = level(1, end);
                end

                if round(level(2, end), 2) == 0.20 || round(level(2, end), 2) == 0.21
                    level(2, end) = level(2, end);
                end                
            end

            level_counter = 1;
            % for i = 1:length(app.CustomData.eventTime)
            %     if app.CustomData.eventTime(i) >= app.CustomData.levelTime(level_counter)
            %         level_counter = level_counter + 1;
            %     end
            % 
            %     app.CustomData.TrialLevel(2, i) = level(1, level_counter); % channel 1
            %     app.CustomData.TrialLevel(3, i) = level(2, level_counter); % channel 2
            % end

            for i = 1:length(app.CustomData.eventTime)
                % Increment level_counter only if it doesn’t exceed length of levelTime
                while level_counter < length(app.CustomData.levelTime) && ...
                        app.CustomData.eventTime(i) >= app.CustomData.levelTime(level_counter)
                    level_counter = level_counter + 1;
                end

                % If level_counter exceeds the number of level columns, cap it
                if level_counter > size(level, 2)
                    app.CustomData.TrialLevel(2, i) = level(1, end); % channel 1
                    app.CustomData.TrialLevel(3, i) = level(2, end); % channel 2
                else
                    app.CustomData.TrialLevel(2, i) = level(1, level_counter); % channel 1
                    app.CustomData.TrialLevel(3, i) = level(2, level_counter); % channel 2
                end
            end

            % Get unique comment values as strings
            channel_events = "Ch" + comment;
            uniqueEvents = unique(channel_events);

            % Initialize a struct
            app.ThresholdInfo = struct();

            % Loop through each unique comment and find indices
            for i = 1:length(uniqueEvents)
                currentComment = uniqueEvents(i); % currentComment is now a string
                % indicesStruct.(string(currentComment)) = find(channel_events == currentComment);
                eventTrialIDX = find(channel_events == currentComment);
                ch1TrialThresh = app.CustomData.TrialLevel(2, eventTrialIDX);
                ch2TrialThresh = app.CustomData.TrialLevel(3, eventTrialIDX);

                app.ThresholdInfo.(string(currentComment))(1, :) = eventTrialIDX;
                app.ThresholdInfo.(string(currentComment))(2, :) = ch1TrialThresh;
                app.ThresholdInfo.(string(currentComment))(3, :) = ch2TrialThresh;
            end

            switch app.unit
                case 'V'                    % other units?
                    unitsMutiple = 1000;
                    app.CustomData.data = app.CustomData.data .* unitsMutiple;
            end
            app.unit = 'mV';
            
            %% Aaron's Code: Artificial carry out the rest of the steps of DIG
            %% 1. Press comments radio button
            % Set the selected object to 'CommentsButton'
            app.ButtonGroup.SelectedObject = app.CommentsButton;

            % Call the callback manually
            ButtonGroupSelectionChanged(app, []);

            %% 2. set Pre Dur (s) and Post Dur to 3
            app.PreCue.Value = '3';
            PreCueValueChanged(app, [])

            app.PostCue.Value = '3';
            PostCueValueChanged(app, [])

            % 3. Press 'Find all Events' then 'Select Multiple Trials'
            FindallEventsButtonPushed(app, [])
            SelectMultipleTrialsButtonPushed(app, [])

            % 4. Select 'Define Condition'
            DefineConditionButtonPushed(app, [])

            % 5. Press 'Import Events' button
            ImportEventsButtonPushed(app, [])

            % 6. Press 'Click to Select Single'
            SelectMultipleTrialsButtonPushed(app, [])

            % 7. Change 'Channel #' to 2
            app.ChannelSpinner.Value = 2;
            ChannelSpinnerValueChanged(app, [])

            % 8. Press 'Import Events' button again
            ImportEventsButtonPushed(app, [])

            % 9. Define Results Directory
            % find way to save it to parent directory
            app.toolboxDir = app.SubjectParentFolder; % defined at top of callback

            parts = strsplit(app.SubjectParentFolder, '/');
            file_id = parts(end-3:end);
            % file_parts = strsplit(file_id, '_');
            id = strjoin(file_id, '/');

            app.Path.Value = id;
            % 10. Press green 'Save' button? might just keep user doing
            % this manually
            % SaveButtonPushed(app, [])
            
        end

        % Menu selected function: RefreshMenu
        function RefreshMenuSelected(app, event)
            % refresh the setting

            app.reloadInd = 0;
            app.ProcessInd = 0;
            % app.ProcessButton.Enable = 1;
            % app.EditField.Visible = 0;
            app.TrialSelectPanel.Visible = 'off';
            app.ChannelSpinner.Value = 1;
            app.TrialSpinner.Value = 1;
            app.DefineConditionsPanel.Visible = 'on';
            app.UIAxes.Toolbar.Visible = 'on';
            cla(app.UIAxes);
            app.PreCue.Value = '0';
            app.PostCue.Value = '0';
            % app.Button.Value = true;
            app.CommentsButton.Enable = 'on';
            % app.EventsegTTLButton.Enable = "on";
            app.Path.Value = app.Path.Placeholder;
            % app.uVButton.Enable = 'off';
            % app.mVButton.Enable = 'off';
            % app.VButton.Enable = 'off';
            % app.Panel_17.Visible = 'off';
            app.EventNameListBox.Visible = 'off';
            app.EventNameListBoxLabel.Visible = 'off';
            % app.msEditField.Enable = 'off';
            % app.Panel_4.Visible = 0;
            % app.EMGAnalysisPanel.Visible = 0;
            % app.FigureCheckCheckBox.Visible = 'off';
            % app.FigureCheckCheckBox.Value = 0;
            %             app.ChannelSpinner.Value = 1;
            %             app.TrialSpinner = 1;
            app.ChannelSpinner.Enable = 1;
            app.TrialSpinner.Enable = 1;
            app.TrialSpinnerLabel.Text = 'Trial #';
            %reset all private properties
            % app.labChartData = [];        % data package of the TMS data exported from LabChart
            % app.AcqknowledgeData = [];     % data package of the TMS data exported from Acqknowledge
            % app.SignalData = [];          % data package of the TMS data exported from Signal
            % app.BrainVisionData = [];     % data package of the TMS data exported from BrainVision
            app.origSampleRate = [];      % define the sample rate variable
            app.eventInfo = [];           % define the event/trial information
            app.totChannel = [];          % define the channel number variable
            app.totEvent = [];            % define the event/trial number variable
            app.totBlock = [];            % define the block number variable
            % app.unit = [];                % define the unit of the TMS data
            app.EventType = [];           % define the type of event 1/2/3 refers to comment/event/block ???
            app.allData = [];             % define the matrix which saves data of all channels and all trials
            app.reloadInd = 0;           % define a mark to reveal if it is the first time to load the data
            app.fileName = [];            % name of the data
            app.dataType = [];            % data type. 1-labChart; 2-Acqknowledge; 3-Signal; 4-BrainVision
            app.time = [];                % time points of the data
            app.timeUnit = [];            % unit of the time
            app.MultiChan = [];           % clear multiple channels variable
            app.clickCount = 0;                % count the clicks of 'Select Multiple Trials' button. '0' for unclick
            app.chosenData = [];                % data to plot
            app.meanData = [];                 % mean data of multiple selected trials
            % app.DSData = [];                 % save the down sampled data
            % app.DSTime = [];                    % save the down sampled time
            % app.DelayTime = [];                % save the delayed time
            % app.BPData = [];                   % save the bandpass data
            % app.NotData = [];                  % save the data after Notch filter
            % app.LPData = [];                   % save the Low Pass data
            % app.dwnSampleRate = [];            % save the down samplerate
            % app.TKEOData = [];                 % save TKEO data
            % app.RectifyData = [];              % save rectified data
            app.DataToPlot = [];               % save the plot data
            app.TimeToPlot = [];               % save the time vector to plot
            app.dataOut = [];               % variables to export
            app.UITable.Data = [];          % clear UITable
            app.DelayTime = [];               % initialize delayed time, which is the time variable after pre-processing
            app.origTime = [];                %clear time
            % app.VelocityData = [];
            % app.BPData = [];              % save the bandpass data
            % app.NotData = [];          % save the data after Notch filter
            % app.LPData = [];              % save the Low Pass data
            % app.dwnSampleRate = [];       % save the down samplerate
            % app.TKEOData = [];            % save TKEO data
            % app.RectifyData = [];         % save rectified data
            app.DataToPlot = [];          % save the plot data
            app.TimeToPlot = [];          % save the time vector to plot
            app.ProcessInd = 0;          % data is pre-processed or not
            % app.onsetLine = [];           % onset line to determine the silent period
            % app.offsetLine = [];          % offset line to determine the silent period
            % app.overRideMark = [];        % if override button is clicked
            % app.EMGBurstoverRideMark = 0;% if override button in EMG Burst detection is clicked
            % app.EMGAnalysisOR = 0;
            % app.rMEPoverrideMark = 0;    % resting MEP override
            % app.overRideInd = 0;         % save the override onset and offset index
            app.dataOut = [];             % variables to export
            app.tempH = [];               % temporally saves the lines of the figure in mV
            % app.WastedTrials = [];       % counts of wasted trials
            % app.Patch = [];              % Patch the prestimulation period
            % app.AnalyzeInd = 0;          % Analyze button clicked
            % app.PreStimMean = [];         % Pre stimulation mean
            % app.Ztrans = 0;              % if Ztrans is clicked
            % app.Prolific = 0;            % if Prolific is clicked
            app.TrialNum_CurrentGroup = []; % trial numbers when hit 'add new group'. Create new groups and assignl trials to them
            app.TrialName_CurrentGroup = []; % group name when hit 'add new group'
            app.conditionData = [];       % save the condition data if condition applied
            app.conditionMean = [];       % save the mean data of all conditions in the condition list
            app.conditionTotalEvent = []; % save the number of trials within the condition
            app.EventName = [];           % save the event name
            app.allConditionEvents = [];  % save all event names to the conditions
            app.allConditionData = [];    % save the data to all condition and event names
            app.conditionInd = [];        % save the condition index when select multiple conditions
            % app.LowThresh = [];           % Contains Remove LowThresh Value for Active Conditions
            % app.HighThresh = [];          % Contains Remove HighThresh Value for Active Conditions
            % app.LowThreshRest = [];         % Contains Remove LowThresh Value for Rest Conditions
            % app.HighThreshRest = [];        % Contains Remove HighThresh Value for Rest Conditions
            % app.removeIDX = [];            % Contains Removed TMS values based on background
            % app.BrainVisionData  = [];         % Contains Removed brainsight             app.RestingMEPPanel.Visible = 0; % Hides Resting MEP Analysis
            % app.EMGAnalysisPanel.Visible = 0; % Hides EMG Burst Analysis
            % app.SilentPeriodPanel.Visible = 0; % Hides SP/ActiveMEP Analysis
            % app.MCDCheckBox.Visible = 0; % Hides MCD CheckBox
            % app.GroupAnalysisPanel.Visible = 0; %Hides Group Analysis Option
            % app.RecruitmentCurvePanel.Visible = 0; %Hides Recruitment Curve Analysis Options
            % % app.Panel_17.Visible = 0; %Hides Block Event options
            % app.Visualize.Visible = 0; %Hides Visualize Panel
            % app.Processing.Visible = 0; %Hides Processing indicator
            % app.ConditionsListBox.Visible = 0; %Hides Condition Select Options
            % app.ConditionsListBoxLabel.Visible  = 0; %Hides Condition Select Options
            % app.TrialSelectPanel.Visible = 0; %Hides Condition Select Options
            % app.MultiConditionAnalysisCheckBox.Visible = 0; %Hides Condition Select Options
            % app.ConditionMeanCheckBox.Visible = 0; %Hides Condition Select Options
            % app.PairedPulse.Visible = 0;  %Hides Paired Pulse Panel
            % app.PreStimMeanEditField.Value = 0; %resete mean
            % app.PreStimStdEditField.Value = 0'; %resets STD
            app.SampleRateHzEditField.Value = 0; %resets SampleRate
            % app.EnableCheckBox.Value = 0;
            % app.Mapping.Visible = 0; %Hides Mapping MultiSelect
            %EnableCheckBoxValueChanged(app, event)
            % app.boltzY =[];            % RC Boltzman Fit
            % app.boltzX =[];              % RC Boltzman Fit
            % app.rcData =[];               % RC Trial x Trial MEP
            % app.percentMT =[];           % RC X axis
            % app.RC =[] ;               % used to save mat file
            % app.OnsetThrePlot =[];       % Contains threshold onset plot
            % app.OffsetThrePlot =[];      % Contains threshold offset plot
            % app.PreMeanPlot =[];        % Contains premean plot
            % app.wave =[];               % Contains raw data for Spike type data
            % app.tick =[];               % Contains tick information for Spike type data
            % app.tickcat =[];            % Contains tick information for Spike type data
            % app.TableRow =[];           % Contains UITable information to delete
            % app.condition =[];        % Contains condition IDX for MultiSelect
            % app.conditionOutcome =[];   % Contains condition outcome for MultiSelect
        end

        % Button pushed function: InitializationButton
        function InitializationButtonPushed(app, event)
            app.reloadInd = 0;
            app.ProcessInd = 0;
            app.allData = [];
            app.time = [];
            %             app.TrialSelectPanel.Visible = 'off';
            app.ConditionsListBox.Visible = 0;  % condition list box visibility is an important sign of if conditions is applied

            %             app.ChannelSpinner.Value = 1;
            app.TrialSpinner.Value = 1;
            app.dataOut = [];
            app.overRideMark = [];
            app.EMGBurstoverRideMark = [];
            app.EMGAnalysisOR = [];
            app.tempH = 0;
            % app.TrialsnotfoundEditField.Visible = 0;
            % app.TrialsnotfoundEditFieldLabel.Visible = 0;
            app.AnalyzeInd = 0;
            app.Patch = [];
            app.SampleRateHzEditField.Value = 0;
            app.Ztrans = 0;
            app.Prolific = 0;

            %             app.clickCount = 0;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenNewFileMenu = uimenu(app.FileMenu);
            app.OpenNewFileMenu.Visible = 'off';
            app.OpenNewFileMenu.Text = 'Open New File';
            app.CustomMenu = uimenu(app.OpenNewFileMenu);
            app.CustomMenu.Text = 'Custom';
            app.GeorgetownMenu = uimenu(app.CustomMenu);
            app.GeorgetownMenu.Text = 'Georgetown';
            app.RochesterMenu = uimenu(app.CustomMenu);
            app.RochesterMenu.MenuSelectedFcn = createCallbackFcn(app, @RochesterMenuSelected, true);
            app.RochesterMenu.Text = 'Rochester';
            app.CCFMenu = uimenu(app.CustomMenu);
            app.CCFMenu.Text = 'CCF';
            app.MCRRMenu = uimenu(app.CustomMenu);
            app.MCRRMenu.Text = 'MCRR';
            app.LoadxdffileMenu = uimenu(app.FileMenu);
            app.LoadxdffileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadxdffileMenuSelected, true);
            app.LoadxdffileMenu.Text = 'Load .xdf file';
            app.RefreshMenu = uimenu(app.UIFigure);
            app.RefreshMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshMenuSelected, true);
            app.RefreshMenu.Text = 'Refresh';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Y')
            app.SetEventWindowPanel = uipanel(app.UIFigure);
            app.SetEventWindowPanel.Title = 'Set Event Window';
            app.FindallEventsButton = uibutton(app.SetEventWindowPanel, 'push');
            app.FindallEventsButton.ButtonPushedFcn = createCallbackFcn(app, @FindallEventsButtonPushed, true);
            app.FindallEventsButton.Text = 'Find all Events';
            app.ChannelSpinnerLabel = uilabel(app.SetEventWindowPanel);
            app.ChannelSpinnerLabel.Text = 'Channel #';
            app.ChannelSpinner = uispinner(app.SetEventWindowPanel);
            app.ChannelSpinner.ValueChangingFcn = createCallbackFcn(app, @FindallEventsButtonPushed, true);
            app.ChannelSpinner.ValueChangedFcn = createCallbackFcn(app, @ChannelSpinnerValueChanged, true);
            app.ChannelSpinner.Value = 1;
            app.TrialSpinnerLabel = uilabel(app.SetEventWindowPanel);
            app.TrialSpinnerLabel.Text = 'Trial #';
            app.TrialSpinner = uispinner(app.SetEventWindowPanel);
            app.TrialSpinner.ValueChangingFcn = createCallbackFcn(app, @FindallEventsButtonPushed, true);
            app.TrialSpinner.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.TrialSpinner.Value = 1;
            app.PreCueDursLabel = uilabel(app.SetEventWindowPanel);
            app.PreCueDursLabel.Text = 'Pre Cue';
            app.PreCue = uieditfield(app.SetEventWindowPanel, 'text');
            app.PreCue.ValueChangedFcn = createCallbackFcn(app, @PreCueValueChanged, true);
            app.PreCue.Value = '3';
            app.PostCueLabel = uilabel(app.SetEventWindowPanel);
            app.PostCueLabel.Text = 'Post Cue';
            app.PostCue = uieditfield(app.SetEventWindowPanel, 'text');
            app.PostCue.ValueChangedFcn = createCallbackFcn(app, @PostCueValueChanged, true);
            app.PostCue.Value = '3';
            app.SelectMultipleTrialsButton = uibutton(app.SetEventWindowPanel, 'push');
            app.SelectMultipleTrialsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMultipleTrialsButtonPushed, true);
            app.SelectMultipleTrialsButton.Text = 'Select Multiple Trials';
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'Path';
            app.Path = uieditfield(app.UIFigure, 'text');
            app.Path.ValueChangedFcn = createCallbackFcn(app, @PathValueChanged, true);
            app.Path.Editable = 'off';
            app.Path.Enable = 'off';
            app.Path.Placeholder = '/Users/yourname/Downloads';
            app.TrialSelectPanel = uipanel(app.UIFigure);
            app.TrialSelectPanel.Title = 'Trial Select';
            app.TrialSelectPanel.Visible = 'off';
            app.TrialSelectPanel.SizeChangedFcn = createCallbackFcn(app, @TrialSelectPanelSizeChanged, true);
            app.MultiChanListBox = uilistbox(app.TrialSelectPanel);
            app.MultiChanListBox.Multiselect = 'on';
            app.MultiChanListBox.ValueChangedFcn = createCallbackFcn(app, @TrialSpinnerValueChanged, true);
            app.MultiChanListBox.Value = {};
            app.OKButton = uibutton(app.TrialSelectPanel, 'push');
            app.OKButton.Text = 'OK';
            app.DefineConditionButton = uibutton(app.TrialSelectPanel, 'push');
            app.DefineConditionButton.ButtonPushedFcn = createCallbackFcn(app, @DefineConditionButtonPushed, true);
            app.DefineConditionButton.Text = {'Define'; 'Condition'};
            app.SampleRateHzEditFieldLabel = uilabel(app.UIFigure);
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.SampleRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @SampleRateHzEditFieldValueChanged, true);
            app.SampleRateHzEditField.Editable = 'off';
            app.DefineConditionsPanel = uipanel(app.UIFigure);
            app.DefineConditionsPanel.Title = 'Define Conditions';
            app.UITable = uitable(app.DefineConditionsPanel);
            app.UITable.ColumnName = {'Pages'; 'Group Name'; 'Channel'; 'x'; 'y'; 'z'; 'Grid'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.ImportEventsButton = uibutton(app.DefineConditionsPanel, 'push');
            app.ImportEventsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEventsButtonPushed, true);
            app.ImportEventsButton.Text = {'Import Events'; ''};
            app.SaveButton = uibutton(app.DefineConditionsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.DefineConditionsPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ChangepathButton = uibutton(app.UIFigure, 'push');
            app.ChangepathButton.ButtonPushedFcn = createCallbackFcn(app, @ChangepathButtonPushed, true);
            app.ChangepathButton.Text = 'Change path';
            app.EventNameListBox = uilistbox(app.UIFigure);
            app.EventNameListBox.ValueChangedFcn = createCallbackFcn(app, @EventNameListBoxValueChanged, true);
            app.EventNameListBox.Visible = 'off';
            app.EventNameListBoxLabel = uilabel(app.UIFigure);
            app.EventNameListBoxLabel.Visible = 'off';
            app.EventNameListBoxLabel.Text = 'Event Name';
            app.Citation = uilabel(app.UIFigure);
            app.Citation.Text = {'D. Cunningham, B. Zhang and A. Cahn, "Transcranial Magnetic Stimulation (TMS) Analysis Toolbox: '; 'A user friendly open source software for basic and advanced analysis and data sharing of TMS related '; 'outcomes", Brain Stimul., vol. 14, no. 6, pp. 1641-1642, Nov. 2021'};
            app.PleaseCiteLabel = uilabel(app.UIFigure);
            app.PleaseCiteLabel.Text = 'Please Cite:';
            app.ResetScaleButton = uibutton(app.UIFigure, 'push');
            app.ResetScaleButton.Visible = 'off';
            app.ResetScaleButton.Text = 'Reset Scale';
            app.YMin = uieditfield(app.UIFigure, 'text');
            app.YMin.Visible = 'off';
            app.YMin.Value = 'NA';
            app.YMax = uieditfield(app.UIFigure, 'text');
            app.YMax.Visible = 'off';
            app.YMax.Value = 'NA';
            app.XMin = uieditfield(app.UIFigure, 'text');
            app.XMin.Visible = 'off';
            app.XMin.Value = 'NA';
            app.XMax = uieditfield(app.UIFigure, 'text');
            app.XMax.Visible = 'off';
            app.XMax.Value = 'NA';
            app.MinLabel = uilabel(app.UIFigure);
            app.MinLabel.Visible = 'off';
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.UIFigure);
            app.MaxLabel.Visible = 'off';
            app.MaxLabel.Text = 'Max';
            app.YLabel = uilabel(app.UIFigure);
            app.YLabel.Visible = 'off';
            app.YLabel.Text = 'Y';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Visible = 'off';
            app.XLabel.Text = 'X';
            app.ScaleButton = uibutton(app.UIFigure, 'push');
            app.ScaleButton.Visible = 'off';
            app.ScaleButton.Text = 'Scale';
            app.EventTypePanel = uipanel(app.UIFigure);
            app.EventTypePanel.Title = 'Event Type';
            app.EventTypePanel.Visible = 'off';
            app.EventTypePanel.SizeChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup = uibuttongroup(app.EventTypePanel);
            app.CommentsButton = uiradiobutton(app.ButtonGroup);
            app.CommentsButton.Text = 'Comments';
            app.EventsegTTLButton = uiradiobutton(app.ButtonGroup);
            app.EventsegTTLButton.Text = 'Events (e.g. TTL)';
            app.BlocksFramesButton = uiradiobutton(app.ButtonGroup);
            app.BlocksFramesButton.Text = 'Blocks/Frames';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Visible = 'off';
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.InitializationButton = uibutton(app.UIFigure, 'push');
            app.InitializationButton.ButtonPushedFcn = createCallbackFcn(app, @InitializationButtonPushed, true);
            app.InitializationButton.Visible = 'off';
            app.InitializationButton.Text = 'Initialization';
            app.ConditionMeanCheckBox = uicheckbox(app.UIFigure);
            app.ConditionMeanCheckBox.Visible = 'off';
            app.ConditionMeanCheckBox.Text = {'Condition '; 'Mean'};
            app.MultiConditionAnalysisCheckBox = uicheckbox(app.UIFigure);
            app.MultiConditionAnalysisCheckBox.Visible = 'off';
            app.MultiConditionAnalysisCheckBox.Text = 'Multi Condition Analysis';
            app.ConditionsListBoxLabel = uilabel(app.UIFigure);
            app.ConditionsListBoxLabel.Visible = 'off';
            app.ConditionsListBoxLabel.Text = 'Conditions';
            app.ConditionsListBox = uilistbox(app.UIFigure);
            app.ConditionsListBox.Multiselect = 'on';
            app.ConditionsListBox.Visible = 'off';
            app.ConditionsListBox.Value = {};
            app.ProcessPanel = uipanel(app.UIFigure);
            app.ProcessPanel.Title = 'Process';
            app.ProcessPanel.Visible = 'off';
            app.EnableCheckBox = uicheckbox(app.ProcessPanel);
            app.EnableCheckBox.Text = 'Enable';
            app.Panel = uipanel(app.ProcessPanel);
            app.DownSampleCheckBox = uicheckbox(app.Panel);
            app.DownSampleCheckBox.Enable = 'off';
            app.DownSampleCheckBox.Text = 'Down Sample';
            app.FactorEditFieldLabel = uilabel(app.Panel);
            app.FactorEditFieldLabel.Enable = 'off';
            app.FactorEditFieldLabel.Text = 'Factor';
            app.FactorEditField = uieditfield(app.Panel, 'numeric');
            app.FactorEditField.Enable = 'off';
            app.FactorEditField.Value = 1;
            app.BandPassCheckBox = uicheckbox(app.Panel);
            app.BandPassCheckBox.Enable = 'off';
            app.BandPassCheckBox.Text = 'Band Pass';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Enable = 'off';
            app.HzEditFieldLabel = uilabel(app.Panel);
            app.HzEditFieldLabel.Enable = 'off';
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.Panel, 'numeric');
            app.HzEditField.Enable = 'off';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '---';
            app.NotchCheckBox = uicheckbox(app.Panel);
            app.NotchCheckBox.Enable = 'off';
            app.NotchCheckBox.Text = 'Notch ';
            app.HzEditField_2Label = uilabel(app.Panel);
            app.HzEditField_2Label.Enable = 'off';
            app.HzEditField_2Label.Text = 'Hz';
            app.HzEditField_2 = uieditfield(app.Panel, 'numeric');
            app.HzEditField_2.Enable = 'off';
            app.TKEOCheckBox = uicheckbox(app.Panel);
            app.TKEOCheckBox.Enable = 'off';
            app.TKEOCheckBox.Text = 'TKEO';
            app.RectifyCheckBox = uicheckbox(app.Panel);
            app.RectifyCheckBox.Enable = 'off';
            app.RectifyCheckBox.Text = 'Rectify';
            app.LowPassCheckBox = uicheckbox(app.Panel);
            app.LowPassCheckBox.Enable = 'off';
            app.LowPassCheckBox.Text = 'Low Pass';
            app.HzEditField_3Label = uilabel(app.Panel);
            app.HzEditField_3Label.Enable = 'off';
            app.HzEditField_3Label.Text = 'Hz';
            app.HzEditField_3 = uieditfield(app.Panel, 'numeric');
            app.HzEditField_3.Enable = 'off';
            app.msEditFieldLabel = uilabel(app.Panel);
            app.msEditFieldLabel.Enable = 'off';
            app.msEditFieldLabel.Text = 'ms';
            app.msEditField = uieditfield(app.Panel, 'numeric');
            app.msEditField.Enable = 'off';
            app.DelayCheckBox = uicheckbox(app.Panel);
            app.DelayCheckBox.Enable = 'off';
            app.DelayCheckBox.Text = 'Delay';
            app.VelocityCheckBox = uicheckbox(app.Panel);
            app.VelocityCheckBox.Enable = 'off';
            app.VelocityCheckBox.Text = 'Velocity';
            app.InvertCheckBox = uicheckbox(app.Panel);
            app.InvertCheckBox.Enable = 'off';
            app.InvertCheckBox.Text = 'Invert';
            app.ProcessButton = uibutton(app.ProcessPanel, 'push');
            app.ProcessButton.Text = 'Process';
            app.NonRectifiedLabel = uilabel(app.ProcessPanel);
            app.NonRectifiedLabel.Text = 'Non-Rectified =';
            app.NALabel = uilabel(app.ProcessPanel);
            app.NALabel.Text = 'NA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Modified_DIG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end